classdef CANTER_Processing_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        menu_exit                    matlab.ui.container.Menu
        menu_help                    matlab.ui.container.Menu
        help_wiki                    matlab.ui.container.Menu
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        ChooseApplicationPanel       matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        listbox1                     matlab.ui.control.ListBox
        GridLayout6                  matlab.ui.container.GridLayout
        pushbutton1                  matlab.ui.control.Button
        Procedure_description_panel  matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        Application_Description      matlab.ui.control.TextArea
        GridLayout4                  matlab.ui.container.GridLayout
        uipanel4                     matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        text2                        matlab.ui.control.Label
        text1                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CANTER_Auswertetool_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CANTER_Auswertetool (see VARARGIN)
            
            % Choose default command line output for CANTER_Auswertetool
            handles.output = hObject;
            
            % Update handles structure
            handles.options = struct([]);
            guidata(hObject, handles);
            
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObjects, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            menu_exit_Callback(app, event)
        end

        % Menu selected function: help_wiki
        function help_wiki_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to help_wiki (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % if clicked, open the help wiki on our github site
            web('https://github.com/CANTERhm/CANTER_Processing_Tool/wiki/1.-Start-the-CANTER-Processing-Tool','-browser');
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox1
            list_check = get(hObject,'Value');
            
            switch list_check
                case 1
                    app.Procedure_description_panel.Title = "Description: Hertz-Fit";
                    app.Application_Description.Value = ["With this processing tool it is possible to load force maps or text-files to fit the hertz or bihertz model on the force curves.";
                                                         "";
                                                         "You can load a single text-file or a folder containing several text-files.";
                                                         "";
                                                         "After loading a force map, you can also diplay all map channels with different interpolations (none, linear, bicubic).";
                                                         "";
                                                         "Switching the interpolation type is possible by clicking with the right mouse button on the map image."];
                case 2
                    app.Procedure_description_panel.Title = "Description: BiHertz-Fit";
                    app.Application_Description.Value = ["With this processing tool it is possible to load force maps or text-files to fit the hertz or bihertz model on the force curves.";
                                                         "";
                                                         "You can load a single text-file or a folder containing several text-files.";
                                                         "";
                                                         "After loading a force map, you can also diplay all map channels with different interpolations (none, linear, bicubic).";
                                                         "";
                                                         "Switching the interpolation type is possible by clicking with the right mouse button on the map image."];
                case 3
                    app.Procedure_description_panel.Title = "Description: Simple Filtering Tool";
                    app.Application_Description.Value = ["This filtering tool is designed for fast filtering of result data in a tsv (tab-separated values) file.";
                                                         "";
                                                         "The main purpose is to filter the results of the Hertz- or BiHertz-Fit tool with respect to the rsquare values. But in general, any tsv file can be filtered with this tool if the data is in a tabular form.";
                                                         "";
                                                         "When a tsv file is selected for filtering, two list dialogs are displayed one after the other. In these list dialogs the user selects:";
                                                         "In the first dialog: the data where the user whants to see the filtering changes on (e.g.: the Young's modulus column).";
                                                         "In the second dialog: the data column that will be used as the filtering criterion (e.g.: the rsquare value column).";
                                                         "";
                                                         "After filtering, an tsv file can be exported by clicking on 'Save' containing only the rows where the value in the filtering column is less than the set filter value."];
                case 4
                    app.Procedure_description_panel.Title = "Description: Histogram Plotting Tool";
                    app.Application_Description.Value = ["With this tool, data of a column in one or more tsv (tab-separated values) files can be plotted as a histogram and different fits can be applied.";
                                                         "";
                                                         "A lot of parameters can be customized like the histogram axes label, histogram title, plotting range, number of bins and fit function.";
                                                         "";
                                                         "When more than one tsv files are loaded, it is possible to combine and export them as a single tsv file by clicking on 'Merge Loaded tsv Files'";
                                                         "";
                                                         "As fit funciton, one can select either 'gauss1' (wich is a single Gaussian function) and 'gauss2' (which is the linear combination of two Gaussian functions).";
                                                         "";
                                                         "To save the displayed histogram to an image file, the whole contant of the axes can be exportet to an figure window by clicking on 'Open in Figure Window'. In this window, further changes can be done and afterwards exported as any image file supported by MATLAB."];
                case 5
                    app.Procedure_description_panel.Title = "Description: Lateral Deflection Processing";
                    app.Application_Description.Value = ["With this Processing Tool it is possible to load the lateral deflection channel from a .jpk image, which was first converted to a .xyz text file with the freeware Gwyddion.";
                                                         "";
                                                         "This tool plots the fast axis lines of the loaded image file and find peaks in the lines who have a certain prominence above the noise level of the line.";
                                                         "";
                                                         "The user can chose which peaks he wants to keep for the results of every line.";
                                                         "";
                                                         "When you click on the help button in the top menu bar of the GUI you get a more detailed explanation."];
                case 6
                    app.Procedure_description_panel.Title = "Description: Result Filtering Tool";
                    app.Application_Description.Value = ["With this tool you can filter result data interactively or highlight result data in the corresponding image channels.";
                                                         "";
                                                         "There are two filter modes that are available:";
                                                         "";
                                                         "1. by selecting a filter range in the result histogram, the corresponding values in the displayed image channels of the force maps are highlighted. You can display two image channels simultaneously.";
                                                         "";
                                                         "2. different mask geometries can be added to the image channels. The result histogram then contains only the values that are correlated with the masked force map pixels. The filtered result data can be saved as .tsv or .xlsx file."];
                case 7
                    app.Procedure_description_panel.Title = "Description: Sader-Method Calibration";
                    app.Application_Description.Value = ["With this little tool it is possible to calculate the spring constant of a rectengular cantilever using the Sader-Method";
                                                         "";
                                                         "You can load a sweep-file created with the NanoWizard I in the AC Air Mode.";
                                                         "";
                                                         "After loading the amplitude spectrum of the first resonance, you can enter the width and length of the cantilever, as well as, the viscosity and density of the surrounding medium (default values are from air at room temperature).";
                                                         "Subsequently, the spring constant can be calculated by simply clicking on the button 'Calculate'."];
            end
            
            guidata(hObject,handles);
        end

        % Menu selected function: menu_exit
        function menu_exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menu_exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(app)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            switch app.listbox1.Value
                case "BiHertz-Fit"
                    options = struct('list_object',1,'model','bihertz','bihertz_processing','','bihertz_baseline','',...
                                             'bihertz_results','','bihertz_save','');
                    options.bihertz_processing = 'automatic';
                    options.bihertz_baseline = 'offset_and_tilt';
                    options.bihertz_results = 'no';
                    options.bihertz_save = 'dont_save';
%                     bihertz_gui(options);
                    bihertz_gui_App(options);
                case "Hertz-Fit"
                    options = struct('list_object',2,'model','hertz','bihertz_processing','','bihertz_baseline','',...
                                             'bihertz_results','','bihertz_save','');
                    options.bihertz_processing = 'automatic';
                    options.bihertz_baseline = 'offset_and_tilt';
                    options.bihertz_results = 'no';
                    options.bihertz_save = 'dont_save';
%                     bihertz_gui(options);
                    bihertz_gui_App(options);
                case "Simple Filtering Tool"
                    SimpleFilteringTool
                case "Histogram Plotting Tool"
                    HistogramPlottingTool
                case "Lateral Deflection Processing"
                    lateral_dev_gui;
                case "Result Filtering Tool"
                    Hist_GUI;
                case "Sader-Method Calibration"
                    SaderMethodCalibration_GUI;
            end
            
            
            guidata(hObject,handles);
            menu_exit_Callback(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CANTER Processing Tool';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'Main_Window';
            app.menu_exit = uimenu(app.figure1);
            app.menu_exit.MenuSelectedFcn = createCallbackFcn(app, @menu_exit_Callback, true);
            app.menu_exit.Text = 'Exit';
            app.menu_exit.Tag = 'menu_exit';
            app.menu_help = uimenu(app.figure1);
            app.menu_help.Text = 'Help';
            app.menu_help.Tag = 'menu_help';
            app.help_wiki = uimenu(app.menu_help);
            app.help_wiki.MenuSelectedFcn = createCallbackFcn(app, @help_wiki_Callback, true);
            app.help_wiki.Text = 'Go to Wiki';
            app.help_wiki.Tag = 'help_wiki';
            app.GridLayout3 = uigridlayout(app.figure1);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.uipanel4 = uipanel(app.GridLayout4);
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout2 = uigridlayout(app.uipanel4);
            app.text1 = uilabel(app.GridLayout2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'CANTER Processing Tool';
            app.text2 = uilabel(app.GridLayout2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'The essential tool for AFM data processing';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Procedure_description_panel = uipanel(app.GridLayout5);
            app.Procedure_description_panel.Title = 'Description: Hertz-Fit';
            app.Procedure_description_panel.Tag = 'bihertz_options';
            app.GridLayout7 = uigridlayout(app.Procedure_description_panel);
            app.Application_Description = uitextarea(app.GridLayout7);
            app.Application_Description.Value = {'With this processing tool it is possible to load force maps or text-files to fit the hertz or bihertz model on the force curves.'; ''; 'You can load a single text-file or a folder containing several text-files.'; ''; 'After loading a force map, you can also diplay all map channels with different interpolations (none, linear, bicubic).'; ''; 'Switching the interpolation type is possible by clicking with the right mouse button on the map image.'};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.pushbutton1 = uibutton(app.GridLayout6, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Start Application';
            app.ChooseApplicationPanel = uipanel(app.GridLayout5);
            app.ChooseApplicationPanel.Title = 'Choose Application:';
            app.GridLayout8 = uigridlayout(app.ChooseApplicationPanel);
            app.listbox1 = uilistbox(app.GridLayout8);
            app.listbox1.Items = {'Hertz-Fit', 'BiHertz-Fit', 'Simple Filtering Tool', 'Histogram Plotting Tool', 'Lateral Deflection Processing', 'Result Filtering Tool', 'Sader-Method Calibration'};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = 'Hertz-Fit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CANTER_Processing_Tool(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CANTER_Auswertetool_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end