classdef Calibration_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        get_values_button   matlab.ui.control.Button
        button_submit       matlab.ui.control.Button
        uipanel1            matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        text9               matlab.ui.control.Label
        uipanel4            matlab.ui.container.Panel
        GridLayout4         matlab.ui.container.GridLayout
        text8               matlab.ui.control.Label
        spring_const_value  matlab.ui.control.EditField
        text7               matlab.ui.control.Label
        uipanel2            matlab.ui.container.Panel
        GridLayout5         matlab.ui.container.GridLayout
        text4               matlab.ui.control.Label
        sensitivity_value   matlab.ui.control.EditField
        text3               matlab.ui.control.Label
        correction_group    matlab.ui.container.ButtonGroup
        no                  matlab.ui.control.RadioButton
        yes                 matlab.ui.control.RadioButton
        text2               matlab.ui.control.Label
    end

    
    properties (Access = public)
        app_output = []; % Public property used to share data with another app.
        app_CloseRequested = false; % Boolean public proberty that tells any onther app if the user wants to close this app.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Calibration_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Calibration (see VARARGIN)
            
            % Choose default command line output for Calibration
            handles.output = hObject;
            handles.options = varargin{1};
            if isfield(handles.options,'correction')
                switch handles.options.correction
                    case "yes"
                        app.yes.Value = 1;
                        app.no.Value = 0;
                    case "no"
                        app.yes.Value = 0;
                        app.no.Value = 1;
                    otherwise
                        % leave as is
                end
            end
            handles.options.correction = [];
            
            % Remember last sens. and spring const. values if available
            if isfield(handles.options,'sensitivity') && isfield(handles.options,'spring_const')
               handles.sensitivity_value.String = sprintf('%.2f',handles.options.sensitivity);
               handles.spring_const_value.String = sprintf('%.4f',handles.options.spring_const);
            end
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes Calibration wait for user response (see UIRESUME)
%             uiwait(handles.figure1);
        end

        % Button pushed function: button_submit
        function button_submit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_submit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            sens = get(handles.sensitivity_value,'String');
            sens = strrep(sens,',','.');
            
            cons = get(handles.spring_const_value,'String');
            cons = strrep(cons,',','.');
            
            comp = (strcmp(sens,'28.00') || strcmp(cons,'0.1400')) && (strcmp(handles.correction_group.SelectedObject.Tag,'yes')) ;
            
            % write selection to handles
            handles.options.correction = handles.correction_group.SelectedObject.Tag;
            
            sens = str2double(sens);
            
            if isnan(sens)
                set(handles.sensitivity_value,'String','28.00');
                warndlg('Sensitivity must be a numeric value!','Non numeric value error');
                return;
            end
            
            cons = str2double(cons);
            
            if isnan(cons)
                set(handles.spring_const_value,'String','0.1400');
                warndlg('Spring constant must be a numeric value!','Non numeric value error');
                return;
            end
            
            if comp
                answer = questdlg('Would you really like to work with a default value for sensitivity or/and spring constant?',...
                                  'default value question','Yes','No','No');
%                 answer = uiconfirm(confirm_fig,...
%                                    "Would you really like to work with a default value for sensitivity or/and spring constant?",...
%                                    "Default Value Question",...
%                                    "Icon","question",...
%                                    "Options",["Yes","No"],...
%                                    "DefaultOption",2,...
%                                    "CancelOption",2);
                if strcmp(answer,'No')
                    return;
                elseif strcmp(answer,'Yes')
                    handles.options(:).sensitivity = 0;
                    handles.options.sensitivity = sens;
            
                    handles.options(:).spring_const = 0;
                    handles.options.spring_const = cons;
            
                    switch get(handles.correction_group.SelectedObject,'Tag')
                    case 'yes'
                        handles.options(:).tip_sample_correction = '';
                        handles.options.tip_sample_correction = 'yes';
            
                    case 'no'
                        handles.options(:).tip_sample_correction = '';
                        handles.options.tip_sample_correction = 'no';
                    end
                end
            else
                handles.options(:).sensitivity = 0;
                if strcmp(handles.correction_group.SelectedObject.Tag,'yes')
                    handles.options.sensitivity = sens;
                end
            
                handles.options(:).spring_const = 0;
                if strcmp(handles.correction_group.SelectedObject.Tag,'yes')
                    handles.options.spring_const = cons;
                end
            
                switch get(handles.correction_group.SelectedObject,'Tag')
                    case 'yes'
                        handles.options(:).tip_sample_correction = '';
                        handles.options.tip_sample_correction = 'yes';
            
                    case 'no'
                        handles.options(:).tip_sample_correction = '';
                        handles.options.tip_sample_correction = 'no';
                end
%                 guidata(hObject,handles);
%                 uiresume(handles.figure1);
            end
            guidata(hObject,handles);
            app.app_output = handles.options;
            app.app_CloseRequested = true;
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            c = get(hObject, 'CurrentCharacter');
            switch c
                case 29
                    set(handles.no,'Value',1);
                    set(handles.yes,'Value',0);
                case 28
                    set(handles.no,'Value',0);
                    set(handles.yes,'Value',1);
            end
        end

        % Button pushed function: get_values_button
        function get_values_button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to get_values_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % call Calibration_file_generator
            if ~isfield(handles,'Calibration_values_struct')
               handles.Calibration_values_struct = struct('Sens_1',[],'Spring_1',[],...
                                                          'Sens_2',[],'Spring_2',[],...
                                                          'Sens_3',[],'Spring_3',[]);
            end
            % Call Calibration_file_generator app and wait till the app sets its
            % keep_waiting property to 0
            Cal_app = Calibration_file_generator(handles.Calibration_values_struct);
            waitfor(Cal_app,'keep_waiting',0);
            if ~isempty(Cal_app.Sens_output) || ~isempty(Cal_app.Spring_output)
                sens_num = Cal_app.Sens_output;
                spring_num = Cal_app.Spring_output;
                handles.Calibration_values_struct = Cal_app.Prev_values;
                handles.sensitivity_value.String = sprintf('%.2f',sens_num);
                handles.spring_const_value.String = sprintf('%.4f',spring_num);
            end
            delete(Cal_app);
            guidata(hObject,handles);
        end

        % Value changed function: sensitivity_value
        function sensitivity_value_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sensitivity_value (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of sensitivity_value as text
            %        str2double(get(hObject,'String')) returns contents of sensitivity_value as a double
            test = get(hObject,'String');
            test = strrep(test,',','.');
            hObject.String = test;
            test = str2double(test);
            if test <= 0
                set(hObject,'String','28.00')
                warndlg('Sensitivity must be a positive value','Error');
            end
        end

        % Value changed function: spring_const_value
        function spring_const_value_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to spring_const_value (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of spring_const_value as text
            %        str2double(get(hObject,'String')) returns contents of spring_const_value as a double
            test = get(hObject,'String');
            test = strrep(test,',','.');
            hObject.String = test;
            test = str2double(test);
            if test <= 0
                set(hObject,'String','0.1400')
                warndlg('Spring constant must be a positive value','Error');
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.app_CloseRequested = true;
            app.app_output = [];            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Calibration';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.text2 = uilabel(app.GridLayout);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Input of'; 'Calibration Values'};
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Calibration values';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout3 = uigridlayout(app.uipanel1);
            app.correction_group = uibuttongroup(app.GridLayout3);
            app.correction_group.Tag = 'correction_group';
            app.yes = uiradiobutton(app.correction_group);
            app.yes.Tag = 'yes';
            app.yes.Text = 'yes';
            app.yes.Value = true;
            app.no = uiradiobutton(app.correction_group);
            app.no.Tag = 'no';
            app.no.Text = 'no';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout5 = uigridlayout(app.uipanel2);
            app.text3 = uilabel(app.GridLayout5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Sensitivity:';
            app.sensitivity_value = uieditfield(app.GridLayout5, 'text');
            app.sensitivity_value.ValueChangedFcn = createCallbackFcn(app, @sensitivity_value_Callback, true);
            app.sensitivity_value.Tag = 'sensitivity_value';
            app.sensitivity_value.Value = '28.00';
            app.text4 = uilabel(app.GridLayout5);
            app.text4.Tag = 'text4';
            app.text4.Text = 'nm/V';
            app.uipanel4 = uipanel(app.GridLayout3);
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout4 = uigridlayout(app.uipanel4);
            app.text7 = uilabel(app.GridLayout4);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Spring constant:';
            app.spring_const_value = uieditfield(app.GridLayout4, 'text');
            app.spring_const_value.ValueChangedFcn = createCallbackFcn(app, @spring_const_value_Callback, true);
            app.spring_const_value.Tag = 'spring_const_value';
            app.spring_const_value.Value = '0.1400';
            app.text8 = uilabel(app.GridLayout4);
            app.text8.Tag = 'text8';
            app.text8.Text = 'N/m';
            app.text9 = uilabel(app.GridLayout3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Correct tip-sample-separation?';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.button_submit = uibutton(app.GridLayout2, 'push');
            app.button_submit.ButtonPushedFcn = createCallbackFcn(app, @button_submit_Callback, true);
            app.button_submit.Tag = 'button_submit';
            app.button_submit.Text = 'Submit';
            app.get_values_button = uibutton(app.GridLayout2, 'push');
            app.get_values_button.ButtonPushedFcn = createCallbackFcn(app, @get_values_button_Callback, true);
            app.get_values_button.Tag = 'get_values_button';
            app.get_values_button.Text = 'Get Values';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibration_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Calibration_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end