classdef Calibration_file_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cal_file_gen             matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        ResetButton              matlab.ui.control.Button
        SavetoFileButton         matlab.ui.control.Button
        InsertButton             matlab.ui.control.Button
        GridLayout5              matlab.ui.container.GridLayout
        Spring_mean_edit         matlab.ui.control.NumericEditField
        Sens_mean_edit           matlab.ui.control.NumericEditField
        NmLabel_4                matlab.ui.control.Label
        nmVLabel_4               matlab.ui.control.Label
        kLabel_4                 matlab.ui.control.Label
        SLabel_4                 matlab.ui.control.Label
        MeanvaluesLabel          matlab.ui.control.Label
        GridLayout4              matlab.ui.container.GridLayout
        Spring_3_edit            matlab.ui.control.NumericEditField
        Sens_3_edit              matlab.ui.control.NumericEditField
        NmLabel_3                matlab.ui.control.Label
        nmVLabel_3               matlab.ui.control.Label
        kLabel_3                 matlab.ui.control.Label
        SLabel_3                 matlab.ui.control.Label
        ThirdpairofvaluesLabel   matlab.ui.control.Label
        GridLayout3              matlab.ui.container.GridLayout
        Spring_2_edit            matlab.ui.control.NumericEditField
        Sens_2_edit              matlab.ui.control.NumericEditField
        NmLabel_2                matlab.ui.control.Label
        nmVLabel_2               matlab.ui.control.Label
        kLabel_2                 matlab.ui.control.Label
        SLabel_2                 matlab.ui.control.Label
        SecondpairofvaluesLabel  matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        FirstpairofvaluesLabel   matlab.ui.control.Label
        NmLabel                  matlab.ui.control.Label
        Spring_1_edit            matlab.ui.control.NumericEditField
        kLabel                   matlab.ui.control.Label
        nmVLabel                 matlab.ui.control.Label
        Sens_1_edit              matlab.ui.control.NumericEditField
        SLabel                   matlab.ui.control.Label
        CalibrationvaluecalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Prev_values = []; % In the StartUp Function, a struct with previous values (if the exist) will be stored here.
        Sens_output = []; % Output property for the calling GUI of the sensitivity value.
        Spring_output = []; % Output property for the calling GUI of the value of the spring constant.
        keep_waiting = 1; % Let the Calibration GUI wait while this app is doing its job. 
    end
    
    methods (Access = private)
        
        function update_mean_values(app)
            % get all value pairs
            sens_1 = app.Sens_1_edit.Value;
            spring_1 = app.Spring_1_edit.Value;
            sens_2 = app.Sens_2_edit.Value;
            spring_2 = app.Spring_2_edit.Value;
            sens_3 = app.Sens_3_edit.Value;
            spring_3 = app.Spring_3_edit.Value;
            
            % ignor zeros
            ignor_vector = [sens_1;spring_1;sens_2;spring_2;sens_3;spring_3];
            ignor_mask = ignor_vector == 0;
            ignor_vector(ignor_mask) = NaN;
            
            % calculate means without NaNs
            sens_vec = ignor_vector([1,3,5]);
            spring_vec = ignor_vector([2,4,6]);
            sens_mean = mean(sens_vec,'omitnan');
            spring_mean = mean(spring_vec,'omitnan');
            
            % update displayed mean values
            if isnan(sens_mean)
                app.Sens_mean_edit.Value = 0;
            else
                app.Sens_mean_edit.Value = sens_mean;
            end
            if isnan(spring_mean)
                app.Spring_mean_edit.Value = 0;
            else
                app.Spring_mean_edit.Value = spring_mean;
            end
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, prev_struct)
            screen_size = get(groot,'ScreenSiz');
            app_position = app.Cal_file_gen.Position;
            app.Cal_file_gen.Position = [(screen_size(3)-app_position(3))/2,(screen_size(4)-app_position(4))/2 ,app_position(3:4)];
            app.Prev_values = prev_struct;
            check_1 = isempty(app.Prev_values.Sens_1) || isempty(app.Prev_values.Spring_1);
            check_2 = isempty(app.Prev_values.Sens_2) || isempty(app.Prev_values.Spring_2);
            check_3 = isempty(app.Prev_values.Sens_3) || isempty(app.Prev_values.Spring_3);
            if ~any([check_1,check_2,check_3])
                app.Sens_1_edit.Value = app.Prev_values.Sens_1;
                app.Spring_1_edit.Value = app.Prev_values.Spring_1;
                app.Sens_2_edit.Value = app.Prev_values.Sens_2;
                app.Spring_2_edit.Value = app.Prev_values.Spring_2;
                app.Sens_3_edit.Value = app.Prev_values.Sens_3;
                app.Spring_3_edit.Value = app.Prev_values.Spring_3;
                app.update_mean_values;
            end
        end

        % Value changed function: Sens_1_edit
        function Sens_1_editValueChanged(app, event)
            update_mean_values(app);
        end

        % Value changed function: Spring_1_edit
        function Spring_1_editValueChanged(app, event)
            update_mean_values(app);
        end

        % Value changed function: Sens_2_edit
        function Sens_2_editValueChanged(app, event)
            update_mean_values(app);
        end

        % Value changed function: Spring_2_edit
        function Spring_2_editValueChanged(app, event)
            update_mean_values(app);
        end

        % Value changed function: Sens_3_edit
        function Sens_3_editValueChanged(app, event)
            update_mean_values(app);
        end

        % Value changed function: Spring_3_edit
        function Spring_3_editValueChanged(app, event)
            update_mean_values(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Sens_1_edit.Value = 0;
            app.Spring_1_edit.Value = 0;
            app.Sens_2_edit.Value = 0;
            app.Spring_2_edit.Value = 0;
            app.Sens_3_edit.Value = 0;
            app.Spring_3_edit.Value = 0;
            update_mean_values(app);
        end

        % Button pushed function: SavetoFileButton
        function SavetoFileButtonPushed(app, event)
            % get value pairs
            sens_1 = app.Sens_1_edit.Value;
            spring_1 = app.Spring_1_edit.Value;
            sens_2 = app.Sens_2_edit.Value;
            spring_2 = app.Spring_2_edit.Value;
            sens_3 = app.Sens_3_edit.Value;
            spring_3 = app.Spring_3_edit.Value;
            sens_mean = app.Sens_mean_edit.Value;
            spring_mean = app.Spring_mean_edit.Value;
            
            % calculate uifigure position for all following message dialogs
            app_pos = app.Cal_file_gen.Position;
            app_middles = [app_pos(1)+app_pos(3)/2 app_pos(2)+app_pos(4)/2];
            ui_fig_pos = [app_middles(1)-200,app_middles(2)-80,400,160];
            
            % check if there is something to save
            if sens_mean == 0 && spring_mean == 0
                alert_fig = uifigure('Position',ui_fig_pos);
                uialert(alert_fig,{'Both mean values are 0!','There is nothing to save!'},'No save values',"Modal",true,'Icon',"error",'CloseFcn','uiresume(gcbf)');
                uiwait(gcbf);
                delete(alert_fig);
                return;
            end
            
            % check for any zero in pair if not both are zero
            selection_1 = "";
            selection_2 = "";
            selection_3 = "";
            if xor(sens_1 == 0,spring_1 == 0)
                sel_fig = uifigure('Position',ui_fig_pos);
                selection_1 = uiconfirm(sel_fig,{'One value of the first value pair is 0!','Do you still want to save the values in a file?'},'Zero value',"Options",{'Yes','No'},"DefaultOption",'No');
                delete(sel_fig);
            end
            if xor(sens_2 == 0,spring_2 == 0)
                sel_fig = uifigure('Position',ui_fig_pos);
                selection_2 = uiconfirm(sel_fig,{'One value of the second value pair is 0!','Do you still want to save the values in a file?'},'Zero value',"Options",{'Yes','No'},"DefaultOption",'No');
                delete(sel_fig);
            end
            if xor(sens_3 == 0,spring_3 == 0)
                sel_fig = uifigure('Position',ui_fig_pos);
                selection_3 = uiconfirm(sel_fig,{'One value of the third value pair is 0!','Do you still want to save the values in a file?'},'Zero value',"Options",{'Yes','No'},"DefaultOption",'No');
                delete(sel_fig);
            end
            comp_string = string();
            comp_string(1,1) = selection_1;
            comp_string(2,1) = selection_2;
            comp_string(3,1) = selection_3;            
            if any(ismember(comp_string,'No'))
                return;
            end
            
            % get save path for calibration file (.txt-file)
            [save_file,save_path] = uiputfile('*.txt','Saveing of calibation file','Calibration file');
            if isequal(save_file,0) || isequal(save_path,0)
               return;
            end
            
            [~,split_file,split_ext] = fileparts(save_file);
            if ~strcmp(split_ext,'.txt')
             save_file = strcat(split_file,'.txt');
            end
            full_save_str = fullfile(save_path,save_file);
            
            % save all valid value pairs
            txt_file_ID = fopen(full_save_str,"wt");
            num_var = 1;
            if ~(sens_1 == 0 && spring_1 == 0)
                fprintf(txt_file_ID,'%d)\tS = %.2f nm/V\n\tk = %.4f N/m\n\n',num_var,sens_1,spring_1);
                num_var = num_var + 1;
            end
            if ~(sens_2 == 0 && spring_2 == 0)
                fprintf(txt_file_ID,'%d)\tS = %.2f nm/V\n\tk = %.4f N/m\n\n',num_var,sens_2,spring_2);
                num_var = num_var + 1;
            end
            if ~(sens_3 == 0 && spring_3 == 0)
                fprintf(txt_file_ID,'%d)\tS = %.2f nm/V\n\tk = %.4f N/m\n\n',num_var,sens_3,spring_3);
            end
            fprintf(txt_file_ID,'Mean values:\n\tS = %.2f nm/V\n\tk = %.4f N/m\n',sens_mean,spring_mean);
            
            fclose(txt_file_ID);
            
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
            % get mean values
            sens_mean = app.Sens_mean_edit.Value;
            spring_mean = app.Spring_mean_edit.Value;
            
            % calculate uifigure position for all following message dialogs
            app_pos = app.Cal_file_gen.Position;
            app_middles = [app_pos(1)+app_pos(3)/2 app_pos(2)+app_pos(4)/2];
            ui_fig_pos = [app_middles(1)-200,app_middles(2)-80,400,160];
                        
            % check if one or both mean values are empty
            selection = 'Yes';
            if sens_mean == 0 && spring_mean == 0
                sel_fig = uifigure('Position',ui_fig_pos);
                selection = uiconfirm(sel_fig,{'The mean values are both 0!','Do you still want to insert the values in the previous GUI?'},'Zero values',"Options",{'Yes','No'},"DefaultOption",'No');
                delete(sel_fig);
            elseif xor(sens_mean==0,spring_mean==0)
                if sens_mean == 0
                    sel_fig = uifigure('Position',ui_fig_pos);
                    selection = uiconfirm(sel_fig,{'The sensitivity mean value is 0!','Do you still want to insert it in the previous GUI?'},'Zero values',"Options",{'Yes','No'},"DefaultOption",'No');
                    delete(sel_fig);
                elseif spring_mean == 0
                    sel_fig = uifigure('Position',ui_fig_pos);
                    selection = uiconfirm(sel_fig,{'The spring constant mean value is 0!','Do you still want to insert it in the previous GUI?'},'Zero values',"Options",{'Yes','No'},"DefaultOption",'No');
                    delete(sel_fig);
                end
            end
            
            if strcmp(selection,'No')
                return;
            end
            
            % pass values back to Calibration GUI
            app.Prev_values.Sens_1 = app.Sens_1_edit.Value;
            app.Prev_values.Spring_1 = app.Spring_1_edit.Value;
            app.Prev_values.Sens_2 = app.Sens_2_edit.Value;
            app.Prev_values.Spring_2 = app.Spring_2_edit.Value;
            app.Prev_values.Sens_3 = app.Sens_3_edit.Value;
            app.Prev_values.Spring_3 = app.Spring_3_edit.Value;
            app.Sens_output = sens_mean;
            app.Spring_output = spring_mean;
            app.keep_waiting = 0;
        end

        % Close request function: Cal_file_gen
        function Cal_file_genCloseRequest(app, event)
            app.Sens_output = [];
            app.Spring_output = [];
            app.keep_waiting = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cal_file_gen = uifigure('Visible', 'off');
            colormap(app.Cal_file_gen, 'parula');
            app.Cal_file_gen.Name = 'Cal_file_gen';
            app.Cal_file_gen.CloseRequestFcn = createCallbackFcn(app, @Cal_file_genCloseRequest, true);
            app.GridLayout = uigridlayout(app.Cal_file_gen);
            app.CalibrationvaluecalculatorLabel = uilabel(app.GridLayout);
            app.CalibrationvaluecalculatorLabel.Text = 'Calibration value calculator';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SLabel = uilabel(app.GridLayout2);
            app.SLabel.Text = 'S =';
            app.Sens_1_edit = uieditfield(app.GridLayout2, 'numeric');
            app.Sens_1_edit.Limits = [0 Inf];
            app.Sens_1_edit.ValueDisplayFormat = '%.2f';
            app.Sens_1_edit.ValueChangedFcn = createCallbackFcn(app, @Sens_1_editValueChanged, true);
            app.nmVLabel = uilabel(app.GridLayout2);
            app.nmVLabel.Text = 'nm/V';
            app.kLabel = uilabel(app.GridLayout2);
            app.kLabel.Text = 'k =';
            app.Spring_1_edit = uieditfield(app.GridLayout2, 'numeric');
            app.Spring_1_edit.Limits = [0 Inf];
            app.Spring_1_edit.ValueDisplayFormat = '%.4f';
            app.Spring_1_edit.ValueChangedFcn = createCallbackFcn(app, @Spring_1_editValueChanged, true);
            app.NmLabel = uilabel(app.GridLayout2);
            app.NmLabel.Text = 'N/m';
            app.FirstpairofvaluesLabel = uilabel(app.GridLayout2);
            app.FirstpairofvaluesLabel.Text = 'First pair of values:';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SecondpairofvaluesLabel = uilabel(app.GridLayout3);
            app.SecondpairofvaluesLabel.Text = 'Second pair of values:';
            app.SLabel_2 = uilabel(app.GridLayout3);
            app.SLabel_2.Text = 'S =';
            app.kLabel_2 = uilabel(app.GridLayout3);
            app.kLabel_2.Text = 'k =';
            app.nmVLabel_2 = uilabel(app.GridLayout3);
            app.nmVLabel_2.Text = 'nm/V';
            app.NmLabel_2 = uilabel(app.GridLayout3);
            app.NmLabel_2.Text = 'N/m';
            app.Sens_2_edit = uieditfield(app.GridLayout3, 'numeric');
            app.Sens_2_edit.Limits = [0 Inf];
            app.Sens_2_edit.ValueDisplayFormat = '%.2f';
            app.Sens_2_edit.ValueChangedFcn = createCallbackFcn(app, @Sens_2_editValueChanged, true);
            app.Spring_2_edit = uieditfield(app.GridLayout3, 'numeric');
            app.Spring_2_edit.Limits = [0 Inf];
            app.Spring_2_edit.ValueDisplayFormat = '%.4f';
            app.Spring_2_edit.ValueChangedFcn = createCallbackFcn(app, @Spring_2_editValueChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.ThirdpairofvaluesLabel = uilabel(app.GridLayout4);
            app.ThirdpairofvaluesLabel.Text = 'Third pair of values:';
            app.SLabel_3 = uilabel(app.GridLayout4);
            app.SLabel_3.Text = 'S =';
            app.kLabel_3 = uilabel(app.GridLayout4);
            app.kLabel_3.Text = 'k =';
            app.nmVLabel_3 = uilabel(app.GridLayout4);
            app.nmVLabel_3.Text = 'nm/V';
            app.NmLabel_3 = uilabel(app.GridLayout4);
            app.NmLabel_3.Text = 'N/m';
            app.Sens_3_edit = uieditfield(app.GridLayout4, 'numeric');
            app.Sens_3_edit.Limits = [0 Inf];
            app.Sens_3_edit.ValueDisplayFormat = '%.2f';
            app.Sens_3_edit.ValueChangedFcn = createCallbackFcn(app, @Sens_3_editValueChanged, true);
            app.Spring_3_edit = uieditfield(app.GridLayout4, 'numeric');
            app.Spring_3_edit.Limits = [0 Inf];
            app.Spring_3_edit.ValueDisplayFormat = '%.4f';
            app.Spring_3_edit.ValueChangedFcn = createCallbackFcn(app, @Spring_3_editValueChanged, true);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.MeanvaluesLabel = uilabel(app.GridLayout5);
            app.MeanvaluesLabel.Text = 'Mean values:';
            app.SLabel_4 = uilabel(app.GridLayout5);
            app.SLabel_4.Text = 'S =';
            app.kLabel_4 = uilabel(app.GridLayout5);
            app.kLabel_4.Text = 'k =';
            app.nmVLabel_4 = uilabel(app.GridLayout5);
            app.nmVLabel_4.Text = 'nm/V';
            app.NmLabel_4 = uilabel(app.GridLayout5);
            app.NmLabel_4.Text = 'N/m';
            app.Sens_mean_edit = uieditfield(app.GridLayout5, 'numeric');
            app.Sens_mean_edit.Limits = [0 Inf];
            app.Sens_mean_edit.ValueDisplayFormat = '%.2f';
            app.Sens_mean_edit.Editable = 'off';
            app.Spring_mean_edit = uieditfield(app.GridLayout5, 'numeric');
            app.Spring_mean_edit.Limits = [0 Inf];
            app.Spring_mean_edit.ValueDisplayFormat = '%.4f';
            app.Spring_mean_edit.Editable = 'off';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.Panel);
            app.InsertButton = uibutton(app.GridLayout6, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert';
            app.SavetoFileButton = uibutton(app.GridLayout6, 'push');
            app.SavetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoFileButtonPushed, true);
            app.SavetoFileButton.Text = 'Save to File';
            app.ResetButton = uibutton(app.GridLayout6, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.Cal_file_gen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibration_file_generator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cal_file_gen)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cal_file_gen)
        end
    end
end