classdef canti_sample_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        parameter_quad_pyra      matlab.ui.container.ButtonGroup
        angle_edge               matlab.ui.control.RadioButton
        angle_face               matlab.ui.control.RadioButton
        edit1                    matlab.ui.control.EditField
        text5                    matlab.ui.control.Label
        text3                    matlab.ui.control.Label
        parameter_cone           matlab.ui.container.ButtonGroup
        cone_half_opening_angle  matlab.ui.control.EditField
        text5_3                  matlab.ui.control.Label
        text3_3                  matlab.ui.control.Label
        parameter_three_pyra     matlab.ui.container.ButtonGroup
        three_angle_face         matlab.ui.control.EditField
        text5_2                  matlab.ui.control.Label
        text3_2                  matlab.ui.control.Label
        parameter_flat_cylinder  matlab.ui.container.Panel
        GridLayout6              matlab.ui.container.GridLayout
        diameter_flat_unit       matlab.ui.control.DropDown
        diameter_flat_value      matlab.ui.control.EditField
        text12                   matlab.ui.control.Label
        GridLayout5              matlab.ui.container.GridLayout
        pushbutton1              matlab.ui.control.Button
        SamplePanel              matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        edit5                    matlab.ui.control.EditField
        text9                    matlab.ui.control.Label
        GeometryPanel            matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        popupmenu1               matlab.ui.control.DropDown
        GridLayout2              matlab.ui.container.GridLayout
        text2                    matlab.ui.control.Label
        axes_cantilever          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        app_output = []; % Public property for app output.
        app_CloseRequested = false; % Public property to detect if the closing of the app was requested.
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function canti_sample_gui_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to canti_sample_gui (see VARARGIN)

            
            % Choose default command line output for canti_sample_gui
            handles.output = hObject;
            
            % get options as input parameter

            handles.options = varargin{1};
            if length(varargin) > 1
                pre_settings = varargin{2};
                if isempty(pre_settings.PreviousGeometry) && strcmp(pre_settings.FileType,'txt')
                    app.popupmenu1.Value = "Flat cylinder";
                    ev = struct('Source',app.popupmenu1,'EventName','SelectionChanged');
                    popupmenu1_Callback(app, ev);
                elseif isempty(pre_settings.PreviousGeometry) && (strcmp(pre_settings.FileType,'jpk-force-map') || strcmp(pre_settings.FileType,'folder'))
                    app.popupmenu1.Value = "Four sided pyramid";
                    ev = struct('Source',app.popupmenu1,'EventName','SelectionChanged');
                    popupmenu1_Callback(app, ev);
                else
                    app.popupmenu1.Value = pre_settings.PreviousGeometry;
                    ev = struct('Source',app.popupmenu1,'EventName','SelectionChanged');
                    popupmenu1_Callback(app, ev);
                end
            end

            if ~isempty(pre_settings.PreviousAngleOption)
                switch pre_settings.PreviousAngleOption
                    case "angle_edge"
                        app.angle_edge.Value = 1;
                        app.angle_face.Value = 0;
                    case "angle_face"
                        app.angle_edge.Value = 0;
                        app.angle_face.Value = 1;
                    otherwise
                        disp("Unspecific Angle Option")
                end
            end

            if ~isempty(pre_settings.PreviousAngle) & ~isnan(pre_settings.PreviousAngle)
                switch pre_settings.PreviousGeometry
                    case "Four sided pyramid"
                        app.edit1.Value = pre_settings.PreviousAngle;
                    case "Three sided pyramid"
                        app.three_angle_face.Value = pre_settings.PreviousAngle;
                    case "Cone"
                        app.cone_half_opening_angle.Value = pre_settings.PreviousAngle;
                end
            end

            if ~isempty(pre_settings.PreviousUnit)
                app.diameter_flat_unit.Value = pre_settings.PreviousUnit;
            end

            if ~isempty(pre_settings.PreviousRadius) & ~isnan(pre_settings.PreviousRadius)
                app.diameter_flat_value.Value = pre_settings.PreviousRadius;
            end

            if ~isempty(pre_settings.PreviousPoisson)
                app.edit5.Value = pre_settings.PreviousPoisson;
            end

            % Update handles structure
            guidata(hObject, handles);
            
            % set gui images
            % axes(handles.axes_cantilever)
            % canti_ima = imread('cantilever_tip.png');
            % canti_ima(:,:,2) = canti_ima(:,:,1);
            % canti_ima(:,:,3) = canti_ima(:,:,1);
            % image(app.axes_cantilever,canti_ima);
            % axis off
            % axis image
            disableDefaultInteractivity(handles.axes_cantilever);
            handles.axes_cantilever.Toolbar.Visible = "off";
            % UIWAIT makes canti_sample_gui wait for user response (see UIRESUME)
%             uiwait(handles.figure1);
        end

        % Value changed function: diameter_flat_value
        function diameter_flat_value_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to diameter_flat_value (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of diameter_flat_value as text
            %        str2double(get(hObject,'String')) returns contents of diameter_flat_value as a double
            
            value_string = hObject.String;
            value_string = strrep(value_string,',','.');
            hObject.String = value_string;
            value = str2double(value_string);
            if isnan(value)
                hObject.String = '1.0';
            end
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            angle = get(hObject,'String');
            angle = strrep(angle,',','.');
            hObject.String = angle;
            angle = str2double(angle);
            if angle <= 0
                set(hObject,'String','17.5')
                warndlg('Angle must be a positive value','Error');
            end
            
            if isnan(angle)
                handles.edit1.String = '17.5';
                warndlg('Angle must be a numeric value!','Non numeric value error');
                return;
            end
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit5 as text
            %        str2double(get(hObject,'String')) returns contents of edit5 as a double
            pois = get(hObject,'String');
            pois = strrep(pois,',','.');
            hObject.String = pois;
            pois = str2double(pois);
            
            if isnan(pois)
                handles.edit5.String = 0.5;
                warndlg('Poisson ratio must be a numeric value!','Non numeric value error');
                return;
            end
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            content_cell = hObject.String;
            content_str = content_cell{hObject.Value};
            
            switch content_str
                case 'Four sided pyramid'
                    app.parameter_quad_pyra.Visible = "on";
                    app.parameter_three_pyra.Visible = "off";
                    app.parameter_cone.Visible = "off";
                    app.parameter_flat_cylinder.Visible = "off";
                    % display suitable image
                    axes(handles.axes_cantilever)
                    canti_ima = imread('cantilever_tip.png');
                    canti_ima(:,:,2) = canti_ima(:,:,1);
                    canti_ima(:,:,3) = canti_ima(:,:,1);
                    image(canti_ima);
                    axis off
                    axis image
                case "Three sided pyramid"
                    app.parameter_quad_pyra.Visible = "off";
                    app.parameter_three_pyra.Visible = "on";
                    app.parameter_cone.Visible = "off";
                    app.parameter_flat_cylinder.Visible = "off";
                    % display suitable image
                    axes(handles.axes_cantilever)
                    canti_ima = imread('Three-sided-pyramid.png');
                    canti_ima(:,:,2) = canti_ima(:,:,1);
                    canti_ima(:,:,3) = canti_ima(:,:,1);
                    image(canti_ima);
                    axis off
                    axis image
                case "Cone"
                    app.parameter_quad_pyra.Visible = "off";
                    app.parameter_three_pyra.Visible = "off";
                    app.parameter_cone.Visible = "on";
                    app.parameter_flat_cylinder.Visible = "off";
                    % display suitable image
                    axes(handles.axes_cantilever)
                    canti_ima = imread('Cone.png');
                    canti_ima(:,:,2) = canti_ima(:,:,1);
                    canti_ima(:,:,3) = canti_ima(:,:,1);
                    image(canti_ima);
                    axis off
                    axis image
                case 'Flat cylinder'
                    app.parameter_quad_pyra.Visible = "off";
                    app.parameter_three_pyra.Visible = "off";
                    app.parameter_cone.Visible = "off";
                    app.parameter_flat_cylinder.Visible = "on";
                    % display suitable image
                    axes(handles.axes_cantilever)
                    canti_ima = imread('flat_cylinder.png');
                    canti_ima(:,:,2) = canti_ima(:,:,1);
                    canti_ima(:,:,3) = canti_ima(:,:,1);
                    image(canti_ima);
                    axis off
                    axis image
            end
            disableDefaultInteractivity(handles.axes_cantilever);
            handles.axes_cantilever.Toolbar.Visible = "off";
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            pois_str = handles.edit5.String;
            pois = str2double(pois_str);
            
            switch handles.popupmenu1.Value
                case 1
                    handles.options.tip_shape = 'four_sided_pyramid';
                    switch handles.parameter_quad_pyra.SelectedObject.Tag
                        case 'angle_edge'
                            angle = handles.edit1.String;
                            angle = str2double(angle);
                        case 'angle_face'
                            angle = handles.edit1.String;
                            angle = atand(sqrt(2)*tand(str2double(angle)));
                    end
                    handles.options.tip_angle = angle;
                    handles.options.AngleRaw = app.edit1.Value;
                    handles.options.cylinder_radius = NaN;
                case 2
                    handles.options.tip_shape = 'three_sided_pyramid';
                    handles.options.tip_angle = str2double(app.three_angle_face.Value);
                    handles.options.AngleRaw = app.three_angle_face.Value;
                    handles.options.cylinder_radius = NaN;
                case 3
                    handles.options.tip_shape = 'cone';
                    handles.options.tip_angle = str2double(app.cone_half_opening_angle.Value);
                    handles.options.AngleRaw = app.cone_half_opening_angle.Value;
                    handles.options.cylinder_radius = NaN;
                case 4
                    handles.options.tip_shape = 'flat_cylinder';
                    radius_str = handles.diameter_flat_value.String;
                    radius = str2double(radius_str);
                    radius_cell = handles.diameter_flat_unit.String;
                    radius_unit = radius_cell{handles.diameter_flat_unit.Value};
                    switch radius_unit
                        case 'm'
                            handles.options.cylinder_radius = radius;
                        case 'cm'
                            handles.options.cylinder_radius = radius*1e-2;
                        case 'mm'
                            handles.options.cylinder_radius = radius*1e-3;
                        case 'µm'
                            handles.options.cylinder_radius = radius*1e-6;
                        case 'nm'
                            handles.options.cylinder_radius = radius*1e-9;
                    end
                    handles.options.tip_angle = NaN;
                    handles.options.GeometryModifier = [];
            end
            
            handles.options.PopupValue = app.popupmenu1.Value;
            handles.options.AngleButton = app.parameter_quad_pyra.SelectedObject.Tag;
            handles.options.RadiusRaw = app.diameter_flat_value.Value;
            handles.options.RadiusUnit = app.diameter_flat_unit.Value;
            handles.options.PoissRaw = pois_str;
            handles.options.poisson = pois;
            guidata(hObject,handles);
            app.app_output = handles.options;
            app.app_CloseRequested = true;
%             uiresume(handles.figure1);
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.app_output = [];
            app.app_CloseRequested = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Indentation Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.axes_cantilever = uiaxes(app.GridLayout2);
            app.axes_cantilever.Tag = 'axes_cantilever';
            app.text2 = uilabel(app.GridLayout2);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Indenter and Sample'; 'Parameters'};
            app.GeometryPanel = uipanel(app.GridLayout);
            app.GeometryPanel.Title = 'Geometry';
            app.GridLayout3 = uigridlayout(app.GeometryPanel);
            app.popupmenu1 = uidropdown(app.GridLayout3);
            app.popupmenu1.Items = {'Four sided pyramid', 'Three sided pyramid', 'Cone', 'Flat cylinder'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Four sided pyramid';
            app.SamplePanel = uipanel(app.GridLayout);
            app.SamplePanel.Title = 'Sample';
            app.GridLayout4 = uigridlayout(app.SamplePanel);
            app.text9 = uilabel(app.GridLayout4);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Poisson ratio:';
            app.edit5 = uieditfield(app.GridLayout4, 'text');
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '0.5';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.pushbutton1 = uibutton(app.GridLayout5, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Submit';
            app.parameter_flat_cylinder = uipanel(app.GridLayout);
            app.parameter_flat_cylinder.Title = 'Indenter Parameter';
            app.parameter_flat_cylinder.Visible = 'off';
            app.GridLayout6 = uigridlayout(app.parameter_flat_cylinder);
            app.text12 = uilabel(app.GridLayout6);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Indenter radius:';
            app.diameter_flat_value = uieditfield(app.GridLayout6, 'text');
            app.diameter_flat_value.ValueChangedFcn = createCallbackFcn(app, @diameter_flat_value_Callback, true);
            app.diameter_flat_value.Tag = 'diameter_flat_value';
            app.diameter_flat_value.Value = '1.0';
            app.diameter_flat_unit = uidropdown(app.GridLayout6);
            app.diameter_flat_unit.Items = {'m', 'cm', 'mm', 'µm', 'nm'};
            app.diameter_flat_unit.Tag = 'diameter_flat_unit';
            app.diameter_flat_unit.Value = 'mm';
            app.parameter_three_pyra = uibuttongroup(app.GridLayout);
            app.parameter_three_pyra.Title = 'Indenter Parameter';
            app.parameter_three_pyra.Visible = 'off';
            app.parameter_three_pyra.Tag = 'parameter_quad_pyra';
            app.text3_2 = uilabel(app.parameter_three_pyra);
            app.text3_2.Tag = 'text3';
            app.text3_2.Text = 'Half opening angle to face';
            app.text5_2 = uilabel(app.parameter_three_pyra);
            app.text5_2.Tag = 'text5';
            app.text5_2.Text = '°';
            app.three_angle_face = uieditfield(app.parameter_three_pyra, 'text');
            app.three_angle_face.Tag = 'edit1';
            app.three_angle_face.Value = '17.5';
            app.parameter_cone = uibuttongroup(app.GridLayout);
            app.parameter_cone.Title = 'Indenter Parameter';
            app.parameter_cone.Visible = 'off';
            app.parameter_cone.Tag = 'parameter_quad_pyra';
            app.text3_3 = uilabel(app.parameter_cone);
            app.text3_3.Tag = 'text3';
            app.text3_3.Text = 'Half opening angle of the cone';
            app.text5_3 = uilabel(app.parameter_cone);
            app.text5_3.Tag = 'text5';
            app.text5_3.Text = '°';
            app.cone_half_opening_angle = uieditfield(app.parameter_cone, 'text');
            app.cone_half_opening_angle.Tag = 'edit1';
            app.cone_half_opening_angle.Value = '17.5';
            app.parameter_quad_pyra = uibuttongroup(app.GridLayout);
            app.parameter_quad_pyra.Title = 'Indenter Parameter';
            app.parameter_quad_pyra.Tag = 'parameter_quad_pyra';
            app.text3 = uilabel(app.parameter_quad_pyra);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Angle:';
            app.text5 = uilabel(app.parameter_quad_pyra);
            app.text5.Tag = 'text5';
            app.text5.Text = '°';
            app.edit1 = uieditfield(app.parameter_quad_pyra, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '17.5';
            app.angle_face = uiradiobutton(app.parameter_quad_pyra);
            app.angle_face.Tag = 'angle_face';
            app.angle_face.Text = 'half angle to face';
            app.angle_edge = uiradiobutton(app.parameter_quad_pyra);
            app.angle_edge.Tag = 'angle_edge';
            app.angle_edge.Text = 'half angle to edge';
            app.angle_edge.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = canti_sample_gui_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)canti_sample_gui_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end