classdef HistogramPlottingTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistogramPlottingToolUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LeftMainGrid                   matlab.ui.container.GridLayout
        FitSettingsPanel               matlab.ui.container.Panel
        FitSettingsRowGrid             matlab.ui.container.GridLayout
        InitialGuessesGrid             matlab.ui.container.GridLayout
        PaLabel_c2                     matlab.ui.control.Label
        PaLabel_b2                     matlab.ui.control.Label
        PaLabel_c1                     matlab.ui.control.Label
        PaLabel_b1                     matlab.ui.control.Label
        c2EditField                    matlab.ui.control.NumericEditField
        c2EditFieldLabel               matlab.ui.control.Label
        b2EditField                    matlab.ui.control.NumericEditField
        b2EditFieldLabel               matlab.ui.control.Label
        a2EditField                    matlab.ui.control.NumericEditField
        a2EditFieldLabel               matlab.ui.control.Label
        c1EditField                    matlab.ui.control.NumericEditField
        c1EditFieldLabel               matlab.ui.control.Label
        b1EditField                    matlab.ui.control.NumericEditField
        b1EditFieldLabel               matlab.ui.control.Label
        a1EditField                    matlab.ui.control.NumericEditField
        a1EditFieldLabel               matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        ButtonGroupHelpPanel           matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        UseInitialGuessesButton        matlab.ui.control.RadioButton
        NoInitialGuessesButton         matlab.ui.control.RadioButton
        FitFunctionHelpGrid            matlab.ui.container.GridLayout
        FitFunctionDropDown            matlab.ui.control.DropDown
        FitFunctionDropDownLabel       matlab.ui.control.Label
        PlotSettingsPanel              matlab.ui.container.Panel
        LeftMiddleRows                 matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        NormalizationDropDown          matlab.ui.control.DropDown
        NormalizationLabel             matlab.ui.control.Label
        AnnotationBoxHelpGrid          matlab.ui.container.GridLayout
        UnitDropDown                   matlab.ui.control.DropDown
        UnitLabel                      matlab.ui.control.Label
        AnnotationShowCheckBox         matlab.ui.control.CheckBox
        AnnotationLabel                matlab.ui.control.Label
        HelpGridBinNum                 matlab.ui.container.GridLayout
        BinNumLabel                    matlab.ui.control.Label
        BinNumGrid                     matlab.ui.container.GridLayout
        BinNumEditField                matlab.ui.control.NumericEditField
        PlotRangeResetHelpGrid         matlab.ui.container.GridLayout
        ResetButtonGrid                matlab.ui.container.GridLayout
        ResetRangeButton               matlab.ui.control.Button
        PlotRangeGrid                  matlab.ui.container.GridLayout
        HelpGridPlotRange              matlab.ui.container.GridLayout
        PaLabel_2                      matlab.ui.control.Label
        PaLabel                        matlab.ui.control.Label
        PlotRangeEnd                   matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        PlotRangeStart                 matlab.ui.control.NumericEditField
        PlotRangeLabel                 matlab.ui.control.Label
        LeftTopRows                    matlab.ui.container.GridLayout
        GridLayout2_2                  matlab.ui.container.GridLayout
        TitleEditField                 matlab.ui.control.EditField
        xLabelEditField_2Label         matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        SIUnitEditField                matlab.ui.control.EditField
        SIUnitEditFieldLabel           matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        xLabelEditField                matlab.ui.control.EditField
        xLabelEditFieldLabel           matlab.ui.control.Label
        OpenButtonGrid                 matlab.ui.container.GridLayout
        OpenButton                     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        RightMainGrid                  matlab.ui.container.GridLayout
        RightBottomHelpGrid            matlab.ui.container.GridLayout
        OpeninFigureWindowButton       matlab.ui.control.Button
        MergeLoadedtsvFilesButton      matlab.ui.control.Button
        HistogramAxes                  matlab.ui.control.UIAxes
        PlotContextMenu                matlab.ui.container.ContextMenu
        ShowFitResultsMenu             matlab.ui.container.Menu
        OpeninFigureMenu               matlab.ui.container.Menu
        MergetsvFilesMenu              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        HistoPlotOption = "stacked"; % Defines if the histogram is plotted as stacked bars or as a uni-color histogram
        DefOpenPath = []; % Default path for the open folder button.
        DefSavePath = []; % Default path for saving (Button: Merge Loaded tsv Files).
        SelectedFiles = []; % String array containing the names of the files the user selected.
        PlotValues_Raw = []; % % Cell array containing all values loaded from the tsv files.
        PlotValues = []; % Cell array containing all values loaded from the tsv files for plotting.
        PlotValues_scaled = []; % Cell array containing the loaded values scaled for the maximum order of magnitude.
        UserDefinedRange = false; % Boolean detecting if the user set a specific plot range.
        HistBinEdges = []; % Description
        NValues = []; % Number of values for each loaded file.
        BinCounts = []; % Array of the individual bin counts per file.
        HistHandle = []; % Handle of the plotted histogram.
        FitObject = []; % Fit object of the selected function on the histogram.
        FitLine = []; % Handle to the plotted line of the fit.
        FitG1Line = []; % Handle to the plotted line of the 1st gauss function.
        FitG2Line = []; % Handle to the plotted line of the 2nd gauss function.
        ScaledLabel = "Pa"; % Unit label "scaled" for the order of magnetude.
        BinCenters = []; % Center position of the histogram bins.
        Saved_OoM = 0; % Previously determined order of magnitude of the loaded data.
        LegendObject = []; % Handle to the legend object.
    end
    
    methods (Access = public)
        
        function PlotandFitValues(app)
            if isempty(app.PlotValues)
                return;
            end
            % Get Selections
            value_cell = app.PlotValues;
            bin_number = app.BinNumEditField.Value;
            annotation_status = app.AnnotationShowCheckBox.Value;
            si_unit = app.SIUnitEditField.Value;
            
            
            % Determine HistEdges
            if app.UserDefinedRange
                plot_min = app.PlotRangeStart.Value.*10^(app.Saved_OoM);
                plot_max = app.PlotRangeEnd.Value.*10^(app.Saved_OoM);
                HistEdges = linspace(plot_min,plot_max,bin_number);
            else
                column_vec = [];
                for j = 1:length(value_cell)
                    curr_vec = value_cell{j};
                    column_vec = [column_vec;curr_vec];
                end
                [~,HistEdges] = discretize(column_vec,bin_number);
            end

            % Determine Order of Magnitude
            VecMax = max(HistEdges);
            [OoM,Unit_Label] = DetOrdofMag(app,VecMax,si_unit);
            app.Saved_OoM = OoM;
            
            % Set Plot Range
            if ~app.UserDefinedRange
                app.PlotRangeStart.Value = min(HistEdges)./10^(OoM);
                app.PlotRangeEnd.Value = max(HistEdges)./10^(OoM);
            end

            % Set new unit labels
            app.ScaledLabel = Unit_Label;
            SetUnitLabels(app)
            resetAnnoUnit(app)

            % Correct Vector_Cell for OoM
            value_cell_scaled = value_cell;
            for j = 1:length(value_cell)
                value_cell_scaled{j} = value_cell{j}./10^(OoM);
            end
            app.PlotValues_scaled = value_cell_scaled;

            % Correct HistEdges
            HistEdges = HistEdges./10^(OoM);
            app.HistBinEdges = HistEdges;

            % Calculate Counts and Value numbers
            Counts = [];
            N_values = zeros(length(value_cell_scaled),1);
            for i = 1:length(value_cell_scaled)
                curr_vec = value_cell_scaled{i};
                N_values(i) = length(curr_vec);
                curr_counts = histcounts(curr_vec,HistEdges);
                Counts(i,:) = curr_counts;
            end
            app.NValues = N_values;
            app.BinCounts = Counts;

            % Calculate BinCenters
            bin_centers = HistEdges + (HistEdges(2)-HistEdges(1))/2;
            bin_centers(end) = [];
            app.BinCenters = bin_centers;

            % Plot Histogram
            cla(app.HistogramAxes);
            legend(app.HistogramAxes,"off")
            switch app.HistoPlotOption
                case "stacked"
                    app.HistHandle = bar(app.HistogramAxes,bin_centers,Counts,1,"stacked");
                case "classic"
                    app.HistHandle = bar(app.HistogramAxes,bin_centers,sum(Counts,1),1);
            end

            % Correct Displayed Plot Range
            if app.UserDefinedRange
                xlim(app.HistogramAxes,[app.PlotRangeStart.Value,app.PlotRangeEnd.Value])
            else
                xlim(app.HistogramAxes,"auto")
            end

            % Fit selected model
            FitSelectedModel(app)

            % Plot Fit Results
            PlotFitResults(app)

            % Add Annotation
            if annotation_status
                AddAnnotation(app)
            end

        end
        
        function [OoM,Unit_Label] = DetOrdofMag(~,VecMax,SiUnit)
            MaxOrd = floor(log10(VecMax));
            
            % Determine Unit and OoM (Order of Magnitude)
            if (MaxOrd >= -15) && (MaxOrd < -12)
                OoM = -15;
                unit = "f"+SiUnit;
            elseif (MaxOrd >= -12) && (MaxOrd < -9)
                OoM = -12;
                unit = "p"+SiUnit;
            elseif (MaxOrd >= -9) && (MaxOrd < -6)
                OoM = -9;
                unit = "n"+SiUnit;
            elseif (MaxOrd >= -6) && (MaxOrd < -3)
                OoM = -6;
                unit = "µ"+SiUnit;
            elseif (MaxOrd >= -3) && (MaxOrd < 0)
                OoM = -3;
                unit = "m"+SiUnit;
            elseif (MaxOrd >= 0) && (MaxOrd < 3)
                OoM = 0;
                unit = SiUnit;
            elseif (MaxOrd >= 3) && (MaxOrd < 6)
                OoM = 3;
                unit = "k"+SiUnit;
            elseif (MaxOrd >= 6) && (MaxOrd < 9)
                OoM = 6;
                unit = "M"+SiUnit;
            elseif (MaxOrd >= 9) && (MaxOrd < 12)
                OoM = 9;
                unit = "G"+SiUnit;
            elseif (MaxOrd >= 12) && (MaxOrd < 15)
                OoM = 12;
                unit = "T"+SiUnit;
            elseif (MaxOrd >= 15) && (MaxOrd < 18)
                OoM = 15;
                unit = "P"+SiUnit;
            else
                OoM = 0;
                unit = SiUnit;
            end

            Unit_Label = unit;
        end
        
        function SetUnitLabels(app)
            switch app.NormalizationDropDown.Value

                case "None"
                    app.PaLabel.Text = " "+app.ScaledLabel;
                    app.PaLabel_2.Text = " "+app.ScaledLabel;
                    app.PaLabel_b1.Text = app.ScaledLabel;
                    app.PaLabel_c1.Text = app.ScaledLabel;
                    app.PaLabel_b2.Text = app.ScaledLabel;
                    app.PaLabel_c2.Text = app.ScaledLabel;
                    if ~isempty(app.SIUnitEditField.Value)
                        x_label = app.xLabelEditField.Value + " [" + app.ScaledLabel + "]";
                    else
                        x_label = app.xLabelEditField.Value;
                    end
                
                case "Log"
                    noUnitLabel = replace(app.ScaledLabel,app.SIUnitEditField.Value,"");
                    app.PaLabel.Text = " "+noUnitLabel;
                    app.PaLabel_2.Text = " "+noUnitLabel;
                    app.PaLabel_b1.Text = noUnitLabel;
                    app.PaLabel_c1.Text = noUnitLabel;
                    app.PaLabel_b2.Text = noUnitLabel;
                    app.PaLabel_c2.Text = noUnitLabel;
    
                    if ~isempty(app.SIUnitEditField.Value)
                        x_label = "ln(" + app.xLabelEditField.Value + " / " + app.ScaledLabel + ")";
                    else
                        x_label = "ln(" + app.xLabelEditField.Value + ")";
                    end
    
                    if isempty(app.xLabelEditField.Value) && ~isempty(app.SIUnitEditField.Value)
                        x_label = "ln( 1 / " + app.ScaledLabel + ")";
                    end
    
                    if isempty(app.xLabelEditField.Value) && isempty(app.SIUnitEditField.Value)
                        x_label = "";
                    end
            end

            app.HistogramAxes.XLabel.String = x_label;     
        end
        
        function FitSelectedModel(app)

            fitFunc = app.FitFunctionDropDown.Value;
            y_vec = sum(app.BinCounts,1);
            [xFit, yFit] = prepareCurveData(app.BinCenters,y_vec);
            switch app.ButtonGroup.SelectedObject.Tag
                case "no"
                    try
                        FitObj = fit(xFit,yFit,fitFunc);
                    catch ME
                        ME.identifier
                        switch ME.identifier
                            case "curvefit:fit:nanComputed"
                                uialert(app.HistogramPlottingToolUIFigure,["The fit computed only NaN values!";"Try to refine the histogram parameters or use initial guesses for the fit parameters."],"Fit Computed NaN","Icon","error");
                                return;
                            otherwise
                                throw(ME);
                        end
                    end
                case "yes"
                    switch fitFunc
                        case "gauss1"
                            StartPoints = [app.a1EditField.Value,...
                                app.b1EditField.Value,...
                                app.c1EditField.Value];
                        case "gauss2"
                            StartPoints = [app.a1EditField.Value,...
                                app.b1EditField.Value,...
                                app.c1EditField.Value,...
                                app.a2EditField.Value,...
                                app.b2EditField.Value,...
                                app.c2EditField.Value];
                    end
                    try
                        FitObj = fit(xFit,yFit,fitFunc,"StartPoint",StartPoints);
                    catch ME
                        switch ME.identifier
                            case "curvefit:fit:nanComputed"
                                uialert(app.HistogramPlottingToolUIFigure,["The fit computed only NaN values!";"Try to refine the histogram parameters or the initial guesses for the fit parameters."],"Fit Computed NaN","Icon","error");
                                return;
                            otherwise
                                throw(ME);
                        end
                    end
            end

            app.FitObject = FitObj;
        end
        
        function PlotFitResults(app)

            if ~isempty(app.FitLine)
                delete(app.FitLine);
                app.FitLine = [];
            end
            if ~isempty(app.FitG1Line)
                delete(app.FitG1Line);
                app.FitG1Line = [];
            end
            if ~isempty(app.FitG2Line)
                delete(app.FitG2Line);
                app.FitG2Line = [];
            end
            
            hold(app.HistogramAxes,"on")

            plot_lims = xlim(app.HistogramAxes);
            x_fit = linspace(plot_lims(1),plot_lims(2),200);
            y_fit = feval(app.FitObject,x_fit);
            app.FitLine = plot(app.HistogramAxes,x_fit,y_fit,"-k","LineWidth",2);

            fitFunc = app.FitFunctionDropDown.Value;
            FitObj = app.FitObject;
            if strcmp(fitFunc,"gauss2")
                y_g1 = FitObj.a1.*exp(-((x_fit-FitObj.b1)./FitObj.c1).^2);
                y_g2 = FitObj.a2.*exp(-((x_fit-FitObj.b2)./FitObj.c2).^2);
                app.FitG1Line = plot(app.HistogramAxes,x_fit,y_g1,"--k","LineWidth",1.5);
                app.FitG2Line = plot(app.HistogramAxes,x_fit,y_g2,"--k","LineWidth",1.5);
            end
            
            hold(app.HistogramAxes,"off")
        end
        
        function AddAnnotation(app)
            if ~isempty(app.LegendObject)
                delete(app.LegendObject)
                app.LegendObject = [];
            end
            % Create Map String for Legend
            N_values = app.NValues;
            switch app.HistoPlotOption
                case "stacked"
                    map_string = string(zeros(length(N_values),1));
                    for i=1:length(N_values)
                        map_string(i) = sprintf("Map %d (N = %d)",i,N_values(i));
                    end
                case "classic"
                    map_string = sprintf("Histogram (N = %d)",sum(N_values));
            end

            fitFunc = app.FitFunctionDropDown.Value;
            FitObj = app.FitObject;
            unit = app.ScaledLabel;

            % Determine if the user set a different order of magnitude
            unit_dropdown = app.UnitDropDown.Value;
            OoM = app.Saved_OoM;
            if ~strcmp(unit_dropdown,unit)
                if strcmp(unit_dropdown,app.SIUnitEditField.Value)
                    selected_OoM = 0;
                else
                    scaler = unit_dropdown(1);
                    switch scaler
                        case "f"
                            selected_OoM = -15;
                        case "p"
                            selected_OoM = -12;
                        case "n"
                            selected_OoM = -9;
                        case "µ"
                            selected_OoM = -6;
                        case "m"
                            selected_OoM = -3;
                        case "k"
                            selected_OoM = 3;
                        case "M"
                            selected_OoM = 6;
                        case "G"
                            selected_OoM = 9;
                        case "T"
                            selected_OoM = 12;
                        case "P"
                            selected_OoM = 15;
                    end
                end
                unit_scale = 10^(OoM-selected_OoM);
            else 
                unit_scale = 1;
            end

            switch fitFunc
                case "gauss1"
                    switch app.NormalizationDropDown.Value
                        case "None"
                            b1 = FitObj.b1*unit_scale;
                            c1 = FitObj.c1*unit_scale;
                            
                            fit_string = "\mu = " + sprintf("%.2f\n",b1)...
                                +"\sigma = "+sprintf("%.2f",c1/sqrt(2));
                            app.LegendObject = legend(app.HistogramAxes,[map_string;fit_string],"Location","northeast");
                        case "Log"
                            b1 = FitObj.b1*unit_scale;
                            c1 = FitObj.c1*unit_scale;
                            
                            fit_string = sprintf("E = %.2f %s\n",b1,unit_dropdown)...
                                +"\sigma = "+sprintf("%.2f %s",c1/sqrt(2),unit_dropdown);
                            app.LegendObject = legend(app.HistogramAxes,[map_string;fit_string],"Location","northeast");
                    end
                case "gauss2"
                    switch app.NormalizationDropDown.Value
                        case "None"
                            b1 = FitObj.b1*unit_scale;
                            c1 = FitObj.c1*unit_scale;
                            b2 = FitObj.b2*unit_scale;
                            c2 = FitObj.c2*unit_scale;
                            
                            fit_string = sprintf("E_{1} = %.2f %s\n",b1,unit_dropdown)+"\sigma_{1} = "+sprintf("%.2f %s\n",c1/sqrt(2),unit_dropdown)+...
                                sprintf("E_{2} = %.2f %s\n",b2,unit_dropdown)+"\sigma_{2} = "+sprintf("%.2f %s",c2/sqrt(2),unit_dropdown);
                            app.LegendObject = legend(app.HistogramAxes,[map_string;fit_string],"Location","northeast");
                        case "Log"
                            b1 = FitObj.b1*unit_scale;
                            c1 = FitObj.c1*unit_scale;
                            b2 = FitObj.b2*unit_scale;
                            c2 = FitObj.c2*unit_scale;
                            
                            fit_string = "\mu_{1} = " + sprintf("%.2f\n",b1)+"\sigma_{1} = "+sprintf("%.2f\n",c1/sqrt(2))+...
                                "\mu_{2} = " + sprintf("%.2f\n",b2)+"\sigma_{2} = "+sprintf("%.2f",c2/sqrt(2));
                            app.LegendObject = legend(app.HistogramAxes,[map_string;fit_string],"Location","northeast");
                    end
            end
        end
        
        function EnableDisableGuessFields(app)
            state = app.ButtonGroup.SelectedObject.Tag;
            switch state
                case "no"
                    % Edit Fields
                    app.a1EditField.Enable = false;
                    app.a2EditField.Enable = false;
                    app.b1EditField.Enable = false;
                    app.b2EditField.Enable = false;
                    app.c1EditField.Enable = false;
                    app.c2EditField.Enable = false;
                    % Lables
                    app.a1EditFieldLabel.Enable = false;
                    app.b1EditFieldLabel.Enable = false;
                    app.c1EditFieldLabel.Enable = false;
                    app.a2EditFieldLabel.Enable = false;
                    app.b2EditFieldLabel.Enable = false;
                    app.c2EditFieldLabel.Enable = false;
                    % Units
                    app.PaLabel_b1.Enable = false;
                    app.PaLabel_c1.Enable = false;
                    app.PaLabel_b2.Enable = false;
                    app.PaLabel_c2.Enable = false;

                case "yes"
                    fitFunc = app.FitFunctionDropDown.Value;
                    % Edit Fields
                    app.a1EditField.Enable = true;
                    app.b1EditField.Enable = true;
                    app.c1EditField.Enable = true;
                    % Labels
                    app.a1EditFieldLabel.Enable = true;
                    app.b1EditFieldLabel.Enable = true;
                    app.c1EditFieldLabel.Enable = true;
                    % Units
                    app.PaLabel_b1.Enable = true;
                    app.PaLabel_c1.Enable = true;
                    if strcmp(fitFunc,"gauss2")
                        % Edit Fields
                        app.a2EditField.Enable = true;
                        app.b2EditField.Enable = true;
                        app.c2EditField.Enable = true;
                        % Labels
                        app.a2EditFieldLabel.Enable = true;
                        app.b2EditFieldLabel.Enable = true;
                        app.c2EditFieldLabel.Enable = true;
                        % Units
                        app.PaLabel_b2.Enable = true;
                        app.PaLabel_c2.Enable = true;
                    else
                        % Edit Fields
                        app.a2EditField.Enable = false;
                        app.b2EditField.Enable = false;
                        app.c2EditField.Enable = false;
                        % Labels
                        app.a2EditFieldLabel.Enable = false;
                        app.b2EditFieldLabel.Enable = false;
                        app.c2EditFieldLabel.Enable = false;
                        % Units
                        app.PaLabel_b2.Enable = false;
                        app.PaLabel_c2.Enable = false;
                    end
            end
        end
        
        function resetAnnoUnit(app)
            if isempty(app.ScaledLabel)
                return
            end
            SI_Unit = app.SIUnitEditField.Value;
            scaled_label = app.ScaledLabel;
            prefixes = ["f",;"p";"n";"µ";"m";"";"k";"M";"G";"T";"P"];
            new_list = strcat(prefixes,SI_Unit);
            app.UnitDropDown.Items = new_list;
            app.UnitDropDown.Value = scaled_label;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HistogramAxes.Toolbar.Visible = 0;
            disableDefaultInteractivity(app.HistogramAxes);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            default_path = fullfile(app.DefOpenPath,"*.tsv");
            [op_file,op_path] = uigetfile(default_path,"Select tsv files","MultiSelect","on");
            if isnumeric(op_file) && op_file == 0
                return;
            end
            app.DefOpenPath = op_path;
            if isempty(app.DefSavePath)
                app.DefSavePath = op_path;
            end
            files_string = string(op_file);
            app.SelectedFiles = files_string;
            All_Values = {};
            for i=1:length(files_string)
                curr_file = files_string(i);
                curr_full_path = fullfile(op_path,curr_file);
                importOpts = detectImportOptions(curr_full_path,"FileType","delimitedtext","TextType","string","ReadVariableNames",true,"VariableNamesLine",1,"Delimiter","\t");
                                
                varNames = string(importOpts.VariableNames);
                varTypes = string(importOpts.VariableTypes);
                varMask = strcmp(varTypes,"double") | strcmp(varTypes,"single") | strcmp(varTypes,"int8") | strcmp(varTypes,"int16") | strcmp(varTypes,"int32")...
                    | strcmp(varTypes,"int64") | strcmp(varTypes,"uint8") | strcmp(varTypes,"uint16") | strcmp(varTypes,"uint32") | strcmp(varTypes,"uint64");
                numericVars = varNames(varMask);
                if i == 1
                    [indx,tf] = listdlg('ListString',numericVars,"SelectionMode","single","Name","Select a numeric column","Listsize",[160 150]);
                    if tf == 0
                        return
                    end
                    readCol = numericVars(indx);
                end
                tbl = readtable(curr_full_path, importOpts);
                curr_vec = tbl.(readCol);
                All_Values{i} = curr_vec;
            end
            app.PlotValues_Raw = All_Values;
            
            switch app.NormalizationDropDown.Value
                case "None"
                    app.PlotValues = app.PlotValues_Raw;
                case "Log"
                    detected_neg = false;
                    for j = 1:length(All_Values)
                        curr_vec = All_Values{j};
                        if any(curr_vec <= 0)
                            detected_neg = true;
                            delete_mask = curr_vec <= 0;
                            curr_vec(delete_mask) = [];
                        end
                        app.PlotValues{j} = log(curr_vec);
                    end
                    if detected_neg
                        uialert(app.HistogramPlottingToolUIFigure,["Warning: Your loaded data contains negative or zero values!";"These values will be ignored for log normalization."],"Detected values <= 0","Icon","warning");
                    end
            end

            % Asked user for HistPlotOption if more than one file is loaded
            if length(files_string) > 1
                selection = uiconfirm(app.HistogramPlottingToolUIFigure,"Do you want a stacked or a classic histogram?","Select Plot Style","Options",["Stacked","Classic"],"DefaultOption",1);
                app.HistoPlotOption = lower(selection);
            else
                app.HistoPlotOption = "classic";
            end
            
            % Reset Initial Guess Button
            app.ButtonGroup.SelectedObject = app.NoInitialGuessesButton;
            ButtonGroupSelectionChanged(app,"OpenFiles");
            
            % Set open path as tooltip for open button
            app.OpenButton.Tooltip = "Current Folder: "+op_path;

            PlotandFitValues(app);


            % Enable Buttons
            app.MergeLoadedtsvFilesButton.Enable = true;
            app.OpeninFigureWindowButton.Enable = true;
        end

        % Value changed function: xLabelEditField
        function xLabelEditFieldValueChanged(app, event)
            value = app.xLabelEditField.Value;
            if ~isempty(app.SIUnitEditField.Value)
                x_label = value + " [" + app.ScaledLabel + "]";
            else
                x_label = value;
            end
            app.HistogramAxes.XLabel.String = x_label;
            SetUnitLabels(app)
        end

        % Value changed function: SIUnitEditField
        function SIUnitEditFieldValueChanged(app, event)
            value = app.SIUnitEditField.Value;
            if ~isempty(app.Saved_OoM)
                dummy_number = 1*10^(app.Saved_OoM);
                [~,new_label] = DetOrdofMag(app,dummy_number,value);
            else
                new_label = value;
            end
            app.ScaledLabel = new_label;
            SetUnitLabels(app)
            resetAnnoUnit(app);
            if ~isempty(app.LegendObject) && (app.AnnotationShowCheckBox.Value == true)
                AddAnnotation(app)
            end
        end

        % Value changed function: PlotRangeStart
        function PlotRangeStartValueChanged(app, event)
            value = app.PlotRangeStart.Value;
            end_value = app.PlotRangeEnd.Value;
            prev_val = event.PreviousValue;
            if value >= end_value
                uialert(app.HistogramPlottingToolUIFigure,"The start value of the plot range has to be smaller than the end value!","Start value entered too large","Icon","warning");
                app.PlotRangeStart.Value = prev_val;
                return;
            end
            app.UserDefinedRange = true;
            if ~isempty(app.HistHandle)
                PlotandFitValues(app);
            end
        end

        % Value changed function: PlotRangeEnd
        function PlotRangeEndValueChanged(app, event)
            value = app.PlotRangeEnd.Value;
            start_value = app.PlotRangeStart.Value;
            prev_val = event.PreviousValue;
            if value <= start_value
                uialert(app.HistogramPlottingToolUIFigure,"The end value of the plot range has to be greater than the end value!","End value entered too smal","Icon","warning");
                app.PlotRangeEnd.Value = prev_val;
                return;
            end
            app.UserDefinedRange = true;
            old_OoM = app.Saved_OoM;
            [new_OoM,new_label] = DetOrdofMag(app,value.*10^(old_OoM),app.SIUnitEditField.Value);
            if new_OoM ~= old_OoM
                scaled_value = value*10^(old_OoM-new_OoM);
                app.PlotRangeEnd.Value = scaled_value;
                app.Saved_OoM = new_OoM;
                app.ScaledLabel = new_label;
                SetUnitLabels(app);
                resetAnnoUnit(app);
            end
            if ~isempty(app.HistHandle)
                PlotandFitValues(app);
            end
        end

        % Button pushed function: ResetRangeButton
        function ResetRangeButtonPushed(app, event)
            app.UserDefinedRange = false;
            if ~isempty(app.HistHandle)
                PlotandFitValues(app);
            else
                app.PlotRangeStart.Value = 0;
                app.PlotRangeEnd.Value = 0;
            end
        end

        % Value changed function: BinNumEditField
        function BinNumEditFieldValueChanged(app, event)
%             value = app.BinNumEditField.Value;
            if ~isempty(app.HistHandle)
                PlotandFitValues(app);
            end
        end

        % Value changed function: AnnotationShowCheckBox
        function AnnotationShowCheckBoxValueChanged(app, event)
            value = app.AnnotationShowCheckBox.Value;
            if value && (~isempty(app.HistHandle))
                AddAnnotation(app)
            else
                legend(app.HistogramAxes,"off")
            end
        end

        % Value changed function: FitFunctionDropDown
        function FitFunctionDropDownValueChanged(app, event)
%             value = app.FitFunctionDropDown.Value;
            EnableDisableGuessFields(app)
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;
            EnableDisableGuessFields(app)
            if ~isempty(app.HistHandle) && (~isstring(event) && ~strcmp(event,"OpenFiles"))
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Value changed function: a1EditField
        function a1EditFieldValueChanged(app, event)
%             value = app.a1EditField.Value;
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Value changed function: b1EditField
        function b1EditFieldValueChanged(app, event)
%             value = app.b1EditField.Value;
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Value changed function: c1EditField
        function c1EditFieldValueChanged(app, event)
%             value = app.c1EditField.Value;
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Value changed function: a2EditField
        function a2EditFieldValueChanged(app, event)
%             value = app.a2EditField.Value;
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Value changed function: b2EditField
        function b2EditFieldValueChanged(app, event)
%             value = app.b2EditField.Value;
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Value changed function: c2EditField
        function c2EditFieldValueChanged(app, event)
%             value = app.c2EditField.Value;
            if ~isempty(app.HistHandle)
                legend(app.HistogramAxes,"off")
                FitSelectedModel(app)
                PlotFitResults(app)
                AddAnnotation(app)
            end
        end

        % Button pushed function: MergeLoadedtsvFilesButton
        function MergeLoadedtsvFilesButtonPushed(app, event)
            put_path = fullfile(app.DefSavePath,"*.tsv");
            [s_file,s_path] = uiputfile(put_path,"Save merged results file");
            if s_path == 0
                return;
            end
            app.DefSavePath = s_path;
            save_path = fullfile(s_path,s_file);

            files_array = app.SelectedFiles;

            for i = 1:length(files_array)
                curr_file = files_array(i);
                curr_file_path = fullfile(app.DefOpenPath,curr_file);
                if i == 1
                    readOpts = detectImportOptions(curr_file_path,"FileType","delimitedtext","TextType","string","ReadVariableNames",true,"VariableNamesLine",1,"Delimiter","\t");
                    T_out = readtable(curr_file_path,readOpts);
                else
                    curr_table = readtable(curr_file_path,readOpts);
                    T_out = [T_out;curr_table];
                end
            end

            writetable(T_out,save_path,"FileType","text","WriteVariableNames",true,"WriteMode","overwrite","Delimiter","\t","QuoteStrings",false);
        end

        % Button pushed function: OpeninFigureWindowButton
        function OpeninFigureWindowButtonPushed(app, event)
            new_fig = figure();
            new_ax = axes(new_fig);
            copyUIAxes_new(app.HistogramAxes,new_ax);
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            app.HistogramAxes.Title.String = value;
        end

        % Menu selected function: OpeninFigureMenu
        function OpeninFigureMenuSelected(app, event)
            OpeninFigureWindowButtonPushed(app,"ContextMenu");
        end

        % Menu selected function: MergetsvFilesMenu
        function MergetsvFilesMenuSelected(app, event)
            MergeLoadedtsvFilesButtonPushed(app,"contextMenu");
        end

        % Menu selected function: ShowFitResultsMenu
        function ShowFitResultsMenuSelected(app, event)
            if isempty(app.FitObject)
                return;
            end
            fitObj = app.FitObject;
            FitObjText = formattedDisplayText(fitObj);
            FitObjText = FitObjText+sprintf("\n\nValues are given in %s",app.ScaledLabel);
            helpdlg(FitObjText,"Fit Results");
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            value = app.UnitDropDown.Value;
            AddAnnotation(app);
        end

        % Close request function: HistogramPlottingToolUIFigure
        function HistogramPlottingToolUIFigureCloseRequest(app, event)
            delete(app)
            run("CANTER_Processing_Toolbox.mlapp")
        end

        % Callback function: not associated with a component
        function LogNormNoValueChanged(app, event)
            % value = app.LogNormNo.Value;
            % switch value
            %     case 1
            % 
            %         app.UserDefinedRange = false;
            %         SetUnitLabels(app)
            %         PlotandFitValues(app);
            %     case 0
            %         app.LogNormNo.Value = 1;
            % end

        end

        % Callback function: not associated with a component
        function LogNormYesValueChanged(app, event)
            % value = app.LogNormYes.Value;
            % switch value
            %     case 1
            %         app.LogNormNo.Value = 0;
            %         app.UserDefinedRange = false;
            %         SetUnitLabels(app)
            %         if ~isempty(app.PlotValues)
            %             value_cell = app.PlotValues;
            %             detected_neg = false;
            %             for j = 1:length(value_cell)
            %                 curr_vec = value_cell{j};
            %                 if any(curr_vec <= 0)
            %                     detected_neg = true;
            %                     delete_mask = curr_vec <= 0;
            %                     curr_vec(delete_mask) = [];
            %                 end
            %                 app.PlotValues_lognormal{j} = log(curr_vec);
            %             end
            %             if detected_neg
            %                 uialert(app.HistogramPlottingToolUIFigure,["Warning: Your loaded data contains negative or zero values!";"These values will be ignored for log normalization."],"Detected values <= 0","Icon","warning");
            %             end
            %             PlotandFitValues(app);
            %         end
            %     case 0
            %         app.LogNormYes.Value = 1;
            % end
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            value = app.NormalizationDropDown.Value;
            switch value
                case "None"
                    app.PlotValues = app.PlotValues_Raw;
                    app.UserDefinedRange = false;
                    SetUnitLabels(app)
                    PlotandFitValues(app);
                case "Log"
                    app.UserDefinedRange = false;
                    SetUnitLabels(app)
                    if ~isempty(app.PlotValues_Raw)
                        value_cell = app.PlotValues_Raw;
                        detected_neg = false;
                        for j = 1:length(value_cell)
                            curr_vec = value_cell{j};
                            if any(curr_vec <= 0)
                                detected_neg = true;
                                delete_mask = curr_vec <= 0;
                                curr_vec(delete_mask) = [];
                            end
                            app.PlotValues{j} = log(curr_vec);
                        end
                        if detected_neg
                            uialert(app.HistogramPlottingToolUIFigure,["Warning: Your loaded data contains negative or zero values!";"These values will be ignored for log normalization."],"Detected values <= 0","Icon","warning");
                        end
                        PlotandFitValues(app);
                    end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.HistogramPlottingToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {329, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistogramPlottingToolUIFigure = uifigure('Visible', 'off');
            app.HistogramPlottingToolUIFigure.Name = 'Histogram Plotting Tool';
            app.HistogramPlottingToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @HistogramPlottingToolUIFigureCloseRequest, true);
            app.HistogramPlottingToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.HistogramPlottingToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftMainGrid = uigridlayout(app.LeftPanel);
            app.LeftTopRows = uigridlayout(app.LeftMainGrid);
            app.OpenButtonGrid = uigridlayout(app.LeftTopRows);
            app.OpenButton = uibutton(app.OpenButtonGrid, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Current Folder: No Loaded Files'};
            app.OpenButton.Text = 'Open Files';
            app.GridLayout2 = uigridlayout(app.LeftTopRows);
            app.xLabelEditFieldLabel = uilabel(app.GridLayout2);
            app.xLabelEditFieldLabel.Text = 'x-Label: ';
            app.xLabelEditField = uieditfield(app.GridLayout2, 'text');
            app.xLabelEditField.ValueChangedFcn = createCallbackFcn(app, @xLabelEditFieldValueChanged, true);
            app.xLabelEditField.Value = 'Young''s modulus';
            app.GridLayout3 = uigridlayout(app.LeftTopRows);
            app.SIUnitEditFieldLabel = uilabel(app.GridLayout3);
            app.SIUnitEditFieldLabel.Text = 'SI-Unit';
            app.SIUnitEditField = uieditfield(app.GridLayout3, 'text');
            app.SIUnitEditField.ValueChangedFcn = createCallbackFcn(app, @SIUnitEditFieldValueChanged, true);
            app.SIUnitEditField.Value = 'Pa';
            app.GridLayout2_2 = uigridlayout(app.LeftTopRows);
            app.xLabelEditField_2Label = uilabel(app.GridLayout2_2);
            app.xLabelEditField_2Label.Text = 'Title: ';
            app.TitleEditField = uieditfield(app.GridLayout2_2, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.TitleEditField.Value = 'Summary Histogram';
            app.PlotSettingsPanel = uipanel(app.LeftMainGrid);
            app.PlotSettingsPanel.Title = 'Plot-Settings';
            app.LeftMiddleRows = uigridlayout(app.PlotSettingsPanel);
            app.PlotRangeGrid = uigridlayout(app.LeftMiddleRows);
            app.PlotRangeLabel = uilabel(app.PlotRangeGrid);
            app.PlotRangeLabel.Text = 'Plot-Range: ';
            app.HelpGridPlotRange = uigridlayout(app.PlotRangeGrid);
            app.PlotRangeStart = uieditfield(app.HelpGridPlotRange, 'numeric');
            app.PlotRangeStart.ValueChangedFcn = createCallbackFcn(app, @PlotRangeStartValueChanged, true);
            app.Label = uilabel(app.HelpGridPlotRange);
            app.Label.Text = '-';
            app.PlotRangeEnd = uieditfield(app.HelpGridPlotRange, 'numeric');
            app.PlotRangeEnd.ValueChangedFcn = createCallbackFcn(app, @PlotRangeEndValueChanged, true);
            app.PaLabel = uilabel(app.HelpGridPlotRange);
            app.PaLabel.Text = ' Pa';
            app.PaLabel_2 = uilabel(app.HelpGridPlotRange);
            app.PaLabel_2.Text = ' Pa';
            app.PlotRangeResetHelpGrid = uigridlayout(app.LeftMiddleRows);
            app.ResetButtonGrid = uigridlayout(app.PlotRangeResetHelpGrid);
            app.ResetRangeButton = uibutton(app.ResetButtonGrid, 'push');
            app.ResetRangeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRangeButtonPushed, true);
            app.ResetRangeButton.Text = 'Reset Range';
            app.HelpGridBinNum = uigridlayout(app.LeftMiddleRows);
            app.BinNumGrid = uigridlayout(app.HelpGridBinNum);
            app.BinNumEditField = uieditfield(app.BinNumGrid, 'numeric');
            app.BinNumEditField.Limits = [1 Inf];
            app.BinNumEditField.ValueDisplayFormat = '%11d';
            app.BinNumEditField.ValueChangedFcn = createCallbackFcn(app, @BinNumEditFieldValueChanged, true);
            app.BinNumEditField.Value = 50;
            app.BinNumLabel = uilabel(app.HelpGridBinNum);
            app.BinNumLabel.Text = 'Bin-Num: ';
            app.AnnotationBoxHelpGrid = uigridlayout(app.LeftMiddleRows);
            app.AnnotationLabel = uilabel(app.AnnotationBoxHelpGrid);
            app.AnnotationLabel.Text = 'Annotation: ';
            app.AnnotationShowCheckBox = uicheckbox(app.AnnotationBoxHelpGrid);
            app.AnnotationShowCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnnotationShowCheckBoxValueChanged, true);
            app.AnnotationShowCheckBox.Text = 'Show';
            app.AnnotationShowCheckBox.Value = true;
            app.UnitLabel = uilabel(app.AnnotationBoxHelpGrid);
            app.UnitLabel.Text = {'Unit:'; ''};
            app.UnitDropDown = uidropdown(app.AnnotationBoxHelpGrid);
            app.UnitDropDown.Items = {'fPa', 'pPa', 'nPa', 'µPa', 'mPa', 'Pa', 'kPa', 'MPa', 'GPa', 'TPa', 'PPa'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'Pa';
            app.GridLayout5 = uigridlayout(app.LeftMiddleRows);
            app.NormalizationLabel = uilabel(app.GridLayout5);
            app.NormalizationLabel.Text = 'Normalization:';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.NormalizationDropDown = uidropdown(app.GridLayout6);
            app.NormalizationDropDown.Items = {'None', 'Log'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'None';
            app.FitSettingsPanel = uipanel(app.LeftMainGrid);
            app.FitSettingsPanel.Title = 'Fit-Settings';
            app.FitSettingsRowGrid = uigridlayout(app.FitSettingsPanel);
            app.FitFunctionHelpGrid = uigridlayout(app.FitSettingsRowGrid);
            app.FitFunctionDropDownLabel = uilabel(app.FitFunctionHelpGrid);
            app.FitFunctionDropDownLabel.Text = 'Fit-Function: ';
            app.FitFunctionDropDown = uidropdown(app.FitFunctionHelpGrid);
            app.FitFunctionDropDown.Items = {'gauss2', 'gauss1'};
            app.FitFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FitFunctionDropDownValueChanged, true);
            app.FitFunctionDropDown.Value = 'gauss2';
            app.GridLayout4 = uigridlayout(app.FitSettingsRowGrid);
            app.ButtonGroupHelpPanel = uipanel(app.GridLayout4);
            app.ButtonGroup = uibuttongroup(app.ButtonGroupHelpPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NoInitialGuessesButton = uiradiobutton(app.ButtonGroup);
            app.NoInitialGuessesButton.Tag = 'no';
            app.NoInitialGuessesButton.Text = 'No Initial Guesses';
            app.NoInitialGuessesButton.Value = true;
            app.UseInitialGuessesButton = uiradiobutton(app.ButtonGroup);
            app.UseInitialGuessesButton.Tag = 'yes';
            app.UseInitialGuessesButton.Text = 'Use Initial Guesses';
            app.InitialGuessesGrid = uigridlayout(app.FitSettingsRowGrid);
            app.a1EditFieldLabel = uilabel(app.InitialGuessesGrid);
            app.a1EditFieldLabel.Enable = 'off';
            app.a1EditFieldLabel.Text = 'a1: ';
            app.a1EditField = uieditfield(app.InitialGuessesGrid, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @a1EditFieldValueChanged, true);
            app.a1EditField.Enable = 'off';
            app.b1EditFieldLabel = uilabel(app.InitialGuessesGrid);
            app.b1EditFieldLabel.Enable = 'off';
            app.b1EditFieldLabel.Text = 'b1: ';
            app.b1EditField = uieditfield(app.InitialGuessesGrid, 'numeric');
            app.b1EditField.ValueChangedFcn = createCallbackFcn(app, @b1EditFieldValueChanged, true);
            app.b1EditField.Enable = 'off';
            app.c1EditFieldLabel = uilabel(app.InitialGuessesGrid);
            app.c1EditFieldLabel.Enable = 'off';
            app.c1EditFieldLabel.Text = 'c1: ';
            app.c1EditField = uieditfield(app.InitialGuessesGrid, 'numeric');
            app.c1EditField.ValueChangedFcn = createCallbackFcn(app, @c1EditFieldValueChanged, true);
            app.c1EditField.Enable = 'off';
            app.a2EditFieldLabel = uilabel(app.InitialGuessesGrid);
            app.a2EditFieldLabel.Enable = 'off';
            app.a2EditFieldLabel.Text = 'a2: ';
            app.a2EditField = uieditfield(app.InitialGuessesGrid, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @a2EditFieldValueChanged, true);
            app.a2EditField.Enable = 'off';
            app.b2EditFieldLabel = uilabel(app.InitialGuessesGrid);
            app.b2EditFieldLabel.Enable = 'off';
            app.b2EditFieldLabel.Text = 'b2: ';
            app.b2EditField = uieditfield(app.InitialGuessesGrid, 'numeric');
            app.b2EditField.ValueChangedFcn = createCallbackFcn(app, @b2EditFieldValueChanged, true);
            app.b2EditField.Enable = 'off';
            app.c2EditFieldLabel = uilabel(app.InitialGuessesGrid);
            app.c2EditFieldLabel.Enable = 'off';
            app.c2EditFieldLabel.Text = 'c2: ';
            app.c2EditField = uieditfield(app.InitialGuessesGrid, 'numeric');
            app.c2EditField.ValueChangedFcn = createCallbackFcn(app, @c2EditFieldValueChanged, true);
            app.c2EditField.Enable = 'off';
            app.PaLabel_b1 = uilabel(app.InitialGuessesGrid);
            app.PaLabel_b1.Enable = 'off';
            app.PaLabel_b1.Text = 'Pa';
            app.PaLabel_c1 = uilabel(app.InitialGuessesGrid);
            app.PaLabel_c1.Enable = 'off';
            app.PaLabel_c1.Text = 'Pa';
            app.PaLabel_b2 = uilabel(app.InitialGuessesGrid);
            app.PaLabel_b2.Enable = 'off';
            app.PaLabel_b2.Text = 'Pa';
            app.PaLabel_c2 = uilabel(app.InitialGuessesGrid);
            app.PaLabel_c2.Enable = 'off';
            app.PaLabel_c2.Text = 'Pa';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightMainGrid = uigridlayout(app.RightPanel);
            app.HistogramAxes = uiaxes(app.RightMainGrid);
            title(app.HistogramAxes, 'Summary Histogram')
            xlabel(app.HistogramAxes, 'Young''s modulus [Pa]')
            ylabel(app.HistogramAxes, 'Frequency')
            zlabel(app.HistogramAxes, 'Z')
            app.RightBottomHelpGrid = uigridlayout(app.RightMainGrid);
            app.MergeLoadedtsvFilesButton = uibutton(app.RightBottomHelpGrid, 'push');
            app.MergeLoadedtsvFilesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeLoadedtsvFilesButtonPushed, true);
            app.MergeLoadedtsvFilesButton.Enable = 'off';
            app.MergeLoadedtsvFilesButton.Text = 'Merge Loaded tsv Files';
            app.OpeninFigureWindowButton = uibutton(app.RightBottomHelpGrid, 'push');
            app.OpeninFigureWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninFigureWindowButtonPushed, true);
            app.OpeninFigureWindowButton.Enable = 'off';
            app.OpeninFigureWindowButton.Text = 'Open in Figure Window';
            app.PlotContextMenu = uicontextmenu(app.HistogramPlottingToolUIFigure);
            app.ShowFitResultsMenu = uimenu(app.PlotContextMenu);
            app.ShowFitResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowFitResultsMenuSelected, true);
            app.ShowFitResultsMenu.Text = 'Show Fit Results';
            app.OpeninFigureMenu = uimenu(app.PlotContextMenu);
            app.OpeninFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninFigureMenuSelected, true);
            app.OpeninFigureMenu.Text = 'Open in Figure';
            app.MergetsvFilesMenu = uimenu(app.PlotContextMenu);
            app.MergetsvFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @MergetsvFilesMenuSelected, true);
            app.MergetsvFilesMenu.Text = 'Merge tsv Files';
            % Assign app.PlotContextMenu
            app.HistogramAxes.ContextMenu = app.PlotContextMenu;
            % Show the figure after all components are created
            app.HistogramPlottingToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistogramPlottingTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistogramPlottingToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistogramPlottingToolUIFigure)
        end
    end
end