classdef Get_Paths_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        JPKMapsTab                   matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout8                  matlab.ui.container.GridLayout
        ResetButton                  matlab.ui.control.Button
        SubmitButton                 matlab.ui.control.Button
        GridLayout7                  matlab.ui.container.GridLayout
        SetButton_2                  matlab.ui.control.Button
        EditField_2                  matlab.ui.control.EditField
        GridLayout6                  matlab.ui.container.GridLayout
        PathtomapfilejpkforcemapjpkqidataLabel  matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        SetButton                    matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        GridLayout4                  matlab.ui.container.GridLayout
        PathtotsvfileLabel           matlab.ui.control.Label
        MFP3DMapsTab                 matlab.ui.container.Tab
        GridLayout12                 matlab.ui.container.GridLayout
        GridLayout17                 matlab.ui.container.GridLayout
        ResetButton_mfp              matlab.ui.control.Button
        SubmitButton_mfp             matlab.ui.control.Button
        GridLayout16                 matlab.ui.container.GridLayout
        SetButton_mfp_2              matlab.ui.control.Button
        EditField_mfp_2              matlab.ui.control.EditField
        GridLayout15                 matlab.ui.container.GridLayout
        PathtomapfoldercontainingtheibwfilesLabel  matlab.ui.control.Label
        GridLayout14                 matlab.ui.container.GridLayout
        SetButton_mfp                matlab.ui.control.Button
        EditField_mfp                matlab.ui.control.EditField
        GridLayout13                 matlab.ui.container.GridLayout
        PathtotsvfileLabel_2         matlab.ui.control.Label
        tsvFileTab                   matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout18                 matlab.ui.container.GridLayout
        MapPixelPanel                matlab.ui.container.Panel
        GridLayout20                 matlab.ui.container.GridLayout
        SlowPixelEditField           matlab.ui.control.NumericEditField
        SlowPixelEditFieldLabel      matlab.ui.control.Label
        FastPixelEditField           matlab.ui.control.NumericEditField
        FastPixelEditFieldLabel      matlab.ui.control.Label
        MapSizePanel                 matlab.ui.container.Panel
        GridLayout19                 matlab.ui.container.GridLayout
        mLabel_2                     matlab.ui.control.Label
        mLabel                       matlab.ui.control.Label
        SlowEditField                matlab.ui.control.NumericEditField
        SlowEditFieldLabel           matlab.ui.control.Label
        FastEditField                matlab.ui.control.NumericEditField
        FastEditFieldLabel           matlab.ui.control.Label
        SelectCurveOrderButtonGroup  matlab.ui.container.ButtonGroup
        SameDirectionButton          matlab.ui.control.ToggleButton
        MeanderPathButton            matlab.ui.control.ToggleButton
        GridLayout11                 matlab.ui.container.GridLayout
        ResetButton_tsv              matlab.ui.control.Button
        SubmitButton_tsv             matlab.ui.control.Button
        GridLayout10                 matlab.ui.container.GridLayout
        SetButton_tsv                matlab.ui.control.Button
        EditField_tsv                matlab.ui.control.EditField
        GridLayout9                  matlab.ui.container.GridLayout
        PathtotsvfileLabel_3         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        tsv_path_old = '' % Path to the tsv file 
        meta_path_old = '' % Path to the meta file
        tsv_path_new = '';  % New path to the tsv file.
        meta_path_new = ''; % New path to the meta file.
        main_app = [];  % Object of the main GUI invoking this GUI.
        wait_status = 1; % Status for the main GUI calling this sub-GUI to wait 
        canceled = 0 % Is set to 1 if GUI is forced closed.
        SelectecCurveOrder = ""; % This propertie stores the selected curve order in the ".tsv File" tab.
        XLenght = []; % This propertie stores the selected "Fast" value in the ".tsv File" tab.
        YLength = []; % This propertie stores the selected "Slow" value in the ".tsv File" tab.
        XPixel = []; % This propertie stores the selected "Fast Pixel" value in the ".tsv File" tab.
        YPixel = [];% This propertie stores the selected "Slow Pixel" value in the ".tsv File" tab.
    end
        
    methods (Access = public)
        
        function display_previous_paths(app,tsv_path,meta_path,last_tab,varargin)
            if isempty(last_tab)
                return;
            end
            switch last_tab
                case "JPK Maps"
                    app.TabGroup.SelectedTab = app.JPKMapsTab;
                    app.tsv_path_old = tsv_path;
                    app.meta_path_old = meta_path;
                    app.EditField.Value = tsv_path;
                    app.EditField_2.Value = meta_path;
                case "MFP-3D Maps"
                    app.TabGroup.SelectedTab = app.MFP3DMapsTab;
                    app.tsv_path_old = tsv_path;
                    app.meta_path_old = meta_path;
                    app.EditField_mfp.Value = tsv_path;
                    app.EditField_mfp_2.Value = meta_path;
                case ".tsv File"
                    app.TabGroup.SelectedTab = app.tsvFileTab;
                    app.tsv_path_old = tsv_path;
                    app.EditField_tsv.Value = tsv_path;
                    if isempty(varargin{1})
                        return;
                    else
                        previousOptions = varargin{1};
                        app.FastEditField.Value = previousOptions.XLen;
                        app.SlowEditField.Value = previousOptions.YLen;
                        app.FastPixelEditField.Value = previousOptions.XPix;
                        app.SlowPixelEditField.Value = previousOptions.YPix;
                        toggletext = previousOptions.COrd;
                        switch toggletext
                            case "meander"
                                app.SelectCurveOrderButtonGroup.SelectedObject = app.MeanderPathButton;
                            case "same"
                                app.SelectCurveOrderButtonGroup.SelectedObject = app.SameDirectionButton;
                        end
                    end
                    
            end
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tsv_path, meta_path, varargin)
            app.tsv_path_old = tsv_path;
            app.meta_path_old = meta_path;
            % app.EditField.Value = tsv_path;
            % app.EditField_2.Value = meta_path;
%             app.main_app = app2;
%             main_app_pos = app.main_app.Hist_GUI_UIFigure.Position;
%             this_app_pos = app.UIFigure.Position;
%             app.UIFigure.Position([1 2]) = [main_app_pos(3)/2-this_app_pos(3)/2 main_app_pos(4)/2-this_app_pos(4)/2];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~strcmp(event,'submitButton')
                app.canceled = 1;
            end
            
            app.wait_status = 0; 
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            [tsv_path,~,~] = fileparts(app.tsv_path_old);
            [selected_f,selected_p] = uigetfile('*.tsv','Please select a tsv file',tsv_path);
            if isequal(selected_f,0) || isequal(selected_p,0)                
                return;
            end
            selected_file = fullfile(selected_p,selected_f);
            app.tsv_path_new = selected_file;
            app.EditField.Value = selected_file;
            app.EditField.Tooltip = selected_file;
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            [meta_path,~,~] = fileparts(app.meta_path_old);
            [selected_f,selected_p] = uigetfile({'*.jpk-qi-data; *.jpk-force-map','JPK Meta data files (*.jpk-qi-data, *.jpk-force-map)'},'Please select a JPK meta data file',meta_path);
            if isequal(selected_f,0) || isequal(selected_p,0)
                return;
            end
            selected_file = fullfile(selected_p,selected_f);
            app.meta_path_new = selected_file;
            app.EditField_2.Value = selected_file;
            app.EditField_2.Tooltip = selected_file;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditField.Value = app.tsv_path_old;
            app.EditField_2.Value = app.meta_path_old;
            app.tsv_path_new = '';
            app.meta_path_new = '';
            app.EditField.Tooltip = app.tsv_path_old;
            app.EditField_2.Tooltip = app.meta_path_old;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % error handling for callback
            switch app.TabGroup.SelectedTab.Title
                case "JPK Maps"
                    if isempty(app.EditField.Value)
                        uialert(app.UIFigure,"tsv file path is empty -> You must set the tsv file path!","tsv file path error','modal","modal",true,"Icon","error");
                        return;
                    end
                    if isempty(app.EditField_2.Value)
                        uialert(app.UIFigure,"meta data file path is empty -> You must set the meta data file path!","meta data file path error","modal",true,"Icon","error");
                        return;
                    end
                    if ~isfile(app.EditField.Value)
                        uialert(app.UIFigure,"The chosen tsv file does not exist!","Non existing tsv file","Modal",true,"Icon","error");
                        return;
                    end
                    if ~isfile(app.EditField_2.Value)
                        uialert(app.UIFigure,"The chosen meta data file does not exist!","Non existing meta file","Modal",true,"Icon","error");
                    end
                case "MFP-3D Maps"
                    if isempty(app.EditField_mfp.Value)
                        uialert(app.UIFigure,"tsv file path is empty -> You must set the tsv file path!","tsv file path error","modal",true,"Icon","error");
                        return;
                    end
                    if isempty(app.EditField_mfp_2.Value)
                        uialert(app.UIFigure,"folder path is empty -> You must set the folder path!","tsv file path error","modal",true,"Icon","error");
                        return;
                    end
                    if ~isfile(app.EditField_mfp.Value)
                        uialert(app.UIFigure,"The chosen tsv file does not exis!","Non existing tsv file","Modal",true,"Icon","error");
                        return;
                    end
                    if ~isfolder(app.EditField_mfp_2.Value)
                        uialert(app.UIFigure,"The selected folder does not exist!","Non existing folder","Modal",true,"Icon","error");
                        return;
                    end
                case ".tsv File"
                    if isempty(app.EditField_tsv.Value)
                        uialert(app.UIFigure,"tsv file path is empty -> You must set the tsv file path!","tsv file path error','modal","modal",true,"Icon","error");
                        return;
                    end
                    if ~isfile(app.EditField_tsv.Value)
                        uialert(app.UIFigure,"The chosen tsv file does not exis!","Non existing tsv file","Modal",true,"Icon","error");
                        return;
                    end
            end
            
                        
            % main code of callback
            if isempty(app.tsv_path_new)
                app.tsv_path_new = app.tsv_path_old;
            end
            if isempty(app.meta_path_new) && (~strcmp(app.TabGroup.SelectedTab.Title,".tsv File"))
                app.meta_path_new = app.meta_path_old;
            end
            SelectionText = app.SelectCurveOrderButtonGroup.SelectedObject.Text;
            switch SelectionText
                case "Meander Path"
                    app.SelectecCurveOrder = "meander";
                case "Same Direction"
                    app.SelectecCurveOrder = "same";
            end
            app.XLenght = app.FastEditField.Value;
            app.YLength = app.SlowEditField.Value;
            app.XPixel = app.FastPixelEditField.Value;
            app.YPixel = app.SlowPixelEditField.Value;
            app.UIFigure.WindowState = 'minimized';
            % call the close request callback where the edit field values
            % are given to the invoking main GUI and, afterward, this GUI is closed.
            UIFigureCloseRequest(app,'submitButton')
            
        end

        % Button pushed function: SetButton_mfp
        function SetButton_mfpPushed(app, event)
            [tsv_path,~,~] = fileparts(app.tsv_path_old);
            [selected_f,selected_p] = uigetfile('*.tsv','Please select a tsv file',tsv_path);
            if isequal(selected_f,0) || isequal(selected_p,0)                
                return;
            end
            selected_file = fullfile(selected_p,selected_f);
            app.tsv_path_new = selected_file;
            app.EditField_mfp.Value = selected_file;
            app.EditField_mfp.Tooltip = selected_file;
        end

        % Button pushed function: SetButton_mfp_2
        function SetButton_mfp_2Pushed(app, event)
            selpath = uigetdir(app.meta_path_old);
            if isequal(selpath,0)
                return;
            end
            % check if a valid folder was selected
            folder = dir(fullfile(selpath,"*.ibw"));
            files = string({folder.name}');
            if isempty(files)
                uialert(app.UIFigure,sprintf("Invalid folder was selected!\nPlease select a folder containing .ibw files.%s"," "),"Invalid Folder","Icon","warning","Modal",true);
                return;
            end
            % write path to edit field
            app.meta_path_new = selpath;
            app.EditField_mfp_2.Value = selpath;
            app.EditField_mfp_2.Tooltip = selpath;
        end

        % Button pushed function: SubmitButton_mfp
        function SubmitButton_mfpPushed(app, event)
            SubmitButtonPushed(app)
        end

        % Button pushed function: SubmitButton_tsv
        function SubmitButton_tsvPushed(app, event)
            SubmitButtonPushed(app)
        end

        % Button pushed function: ResetButton_mfp
        function ResetButton_mfpPushed(app, event)
            app.EditField_mfp.Value = app.tsv_path_old;
            app.EditField_mfp_2.Value = app.meta_path_old;
            app.tsv_path_new = '';
            app.meta_path_new = '';
            app.EditField_mfp.Tooltip = app.tsv_path_old;
            app.EditField_mfp_2.Tooltip = app.meta_path_old;
        end

        % Button pushed function: ResetButton_tsv
        function ResetButton_tsvPushed(app, event)
            app.EditField_tsv.Value = app.tsv_path_old;
            app.tsv_path_new = '';
            app.EditField_tsv.Tooltip = app.tsv_path_old;
            app.SelectCurveOrderButtonGroup.SelectedObject = app.MeanderPathButton;
            app.FastEditField.Value = 3;
            app.SlowEditField.Value = 3;
            app.FastPixelEditField.Value = 25;
            app.SlowPixelEditField.Value = 25;
        end

        % Button pushed function: SetButton_tsv
        function SetButton_tsvPushed(app, event)
            [tsv_path,~,~] = fileparts(app.tsv_path_old);
            [selected_f,selected_p] = uigetfile('*.tsv','Please select a tsv file',tsv_path);
            if isequal(selected_f,0) || isequal(selected_p,0)                
                return;
            end
            selected_file = fullfile(selected_p,selected_f);
            app.tsv_path_new = selected_file;
            app.EditField_tsv.Value = selected_file;
            app.EditField_tsv.Tooltip = selected_file;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.JPKMapsTab = uitab(app.TabGroup);
            app.JPKMapsTab.Title = 'JPK Maps';
            app.GridLayout3 = uigridlayout(app.JPKMapsTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PathtotsvfileLabel = uilabel(app.GridLayout4);
            app.PathtotsvfileLabel.Text = 'Path to .tsv file:';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.EditField = uieditfield(app.GridLayout5, 'text');
            app.EditField.Editable = 'off';
            app.SetButton = uibutton(app.GridLayout5, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.PathtomapfilejpkforcemapjpkqidataLabel = uilabel(app.GridLayout6);
            app.PathtomapfilejpkforcemapjpkqidataLabel.Text = 'Path to map file (.jpk-force-map; .jpk-qi-data):';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.EditField_2 = uieditfield(app.GridLayout7, 'text');
            app.EditField_2.Editable = 'off';
            app.SetButton_2 = uibutton(app.GridLayout7, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.SubmitButton = uibutton(app.GridLayout8, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ResetButton = uibutton(app.GridLayout8, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MFP3DMapsTab = uitab(app.TabGroup);
            app.MFP3DMapsTab.Title = 'MFP-3D Maps';
            app.GridLayout12 = uigridlayout(app.MFP3DMapsTab);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.PathtotsvfileLabel_2 = uilabel(app.GridLayout13);
            app.PathtotsvfileLabel_2.Text = 'Path to .tsv file:';
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.EditField_mfp = uieditfield(app.GridLayout14, 'text');
            app.EditField_mfp.Editable = 'off';
            app.SetButton_mfp = uibutton(app.GridLayout14, 'push');
            app.SetButton_mfp.ButtonPushedFcn = createCallbackFcn(app, @SetButton_mfpPushed, true);
            app.SetButton_mfp.Text = 'Set';
            app.GridLayout15 = uigridlayout(app.GridLayout12);
            app.PathtomapfoldercontainingtheibwfilesLabel = uilabel(app.GridLayout15);
            app.PathtomapfoldercontainingtheibwfilesLabel.Text = 'Path to map folder containing the .ibw files';
            app.GridLayout16 = uigridlayout(app.GridLayout12);
            app.EditField_mfp_2 = uieditfield(app.GridLayout16, 'text');
            app.EditField_mfp_2.Editable = 'off';
            app.SetButton_mfp_2 = uibutton(app.GridLayout16, 'push');
            app.SetButton_mfp_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_mfp_2Pushed, true);
            app.SetButton_mfp_2.Text = 'Set';
            app.GridLayout17 = uigridlayout(app.GridLayout12);
            app.SubmitButton_mfp = uibutton(app.GridLayout17, 'push');
            app.SubmitButton_mfp.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_mfpPushed, true);
            app.SubmitButton_mfp.Text = 'Submit';
            app.ResetButton_mfp = uibutton(app.GridLayout17, 'push');
            app.ResetButton_mfp.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_mfpPushed, true);
            app.ResetButton_mfp.Text = 'Reset';
            app.tsvFileTab = uitab(app.TabGroup);
            app.tsvFileTab.Title = '.tsv File';
            app.GridLayout2 = uigridlayout(app.tsvFileTab);
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.PathtotsvfileLabel_3 = uilabel(app.GridLayout9);
            app.PathtotsvfileLabel_3.Text = 'Path to .tsv file:';
            app.GridLayout10 = uigridlayout(app.GridLayout2);
            app.EditField_tsv = uieditfield(app.GridLayout10, 'text');
            app.EditField_tsv.Editable = 'off';
            app.SetButton_tsv = uibutton(app.GridLayout10, 'push');
            app.SetButton_tsv.ButtonPushedFcn = createCallbackFcn(app, @SetButton_tsvPushed, true);
            app.SetButton_tsv.Text = 'Set';
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.SubmitButton_tsv = uibutton(app.GridLayout11, 'push');
            app.SubmitButton_tsv.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_tsvPushed, true);
            app.SubmitButton_tsv.Text = 'Submit';
            app.ResetButton_tsv = uibutton(app.GridLayout11, 'push');
            app.ResetButton_tsv.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_tsvPushed, true);
            app.ResetButton_tsv.Text = 'Reset';
            app.GridLayout18 = uigridlayout(app.GridLayout2);
            app.SelectCurveOrderButtonGroup = uibuttongroup(app.GridLayout18);
            app.SelectCurveOrderButtonGroup.Title = 'Select Curve Order';
            app.MeanderPathButton = uitogglebutton(app.SelectCurveOrderButtonGroup);
            app.MeanderPathButton.Icon = fullfile(pathToMLAPP, 'MeanderIcon.png');
            app.MeanderPathButton.Text = 'Meander Path';
            app.MeanderPathButton.Value = true;
            app.SameDirectionButton = uitogglebutton(app.SelectCurveOrderButtonGroup);
            app.SameDirectionButton.Icon = fullfile(pathToMLAPP, 'SameDirectionIcon.png');
            app.SameDirectionButton.Text = 'Same Direction';
            app.MapSizePanel = uipanel(app.GridLayout18);
            app.MapSizePanel.Title = 'Map Size';
            app.GridLayout19 = uigridlayout(app.MapSizePanel);
            app.FastEditFieldLabel = uilabel(app.GridLayout19);
            app.FastEditFieldLabel.Text = 'Fast';
            app.FastEditField = uieditfield(app.GridLayout19, 'numeric');
            app.FastEditField.LowerLimitInclusive = 'off';
            app.FastEditField.Limits = [0 Inf];
            app.FastEditField.Value = 3;
            app.SlowEditFieldLabel = uilabel(app.GridLayout19);
            app.SlowEditFieldLabel.Text = 'Slow';
            app.SlowEditField = uieditfield(app.GridLayout19, 'numeric');
            app.SlowEditField.LowerLimitInclusive = 'off';
            app.SlowEditField.Limits = [0 Inf];
            app.SlowEditField.Value = 3;
            app.mLabel = uilabel(app.GridLayout19);
            app.mLabel.Text = '  µm';
            app.mLabel_2 = uilabel(app.GridLayout19);
            app.mLabel_2.Text = '  µm';
            app.MapPixelPanel = uipanel(app.GridLayout18);
            app.MapPixelPanel.Title = 'Map Pixel';
            app.GridLayout20 = uigridlayout(app.MapPixelPanel);
            app.FastPixelEditFieldLabel = uilabel(app.GridLayout20);
            app.FastPixelEditFieldLabel.Text = 'Fast Pixel';
            app.FastPixelEditField = uieditfield(app.GridLayout20, 'numeric');
            app.FastPixelEditField.Limits = [1 Inf];
            app.FastPixelEditField.Value = 25;
            app.SlowPixelEditFieldLabel = uilabel(app.GridLayout20);
            app.SlowPixelEditFieldLabel.Text = 'Slow Pixel';
            app.SlowPixelEditField = uieditfield(app.GridLayout20, 'numeric');
            app.SlowPixelEditField.Limits = [1 Inf];
            app.SlowPixelEditField.RoundFractionalValues = 'on';
            app.SlowPixelEditField.ValueDisplayFormat = '%.0f';
            app.SlowPixelEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Get_Paths_GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end