classdef Hist_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResultFilteringToolUIFigure  matlab.ui.Figure
        OpenMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SavetopimageMenu             matlab.ui.container.Menu
        SavebottomimageMenu          matlab.ui.container.Menu
        SavehistogramMenu            matlab.ui.container.Menu
        SaveimageMenu                matlab.ui.container.Menu
        SavedataMenu                 matlab.ui.container.Menu
        MaskingMenu                  matlab.ui.container.Menu
        MaskRefinementMenu           matlab.ui.container.Menu
        SaveMaskMenu                 matlab.ui.container.Menu
        LoadMaskMenu                 matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        main_grid                    matlab.ui.container.GridLayout
        GridLayout19                 matlab.ui.container.GridLayout
        GridLayout21                 matlab.ui.container.GridLayout
        bottom_graph_dropdown        matlab.ui.control.DropDown
        DisplayedchannelLabel_2      matlab.ui.control.Label
        GridLayout20                 matlab.ui.container.GridLayout
        top_graph_dropdown           matlab.ui.control.DropDown
        DisplayedchannelLabel        matlab.ui.control.Label
        bottom_axes                  matlab.ui.control.UIAxes
        top_axes                     matlab.ui.control.UIAxes
        middle_grid                  matlab.ui.container.GridLayout
        flow_button                  matlab.ui.control.StateButton
        right_grid                   matlab.ui.container.GridLayout
        tab_group_hist               matlab.ui.container.TabGroup
        show_tab                     matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout22                 matlab.ui.container.GridLayout
        findstartparameterDropDown   matlab.ui.control.DropDown
        findstartparameterDropDownLabel  matlab.ui.control.Label
        startparameterTable          matlab.ui.control.Table
        DisplayoptionsPanel          matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        HistChannelPopup             matlab.ui.control.DropDown
        DatachannelLabel             matlab.ui.control.Label
        ColorLabel                   matlab.ui.control.Label
        ColorDropDown                matlab.ui.control.DropDown
        GridLayout5                  matlab.ui.container.GridLayout
        toLabel                      matlab.ui.control.Label
        EditField_rangeMax           matlab.ui.control.NumericEditField
        EditField_rangeMin           matlab.ui.control.NumericEditField
        GridLayout4                  matlab.ui.container.GridLayout
        EditField_BinNum             matlab.ui.control.NumericEditField
        NumberofbinsLabel            matlab.ui.control.Label
        PlotrangeLabel               matlab.ui.control.Label
        Switch_showSingle            matlab.ui.control.Switch
        Switch_AddAnno               matlab.ui.control.Switch
        Switch_showInitial           matlab.ui.control.Switch
        AddAnnotationLabel           matlab.ui.control.Label
        ShowinitialguessLabel        matlab.ui.control.Label
        ShowsingelGaussiansLabel     matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        GaussiandistributionButton   matlab.ui.control.RadioButton
        bimodaldistributionButton    matlab.ui.control.RadioButton
        FittypeLabel                 matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        HistogramfilteringTab        matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        Switch_hist_filter_type      matlab.ui.control.Switch
        FiltertypeLabel              matlab.ui.control.Label
        GridLayout11                 matlab.ui.container.GridLayout
        TransparencySlider           matlab.ui.control.Slider
        TransparencySliderLabel      matlab.ui.control.Label
        GridLayout10                 matlab.ui.container.GridLayout
        ColorDropDown_2              matlab.ui.control.DropDown
        ColorDropDown_2Label         matlab.ui.control.Label
        Switch_hist_filter           matlab.ui.control.Switch
        GridLayout9                  matlab.ui.container.GridLayout
        setfilteredgesButton         matlab.ui.control.Button
        GridLayout8                  matlab.ui.container.GridLayout
        filter_right_edge            matlab.ui.control.NumericEditField
        GridLayout7                  matlab.ui.container.GridLayout
        filter_left_edge             matlab.ui.control.NumericEditField
        rightedgeLabel               matlab.ui.control.Label
        leftedgeLabel                matlab.ui.control.Label
        FilterLabel                  matlab.ui.control.Label
        ImagefilteringTab            matlab.ui.container.Tab
        GridLayout12                 matlab.ui.container.GridLayout
        Im_ColorDropDown             matlab.ui.control.DropDown
        Im_TransparencySlider        matlab.ui.control.Slider
        Im_ColorLabel                matlab.ui.control.Label
        ImagefilterSwitch            matlab.ui.control.Switch
        ImagefilterSwitchLabel       matlab.ui.control.Label
        GridLayout18                 matlab.ui.container.GridLayout
        Im_TransparencyLabel         matlab.ui.control.Label
        GridLayout17                 matlab.ui.container.GridLayout
        Im_ResetButton               matlab.ui.control.Button
        GridLayout16                 matlab.ui.container.GridLayout
        Im_SubstractButton           matlab.ui.control.Button
        GridLayout15                 matlab.ui.container.GridLayout
        Im_AddButton                 matlab.ui.control.Button
        GridLayout14                 matlab.ui.container.GridLayout
        Im_Geometry                  matlab.ui.control.DropDown
        GridLayout13                 matlab.ui.container.GridLayout
        FiltergeometryLabel          matlab.ui.control.Label
        histogram_axes               matlab.ui.control.UIAxes
        Histogram_ContextMenu        matlab.ui.container.ContextMenu
        ExportimageMenu              matlab.ui.container.Menu
        SavefiltereddataMenu         matlab.ui.container.Menu
        top_image_ContextMenu        matlab.ui.container.ContextMenu
        ImageexportMenu              matlab.ui.container.Menu
        bottom_image_ContextMenu     matlab.ui.container.ContextMenu
        ImageexportMenu_2            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        manipulation_flow = 0;  % This propertie determine the flow of manipulation. It is 0 for the flow "histogram to images" and 1 for the flow "images to histogram". It is changes by pressing the flow_button.
        findStartParameter = 'auto' % Status how the start parameters should be determined for the fits; automatic (auto) or by user input (user defined).
        DialogGUI = [] % Dialog GUI for asking user to provide the paths for the tsv and meta data file.
        tsv_struct = []; % Here the struct imported from the result tsv file is stored.
        HistColor = 'blue'; % Display color style of the histogram.
    end
    
    properties (Access = public)
        lasttsvPath = ""; % The last selected tsv-file path.
        lastMetaDataPath = ""; % Last selected path to the file containing the meta data of the force map.
        EModul = []; % The loaded EModul values from the tsv file.
        EModul_filtered = []; % Filtered EModul values.
        FilteredIndices = []; % The filtered index values.
        HistChannelNames = []; % Available channels which can be displayed as histogram.
        HistObj = []; % Histogram object displayed in the histogram axes.
        Index = []; % Indices of loaded histogram values to connect the corresponding values to channel image pixel.
        FitObj = []; % Object of the fit on the histogram data.
        HistColorValue = []; % Current color code of the histogram face color.
        unimodal_fit = []; % Fit object of the unimodal fit on the histogram.
        unimodal_hist = []; % Histogram object for an unimodal histogram.
        LegendObj = []; % The object handle of the annotation in the histogram plot.
        image_channels = []; % Names of loaded image channels.
        map_images = []; % Struct containing all loaded map images.
        x_pixel = []; % Number of x pixel for the loaded map images.
        y_pixel = []; % Number of y pixel for the loaded map images.
        x_length = []; % Length of loaded map in x direction.
        y_length = []; % Length of loaded map in y direction.
        ColorMap = []; % Contains the AFM Gold color map after loading an image file.
        index_map = []; % Map in size of the loaded image files that contains the indexes at the corresponding position.
        filter_patch = []; % Patch object for the visualization of the filter in the histogram axes.
        mask_image_1 = []; % Image object of the highlighting in the top channel image of filtered values.
        mask_image_2 = []; % Image object of the highlighting in the bottom channel image of filtered values.
        Filter_RGB = []; % RGB vector for the user picked color ('custom') for the filter color.
        ROI_color_matrix = []; % Color matrix for the region mask (see property below).
        ROI_mask = []; % Region of interest mask containing the alpha values for each pixel of the displayed filter region in the top and bottom image.
        top_ROI_obj = []; % Image object of the ROI displayed in the top image axes.
        bottom_ROI_obj = []; % Image object of the ROI displayed in the bottom image axes.
        selected_tab_group = []; % The selected tab group in the Get_Paths_GUI, used to select the correct file load procedure.
        CurveOrder = ""; % Selected curve order in the import tab group ".tsv File".
    end
    
    methods (Access = private)
        
        function app = switch_manipulation_flow(app)
            % Helper function for the switching of the manipulation flow
            % from "histogram to images" to "images to histogram" and vice
            % versa
            
            % Code
            % % % %             Enter code here                 % % % %
            
        end
        
        function hist_display_options_helpfcn(app,action)
        % hist_display_options_helpfcn is a helper function to activate or
        % deactivate the display options for the histogram in the "Histogram options"
        % tab below the histogram axes.
        %
        %   Example:
        %   hist_display_options_helpfcn(app,'activate');
        %   hist_display_options_helpfcn(app,'deactivate');
        
            % error handling for hist_display_options_helpfcn            
            if ~any(ismember({'activate';'deactivate'},action))
                error('The "action" input must be either "activate" or "deactivate"!')
            end
            
            % activate or deactivate histogram display option elements
            switch action
                case 'activate'
                    app.ColorLabel.Enable = 1;
                    app.ColorDropDown.Enable = 1;
                    app.DatachannelLabel.Enable = 1;
                    app.HistChannelPopup.Enable = 1;
                    app.PlotrangeLabel.Enable = 1;
                    app.EditField_rangeMin.Enable = 1;
                    app.toLabel.Enable = 1;
                    app.EditField_rangeMax.Enable = 1;
                    app.NumberofbinsLabel.Enable = 1;
                    app.EditField_BinNum.Enable = 1;
                    app.FittypeLabel.Enable = 1;
                    app.bimodaldistributionButton.Enable = 1;
                    app.GaussiandistributionButton.Enable = 1;
                    app.ShowinitialguessLabel.Enable = 1;
                    if strcmp(app.ButtonGroup.SelectedObject.Text,'bimodal distribution')
                        app.ShowsingelGaussiansLabel.Enable = 1;
                        app.Switch_showSingle.Enable = 1;
                    end
                    app.AddAnnotationLabel.Enable = 1;
                    app.Switch_showInitial.Enable = 1;
                    app.AddAnnotationLabel.Enable = 1;
                    app.Switch_AddAnno.Enable = 1;
                    app.findstartparameterDropDown.Enable = 1;
                    if strcmp(app.findstartparameterDropDown.Value,'user defined')
                        app.startparameterTable.Enable = 'on';
                    end
                case 'deactivate'
                    app.ColorLabel.Enable = 0;
                    app.ColorDropDown.Enable = 0;
                    app.DatachannelLabel.Enable = 0;
                    app.HistChannelPopup.Enable = 0;
                    app.PlotrangeLabel.Enable = 0;
                    app.EditField_rangeMin.Enable = 0;
                    app.toLabel.Enable = 0;
                    app.EditField_rangeMax.Enable = 0;
                    app.NumberofbinsLabel.Enable = 0;
                    app.EditField_BinNum.Enable = 0;
                    app.FittypeLabel.Enable = 0;
                    app.bimodaldistributionButton.Enable = 0;
                    app.GaussiandistributionButton.Enable = 0;
                    app.ShowinitialguessLabel.Enable = 0;
                    if strcmp(app.ButtonGroup.SelectedObject.Text,'bimodal distribution')
                        app.ShowsingelGaussiansLabel.Enable = 0;
                        app.Switch_showSingle.Enable = 0;
                    end
                    app.AddAnnotationLabel.Enable = 0;
                    app.Switch_showInitial.Enable = 0;
                    app.AddAnnotationLabel.Enable = 0;
                    app.Switch_AddAnno.Enable = 0;
                    app.findstartparameterDropDown.Enable = 0;
                    app.startparameterTable.Enable = 'off';
            end
        end
        
        function manage_histogram_helpfcn(app,action,specifier)
            % implemented actions with corresponding specifier:
            %   updateFigure    ->  Color, PlotRange, BinNum, FitType, ShowSingle, ShowInitial, AddAnnotation
            %   updateValues    ->  EModul
            %   draw            ->  Hist, Fit
            %   fit             ->  bimodal, unimodal
            
            % error handling for  maage_histogram_helpfcn
            
            valid_actions = {'updateFigure';...
                             'updateValues';...
                             'draw';...
                             'fit'};
                         
            updateFigureSpecifier = {'Color';...
                                     'PlotRange';...
                                     'BinNum';...
                                     'FitType';...
                                     'ShowSingle';...   % not in use
                                     'ShowInitial'; ... % not in use 
                                     'AddAnnotation'};
                                 
            updateValuesSpecifier = {'EModul'}; 
            drawSpecifier = {'Hist';...
                             'Fit'};
                         
            fitSpecifier = {'bimodal';...
                            'unimodal'};
            
            if ~ischar(action) || ~ischar(specifier)
                error('Inputs for ''action'' and ''specifier'' must both be characters!')
            end
                            
                            
            if ~any(ismember(valid_actions,action))
                error('The input for the parameter "action" must be one of the following:\n''updateFigure''\n''updateValues''\n''draw''\n''fit''%s\n',' ');
            end
            
            switch action
                case 'updateFigure'
                    if ~any(ismember(updateFigureSpecifier,specifier))
                        error('With the action ''updateFigure'' just one of the following specifier is valid:\n''Color''\n''PlotRange\n''BinNum''\n''FitType''\n''ShowSingle''\n''ShowInitial''\n''AddAnnotation''%s\n',' ');
                    end
                case 'updateValues'
                    if ~any(ismember(updateValuesSpecifier,specifier))
                        error('With the action ''updateValues'' just one of the following specifier is valid:\n''EModul''\n%s\n',' ');
                    end
                case 'draw'
                    if ~any(ismember(drawSpecifier,specifier))
                        error('With the action ''draw'' just one of the following specifier is valid:\n''Hist''\n''Fit''%s\n',' ');
                    end
                case 'fit'
                    if ~any(ismember(fitSpecifier,specifier))
                        error('With the action ''fit'' just one of the following specifier is valid:\n''bimodal''\n''unimodal''%s\n',' ');
                    end
            end
            
            % main code of function
            switch action
                case 'updateFigure'
                    switch specifier
                        case 'Color'
                            switch app.HistColor
                                case 'blue'
                                    app.HistObj.hist.FaceColor = [0 .447 .741]; 
                                    app.HistColorValue = [0 .447 .741];
                                case 'red'
                                    app.HistObj.hist.FaceColor = [.9 .1 .1];
                                    app.HistColorValue = [.9 .1 .1];
                                case 'yellow'
                                    app.HistObj.hist.FaceColor = [.929 .694 .125];
                                    app.HistColorValue = [.929 .694 .125];
                                case 'green'
                                    app.HistObj.hist.FaceColor = [.466 .674 .188];
                                    app.HistColorValue = [.466 .674 .188];
                                case 'custom'
                                    C = uisetcolor(app.HistObj.hist,'Select histogram color');
                                    figure(app.ResultFilteringToolUIFigure);
                                    app.HistColorValue = C;
                                    app.HistObj.hist.FaceColor = C;
                            end
                        case 'PlotRange'
                            value_min = app.EditField_rangeMin.Value;
                            value_max = app.EditField_rangeMax.Value;
                            x_axis_lim = app.histogram_axes.XLim;
                            % if value_min < 0
                            %     value_min = 0;
                            %     app.EditField_rangeMin.Value = value_min;
                            % end
                            if value_max <= value_min
                                value_max = x_axis_lim(2);
                                app.EditField_rangeMax.Value = value_max;
                            end
                            if value_min >= value_max
                                value_min = x_axis_lim(1);
                                app.EditField_rangeMin.Value = value_min;
                            end
                            app.histogram_axes.XLim = [value_min value_max];
                            % update the bins of the  displayed according
                            % to the new plot range
                            manage_histogram_helpfcn(app,'updateFigure','BinNum');
                        case 'BinNum'
                            cla(app.histogram_axes);
                            % calculate and draw new histogram
                            app.HistObj = BimodalHistogram(app.EModul_filtered,[app.EditField_rangeMin.Value app.EditField_rangeMax.Value],app.EditField_BinNum.Value,'yes',app.histogram_axes);
                            % calculate new fit
                            switch app.ButtonGroup.SelectedObject.Text
                                case 'bimodal distribution'
                                    manage_histogram_helpfcn(app,'fit','bimodal')
                                case 'Gaussian distribution'
                                    manage_histogram_helpfcn(app,'fit','unimodal')
                            end
                            % plot fit result
                            hold(app.histogram_axes,"on");
                            manage_histogram_helpfcn(app,'draw','Fit');
                            hold(app.histogram_axes,"off");
                            manage_histogram_helpfcn(app,'updateFigure','AddAnnotation');
                        case 'FitType'
                            switch app.ButtonGroup.SelectedObject.Text
                                case 'bimodal distribution'
                                    if strcmp(app.findstartparameterDropDown.Value,'user defined')
                                        app.startparameterTable.Data = {'a1','';'E1','';'w1','';'a2','';'E2','';'w2',''};
                                    end
                                    
                                case 'Gaussian distribution'
                                    if strcmp(app.findstartparameterDropDown.Value,'user defined')
                                        app.startparameterTable.Data = {'a','';'E','';'w',''}; 
                                    end
                            end
                        case 'ShowSingle'
                            % till today not in use
                        case 'ShowInitial'
                            % till today not in use                            
                        case 'AddAnnotation'
                            value = app.Switch_AddAnno.Value;
                            switch value
                                case 'no'
                                    if ~isempty(app.LegendObj)
                                        delete(app.LegendObj)
                                        app.LegendObj = [];
                                    end
                                case 'yes'
                                    if ~isempty(app.LegendObj)
                                        delete(app.LegendObj)
                                    end
                                    switch app.ButtonGroup.SelectedObject.Text
                                        case 'bimodal distribution'
                                            if ~isempty(app.HistObj)
                                                % a1
                                                a1_string = sprintf('%.2f',app.HistObj.parameters.a1);
                                                % E1
                                                [~,~,~,E1_string] = determine_order_of_mag(app,app.HistObj.parameters.E1,'Pa');
                                                % w1
                                                [~,~,~,w1_string] = determine_order_of_mag(app,app.HistObj.parameters.w1,'Pa');
                                                % a2
                                                a2_string = sprintf('%.2f',app.HistObj.parameters.a2);
                                                % E2
                                                [~,~,~,E2_string] = determine_order_of_mag(app,app.HistObj.parameters.E2,'Pa');
                                                % w2
                                                [~,~,~,w2_string] = determine_order_of_mag(app,app.HistObj.parameters.w2,'Pa');
                                                % create annotiation string
                                                a1_an = sprintf('a1 = %s\t',a1_string);
                                                E1_an = sprintf('E1 = %s\t',E1_string);
                                                w1_an = sprintf('w1 = %s\t',w1_string);
                                                a2_an = sprintf('a2 = %s\t',a2_string);
                                                E2_an = sprintf('E2 = %s\t',E2_string);
                                                w2_an = sprintf('w2 = %s\t',w2_string);
                                                anno_string = {'Fit results:';a1_an;E1_an;w1_an;a2_an;E2_an;w2_an};
                                                % add annotation
                                                h1 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h2 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h3 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h4 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h5 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h6 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h7 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                app.LegendObj = legend(app.histogram_axes,[h1 h2 h3 h4 h5 h6 h7],anno_string,"Location","northeast","AutoUpdate","off","FontSize",12);
                                            end
                                        case 'Gaussian distribution'
                                             if ~isempty(app.unimodal_fit)
                                                % a
                                                a1_string = sprintf('%.2f',app.unimodal_fit.a1);
                                                % E
                                                [~,~,~,E1_string] = determine_order_of_mag(app,app.unimodal_fit.b1,'Pa');
                                                % w
                                                [~,~,~,w1_string] = determine_order_of_mag(app,app.unimodal_fit.c1,'Pa');
                                                % create annotiation string
                                                a1_an = sprintf('a = %s  ',a1_string);
                                                E1_an = sprintf('E = %s  ',E1_string);
                                                w1_an = sprintf('w = %s  ',w1_string);
                                                anno_string = {'Fit results:';a1_an;E1_an;w1_an};
                                                % add annotation
                                                h1 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h2 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h3 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                h4 = line(app.histogram_axes, nan, nan, 'Color', 'none');
                                                app.LegendObj = legend(app.histogram_axes,[h1 h2 h3 h4],anno_string,"Location","northeast","AutoUpdate","off","FontSize",12);
                                            end
                                    end
                            end
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''updateFigure'' ',specifier);                          
                    end
                case 'updateValues'
                    switch specifier
                        case 'EModul'
                            cla(app.histogram_axes)
                            manage_histogram_helpfcn(app,'draw','Hist')
                            switch app.ButtonGroup.SelectedObject.Text
                                case 'bimodal distribution'
                                    manage_histogram_helpfcn(app,'fit','bimodal')
                                case 'Gaussian distribution'
                                    manage_histogram_helpfcn(app,'fit','unimodal')
                            end
                                                        
                            hold(app.histogram_axes,'on')
                            manage_histogram_helpfcn(app,'draw','Fit')
                            hold(app.histogram_axes,"off")
                            manage_histogram_helpfcn(app,'updateFigure','AddAnnotation')
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''updateValues'' ',specifier); 
                    end
                case 'draw'
                    switch specifier
                        case 'Hist'
                            app.HistObj = BimodalHistogram(app.EModul_filtered,[app.EditField_rangeMin.Value app.EditField_rangeMax.Value],app.EditField_BinNum.Value,'yes',app.histogram_axes);   
                        case 'Fit'
                            switch app.ButtonGroup.SelectedObject.Text
                                case 'bimodal distribution'
                                    switch app.Switch_showSingle.Value
                                        case 'yes'
                                            app.HistObj.plotFit(app.histogram_axes);
                                        case 'no'
                                            hold(app.histogram_axes,"on");
                                            plot(app.histogram_axes,app.HistObj.x_data_fit,app.HistObj.y_data_fit,'-k','LineWidth',2);
                                            hold(app.histogram_axes,"off");
                                    end
                                    if strcmp(app.Switch_showInitial.Value,'yes') && strcmp(app.findstartparameterDropDown.Value,"user defined")
                                        app.HistObj.showInitialGuess(app.histogram_axes);
                                    end
                                case 'Gaussian distribution'
                                    if isempty(app.unimodal_fit)
                                        error('You first need to call manage_histogram_helpfcn(app,''fit'',''unimodal'') to create the fit object!');
                                    end
                                    x_data = linspace(app.EditField_rangeMin.Value,app.EditField_rangeMax.Value,400);
                                    y_data = feval(app.unimodal_fit,x_data);
                                    hold(app.histogram_axes,'on')
                                    plot(app.histogram_axes,x_data,y_data,'-k','LineWidth',2);
                                    hold(app.histogram_axes,'off')
                                    if strcmp(app.findstartparameterDropDown.Value,'user defined') 
                                        if strcmp(app.Switch_showInitial.Value,'yes') && ~any(isnan(str2double(string(app.startparameterTable.Data(:,2)))))
                                            hold(app.histogram_axes,"on");
                                            x_init = linspace(app.EditField_rangeMin.Value,app.EditField_rangeMax.Value,400);
                                            a_int = str2double(app.startparameterTable.Data{1,2});
                                            E_int = str2double(app.startparameterTable.Data{2,2});
                                            w_int = str2double(app.startparameterTable.Data{3,2});
                                            y_init = feval(@(a1,E1,w1,x)a1.*exp(-((x-E1)./w1).^2),a_int,E_int,w_int,x_init);
                                            plot(app.histogram_axes,x_init,y_init,'-r','LineWidth',2);
                                            hold(app.histogram_axes,"off");
                                        end
                                    end
                            end
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''draw'' ',specifier); 
                    end
                case 'fit'
                    switch specifier
                        case 'bimodal'
                            switch app.findstartparameterDropDown.Value
                                case 'auto'
                                    app.HistObj = app.HistObj.doFit([],'no',app.histogram_axes);
                                    switch app.Switch_showSingle.Value
                                        case 'yes'
                                            hold(app.histogram_axes,"on");
                                            manage_histogram_helpfcn(app,'draw','Fit');
                                            hold(app.histogram_axes,"off");
                                        case 'no'
                                            hold(app.histogram_axes,"on");
                                            plot(app.histogram_axes,app.HistObj.x_data_fit,app.HistObj.y_data_fit,'-k','LineWidth',2);
                                            hold(app.histogram_axes,"off");
                                    end
                                case 'user defined'
                                    if any(isnan(str2double(string(app.startparameterTable.Data(:,2)))))
                                        app.HistObj = app.HistObj.doFit(app.histogram_axes);
                                    else
                                        Start_p = string(app.startparameterTable.Data(:,2));
                                        Start_p = str2double(Start_p)';
                                        app.HistObj = app.HistObj.doFit(Start_p,'no');
                                    end
                            end
                            if strcmp(app.Switch_showInitial.Value,'yes')
                                app.HistObj.showInitialGuess(app.histogram_axes);
                            end
                        case 'unimodal'
                            switch app.findstartparameterDropDown.Value
                                case 'auto'
                                    [x_data,y_data] = prepareCurveData(app.HistObj.BinCenters,app.HistObj.BinCounts);
                                    [center_max,ind_max] = max(y_data);
                                    max_pos = x_data(ind_max);
                                    max_width = (max_pos - min(x_data))/2;
                                    app.unimodal_fit = fit(x_data,y_data,'gauss1','StartPoint',[center_max max_pos max_width]);
                                case 'user defined'
                                    [x_data,y_data] = prepareCurveData(app.HistObj.BinCenters,app.HistObj.BinCounts);
                                    if any(isnan(str2double(string(app.startparameterTable.Data(:,2)))))
                                        app.unimodal_fit = fit(x_data,y_data,'gauss1');
                                    else
                                        Start_p = string(app.startparameterTable.Data(:,2));
                                        Start_p = str2double(Start_p)';
                                        app.unimodal_fit = fit(x_data,y_data,'gauss1','StartPoint',Start_p');
                                    end
                                    if strcmp(app.Switch_showInitial.Value,'yes') && ~any(isnan(str2double(string(app.startparameterTable.Data(:,2)))))
                                        hold(app.histogram_axes,"on");
                                        x_init = linspace(app.EditField_rangeMin.Value,app.EditField_rangeMax.Value,400);
                                        a_int = str2double(app.startparameterTable.Data{1,2});
                                        E_int = str2double(app.startparameterTable.Data{2,2});
                                        w_int = str2double(app.startparameterTable.Data{3,2});
                                        y_init = feval(@(a1,E1,w1,x)a1.*exp(-((x-E1)./w1).^2),a_int,E_int,w_int,x_init);
                                        plot(app.histogram_axes,x_init,y_init,'-r','LineWidth',2);
                                        hold(app.histogram_axes,"off");
                                    end
                            end
                        otherwise
                            error('Unknown specifier ''%s'' for the action ''fit'' ',specifier);
                    end
            end
            
        end
    end
    
    methods (Access = public)
        
        function importResultsFile(app,file_path)
            %   importResultsFile is a function to import the results file
            %   generated after the dada processing of a force map with the
            %   CANTER Processing Tool.
            %
            %   As output this function provides a struct containing the
            %   result file data as N-by-1 arrays. The field names are the
            %   corresponding names of the underlying data, e.g. 'Young's
            %   modulus' or 'Curve Index'.
            %   
            %   As input, the function needs the path to the .tsv result
            %   file as a string.
            %
            %   Example:
            %   result_struct = importResultsFile('C:\Users\User_name\Results\result_file.tsv');
            %
            
            % error handling for importResultsFile
            if ~(ischar(file_path) || isstring(file_path))
                error('file_path must be of type character vector or string array!')
            end
            
            if ~isfile(file_path)   
                error('File not found!\nInput for file_path must be a path to a existing file!%s\n',' ');
            end
            
            [~,~,f_ex] = fileparts(file_path);
            if ~strcmp(f_ex,'.tsv')
                error('Not a .tsv file!\nThis function can only read .tsv files!%s\n',' ')
            end
           
            % main code of function
            % Open tsv file in "read text" mode
            % tsv_ID = fopen(file_path,"rt");
            % % read first line and evaluate if the loaded tsv-file is valid
            % header_line = fgetl(tsv_ID);
            % header_names = strsplit(header_line,'\t');
            % % if ~((strcmp(header_names{1},'File_name') && strcmp(header_names{2},'Index'))||(strcmp(header_names{1},'Filename') && strcmp(header_names{2},'Position Index')))
            % %    uialert(app.ResultFilteringToolUIFigure,'The tsv file you want to load is not a result file generated with the CANTER Processing Tool or the JPK Data Processing software!','Invalid tsv file');
            % %    fclose(tsv_ID);
            % %    return;
            % % end
            % if strcmp(header_names{2},'Position Index')
            %     header_names{2} = 'Index';
            % end
            % 
            % 
            % % read remaining file
            % N_arrays = length(header_names);
            % formatSpec = '%s %d';
            % for i=1:(N_arrays-2)
            %    formatSpec = [formatSpec ' %f'];
            % end
            % tsv_arrays = textscan(tsv_ID,formatSpec,'Delimiter','\t');
            % % close file ID
            % fclose(tsv_ID);
            % % create tsv struct
            % tsv_struc = struct();
            % for i=1:N_arrays
            %     if i == 1
            %         tsv_struc.(header_names{i}) = string(tsv_arrays{i});
            %     else
            %         header_names{i} = strrep(header_names{i},'µ','u');
            %         header_names{i} = strrep(header_names{i},' ','_');
            %         header_names{i} = regexprep(header_names{i},'\W','');
            %         tsv_struc.(header_names{i}) = tsv_arrays{:,i};
            %     end
            % end
            % if tsv_struc.Index(1) == 0
            %     tsv_struc.Index = tsv_struc.Index +1 ;
            % end
            % % write tsv_struct to app property "tsv_struct"
            % app.tsv_struct = tsv_struc;
            % 
            % % NEW PART
            tsvTable = readtable(file_path,"FileType","text","Delimiter","\t","VariableNamingRule","modify","ReadVariableNames",true);
            VarNames = string(tsvTable.Properties.VariableNames);
            Indexvar = find(contains(VarNames,"Index"),1,"first");
            if isempty(Indexvar)
                selection = listdlg("PromptString","Select the column containing the curve indices","SelectionMode","single","ListString",VarNames);
                if isempty(selection)
                    return;
                end
                VarNames(selection) = "Index";
                tsvTable.Properties.VariableNames = VarNames;
            else
                VarNames(Indexvar) = "Index";
                tsvTable.Properties.VariableNames = VarNames;
            end
            
            if tsvTable.Index(1) == 0
                tsvTable.Index = tsvTable.Index + 1;
            end

            Outstruct = table2struct(tsvTable,"ToScalar",true);
            Outstruct.Index = uint32(Outstruct.Index);
            app.tsv_struct = Outstruct;
            
        end
        
        function importFiles_helpFcn(app)
            % Load the tsv results file
            importResultsFile(app,app.lasttsvPath)        
            % create progess bar for histogram
            prog_bar = uiprogressdlg(app.ResultFilteringToolUIFigure,"Message","Loading histogram channels ...","Title","Loading process","Indeterminate","on");
            % get channel names and load them into the HistChannelPopup
            ChannelNames = fieldnames(app.tsv_struct);
            app.HistChannelNames = ChannelNames(3:end);
            app.HistChannelPopup.Items = app.HistChannelNames;
            % import histogram data and index from results struct  and select correct channel in
            % HistChannelPopup
            if any(ismember(app.HistChannelNames,'EModul'))
                app.EModul = app.tsv_struct.EModul;
                app.EModul_filtered = app.EModul;
                app.HistChannelPopup.Value = 'EModul';
            elseif any(ismember(app.HistChannelNames,'E_s'))
                app.EModul = app.tsv_struct.E_s;
                app.EModul_filtered = app.EModul;
                app.HistChannelPopup.Value = 'E_s';
            else
                else_name = app.HistChannelNames{1};
                app.EModul = app.tsv_struct.(else_name);
                app.EModul_filtered = app.EModul;
                app.HistChannelPopup.Value = else_name;
            end
            if isfield(app.tsv_struct,'Index')
                app.Index = app.tsv_struct.Index;
            else
               app.Index = (1:length(app.EModul))';
            end
            % evaluate default display range for the histogram
            app.EditField_rangeMin.Value = min(app.EModul_filtered);
            app.EditField_rangeMax.Value = max(app.EModul_filtered);
            app.histogram_axes.XLim = [app.EditField_rangeMin.Value app.EditField_rangeMax.Value];
            % Clear figure
            cla(app.histogram_axes);
            % Display loaded data
            manage_histogram_helpfcn(app,'draw','Hist');
            % add fit to histogram (default at loading)
            switch app.ButtonGroup.SelectedObject.Text
                case 'bimodal distribution'
                    manage_histogram_helpfcn(app,'fit','bimodal');
                case 'Gaussian distribution'
                    manage_histogram_helpfcn(app,'fit','unimodal');
            end
            % draw fit result
            manage_histogram_helpfcn(app,'draw','Fit')
            % draw annotation if selected
            manage_histogram_helpfcn(app,'updateFigure','AddAnnotation')
            % Enable all relevant elements
            hist_display_options_helpfcn(app,'activate');
            % delete progess bar for histogram
            delete(prog_bar);
            
            % Load the map images
            import_image_files(app,app.lastMetaDataPath)
            
            % activate channel popups
            app.top_graph_dropdown.Enable = 1;
            app.bottom_graph_dropdown.Enable = 1;
            
            % activate histogram filter switch
            app.Switch_hist_filter.Enable = 1;
            app.FilterLabel.Enable = 1;
            
            % reset histogram filter
            app.Switch_hist_filter.Value = 'Off';
            app.Switch_hist_filterValueChanged(app)
            app.Switch_hist_filter_type.Value = 'positive';
            app.filter_left_edge.Value = 0;
            app.filter_right_edge.Value = 0;
            app.filter_patch = [];
            app.ROI_color_matrix = [];
            
            % activate and reset flow button
            app.flow_button.Enable = 1;
            app.flow_button.Value = 0;
            app.manipulation_flow = 0;
            flow_buttonValueChanged(app,'opening file')
            
            % reset image filters
            app.ImagefilterSwitch.Value = 'Off';
            ImagefilterSwitchValueChanged(app,'opening file')
            app.ROI_mask = [];
            app.top_ROI_obj = [];
            app.bottom_ROI_obj = [];
            
        end


        function [plain_numb,numb_power,value_unit,value_string] = determine_order_of_mag(~,value,SI_unit)
            
            ord_of_mag = floor(log10(value));
            
            if ord_of_mag > 15 
                warning('The order of magnetude (%i) of the value is out of range (too high) for this function.\nThus, the output is just the input value!',ord_of_mag)
                plain_numb = value;
                numb_power = 0;
                value_unit = sprintf('%s',SI_unit);
                value_string = sprintf('%.2e %s',plain_numb,value_unit);              
            elseif ord_of_mag < -15
                warning('The order of magnetude (%i) of the value is out of range (too low) for this function.\nThus, the output is just the input value!',ord_of_mag)
                plain_numb = value;
                numb_power = 0;
                value_unit = sprintf('%s',SI_unit);
                value_string = sprintf('%.2e %s',plain_numb,value_unit);
            else
                if ord_of_mag >= 12
                    plain_numb = value*1e-12;
                    numb_power = 12;
                    value_unit = sprintf('%s%s','T',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < 12 && ord_of_mag >= 9
                    plain_numb = value*1e-9;
                    numb_power = 9;
                    value_unit = sprintf('%s%s','G',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < 9 && ord_of_mag >= 6
                    plain_numb = value*1e-6;
                    numb_power = 6;
                    value_unit = sprintf('%s%s','M',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < 6 && ord_of_mag >= 3
                    plain_numb = value*1e-3;
                    numb_power = 3;
                    value_unit = sprintf('%s%s','k',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < 3 && ord_of_mag >= 0
                    plain_numb = value*1e-0;
                    numb_power = 0;
                    value_unit = sprintf('%s',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < 0 && ord_of_mag >= -3
                    plain_numb = value*1e3;
                    numb_power = -3;
                    value_unit = sprintf('%s%s','m',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < -3 && ord_of_mag >= -6
                    plain_numb = value*1e6;
                    numb_power = -6;
                    value_unit = sprintf('%s%s','µ',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < -6 && ord_of_mag >= -9
                    plain_numb = value*1e9;
                    numb_power = -9;
                    value_unit = sprintf('%s%s','n',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < -9 && ord_of_mag >= -12
                    plain_numb = value*1e12;
                    numb_power = -12;
                    value_unit = sprintf('%s%s','p',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                elseif ord_of_mag < -12 && ord_of_mag >= -15
                    plain_numb = value*1e15;
                    numb_power = -15;
                    value_unit = sprintf('%s%s','f',SI_unit);
                    value_string = sprintf('%.2f %s',plain_numb,value_unit);
                end
            end
        end


        function import_image_files(app,file_path)
            
            switch app.selected_tab_group
                case "JPK Maps"
                    file_path = char(file_path);
                    [f_path,f_name,f_ext] = fileparts(file_path);
                    % load images in dependency of the loaded file type
                    switch f_ext
                        case '.jpk-force-map'
                            % load images
                            % initialize progress bar
                            prog_bar = uiprogressdlg(app.ResultFilteringToolUIFigure,"Title","Loading process","Message","Unzipping JPK map file ...","Indeterminate","on");
                            % get paths to zip file and unzipped file                    
                            zipfile = strcat(f_name,'.zip');
                            zippath = fullfile(f_path, zipfile);
                            copyfile (file_path, zippath);
                            force_path = fullfile(f_path,'data-image.force');
                            
                            % unzip the data-image file from the jpk-force-map file
                                            % try
                                            %     file_n = mfilename('fullpath');
                                            %     path_parts = split(file_n,filesep);
                                            %     unzip(fullfile(path_parts{1:end-3},'7-Zip.zip'),fullfile(path_parts{1:end-3}));
                                            %     zip_prog_path = fullfile(path_parts{1:end-3},'7-Zip','7z.exe');
                                            %     zip_command = 'x';
                                            %     switches = ['-bso0' ' ' '-r' ' ' '-y' ' ' '-o' '"' unzipfolder '"' ];
                                            %     status = system(['"' zip_prog_path '"' ' ' zip_command  ' ' '"' zippath '"' ' ' switches]);
                                            %     rmdir(fullfile(path_parts{1:end-3},'7-Zip'),'s');
                                            % catch % or with matlabs unzip function
                                            %     warning('7zip wasn''t found; matlabs unzip function was used instead!');
                                            %     unzip(zippath, unzipfolder);
                                            % end
                                            % if status ~= 0
                                            %     warning('7zip wasn''t successful in unziping. Matlabs unzip function was used instead!');
                                            %     unzip(zippath, unzipfolder);
                                            % end
                            NotSuccessful1 = false;
                            NotSuccessful2 = false;
                            try
                                % This options currently disabled due to deletion problems of the zip file after extraction!
                                % extractSingleFileFromZIP(app,zippath, f_path, "data-image.force");
                                NotSuccessful1 = true;
                            catch %ME if you can
                                NotSuccessful1 = true;
                            end
                            if NotSuccessful1
                                try
                                    file_n = mfilename('fullpath');
                                    path_parts = split(file_n,filesep);
                                    unzip(fullfile(path_parts{1:end-3},'7-Zip.zip'),fullfile(path_parts{1:end-3}));
                                    zip_prog_path = fullfile(path_parts{1:end-3},'7-Zip','7z.exe');
                                    zip_command = 'x';
                                    file_filter = 'data-image.force';
                                    switches = ['-bso0' ' ' '-r-' ' ' '-y' ' ' '-o' '"' f_path '"' ' ' '"' file_filter '"'];
                                    status = system(['"' char(zip_prog_path) '"' ' ' char(zip_command)  ' ' '"' char(zippath) '"' ' ' char(switches)]);
                                    if status ~= 0
                                        NotSuccessful2 = true;
                                    end
                                    rmdir(fullfile(path_parts{1:end-3},'7-Zip'),'s');
                                catch %ME if you can
                                    NotSuccessful2 = true;
                                end
                            end
                            if NotSuccessful2
                                unzip(zippath, f_path);
                                try
                                    rmdir(fullfile(f_path,"index"),"s");
                                    rmdir(fullfile(f_path,"shared-data"),"s");
                                    delete(fullfile(f_path,"header.properties"));
                                    delete(fullfile(f_path,"thumbnail.png"));
                                catch %ME if you can
                                end
                            end

                            % update progress bar
                            prog_bar.Message = "Loading image channels ...";
                            % get map images
                            force_path = fullfile(f_path,'data-image.force');
                            [app.map_images,~] = ForceMapImageData(force_path);
                            
                            % delete data-image file
                                    % try
                                    %     rmdir(unzipfolder,"s")
                                    % catch
                                    %    warning("Deletion of the 'Forcemap' folder was not possible!\nThis could be due to access restrictions.\n\nThe following folder could not be removed from disk:\n%s\n",unzipfolder) 
                                    % end
                            delete(force_path);

                            % delete zip-file
                            delete(zippath);
                            
                            % furhter proccessing of loaded images
                            load('afm_darkgold.mat','afm_darkgold');
                            app.ColorMap = afm_darkgold;
                            % write channel names to drop down menus
                            app.image_channels = fieldnames(app.map_images);
                            % get rid of thumbnail image
                            thumb_mask = ~strcmp(string(app.image_channels),'thumbnail');
                            app.image_channels = app.image_channels(thumb_mask);
                            if isfield(app.map_images,'thumbnail')
                                app.map_images = rmfield(app.map_images,'thumbnail');
                            end
                            % create images of all result parameters
                            result_parameters = string(app.HistChannelNames);
                            app.x_pixel = app.map_images.(app.image_channels{1}).XPixel;
                            app.y_pixel = app.map_images.(app.image_channels{1}).YPixel;
                            app.x_length = app.map_images.(app.image_channels{1}).XLength;
                            app.y_length = app.map_images.(app.image_channels{1}).YLength;
                            
                            for i = 1:length(result_parameters)
                                param_values = app.tsv_struct.(result_parameters(i));
                                % preallocation of image matrix and add parameter
                                % name to image_channels
                                app.map_images.(result_parameters(i)).CData = zeros(app.y_pixel,app.x_pixel);
                                app.image_channels(end+1) = {char(result_parameters(i))};
                                % write values to image matrix
                                ind = 0;
                                jump_count = 0;
                                app.index_map = zeros(app.y_pixel,app.x_pixel);
                                for y = app.y_pixel:-1:1
                                    for x = 1:app.x_pixel
                                        ind = ind + 1;
                                        if ind > length(app.tsv_struct.Index)
                                            jump_count = jump_count + 1;
                                            ind = ind - 1;
                                            app.map_images.(result_parameters(i)).CData(y,x) = NaN;
                                            app.index_map(y,x) = ind + jump_count;
                                            continue;
                                        end
                                        if ind == app.tsv_struct.Index(ind) - jump_count
                                            app.map_images.(result_parameters(i)).CData(y,x) = param_values(ind);
                                            app.index_map(y,x) = ind + jump_count;
                                        else % take care of a missing result index
                                            jump_count = jump_count + 1;
                                            ind = ind - 1;
                                            app.map_images.(result_parameters(i)).CData(y,x) = NaN;
                                            app.index_map(y,x) = ind + jump_count;
                                        end
                                    end
                                end

                                for v = (app.y_pixel-1):-2:1
                                    curr_line = app.map_images.(result_parameters(i)).CData(v,:);
                                    app.map_images.(result_parameters(i)).CData(v,:) = flip(curr_line,2);
                                    curr_index_line = app.index_map(v,:);
                                    app.index_map(v,:) = flip(curr_index_line,2);
                                end

                                % flip whole map vertically 
                                app.map_images.(result_parameters(i)).CData = flip(app.map_images.(result_parameters(i)).CData,1);

                            end
                            % reset dropdown menus
                            app.top_graph_dropdown.Items = {'No Channel'};
                            app.top_graph_dropdown.Value = {'No Channel'};
                            
                            app.bottom_graph_dropdown.Items = {'No Channel'};
                            app.bottom_graph_dropdown.Value = {'No Channel'};
                                                
                            % write channel names to the dropdown menus if a data
                            % value exists
                            for i=1:length(app.image_channels)
                                curr_channel = app.image_channels{i};
                                switch curr_channel
                                    case 'height_measured'
                                        if isfield(app.map_images.height_measured,'nominal_height_data') || isfield(app.map_images.height_measured,'absolut_height_data')
                                            app.top_graph_dropdown.Items{i} = curr_channel;
                                            app.bottom_graph_dropdown.Items{i} = curr_channel;
                                        end
                                    otherwise
                                        if isfield(app.map_images.(curr_channel),sprintf('%s_data',curr_channel))
                                            app.top_graph_dropdown.Items{i} = curr_channel;
                                            app.bottom_graph_dropdown.Items{i} = curr_channel;
                                        elseif isfield(app.map_images.(curr_channel),'CData')
                                            app.top_graph_dropdown.Items{i} = curr_channel;
                                            app.bottom_graph_dropdown.Items{i} = curr_channel;
                                        end                                
                                end
                            end
                            % update progess bar
                            prog_bar.Message = "Displaying channel images ...";
                            
                            % display the first and second channel respectively or same in both if only one exists.
                            if length(app.image_channels) > 1
                                app.top_graph_dropdown.Value = app.image_channels{1};
                                app.bottom_graph_dropdown.Value = app.image_channels{2};
                            else
                                app.top_graph_dropdown.Value = app.image_channels{1};
                                app.bottom_graph_dropdown.Value = app.image_channels{1};
                            end
                            
                            top_graph_dropdownValueChanged(app,'import_image_files')
                            bottom_graph_dropdownValueChanged(app,'import_image_files');
                            
                            % close the progress bar
                            close(prog_bar);


                        case ".jpk-qi-data"
                            prog_bar = uiprogressdlg(app.ResultFilteringToolUIFigure,"Title","Loading process","Message","Unzipping jpk-force-map file ...","Indeterminate","on");
                            % get paths to zip file and unzipped file                    
                            zipfile = strcat(f_name,'.zip');
                            zippath = fullfile(f_path, zipfile);
                            copyfile (file_path, zippath);
                            
                            NotSuccessful = false;
                            try
                                file_n = mfilename('fullpath');
                                path_parts = split(file_n,filesep);
                                unzip(fullfile(path_parts{1:end-3},'7-Zip.zip'),fullfile(path_parts{1:end-3}));
                                zip_prog_path = fullfile(path_parts{1:end-3},'7-Zip','7z.exe');
                                zip_command = 'x';
                                file_filter = 'data-image.jpk-qi-image';
                                switches = ['-bso0' ' ' '-r-' ' ' '-y' ' ' '-o' '"' f_path '"' ' ' '"' file_filter '"'];
                                status = system(['"' char(zip_prog_path) '"' ' ' char(zip_command)  ' ' '"' char(zippath) '"' ' ' char(switches)]);
                                if status ~= 0
                                    NotSuccessful = true;
                                end
                                rmdir(fullfile(path_parts{1:end-3},'7-Zip'),'s');
                            catch %ME if you can
                                NotSuccessful = true;
                            end
                            if NotSuccessful
                                unzip(zippath, f_path);
                                try
                                    rmdir(fullfile(f_path,"index"),"s");
                                    rmdir(fullfile(f_path,"shared-data"),"s");
                                    delete(fullfile(f_path,"header.properties"));
                                    delete(fullfile(f_path,"thumbnail.png"));
                                catch %ME if you can
                                end
                            end
                            delete(zippath);

                            % update progress bar
                            prog_bar.Message = "Loading image channels ...";
                            % get map images
                            force_path = fullfile(f_path,'data-image.jpk-qi-image');
                            [app.map_images,~] = ForceMapImageData(force_path);
                            delete(force_path);
                            
                            % furhter proccessing of loaded images
                            load('afm_darkgold.mat','afm_darkgold');
                            app.ColorMap = afm_darkgold;
                            % write channel names to drop down menus
                            app.image_channels = fieldnames(app.map_images);
                            % get rid of thumbnail image
                            thumb_mask = ~strcmp(string(app.image_channels),'thumbnail');
                            app.image_channels = app.image_channels(thumb_mask);
                            if isfield(app.map_images,'thumbnail')
                                app.map_images = rmfield(app.map_images,'thumbnail');
                            end
                            % create images of all result parameters
                            result_parameters = string(app.HistChannelNames);
                            app.x_pixel = app.map_images.(app.image_channels{1}).XPixel;
                            app.y_pixel = app.map_images.(app.image_channels{1}).YPixel;
                            app.x_length = app.map_images.(app.image_channels{1}).XLength;
                            app.y_length = app.map_images.(app.image_channels{1}).YLength;
                            
                            for i = 1:length(result_parameters)
                                param_values = app.tsv_struct.(result_parameters(i));
                                % preallocation of image matrix and add parameter
                                % name to image_channels
                                app.map_images.(result_parameters(i)).CData = zeros(app.y_pixel,app.x_pixel);
                                app.image_channels(end+1) = {char(result_parameters(i))};
                                % write values to image matrix
                                ind = 0;
                                jump_count = 0;
                                app.index_map = zeros(app.y_pixel,app.x_pixel);
                                for y = app.y_pixel:-1:1
                                    for x = 1:app.x_pixel
                                        ind = ind + 1;
                                        if ind > length(app.tsv_struct.Index)
                                            jump_count = jump_count + 1;
                                            ind = ind - 1;
                                            app.map_images.(result_parameters(i)).CData(y,x) = NaN;
                                            app.index_map(y,x) = ind + jump_count;
                                            continue;
                                        end
                                        if ind == app.tsv_struct.Index(ind) - jump_count
                                            app.map_images.(result_parameters(i)).CData(y,x) = param_values(ind);
                                            app.index_map(y,x) = ind + jump_count;
                                        else % take care of a missing result index
                                            jump_count = jump_count + 1;
                                            ind = ind - 1;
                                            app.map_images.(result_parameters(i)).CData(y,x) = NaN;
                                            app.index_map(y,x) = ind + jump_count;
                                        end
                                    end
                                end
                                % flip whole map horizontally and vertically
                                app.map_images.(result_parameters(i)).CData = flip(app.map_images.(result_parameters(i)).CData,1);   
                            end
                            
                            % reset dropdown menus
                            app.top_graph_dropdown.Items = {'No Channel'};
                            app.top_graph_dropdown.Value = {'No Channel'};
                            
                            app.bottom_graph_dropdown.Items = {'No Channel'};
                            app.bottom_graph_dropdown.Value = {'No Channel'};

                            % write channel names to the dropdown menus if a data
                            % value exists
                            for i=1:length(app.image_channels)
                                curr_channel = app.image_channels{i};
                                if isfield(app.map_images.(curr_channel),sprintf('%s_data',curr_channel))
                                    app.top_graph_dropdown.Items{i} = curr_channel;
                                    app.bottom_graph_dropdown.Items{i} = curr_channel;
                                elseif isfield(app.map_images.(curr_channel),'CData')
                                    app.top_graph_dropdown.Items{i} = curr_channel;
                                    app.bottom_graph_dropdown.Items{i} = curr_channel;
                                end                                
                            end
                            % update progess bar
                            prog_bar.Message = "Displaying channel images ...";
                            
                            % display the first and second channel respectively or same in both if only one exists.
                            if length(app.image_channels) > 1
                                app.top_graph_dropdown.Value = app.image_channels{1};
                                app.bottom_graph_dropdown.Value = app.image_channels{2};
                            else
                                app.top_graph_dropdown.Value = app.image_channels{1};
                                app.bottom_graph_dropdown.Value = app.image_channels{1};
                            end
                            
                            top_graph_dropdownValueChanged(app,'import_image_files')
                            bottom_graph_dropdownValueChanged(app,'import_image_files');
                            
                            % close the progress bar
                            close(prog_bar);
                            
                    end
                    
                case "MFP-3D Maps"
                    
                    wb = uiprogressdlg(app.ResultFilteringToolUIFigure,"Message","The MFP-3D force map is read ...","Title","Read Force Map","Indeterminate","on");
                    [x_data,y_data,x_data_retract,y_data_retract,Forcecurve_count,file_name,mfpmapdata] = ReadMFPMaps_wo_bar(file_path);
                                      
                    % getting important values
                    load('afm_darkgold.mat','afm_darkgold');
                    app.ColorMap = afm_darkgold;
                    info_struct = mfpmapdata{1,2};
                    app.x_pixel = info_struct.FMapScanPoints;
                    app.y_pixel = info_struct.FMapScanLines;
                    app.x_length = info_struct.FastScanSize;
                    app.y_length = info_struct.SlowScanSize;
                    
                    % create index map
                    help_vec = 1:app.x_pixel*app.y_pixel;
                    ind_map = reshape(help_vec,app.x_pixel,app.y_pixel);
                    app.index_map = rot90(ind_map);
                    
                    wb = uiprogressdlg(app.ResultFilteringToolUIFigure,"Message","The channel images are getting reconstructed from the force curves.","Title","Image reconstruction","Value",0);
                    
                    % preallocate map images
                    app.map_images.height_map.height_map_data = zeros(size(app.index_map));
                    app.map_images.slope.slope_data = zeros(size(app.index_map));
                    app.map_images.adhesion.adhesion_data = zeros(size(app.index_map));
                    % preallocate map images for result channels
                    result_parameters = string(app.HistChannelNames);
                    for i = 1:length(result_parameters)
                        app.map_images.(result_parameters(i)).(sprintf("%s_data",result_parameters(i))) = zeros(size(app.index_map));
                    end
                    
                    % write all channel names to the corresponding propertie
                    app.image_channels = fieldnames(app.map_images);
                    
                    num_of_curves = length(fieldnames(x_data));
                    for i=1:num_of_curves
                        wb.Value = i/num_of_curves;
                        c_string = sprintf("curve%d",i);
                        [row,col] = find(app.index_map==i);
                        
                        % determine height value
                        app.map_images.height_map.height_map_data(row,col) = min(x_data.(c_string));
                        
                        % determine slope value
                        x_curve = x_data.(c_string);
                        y_curve = y_data.(c_string);
                        x_diff = max(x_curve) - min(x_curve);
                        data_mask = x_curve <= (min(x_curve)+0.02*x_diff);
                        x_data_fit = x_curve(data_mask);
                        y_data_fit = y_curve(data_mask);
                        p = polyfit(x_data_fit, y_data_fit, 1);
                        app.map_images.slope.slope_data(row,col) = p(1);
                        % determine adhesion
                        app.map_images.adhesion.adhesion_data(row,col) = min(y_data_retract.(c_string));
                        
                        % get result parameters
                        if ismember(i,app.tsv_struct.Index)
                            for j = 1:length(result_parameters)
                                res_par_name = result_parameters(j);
                                res_par_data = sprintf("%s_data",res_par_name);
                                app.map_images.(res_par_name).(res_par_data)(row,col) = app.tsv_struct.(res_par_name)(app.tsv_struct.Index==i);
                            end
                        else
                            for j = 1:length(result_parameters)
                                res_par_name = result_parameters(j);
                                res_par_data = sprintf("%s_data",res_par_name);
                                app.map_images.(res_par_name).(res_par_data)(row,col) = NaN;
                            end
                        end
                    end
                    
                    % reset dropdown menus
                    app.top_graph_dropdown.Items = {'No Channel'};
                    app.top_graph_dropdown.Value = {'No Channel'};
                    
                    app.bottom_graph_dropdown.Items = {'No Channel'};
                    app.bottom_graph_dropdown.Value = {'No Channel'};
                    
                    % write channel names to the dropdown menus if a data
                    % value exists
                    for i=1:length(app.image_channels)
                        curr_channel = app.image_channels{i};
                        app.top_graph_dropdown.Items{i} = curr_channel;
                        app.bottom_graph_dropdown.Items{i} = curr_channel;    
                    end
                    
                    % update progess bar
                    wb.Message = "Displaying channel images ...";
                    wb.Indeterminate = true;
                    
                    % display the first and second channel respectively or same in both if only one exists.
                    if length(app.image_channels) > 1
                        app.top_graph_dropdown.Value = app.image_channels{1};
                        app.bottom_graph_dropdown.Value = app.image_channels{2};
                    else
                        app.top_graph_dropdown.Value = app.image_channels{1};
                        app.bottom_graph_dropdown.Value = app.image_channels{1};
                    end
                    
                    top_graph_dropdownValueChanged(app,'import_image_files')
                    bottom_graph_dropdownValueChanged(app,'import_image_files');
                    
                    % delete the progress bar
                    close(wb);
                case ".tsv File"
                    
                    % show a progess dialog in the app
                    prog_bar = uiprogressdlg(app.ResultFilteringToolUIFigure,"Title","Loading process","Message","Creating channel images from tsv file ...","Indeterminate","on");
                    
                    % clean the map_images propertie
                    app.map_images = [];

                    % Get color map
                    load('afm_darkgold.mat','afm_darkgold');
                    app.ColorMap = afm_darkgold;

                    % create images of all result parameters
                    result_parameters = string(app.HistChannelNames);
                    app.image_channels = {};

                    for i = 1:length(result_parameters)
                        param_values = app.tsv_struct.(result_parameters(i));
                        % preallocation of image matrix and add parameter
                        % name to image_channels
                        app.map_images.(result_parameters(i)).CData = zeros(app.y_pixel,app.x_pixel);
                        if isempty(app.image_channels)
                            app.image_channels = {char(result_parameters(i))};
                        else
                            app.image_channels(end+1) = {char(result_parameters(i))};
                        end
                        % write values to image matrix
                        ind = 0;
                        jump_count = 0;
                        app.index_map = zeros(app.y_pixel,app.x_pixel);
                        for y = app.y_pixel:-1:1
                            for x = 1:app.x_pixel
                                ind = ind + 1;
                                if ind > length(app.tsv_struct.Index)
                                    jump_count = jump_count + 1;
                                    ind = ind - 1;
                                    app.map_images.(result_parameters(i)).CData(y,x) = NaN;
                                    app.index_map(y,x) = ind + jump_count;
                                    continue;
                                end
                                if ind == app.tsv_struct.Index(ind) - jump_count
                                    app.map_images.(result_parameters(i)).CData(y,x) = param_values(ind);
                                    app.index_map(y,x) = ind + jump_count;
                                else % take care of a missing result index
                                    jump_count = jump_count + 1;
                                    ind = ind - 1;
                                    app.map_images.(result_parameters(i)).CData(y,x) = NaN;
                                    app.index_map(y,x) = ind + jump_count;
                                end
                            end
                        end

                        % flip each second line of image vertically if meandder curve oder was selected
                        if strcmp(app.CurveOrder,"meander")
                            for v = (app.y_pixel-1):-2:1
                                curr_line = app.map_images.(result_parameters(i)).CData(v,:);
                                app.map_images.(result_parameters(i)).CData(v,:) = flip(curr_line,2);
                                curr_index_line = app.index_map(v,:);
                                app.index_map(v,:) = flip(curr_index_line,2);
                            end
                        end

                        % flip whole map horizontally and vertically
                        app.map_images.(result_parameters(i)).CData = flip(app.map_images.(result_parameters(i)).CData,1); 
                    end

                    
                    % reset dropdown menus
                    app.top_graph_dropdown.Items = {'No Channel'};
                    app.top_graph_dropdown.Value = {'No Channel'};
                    
                    app.bottom_graph_dropdown.Items = {'No Channel'};
                    app.bottom_graph_dropdown.Value = {'No Channel'};

                    % write channel names to the dropdown menus if a data
                    % value exists
                    for i=1:length(app.image_channels)
                        curr_channel = app.image_channels{i};
                        app.top_graph_dropdown.Items{i} = curr_channel;
                        app.bottom_graph_dropdown.Items{i} = curr_channel;    
                    end
                    
                    % update progess bar
                    prog_bar.Message = "Displaying channel images ...";
                    
                    % display the first and second channel respectively or same in both if only one exists.
                    if length(app.image_channels) > 1
                        app.top_graph_dropdown.Value = app.image_channels{1};
                        app.bottom_graph_dropdown.Value = app.image_channels{2};
                    else
                        app.top_graph_dropdown.Value = app.image_channels{1};
                        app.bottom_graph_dropdown.Value = app.image_channels{1};
                    end
                    
                    top_graph_dropdownValueChanged(app,'import_image_files')
                    bottom_graph_dropdownValueChanged(app,'import_image_files');
                    
                    % delete the progress bar
                    close(prog_bar);
            end
        end
        
        function draw_filter_patch(app)
            if ~isempty(app.filter_patch)
                delete(app.filter_patch);
                app.filter_patch = [];
            end
                left_edge = app.filter_left_edge.Value;
                right_edge = app.filter_right_edge.Value;
                x_ax_lims = xlim(app.histogram_axes);
                x_min = x_ax_lims(1);
                x_max = x_ax_lims(2);
                ylimits = ylim(app.histogram_axes);
                rgb_code = getRGBvalues(app);
                % sort edges
                filter_edges = [left_edge;right_edge];
                if ~issorted(filter_edges,1,"ascend")
                    left_edge = min(filter_edges);
                    right_edge = max(filter_edges);
                end
                if left_edge < x_min
                    left_edge = x_min;
                end
                if right_edge > x_max
                    right_edge = x_max;
                end
                % sort patch x-values in ascending order if required
%                 edges = [x_min;left_edge;right_edge;x_max];
%                 if ~issorted(edges)
%                     edges = sort(edges,1,"ascend");
%                     x_min = edges(1);
%                     left_edge = edges(2);
%                     right_edge = edges(3);
%                     x_max = edges(4);
%                 end
                switch app.Switch_hist_filter_type.Value
                    case 'positive'
                        app.filter_patch = patch(app.histogram_axes,'XData',[left_edge right_edge right_edge left_edge],'YData',[ylimits(1) ylimits(1) ylimits(2) ylimits(2)],"FaceColor",rgb_code,"FaceAlpha",.2,"EdgeColor",'none');
                    case 'negative'
                        x_vec = [x_min right_edge;left_edge x_max;left_edge x_max;x_min right_edge];
                        y_vec = [ylimits(1) ylimits(1);ylimits(1) ylimits(1);ylimits(2) ylimits(2);ylimits(2) ylimits(2)];
                        app.filter_patch = patch(app.histogram_axes,'XData',x_vec,'YData',y_vec,"FaceColor",rgb_code,"FaceAlpha",.2,"EdgeColor",'none');
                end               

        end
        
        function apply_hist_filter(app)
            filter_type = app.Switch_hist_filter_type.Value;
            left_edge = app.filter_left_edge.Value;
            right_edge = app.filter_right_edge.Value;
            ind_map = app.index_map;
            alpha_grid = zeros(app.y_pixel,app.x_pixel);
            hist_values = app.EModul;
                        
            % check if left and right edge value are not the same
            if left_edge == right_edge
                return;                
            end
                        
            % sort left and right edge
            edge_vec = [left_edge right_edge];
            left_edge = min(edge_vec);
            right_edge = max(edge_vec);
            
            switch filter_type
                case 'positive'
                    hist_mask = (hist_values >= left_edge) & (hist_values <= right_edge);
                case 'negative'
                    hist_mask = (hist_values >= left_edge) & (hist_values <= right_edge);
                    hist_mask = ~hist_mask;
            end
            ind_vec = app.Index(hist_mask);
            index_positions = ismember(ind_map,ind_vec);
%           [row,col] = find(index_positions); % -> this step now became useless ... but let's keep it for now, just to be safe. :) 
            slider_value = app.TransparencySlider.Value;
            alpha_grid(index_positions) = slider_value;
            app.ROI_color_matrix = index_positions;
            
            [~,x_power,~,~] = determine_order_of_mag(app,app.x_length,'m');
            [~,y_power,~,~] = determine_order_of_mag(app,app.y_length,'m');
            x_coordinates = linspace(0,app.x_length,app.x_pixel)./10^(x_power);
            y_coordinates = linspace(0,app.y_length,app.y_pixel)./10^(y_power);
            
            mask_image = zeros(app.y_pixel,app.x_pixel,3);
            RGB_vector = getRGBvalues(app);
            mask_image(:,:,1) = RGB_vector(1);
            mask_image(:,:,2) = RGB_vector(2);
            mask_image(:,:,3) = RGB_vector(3);
            
            
            if ~isempty(app.mask_image_1)
                delete(app.mask_image_1);
                app.mask_image_1 = [];
            end
            if~isempty(app.mask_image_2)
                delete(app.mask_image_2)
                app.mask_image_2 = [];
            end

            hold(app.top_axes,"on")
                app.mask_image_1 = imagesc(app.top_axes,'XData',x_coordinates,'YData',y_coordinates,'CData',mask_image,"AlphaData",flip(alpha_grid,1));
            hold(app.top_axes,"off")
            hold(app.bottom_axes,"on")
                app.mask_image_2 = imagesc(app.bottom_axes,'XData',x_coordinates,'YData',y_coordinates,'CData',mask_image,"AlphaData",flip(alpha_grid,1));
            hold(app.bottom_axes,"off")
        end
                
        function extractSingleFileFromZIP(~,zipFilename, outputDir, outputFile)
            % extractFile
    
            % Obtain the entry's output names
            outputName = fullfile(outputDir, outputFile);
            
            % Create a stream copier to copy files.
            streamCopier = ...
                com.mathworks.mlwidgets.io.InterruptibleStreamCopier.getInterruptibleStreamCopier;
            
            % Create a Java zipFile object and obtain the entries.
            try
                % Create a Java file of the Zip filename.
                zipJavaFile = java.io.File(zipFilename);
            
                % Create a java ZipFile and validate it.
                zipFile = org.apache.tools.zip.ZipFile(zipJavaFile);
            
                % Get entry
                entry = zipFile.getEntry(outputFile);
            
            catch exception
                if ~isempty(zipFile)
                    zipFile.close;
                end
                delete(cleanUpUrl);
                error(message('MATLAB:unzip:unvalidZipFile', zipFilename));
            end
            
            % Create the Java File output object using the entry's name.
            file = java.io.File(outputName);
            
            % If the parent directory of the entry name does not exist, then create it.
            parentDir = char(file.getParent.toString);
            if ~exist(parentDir, 'dir')
                mkdir(parentDir)
            end
            
            % Create an output stream
            try
                fileOutputStream = java.io.FileOutputStream(file);
            catch exception
                overwriteExistingFile = file.isFile && ~file.canWrite;
                if overwriteExistingFile
                    warning(message('MATLAB:extractArchive:UnableToOverwrite', outputName));
                else
                    warning(message('MATLAB:extractArchive:UnableToCreate', outputName));
                end
                return
            end
            
            % Create an input stream from the API
            fileInputStream = zipFile.getInputStream(entry);
            
            % Extract the entry via the output stream.
            streamCopier.copyStream(fileInputStream, fileOutputStream);
            
            % Close the output stream.
            fileOutputStream.close;
        end
        
        
        function myCopyAxes(~,axFrom,axTo)
            
                % Copy children (lines)
                copyobj(axFrom.Children, axTo);
                
                % Transfer other properties
                uiAxParams = get(axFrom);
                uiAxParamNames = fieldnames(uiAxParams);
                
        end
        
        
        function RGB_vec = getRGBvalues(app)
            if app.manipulation_flow == 0
                color_string = app.ColorDropDown_2.Value;
            elseif app.manipulation_flow == 1
                color_string = app.Im_ColorDropDown.Value; 
            end
            
            switch color_string
                case 'red'
                    RGB_vec = [1,0,0];
                case 'green'
                    RGB_vec = [0,1,0];
                case 'blue'
                    RGB_vec = [0,0,1];
                case 'yellow'
                    RGB_vec = [1,1,0];
                case 'black'
                    RGB_vec = [0,0,0];
                case 'white'
                    RGB_vec = [1,1,1];
                case 'custom'
                    RGB_vec = app.Filter_RGB;
            end
        end
        
        function user_selection = userROIfunction(app,destination,shape)
            color_code = getRGBvalues(app);
            alpha_value = app.Im_TransparencySlider.Value;
            switch shape
                case 'Rectangle'
                    ROI = drawrectangle("Parent",destination,"Color",color_code,"FaceAlpha",alpha_value,"InteractionsAllowed","all");
                    wait(ROI);
                    if isempty(ROI.Position)
                        user_selection = NaN;
                        return;
                    end                 
                case 'Circle'
                    ROI = drawcircle("Parent",destination,"Color",color_code,"FaceAlpha",alpha_value,"InteractionsAllowed","all");
                    wait(ROI);
                    if isempty(ROI.Position)
                        user_selection = NaN;
                        return;
                    end      
                case 'Ellipse'
                    ROI = drawellipse("Parent",destination,"Color",color_code,"FaceAlpha",alpha_value,"InteractionsAllowed","all");
                    wait(ROI);
                    if isempty(ROI.Position)
                        user_selection = NaN;
                        return;
                    end
                case 'Polygon'
                    ROI = drawpolygon("Parent",destination,"Color",color_code,"FaceAlpha",alpha_value,"InteractionsAllowed","all");
                    wait(ROI);
                    if isempty(ROI.Position)
                        user_selection = NaN;
                        return;
                    end
                case 'Freehand'
                    ROI = drawfreehand("Parent",destination,"Color",color_code,"FaceAlpha",alpha_value,"InteractionsAllowed","all");
                    wait(ROI);
                    if isempty(ROI.Position)
                        user_selection = NaN;
                        return;
                    end
            end
%             user_selection = createMask(ROI,app.y_pixel,app.x_pixel);
            image_handle = findobj(allchild(app.top_axes),'Type','image','-and','AlphaData',1);
            user_selection = createMask(ROI,image_handle);
            delete(ROI);
        end
            
        function Image_filter_items(app,action)
            switch action
                case 'activate'
                    app.ImagefilterSwitch.Enable = 1;
                    app.ImagefilterSwitchLabel.Enable = 1;
                    app.FiltergeometryLabel.Enable = 1;
                    app.Im_Geometry.Enable = 1;
                    app.Im_ColorLabel.Enable = 1;
                    app.Im_ColorDropDown.Enable = 1;
                    app.Im_TransparencyLabel.Enable = 1;
                    app.Im_TransparencySlider.Enable = 1;
                    app.Im_AddButton.Enable = 1;
                    app.Im_SubstractButton.Enable = 1;
                    app.Im_ResetButton.Enable = 1;
                case 'deactivate'
                    app.ImagefilterSwitch.Enable = 0;
                    app.ImagefilterSwitchLabel.Enable = 0;
                    app.FiltergeometryLabel.Enable = 0;
                    app.Im_Geometry.Enable = 0;
                    app.Im_ColorLabel.Enable = 0;
                    app.Im_ColorDropDown.Enable = 0;
                    app.Im_TransparencyLabel.Enable = 0;
                    app.Im_TransparencySlider.Enable = 0;
                    app.Im_AddButton.Enable = 0;
                    app.Im_SubstractButton.Enable = 0;
                    app.Im_ResetButton.Enable = 0;
                otherwise
                    uialert(app.ResultFilteringToolUIFigure,'Invalid action string of function ""Image_filter_items""!','Invalid action string',"Icon","warning");
                    return;
            end
            
        end
        
        function draw_ROI_image(app)
                if isempty(app.ROI_color_matrix) || size(app.ROI_color_matrix,3) ~= 3
                    color_vec = getRGBvalues(app);
                    app.ROI_color_matrix = zeros(app.y_pixel,app.x_pixel,3);
                    app.ROI_color_matrix(:,:,1) = color_vec(1);
                    app.ROI_color_matrix(:,:,2) = color_vec(2);
                    app.ROI_color_matrix(:,:,3) = color_vec(3);
                end
                if ~isempty(app.top_ROI_obj)
                    delete(app.top_ROI_obj)
                end
                if ~isempty(app.bottom_ROI_obj)
                    delete(app.bottom_ROI_obj)
                end
                [~,x_power,~,~] = determine_order_of_mag(app,app.x_length,'m');
                [~,y_power,~,~] = determine_order_of_mag(app,app.y_length,'m');
                x_coordinates = linspace(0,app.x_length,app.x_pixel)./10^(x_power);
                y_coordinates = linspace(0,app.y_length,app.y_pixel)./10^(y_power);
                
                hold(app.top_axes,"on")
                    app.top_ROI_obj = imagesc(app.top_axes,'XData',x_coordinates,'YData',y_coordinates,'CData',app.ROI_color_matrix,"AlphaData",app.ROI_mask);
                hold(app.top_axes,"off")
                hold(app.bottom_axes,"on")
                    app.bottom_ROI_obj = imagesc(app.bottom_axes,'XData',x_coordinates,'YData',y_coordinates,'CData',app.ROI_color_matrix,"AlphaData",app.ROI_mask);
                hold(app.bottom_axes,"off")
        end
       
        function filter_histogram_data(app)
            position_mask = app.ROI_mask ~= 0;
            position_mask = flip(position_mask,1);
            filter_indices = app.index_map(position_mask);
            filter_indices = sort(filter_indices,1,"ascend");
            logical_vec = ismember(app.Index,filter_indices);
            app.EModul_filtered = app.EModul(logical_vec);
            app.FilteredIndices = app.Index(logical_vec);
            % redraw filtered histogram
            manage_histogram_helpfcn(app,'updateValues','EModul')
        end

        function SaveCurrentMask(app,FullSavePath)
            FlowState = app.manipulation_flow;
            switch FlowState
                case 0
	                if isempty(app.mask_image_1)
		                uialert(app.ResultFilteringToolUIFigure,"No Mask to save!","Empty Mask","Icon","info");
		                return;
	                end
	                AlphaMask = app.mask_image_1.AlphaData;
                case 1
	                if isempty(app.ROI_mask)
		                uialert(app.ResultFilteringToolUIFigure,"No Mask to save!","Empty Mask","Icon","info");
		                return;
	                end
	                AlphaMask = app.ROI_mask;
            end

            if isempty(FullSavePath)
                if isempty(app.lasttsvPath)
	                [file,location,check] = uiputfile("*.mask-data","Save Mask Data","SaveMaskData");
                else
	                [filepath,name,~] = fileparts(app.lasttsvPath);
	                [file,location,check] = uiputfile(fullfile(filepath,name+".mask-data"),"Save Mask Data");
                end
                if check == 0
	                return;
                end
                FullSavePath = fullfile(location,file);
            end

            CurrMask = uint64(AlphaMask > 0);
            writematrix(CurrMask,FullSavePath,"FileType","text");
        end
        
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.histogram_axes.Toolbar.Visible = 'off';
            app.top_axes.Toolbar.Visible = 'off';
            app.bottom_axes.Toolbar.Visible = 'off';
            app.histogram_axes.Interactions = [];
            app.top_axes.Interactions = [];
            app.bottom_axes.Interactions = [];
            app.ResultFilteringToolUIFigure.Name = 'Result Filtering Tool';
            
            drawnow;
            % app.ResultFilteringToolUIFigure.WindowState = 'maximized';
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            web('https://github.com/CANTERhm/CANTER_Processing_Tool/wiki/Result-Filtering-Tool','-browser','-new')
        end

        % Value changed function: flow_button
        function flow_buttonValueChanged(app, event)
            value = app.flow_button.Value;
            if value
                app.flow_button.Icon = 'button_1.png';
                app.manipulation_flow = 1;
                % deactivate histogram filters and clear masks
                app.Switch_hist_filter.Value = 'Off';
                Switch_hist_filterValueChanged(app,'flow_button')
                app.Switch_hist_filter.Enable = 0;
                app.FilterLabel.Enable = 0;
                app.mask_image_1 = [];
                app.mask_image_2 = [];
                % switch tab and activate label/switch for image filter
                app.TabGroup.SelectedTab = app.ImagefilteringTab;
                app.ImagefilterSwitchLabel.Enable = 1;
                app.ImagefilterSwitch.Enable = 1;
                app.MaskRefinementMenu.Enable = "on";
                app.LoadMaskMenu.Enable = "on";
                
            else
                app.flow_button.Icon = 'button_0.png';
                app.manipulation_flow = 0;
                % deactivate image filters and clear masks
                app.ImagefilterSwitchLabel.Enable = 0;
                app.ImagefilterSwitch.Enable = 0;
                app.ImagefilterSwitch.Value = 'Off';
                ImagefilterSwitchValueChanged(app,'flow_button')
                app.top_ROI_obj = [];
                app.bottom_ROI_obj = [];
                app.ROI_color_matrix = [];
                app.MaskRefinementMenu.Enable = "off";
                app.LoadMaskMenu.Enable = "off";

                % switch tab and activate label/switch for histogram filter
                app.TabGroup.SelectedTab = app.HistogramfilteringTab;
                app.FilterLabel.Enable = 1;
                app.Switch_hist_filter.Enable = 1;
            end
            
        end

        % Value changed function: findstartparameterDropDown
        function findstartparameterDropDownValueChanged(app, event)
            value = app.findstartparameterDropDown.Value;
            switch value
                case 'auto'
                    app.startparameterTable.Enable = 'off';
                    app.startparameterTable.Data = [];
                    manage_histogram_helpfcn(app,'draw','Hist');
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'bimodal distribution'
                            manage_histogram_helpfcn(app,'fit','bimodal');
                        case 'Gaussian distribution'
                            manage_histogram_helpfcn(app,'fit','unimodal');
                    end
                    hold(app.histogram_axes,"on");
                    manage_histogram_helpfcn(app,'draw','Fit');
                    hold(app.histogram_axes,"off");
                    manage_histogram_helpfcn(app,'updateFigure','AddAnnotation');
                case 'user defined'
                    app.startparameterTable.Enable = 'on';
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'bimodal distribution'
                            app.startparameterTable.Data = {'a1','';'E1','';'w1','';'a2','';'E2','';'w2',''};
                        case 'Gaussian distribution'
                            app.startparameterTable.Data = {'a','';'E','';'w',''};
                    end
            end
            app.findStartParameter = value;
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % open the GUI for selecting and loading the tsv- and
            % Map-data-file.
            paths_app = Get_Paths_GUI(app.lasttsvPath,app.lastMetaDataPath);
            main_app_pos = app.ResultFilteringToolUIFigure.Position;
            this_app_pos = paths_app.UIFigure.Position;
            paths_app.UIFigure.Position([1 2]) = [main_app_pos(1) + main_app_pos(3)/2 - this_app_pos(3)/2 , main_app_pos(2) + main_app_pos(4)/2 - this_app_pos(4)/2];
            if strcmp(app.selected_tab_group,".tsv File")
                if isempty(app.x_length) || isempty(app.y_length) || isempty(app.x_pixel) || isempty(app.y_pixel) || isempty(app.CurveOrder)
                    previousOptions = [];
                else
                    previousOptions.XLen = app.x_length;
                    previousOptions.YLen = app.y_length;
                    previousOptions.XPix = app.x_pixel;
                    previousOptions.YPix = app.y_pixel;
                    previousOptions.COrd = app.CurveOrder;
                end                
                paths_app.display_previous_paths(app.lasttsvPath,[],app.selected_tab_group,previousOptions);
            else
                paths_app.display_previous_paths(app.lasttsvPath,app.lastMetaDataPath,app.selected_tab_group);
            end
            waitfor(paths_app,"wait_status",0);
            
            if paths_app.canceled == 1
                delete(paths_app)
                return
            end
            
            % get file paths from Get_Paths_GUI
            app.lasttsvPath = paths_app.tsv_path_new;
            app.lastMetaDataPath = paths_app.meta_path_new;
            
            % get selected tab from Get_Paths_GUI
            app.selected_tab_group = paths_app.TabGroup.SelectedTab.Title;
            if strcmp(app.selected_tab_group,".tsv File")
                app.x_length = paths_app.XLenght;
                app.y_length = paths_app.YLength;
                app.x_pixel = paths_app.XPixel;
                app.y_pixel = paths_app.YPixel;
                app.CurveOrder = paths_app.SelectecCurveOrder;
            end
            delete(paths_app)

            % open the selected files
            app.importFiles_helpFcn;


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            value = app.ButtonGroup.SelectedObject.Text;            
            manage_histogram_helpfcn(app,'updateFigure','FitType');
            cla(app.histogram_axes);
            manage_histogram_helpfcn(app,'draw','Hist');
            switch value
                case 'bimodal distribution'
                    manage_histogram_helpfcn(app,'fit','bimodal');
                case 'Gaussian distribution'
                    manage_histogram_helpfcn(app,'fit','unimodal');
            end
            manage_histogram_helpfcn(app,'draw','Fit');
            manage_histogram_helpfcn(app,'updateFigure','AddAnnotation');
        end

        % Menu selected function: SavetopimageMenu
        function SavetopimageMenuSelected(app, event)
            save_fig = figure;
            save_ax = axes(save_fig);
            myCopyAxes(app,app.top_axes,save_ax);
            childs = allchild(app.top_axes);
            if length(childs) > 0
                set_afm_gold(0.9,2.2,length(childs));
            end
            save_ax.Box = "on";
            save_ax.DataAspectRatio = [1 1 1];
            axis(save_ax,'tight');
            colorbar(save_ax);
            channel = app.top_graph_dropdown.Value;
            save_ax.Title.String = sprintf('Channel: %s',channel);
            save_ax.XAxis.Label.String = app.top_axes.XAxis.Label.String;
            save_ax.YAxis.Label.String = app.top_axes.YAxis.Label.String;
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.HistColor = value;
            manage_histogram_helpfcn(app,'updateFigure','Color');
        end

        % Value changed function: HistChannelPopup
        function HistChannelPopupValueChanged(app, event)
            value = app.HistChannelPopup.Value;
            app.EModul = app.tsv_struct.(value);
            app.EModul_filtered = app.EModul;
            % take care of image filter
            switch app.ImagefilterSwitch.Value
                case 'On'
                    if ~isempty(app.ROI_mask)
                        filter_histogram_data(app)
                    end
            end
            % reset histogram filter
            app.Switch_hist_filter.Value = 'Off';
            app.Switch_hist_filterValueChanged(app);
            app.filter_left_edge.Value = 0;
            app.filter_right_edge.Value = 0;
            % reset color drop down
            app.ColorDropDown.Value = 'blue';
            % draw new histogram
            cla(app.histogram_axes);
            app.EditField_rangeMin.Value = min(app.EModul_filtered);
            app.EditField_rangeMax.Value = max(app.EModul_filtered);
            app.histogram_axes.XLim = [min(app.EModul_filtered) max(app.EModul_filtered)];
            manage_histogram_helpfcn(app,'draw','Hist');
            % calculate new fit
            switch app.ButtonGroup.SelectedObject.Text
                case 'bimodal distribution'
                    manage_histogram_helpfcn(app,'fit','bimodal')
                case 'Gaussian distribution'
                    manage_histogram_helpfcn(app,'fit','unimodal')
            end
            % plot fit result
            manage_histogram_helpfcn(app,'draw','Fit');
            % update annotation if necessary
            manage_histogram_helpfcn(app,'updateFigure','AddAnnotation');  
        end

        % Value changed function: EditField_rangeMin
        function EditField_rangeMinValueChanged(app, event)
            manage_histogram_helpfcn(app,'updateFigure','PlotRange');
            if strcmp(app.Switch_hist_filter.Value,'On')
                draw_filter_patch(app);
            end
        end

        % Value changed function: EditField_rangeMax
        function EditField_rangeMaxValueChanged(app, event)
            manage_histogram_helpfcn(app,'updateFigure','PlotRange');
            if strcmp(app.Switch_hist_filter.Value,'On')
                draw_filter_patch(app);
            end
        end

        % Value changed function: EditField_BinNum
        function EditField_BinNumValueChanged(app, event)
            manage_histogram_helpfcn(app,'updateFigure','BinNum');
            if strcmp(app.Switch_hist_filter.Value,'On')
                draw_filter_patch(app);
                apply_hist_filter(app);
                if strcmp(app.Switch_AddAnno.Value,'yes')
                    manage_histogram_helpfcn(app,'updateFigure','AddAnnotation')
                end
            end
        end

        % Value changed function: Switch_showInitial
        function Switch_showInitialValueChanged(app, event)
            switch app.Switch_showInitial.Value
                case 'yes'
                    manage_histogram_helpfcn(app,'draw','Fit');
                case 'no'
                    cla(app.histogram_axes)
                    manage_histogram_helpfcn(app,'draw','Hist');
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'bimodal distribution'
                            manage_histogram_helpfcn(app,'fit','bimodal')
                        case 'Gaussian distribution'
                            manage_histogram_helpfcn(app,'fit','unimodal')
                    end
                    manage_histogram_helpfcn(app,'draw','Fit');
            end
            
        end

        % Value changed function: Switch_AddAnno
        function Switch_AddAnnoValueChanged(app, event)
            manage_histogram_helpfcn(app,'updateFigure','AddAnnotation');         
        end

        % Value changed function: Switch_showSingle
        function Switch_showSingleValueChanged(app, event)
            line_childs = findobj(app.histogram_axes.Children,'Type','line');
            if ~isempty(line_childs)
                delete(line_childs);
            end
            hold(app.histogram_axes,"on");
            manage_histogram_helpfcn(app,'draw','Fit');
            hold(app.histogram_axes,"off");
        end

        % Cell edit callback: startparameterTable
        function startparameterTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            newData = strrep(newData,',','.');
            newData = strrep(newData,'i','');
            
            newData_num = str2double(newData);
            % check of entered value
            if isnan(newData_num)
                app.startparameterTable.Data(indices(1),indices(2)) = {oldData};
            else
                app.startparameterTable.Data(indices(1),indices(2)) = {newData};
                if ~any(isnan(str2double(string(app.startparameterTable.Data(:,2)))))
                    manage_histogram_helpfcn(app,'draw','Hist');
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'bimodal distribution'
                            manage_histogram_helpfcn(app,'fit','bimodal');
                        case 'Gaussian distribution'
                            manage_histogram_helpfcn(app,'fit','unimodal');
                    end
                    hold(app.histogram_axes,"on");
                    manage_histogram_helpfcn(app,'draw','Fit');
                    hold(app.histogram_axes,"off");
                    manage_histogram_helpfcn(app,'updateFigure','AddAnnotation');
                else
                    % for now, do nothing
                end
            end
            
            
        end

        % Menu selected function: SaveimageMenu
        function SaveimageMenuSelected(app, event)
            save_fig = figure;
            save_ax = axes(save_fig);
            switch app.Switch_AddAnno.Value
                case 'no'
                    myCopyAxes(app,app.histogram_axes,save_ax);
                case 'yes'
                    legend(app.histogram_axes,'off');
                    myCopyAxes(app,app.histogram_axes,save_ax);
                    manage_histogram_helpfcn(app,'updateFigure','AddAnnotation')
                    legOb = app.LegendObj;
                    switch app.ButtonGroup.SelectedObject.Text
                        case 'bimodal distribution'
                            h1 = line(save_ax, nan, nan, 'Color', 'none');
                            h2 = line(save_ax, nan, nan, 'Color', 'none');
                            h3 = line(save_ax, nan, nan, 'Color', 'none');
                            h4 = line(save_ax, nan, nan, 'Color', 'none');
                            h5 = line(save_ax, nan, nan, 'Color', 'none');
                            h6 = line(save_ax, nan, nan, 'Color', 'none');
                            h7 = line(save_ax, nan, nan, 'Color', 'none');
                            legend(save_ax,[h1 h2 h3 h4 h5 h6 h7],legOb.String,"Location","northeast","AutoUpdate","off","FontSize",12);
                        case 'Gaussian distribution'
                            h1 = line(save_ax, nan, nan, 'Color', 'none');
                            h2 = line(save_ax, nan, nan, 'Color', 'none');
                            h3 = line(save_ax, nan, nan, 'Color', 'none');
                            h4 = line(save_ax, nan, nan, 'Color', 'none');
                            legend(save_ax,[h1 h2 h3 h4],legOb.String,"Location","northeast","AutoUpdate","off","FontSize",12);
                    end
            end
        end

        % Value changed function: top_graph_dropdown
        function top_graph_dropdownValueChanged(app, event)
            value = app.top_graph_dropdown.Value;

            switch value
                case 'height_measured'
                    if isfield(app.map_images.height_measured,'nominal_height_data')
                        image_data =  flip(app.map_images.height_measured.nominal_height_data,1);
                    elseif isfield(app.map_images.height_measured,'absolut_height_data')
                        image_data = flip(app.map_images.height_measured.absolut_height_data,1);
                    end
                otherwise
                    if isfield(app.map_images.(value),sprintf('%s_data',value))
                        image_data =  flip(app.map_images.(value).(sprintf('%s_data',value)),1);
                    elseif isfield(app.map_images.(value),'CData')
                        image_data = app.map_images.(value).CData;
                    else
                        % let user pick the correct data field.
                        list_string = fieldnames(app.map_images.(value));
                        [indx,tf] = listdlg("ListString",list_string,'PromptString','Choose image data',"SelectionMode","single");
                        if tf
                            selected_element = list_string{indx};
                            selected_data = app.map_images.(value).(selected_element);
                            [y_i,x_i] = size(selected_data);
                            if x_i ~= app.x_pixel && y_i || y_i ~= app.y_pixel
                                message_string = sprintf('The size of the selected data (%d x %d) is not valid for the expected image size (%d x %d)!',y_i,x_i,app.y_pixel,app.x_pixel);
                                uialert(app.ResultFilteringToolUIFigure,message_string,'Invalid data size')
                                return;
                            end
                            image_data = selected_data;
                        else
                            return;
                        end
                    end
                
            end
            
            % display the image data and format uiaxes
            image_x = linspace(0,app.x_length,app.x_pixel);
            image_y = linspace(0,app.y_length,app.y_pixel);
            c_data = reshape(image_data,[],1);
            mu = mean(c_data,"omitnan");
            sigma = std(c_data,0,"omitnan");
            cbar_range = sigma*2.2;
            cbar_mean = mu+(0.9*sigma);
            clim_min = cbar_mean - cbar_range;
            clim_max = cbar_mean + cbar_range;
            [~,x_power,x_unit,~] = determine_order_of_mag(app,app.x_length,'m');
            [~,y_power,y_unit,~] = determine_order_of_mag(app,app.y_length,'m');
            cla(app.top_axes);
            imagesc(app.top_axes,'XData',image_x/10^(x_power),'YData',image_y/10^(y_power),'CData',image_data);
            app.top_axes.Colormap = app.ColorMap;
            colorbar(app.top_axes,'off');
            cb = colorbar(app.top_axes);
            app.top_axes.XAxis.Exponent = 0;
            app.top_axes.YAxis.Exponent = 0;
            app.top_axes.CLim = [clim_min clim_max];
            app.top_axes.Box = "on";
            app.top_axes.DataAspectRatio = [1 1 1];
            axis(app.top_axes,'tight');
            app.top_axes.XAxis.Label.String = sprintf('Fast axis [%s]',x_unit);
            app.top_axes.YAxis.Label.String = sprintf('Slow axis [%s]',y_unit);
            % % % shrink color bar to display the order of magnitude
            % % cb_pos = cb.Position;
            % % cb.Position = [cb_pos(1)+cb_pos(3)*1.3 cb_pos(2)+cb_pos(4)*0.05 cb_pos(3) cb_pos(4)-cb_pos(4)*0.1];
            
            % redraw filter mask if neccessary
            if strcmp(app.Switch_hist_filter.Value,'On')
                apply_hist_filter(app);
            end
            
            % redraw image filter patch if neccessary
            if strcmp(app.ImagefilterSwitch.Value,'On') && ~isempty(app.top_ROI_obj) && ~isempty(app.bottom_ROI_obj)
                draw_ROI_image(app)
            end
                        
            
        end

        % Value changed function: bottom_graph_dropdown
        function bottom_graph_dropdownValueChanged(app, event)
            value = app.bottom_graph_dropdown.Value;
            
            switch value
                case 'height_measured'
                    if isfield(app.map_images.height_measured,'nominal_height_data')
                        image_data =  flip(app.map_images.height_measured.nominal_height_data,1);
                    elseif isfield(app.map_images.height_measured,'absolut_height_data')
                        image_data = flip(app.map_images.height_measured.absolut_height_data,1);
                    end
                otherwise
                    if isfield(app.map_images.(value),sprintf('%s_data',value))
                        image_data =  flip(app.map_images.(value).(sprintf('%s_data',value)),1);
                    elseif isfield(app.map_images.(value),'CData')
                        image_data = app.map_images.(value).CData;
                    else
                        % let user pick the correct data field.
                        list_string = fieldnames(app.map_images.(value));
                        [indx,tf] = listdlg("ListString",list_string,'PromptString','Choose image data',"SelectionMode","single");
                        if tf
                            selected_element = list_string{indx};
                            selected_data = app.map_images.(value).(selected_element);
                            [y_i,x_i] = size(selected_data);
                            if x_i ~= app.x_pixel && y_i || y_i ~= app.y_pixel
                                message_string = sprintf('The size of the selected data (%d x %d) is not valid for the expected image size (%d x %d)!',y_i,x_i,app.y_pixel,app.x_pixel);
                                uialert(app.ResultFilteringToolUIFigure,message_string,'Invalid data size')
                                return;
                            end
                            image_data = selected_data;
                        else
                            return;
                        end
                    end                                
            end
            
            % display the image data  and format uiaxes
            image_x = linspace(0,app.x_length,app.x_pixel);
            image_y = linspace(0,app.y_length,app.y_pixel);
            c_data = reshape(image_data,[],1);
            mu = mean(c_data,"omitnan");
            sigma = std(c_data,0,"omitnan");
            cbar_range = sigma*2.2;
            cbar_mean = mu+(0.9*sigma);
            clim_min = cbar_mean - cbar_range;
            clim_max = cbar_mean + cbar_range;
            [~,x_power,x_unit,~] = determine_order_of_mag(app,app.x_length,'m');
            [~,y_power,y_unit,~] = determine_order_of_mag(app,app.y_length,'m');
            cla(app.bottom_axes);
            imagesc(app.bottom_axes,'XData',image_x/10^(x_power),'YData',image_y/10^(y_power),'CData',image_data);
            app.bottom_axes.Colormap = app.ColorMap;
            colorbar(app.bottom_axes,'off');
            cb = colorbar(app.bottom_axes);
            app.bottom_axes.XAxis.Exponent = 0;
            app.bottom_axes.YAxis.Exponent = 0;
            app.bottom_axes.CLim = [clim_min clim_max];
            app.bottom_axes.Box = "on";
            app.bottom_axes.DataAspectRatio = [1 1 1];
            axis(app.bottom_axes,'tight');
            app.bottom_axes.XAxis.Label.String = sprintf('Fast axis [%s]',x_unit);
            app.bottom_axes.YAxis.Label.String = sprintf('Slow axis [%s]',y_unit);
            % % % shrink color bar to display the order of magnitude
            % % % cb_pos = cb.Position;
            % % % cb.Position = [cb_pos(1)+cb_pos(3)*1.3 cb_pos(2)+cb_pos(4)*0.05 cb_pos(3) cb_pos(4)-cb_pos(4)*0.1];
            
            % redraw filter mask if neccessary
            if strcmp(app.Switch_hist_filter.Value,'On')
                apply_hist_filter(app);
            end
            
            % redraw image filter patch if neccessary
            if strcmp(app.ImagefilterSwitch.Value,'On') && ~isempty(app.top_ROI_obj) && ~isempty(app.bottom_ROI_obj)
                draw_ROI_image(app)
            end
            
        end

        % Menu selected function: SavebottomimageMenu
        function SavebottomimageMenuSelected(app, event)
            save_fig = figure;
            save_ax = axes(save_fig);
            myCopyAxes(app,app.bottom_axes,save_ax);
            childs = allchild(app.bottom_axes);
            if length(childs) > 0
                set_afm_gold(0.9,2.2,length(childs));
            end
            save_ax.Box = "on";
            save_ax.DataAspectRatio = [1 1 1];
            axis(save_ax,'tight');
            colorbar(save_ax);
            channel = app.bottom_graph_dropdown.Value;
            save_ax.Title.String = sprintf('Channel: %s',channel);
            save_ax.XAxis.Label.String = app.bottom_axes.XAxis.Label.String;
            save_ax.YAxis.Label.String = app.bottom_axes.YAxis.Label.String;
        
        end

        % Value changed function: Switch_hist_filter
        function Switch_hist_filterValueChanged(app, event)
            value = app.Switch_hist_filter.Value;
            
            app.FiltertypeLabel.Enable = value;
            app.Switch_hist_filter_type.Enable = value;
            app.leftedgeLabel.Enable = value;
            app.rightedgeLabel.Enable = value;
            app.filter_left_edge.Enable = value;
            app.filter_right_edge.Enable = value;
            app.setfilteredgesButton.Enable = value;
            app.Switch_hist_filter_type.Enable = value;
            app.ColorDropDown_2.Enable = value;
            app.TransparencySlider.Enable = value;
            app.SaveMaskMenu.Enable = value;
            
            left_edge = app.filter_left_edge.Value;
            right_edge = app.filter_right_edge.Value;
            
            if ~(left_edge == right_edge) && strcmp(value,'On')
                draw_filter_patch(app);
                apply_hist_filter(app);
            end
            
            if strcmp(value,'Off')
                delete(app.filter_patch);
                delete(app.mask_image_1);
                delete(app.mask_image_2);
                app.filter_patch = [];
                app.mask_image_1 = [];
                app.mask_image_2 = [];
            end
        end

        % Menu selected function: SavedataMenu
        function SavedataMenuSelected(app, event)
            if ~isempty(app.tsv_struct)
                if isequal(app.EModul_filtered,app.EModul)
                    selection = uiconfirm(app.ResultFilteringToolUIFigure,sprintf('You have not applied a filter to the results data (Image filtering).\nDo you really want to create a results file equal the loaded tsv-file?%s',' '),'Save warning',"Icon","warning","Options",{'Yes','No'},'DefaultOption','No',"CancelOption",'No');
                    if strcmp(selection,'No')
                        return;
                    end
                end
                
                list_items = fieldnames(app.tsv_struct);
                [indx,tf] = listdlg('PromptString','Select the results you want to save:',"ListString",list_items);
                
                if ~tf
                    return;
                end
                
                filter_string = {'*.tsv','Save tsv-File (*.tsv)';'*.xlsx','Save Excel-File (*.xlsx)';'*.tsv;*.xlsx','Save both result files (*.tsv, *.xlsx)'};
                [tsv_path,tsv_name,ext] = fileparts(app.lasttsvPath);
                tsv_name = strcat([tsv_name '_filtered' ext]);
                default_path = fullfile(tsv_path,tsv_name);
                [save_file,save_path,save_indx] = uiputfile(filter_string,'Save results file',default_path);
                if (isscalar(save_file)||isscalar(save_path)) && (save_file == 0 || save_path == 0 || save_indx == 0)
                    return;
                end
                
                save_indices = ismember(app.Index,app.FilteredIndices);
                save_names = list_items(indx);
                convert_struct = struct();
                for i = 1:length(save_names)
                    convert_struct.(save_names{i}) = app.tsv_struct.(save_names{i})(save_indices);
                end
                Tabel_to_save = struct2table(convert_struct);
                
                switch save_indx
                    case 1
                        final_save_path = fullfile(save_path,save_file);
                        save_table(Tabel_to_save,'fileFormat','tsv','savepath',final_save_path);
                    case 2
                        final_save_path = fullfile(save_path,save_file);
                        save_table(Tabel_to_save,'fileFormat','excel','savepath',final_save_path);
                    case 3
                        [~,name,~] = fileparts(save_file);
                        final_save_path = fullfile(save_path,strcat([name '.tsv']));
                        save_table(Tabel_to_save,'fileFormat','tsv','savepath',final_save_path);
                        [~,name,~] = fileparts(save_file);
                        final_save_path = fullfile(save_path,strcat([name '.xlsx']));
                        save_table(Tabel_to_save,'fileFormat','excel','savepath',final_save_path);
                end

                % Save applied mask
                [MaskPath,MaskFileName,~] = fileparts(final_save_path);
                FullMaskPath = fullfile(MaskPath,MaskFileName+".mask-data");
                SaveCurrentMask(app,FullMaskPath);
                
            else
                uialert(app.ResultFilteringToolUIFigure,'There is nothing to save at the moment!','Nothing to save',"Icon","info")
            end
        end

        % Button pushed function: setfilteredgesButton
        function setfilteredgesButtonPushed(app, event)
            % select left edge
            window_pos = app.ResultFilteringToolUIFigure.Position;
            x_edge = window_pos(3)*0.25;
            y_edge = window_pos(4)*0.25;
            win_width = window_pos(3)*0.5;
            win_height = window_pos(4)*0.5;
            select_fig = uifigure("Position",[x_edge,y_edge,win_width,win_height]);
            select_ax = uiaxes(select_fig,"Position",[win_width*0.05 win_height*0.05 win_width*0.9 win_height*0.9]);
            select_ax.Toolbar.Visible = "off";
            select_ax.Interactions = [];
            select_ax.Title.String = 'Select LEFT edge of filter via left click and dragging -> Submit position via double-left click!';
            select_ax.Title.FontSize = 16;
            select_ax.YAxis.Label.String = 'Frequenzy';
            select_ax.YAxis.Label.FontSize = 16;
            app.HistObj.plotHist(select_ax);
            switch app.ButtonGroup.SelectedObject.Text
                case 'bimodal distribution'
                    hold(select_ax,'on')
                    app.HistObj.plotFit(select_ax);  
                    hold(select_ax,"off")
                case 'Gaussian distribution'
                    x_data = linspace(select_ax.XAxis.Limits(1),select_ax.XAxis.Limits(2),400);
                    y_data = feval(app.unimodal_fit,x_data);
                    hold(select_ax,'on')
                    plot(select_ax,x_data,y_data,'-k','LineWidth',2);
                    hold(select_ax,"off")
            end
            % set left edge
            left_crosshair = drawcrosshair("Parent",select_ax,"InteractionsAllowed","all",'LineWidth',1,'Color','k');
            left_crosshair.Label = sprintf('X: %3.4g',left_crosshair.Position(1));
            addlistener(left_crosshair,'MovingROI',@(src,data)displayCrosshairInfo(src,data));
            wait(left_crosshair);
            left_crosshair.InteractionsAllowed = 'none';
            left_edge = left_crosshair.Position(1);
            app.filter_left_edge.Value = left_edge;
            % set right edge
            select_ax.Title.String = 'Select RIGHT edge of filter via left click and dragging -> Submit position via double-left click!';
            right_crosshair = drawcrosshair("Parent",select_ax,"InteractionsAllowed","all",'LineWidth',1,'Color','k');
            right_crosshair.Label = sprintf('X: %2.4g',right_crosshair.Position(1));
            ylimits = ylim(select_ax);
            rgb_code = getRGBvalues(app);
            fil_pat = patch(select_ax,'XData',[left_edge right_crosshair.Position(1) right_crosshair.Position(1) left_edge],'YData',[ylimits(1) ylimits(1) ylimits(2) ylimits(2)],"FaceColor",rgb_code,"FaceAlpha",.2);
            addlistener(right_crosshair,'MovingROI',@(src,data)displayCrosshairInfoAndPatch(src,data,fil_pat));
            wait(right_crosshair);
            right_crosshair.InteractionsAllowed = 'none';
            right_edge = right_crosshair.Position(1);
            app.filter_right_edge.Value = right_edge;
            close(select_fig);
                
            % draw patch in histogram_axes
            draw_filter_patch(app);
            
            % draw the mask on the images
            apply_hist_filter(app);
        end

        % Value changed function: filter_left_edge
        function filter_left_edgeValueChanged(app, event)
            value_left = app.filter_left_edge.Value;
            value_right = app.filter_right_edge.Value;
            if value_left == value_right
                if ~isempty(app.filter_patch)
                    delete(app.filter_patch);
                    app.filter_patch = [];
                end
                return;
            end
            if (value_left > value_right) && value_right ~= 0
                app.filter_left_edge.Value = event.PreviousValue;
                uialert(app.ResultFilteringToolUIFigure,'Left-edge value must be smaller than right-edge value!','Value alert',"Icon","info");
                return;
            end
            
            % draw patch in histogram_axes
            draw_filter_patch(app);
            
            % draw the mask on the images
            apply_hist_filter(app);
        end

        % Value changed function: filter_right_edge
        function filter_right_edgeValueChanged(app, event)
            value_right = app.filter_right_edge.Value;
            value_left = app.filter_left_edge.Value;
            if value_left == value_right
                if ~isempty(app.filter_patch)
                    delete(app.filter_patch);
                    app.filter_patch = [];
                end
                return;
            end
            if (value_left > value_right) && value_left ~= 0
                app.filter_right_edge.Value = event.PreviousValue;
                uialert(app.ResultFilteringToolUIFigure,'Right-edge value must be greater than left-edge value!','Value alert',"Icon","info");
                return;
            end
            % draw patch in histogram_axes
            draw_filter_patch(app);
            
            % draw the mask on the images
            apply_hist_filter(app);
        end

        % Value changed function: ColorDropDown_2
        function ColorDropDown_2ValueChanged(app, event)
            color_value = app.ColorDropDown_2.Value;
            old_value = event.PreviousValue;
            if strcmp(color_value,'custom')
                app.Filter_RGB = uisetcolor;
                figure(app.ResultFilteringToolUIFigure);
                if length(app.Filter_RGB) == 1 && app.Filter_RGB == 0
                    app.ColorDropDown_2.Value = old_value;
                end
            end
            
            RGB_vecor = getRGBvalues(app);
            if ~isempty(app.filter_patch)
                app.filter_patch.FaceColor = RGB_vecor;
            end
            if ~isempty(app.mask_image_1)
                app.mask_image_1.CData(:,:,1) = RGB_vecor(1);
                app.mask_image_1.CData(:,:,2) = RGB_vecor(2);
                app.mask_image_1.CData(:,:,3) = RGB_vecor(3);
            end
            if ~isempty(app.mask_image_2)
                app.mask_image_2.CData(:,:,1) = RGB_vecor(1);
                app.mask_image_2.CData(:,:,2) = RGB_vecor(2);
                app.mask_image_2.CData(:,:,3) = RGB_vecor(3);
            end
        end

        % Value changing function: TransparencySlider
        function TransparencySliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(app.mask_image_1)
                tile_pos = flip(app.ROI_color_matrix,1);
                app.mask_image_1.AlphaData(tile_pos) = changingValue;                
            end
            if ~isempty(app.mask_image_2)
                tile_pos = flip(app.ROI_color_matrix,1);
                app.mask_image_2.AlphaData(tile_pos) = changingValue;         
            end
        end

        % Value changed function: Switch_hist_filter_type
        function Switch_hist_filter_typeValueChanged(app, event)
            draw_filter_patch(app);
            if ~isempty(app.mask_image_1) && ~isempty(app.mask_image_2)
                alpha_map = flip(app.ROI_color_matrix);
                app.mask_image_1.AlphaData(:,:) = 0;
                app.mask_image_1.AlphaData(~alpha_map) = app.TransparencySlider.Value;
                app.mask_image_2.AlphaData(:,:) = 0;
                app.mask_image_2.AlphaData(~alpha_map) = app.TransparencySlider.Value;
                app.ROI_color_matrix = ~app.ROI_color_matrix;
            else
                return;
            end
            
        end

        % Value changed function: ImagefilterSwitch
        function ImagefilterSwitchValueChanged(app, event)
            value = app.ImagefilterSwitch.Value;
            app.FiltergeometryLabel.Enable = value;
            app.Im_Geometry.Enable = value;
            app.Im_AddButton.Enable = value;
            app.Im_SubstractButton.Enable = value;
            app.Im_ResetButton.Enable = value;
            app.Im_ColorLabel.Enable = value;
            app.Im_ColorDropDown.Enable = value;
            app.Im_TransparencyLabel.Enable = value;
            app.Im_TransparencySlider.Enable = value;
            app.SaveMaskMenu.Enable = value;
            
            switch value
                case 'On'
                    if ~isempty(app.ROI_mask)
                        draw_ROI_image(app)
                        filter_histogram_data(app)
                    end
                case 'Off'
                    delete(app.top_ROI_obj)
                    delete(app.bottom_ROI_obj)
                    app.top_ROI_obj = [];
                    app.bottom_ROI_obj = [];
                    if ~isequal(app.EModul_filtered,app.EModul)
                        app.EModul_filtered = app.EModul;
                        manage_histogram_helpfcn(app,'updateValues','EModul')
                    end
            end
            
            
        end

        % Button pushed function: Im_AddButton
        function Im_AddButtonPushed(app, event)
            % deactivate all interaction items during ROI selection
            app.top_graph_dropdown.Enable = 0;
            app.bottom_graph_dropdown.Enable = 0;
            app.flow_button.Enable = 0;
            Image_filter_items(app,'deactivate')
            hist_display_options_helpfcn(app,'deactivate')
            hold(app.bottom_axes,'on')
            x_values = xlim(app.bottom_axes);
            y_values = ylim(app.bottom_axes);
            image_shadow = patch(app.bottom_axes,[x_values(1) x_values(2) x_values(2) x_values(1)],[y_values(1) y_values(1) y_values(2) y_values(2)],[.8 .8 .8],"FaceAlpha",.75,"EdgeColor",'none');
            hold(app.bottom_axes,'off')
            hold(app.histogram_axes,'on')
            x_values = xlim(app.histogram_axes);
            y_values = ylim(app.histogram_axes);
            hist_shadow = patch(app.histogram_axes,[x_values(1) x_values(2) x_values(2) x_values(1)],[y_values(1) y_values(1) y_values(2) y_values(2)],[.8 .8 .8],"FaceAlpha",.75,"EdgeColor",'none');
            hold(app.histogram_axes,"off")
            
            % main body of callback
            mask_shape = app.Im_Geometry.Value;
            if isempty(app.ROI_mask)
                app.ROI_mask = zeros(app.y_pixel,app.x_pixel);
            end
            
            user_selection = userROIfunction(app,app.top_axes,mask_shape);
            
            if isnan(user_selection)
                % activate all interaction items after ROI selection
                app.top_graph_dropdown.Enable = 1;
                app.bottom_graph_dropdown.Enable = 1;
                app.flow_button.Enable = 1;
                Image_filter_items(app,'activate')
                hist_display_options_helpfcn(app,'activate')
                delete(image_shadow);
                delete(hist_shadow);
                return;
            end
            % add user selection to ROI mask and draw the ROI in top and
            % bottom image
            alpha_value = app.Im_TransparencySlider.Value;
            app.ROI_mask(user_selection) = alpha_value;
            draw_ROI_image(app)
            
            % activate all interaction items after ROI selection
            app.top_graph_dropdown.Enable = 1;
            app.bottom_graph_dropdown.Enable = 1;
            app.flow_button.Enable = 1;
            Image_filter_items(app,'activate')
            hist_display_options_helpfcn(app,'activate')
            delete(image_shadow);
            delete(hist_shadow);
            
            % filter the displayed data in th histogram axes
            filter_histogram_data(app)
            
            
        end

        % Window key press function: ResultFilteringToolUIFigure
        function ResultFilteringToolUIFigureWindowKeyPress(app, event)
                    
        end

        % Button pushed function: Im_ResetButton
        function Im_ResetButtonPushed(app, event)
            delete(app.top_ROI_obj)
            delete(app.bottom_ROI_obj)
            app.top_ROI_obj = [];
            app.bottom_ROI_obj = [];
            app.ROI_mask = [];
            app.EModul_filtered = app.EModul;
            manage_histogram_helpfcn(app,'updateValues','EModul')
        end

        % Button pushed function: Im_SubstractButton
        function Im_SubstractButtonPushed(app, event)
            % deactivate all interaction items during ROI selection
            app.top_graph_dropdown.Enable = 0;
            app.bottom_graph_dropdown.Enable = 0;
            app.flow_button.Enable = 0;
            Image_filter_items(app,'deactivate')
            hist_display_options_helpfcn(app,'deactivate')
            hold(app.bottom_axes,'on')
            x_values = xlim(app.bottom_axes);
            y_values = ylim(app.bottom_axes);
            image_shadow = patch(app.bottom_axes,[x_values(1) x_values(2) x_values(2) x_values(1)],[y_values(1) y_values(1) y_values(2) y_values(2)],[.8 .8 .8],"FaceAlpha",.75,"EdgeColor",'none');
            hold(app.bottom_axes,'off')
            hold(app.histogram_axes,'on')
            x_values = xlim(app.histogram_axes);
            y_values = ylim(app.histogram_axes);
            hist_shadow = patch(app.histogram_axes,[x_values(1) x_values(2) x_values(2) x_values(1)],[y_values(1) y_values(1) y_values(2) y_values(2)],[.8 .8 .8],"FaceAlpha",.75,"EdgeColor",'none');
            hold(app.histogram_axes,"off")
            
            % main body of callback
            mask_shape = app.Im_Geometry.Value;
            if isempty(app.ROI_mask)
                app.ROI_mask = zeros(app.y_pixel,app.x_pixel);
            end
            user_selection = userROIfunction(app,app.top_axes,mask_shape);
            if isnan(user_selection)
                % activate all interaction items after ROI selection
                app.top_graph_dropdown.Enable = 1;
                app.bottom_graph_dropdown.Enable = 1;
                app.flow_button.Enable = 1;
                Image_filter_items(app,'activate')
                hist_display_options_helpfcn(app,'activate')
                delete(image_shadow);
                delete(hist_shadow);
                return;
            end
            
            % substract user selection from ROI mask and draw the ROI in top and
            % bottom image
            app.ROI_mask(user_selection) = 0;
            draw_ROI_image(app)
            
            % check if any masked point is left
            if ~(any(any(app.ROI_mask)))
                Im_ResetButtonPushed(app,'SubstractButton')
                
                % activate all interaction items after ROI selection
                app.top_graph_dropdown.Enable = 1;
                app.bottom_graph_dropdown.Enable = 1;
                app.flow_button.Enable = 1;
                Image_filter_items(app,'activate')
                hist_display_options_helpfcn(app,'activate')
                delete(image_shadow);
                delete(hist_shadow);
            else
                % filter the displayed data in th histogram axes
                filter_histogram_data(app)
                
                % activate all interaction items after ROI selection
                app.top_graph_dropdown.Enable = 1;
                app.bottom_graph_dropdown.Enable = 1;
                app.flow_button.Enable = 1;
                Image_filter_items(app,'activate')
                hist_display_options_helpfcn(app,'activate')
                delete(image_shadow);
                delete(hist_shadow);
            end
            
            
        end

        % Value changed function: Im_ColorDropDown
        function Im_ColorDropDownValueChanged(app, event)
            value = app.Im_ColorDropDown.Value;
            old_value = event.PreviousValue;
            if strcmp(value,'custom')
                app.Filter_RGB = uisetcolor;
                figure(app.ResultFilteringToolUIFigure);
                if length(app.Filter_RGB) == 1 && app.Filter_RGB == 0
                    app.Im_ColorDropDown.Value = old_value;
                end  
            end
            color_vec = getRGBvalues(app);
            app.ROI_color_matrix = zeros(app.y_pixel,app.x_pixel,3);
            app.ROI_color_matrix(:,:,1) = color_vec(1);
            app.ROI_color_matrix(:,:,2) = color_vec(2);
            app.ROI_color_matrix(:,:,3) = color_vec(3);
            if ~isempty(app.top_ROI_obj) && ~isempty(app.bottom_ROI_obj)
                app.top_ROI_obj.CData = app.ROI_color_matrix;
                app.bottom_ROI_obj.CData = app.ROI_color_matrix;
            end
        end

        % Value changing function: Im_TransparencySlider
        function Im_TransparencySliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isempty(app.top_ROI_obj) && ~isempty(app.bottom_ROI_obj) && ~isempty(app.ROI_mask)
                highlighted_values = app.ROI_mask ~= 0;
                app.top_ROI_obj.AlphaData(highlighted_values) = changingValue;
                app.bottom_ROI_obj.AlphaData(highlighted_values) = changingValue;
            end
        end

        % Value changed function: Im_TransparencySlider
        function Im_TransparencySliderValueChanged(app, event)
            value = app.Im_TransparencySlider.Value;
            if ~isempty(app.top_ROI_obj) && ~isempty(app.bottom_ROI_obj) && ~isempty(app.ROI_mask)
                highlighted_values = app.ROI_mask ~= 0;
                app.ROI_mask(highlighted_values) = value;
            end
        end

        % Key press function: ResultFilteringToolUIFigure
        function ResultFilteringToolUIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'o'
                    if strcmp(string(event.Modifier),'control')
                        OpenMenuSelected(app,event.EventName)
                    end
                case 'f1'
                    HelpMenuSelected(app,event.EventName)
            end
        end

        % Menu selected function: ExportimageMenu
        function ExportimageMenuSelected(app, event)
            SaveimageMenuSelected(app,'Hist_ContextMenu')
        end

        % Menu selected function: SavefiltereddataMenu
        function SavefiltereddataMenuSelected(app, event)
            SavedataMenuSelected(app,'Hist_ContextMenu')
        end

        % Menu selected function: ImageexportMenu
        function ImageexportMenuSelected(app, event)
            SavetopimageMenuSelected(app,'top_image_ContextMenu')
        end

        % Menu selected function: ImageexportMenu_2
        function ImageexportMenu_2Selected(app, event)
            SavebottomimageMenuSelected(app,'bottom_image_ContextMenu')
        end

        % Context menu opening function: Histogram_ContextMenu
        function Histogram_ContextMenuOpening(app, event)
            
        end

        % Window button down function: ResultFilteringToolUIFigure
        function ResultFilteringToolUIFigureWindowButtonDown(app, event)
%             if strcmp(event.Source.SelectionType,'alt')
%                 App_size = event.Source.Position;
%                 x_ratio = 1.75;
%                 y_screen_correction = App_size(4)/1149;
%                 y_correction = @(y_clicked) (y_clicked+831.15*y_screen_correction)/1.7443;                
%                 corrected_currentPoint = event.Source.CurrentPoint;
%                 corrected_currentPoint(1) = corrected_currentPoint(1)/x_ratio;
%                 corrected_currentPoint(2) = y_correction(corrected_currentPoint(2));
%                 switch event.Source.CurrentAxes.Tag
%                     case 'histogram_axes'
%                         open(app.Histogram_ContextMenu,corrected_currentPoint);
%                     case 'top_axes'
%                         open(app.top_image_ContextMenu,corrected_currentPoint);
%                     case 'bottom_axes'
%                         open(app.bottom_image_ContextMenu,corrected_currentPoint);
%                 end
%             end
            
        end

        % Close request function: ResultFilteringToolUIFigure
        function ResultFilteringToolUIFigureCloseRequest(app, event)
            delete(app)
            run("CANTER_Processing_Toolbox.mlapp")
        end

        % Menu selected function: MaskRefinementMenu
        function MaskRefinementMenuSelected(app, event)
            % Turn image filter on if it is off
            state = app.ImagefilterSwitch.Value;
            if strcmp(state,"Off")
                app.ImagefilterSwitch.Value = "On";
                ImagefilterSwitchValueChanged(app, event)
            end
            
            % Predefine imputs for the Refine_BM_Mask application
            top_image_data = [];
            Curr_Mask = [];
            Settings = struct("Color",[],...
                             "Transparency",[],...
                             "MainGUIPosition",[]);
            % Get top image
            top_ch = allchild(app.top_axes);
            images = findobj(top_ch,"Type","image");
            map_image = findobj(images,"AlphaData",1);
            top_image_data = map_image.CData;

            % Get Curr_Mask
            if length(images) > 1
                mask_image = findobj(images,"-not","AlphaData",1);
                Curr_Mask = mask_image;
            end

            % Get Color, Transparency and GUI position
            if ~isempty(app.ROI_color_matrix) && (size(app.ROI_color_matrix,3)==3)
                Settings.Color = app.ROI_color_matrix;
            else
                ColorMatrix = zeros(app.y_pixel,app.x_pixel,3);
                ColorMatrix(:,:,2) = 1;
                Settings.Color = ColorMatrix;
            end
            Settings.Transparency = app.TransparencySlider.Value;
            Settings.MainGUIPosition = app.ResultFilteringToolUIFigure.Position;

            % Open the Refine_BM_Mask app and wait for its close request
            RunningApp = Refine_BM_Mask(top_image_data,Curr_Mask,Settings);

            waitfor(RunningApp,"CloseRequest",1)

            if RunningApp.Canceled
                delete(RunningApp);
                return;
            end
            
            NewMask = flip(RunningApp.Refined_Mask,1).*app.Im_TransparencySlider.Value;
            
            % Set NewMask as ROI_mask and draw it
            app.ROI_mask = NewMask;
            draw_ROI_image(app)
      
            % filter the displayed data in the histogram axes
            filter_histogram_data(app)

            delete(RunningApp);     
        end

        % Menu selected function: SaveMaskMenu
        function SaveMaskMenuSelected(app, event)
            SaveCurrentMask(app,[]);
        end

        % Menu selected function: LoadMaskMenu
        function LoadMaskMenuSelected(app, event)
            if isempty(app.lasttsvPath)
              [MaskFile,MaskLocation,check] = uigetfile("*.mask-data","Select Mask Data File",'MultiSelect','off');
            else
                [lastPath,~,~] = fileparts(app.lasttsvPath);
                [MaskFile,MaskLocation,check] = uigetfile(fullfile(lastPath,"*.mask-data"),"Select Mask Data File",'MultiSelect','off');
            end
            if check == 0
                return;
            end
            LoadedMask = readmatrix(fullfile(MaskLocation,MaskFile),"FileType","text","Delimiter",",","OutputType","uint64");
            [MaskRows,MaskColumns] = size(LoadedMask);
            if (MaskRows ~= app.y_pixel) || (MaskColumns ~= app.x_pixel)
                uialert(app.ResultFilteringToolUIFigure,sprintf("Size of the selected mask data (%d x %d) does not match the pixel size of the loaded map (%d x %d)",MaskRows,MaskColumns,app.y_pixel,app.y_pixel),"Sizes must agree","Icon","error");
                return;
            end
            if strcmp(app.ImagefilterSwitch.Value,"Off")
                app.ImagefilterSwitch.Value = "On";
                ImagefilterSwitchValueChanged(app, event)
            end
            AlphaValue = app.TransparencySlider.Value;
            app.ROI_mask = double(LoadedMask) .* AlphaValue;
            draw_ROI_image(app);
            filter_histogram_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResultFilteringToolUIFigure = uifigure('Visible', 'off');
            colormap(app.ResultFilteringToolUIFigure, 'parula');
            app.ResultFilteringToolUIFigure.Name = 'Result Filtering Tool';
            app.ResultFilteringToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @ResultFilteringToolUIFigureCloseRequest, true);
            app.ResultFilteringToolUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @ResultFilteringToolUIFigureWindowButtonDown, true);
            app.ResultFilteringToolUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @ResultFilteringToolUIFigureWindowKeyPress, true);
            app.ResultFilteringToolUIFigure.KeyPressFcn = createCallbackFcn(app, @ResultFilteringToolUIFigureKeyPress, true);
            app.OpenMenu = uimenu(app.ResultFilteringToolUIFigure);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Tooltip = {'Open the load dialog.'; '(Ctl+O)'};
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ResultFilteringToolUIFigure);
            app.SaveMenu.Text = 'Save';
            app.SavetopimageMenu = uimenu(app.SaveMenu);
            app.SavetopimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetopimageMenuSelected, true);
            app.SavetopimageMenu.Text = 'Save top image';
            app.SavebottomimageMenu = uimenu(app.SaveMenu);
            app.SavebottomimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SavebottomimageMenuSelected, true);
            app.SavebottomimageMenu.Text = 'Save bottom image';
            app.SavehistogramMenu = uimenu(app.SaveMenu);
            app.SavehistogramMenu.Text = 'Save histogram';
            app.SaveimageMenu = uimenu(app.SavehistogramMenu);
            app.SaveimageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveimageMenuSelected, true);
            app.SaveimageMenu.Text = 'Save image';
            app.SavedataMenu = uimenu(app.SavehistogramMenu);
            app.SavedataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedataMenuSelected, true);
            app.SavedataMenu.Text = 'Save data';
            app.MaskingMenu = uimenu(app.ResultFilteringToolUIFigure);
            app.MaskingMenu.Text = 'Masking';
            app.MaskRefinementMenu = uimenu(app.MaskingMenu);
            app.MaskRefinementMenu.MenuSelectedFcn = createCallbackFcn(app, @MaskRefinementMenuSelected, true);
            app.MaskRefinementMenu.Tooltip = {'Only active for image filtering.'};
            app.MaskRefinementMenu.Enable = 'off';
            app.MaskRefinementMenu.Accelerator = 'B';
            app.MaskRefinementMenu.Text = 'Mask Refinement';
            app.SaveMaskMenu = uimenu(app.MaskingMenu);
            app.SaveMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMaskMenuSelected, true);
            app.SaveMaskMenu.Enable = 'off';
            app.SaveMaskMenu.Text = 'Save Mask';
            app.LoadMaskMenu = uimenu(app.MaskingMenu);
            app.LoadMaskMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMaskMenuSelected, true);
            app.LoadMaskMenu.Enable = 'off';
            app.LoadMaskMenu.Text = 'Load Mask';
            app.HelpMenu = uimenu(app.ResultFilteringToolUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Go to the Help-Wiki'; '(F1)'};
            app.HelpMenu.Text = 'Help';
            app.main_grid = uigridlayout(app.ResultFilteringToolUIFigure);
            app.right_grid = uigridlayout(app.main_grid);
            app.histogram_axes = uiaxes(app.right_grid);
            title(app.histogram_axes, 'Histogram')
            app.histogram_axes.Tag = 'histogram_axes';
            app.TabGroup = uitabgroup(app.right_grid);
            app.HistogramfilteringTab = uitab(app.TabGroup);
            app.HistogramfilteringTab.Title = 'Histogram filtering';
            app.GridLayout6 = uigridlayout(app.HistogramfilteringTab);
            app.FilterLabel = uilabel(app.GridLayout6);
            app.FilterLabel.Enable = 'off';
            app.FilterLabel.Text = 'Filter';
            app.leftedgeLabel = uilabel(app.GridLayout6);
            app.leftedgeLabel.Enable = 'off';
            app.leftedgeLabel.Text = 'left edge';
            app.rightedgeLabel = uilabel(app.GridLayout6);
            app.rightedgeLabel.Enable = 'off';
            app.rightedgeLabel.Text = 'right edge';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.filter_left_edge = uieditfield(app.GridLayout7, 'numeric');
            app.filter_left_edge.ValueChangedFcn = createCallbackFcn(app, @filter_left_edgeValueChanged, true);
            app.filter_left_edge.Enable = 'off';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.filter_right_edge = uieditfield(app.GridLayout8, 'numeric');
            app.filter_right_edge.ValueChangedFcn = createCallbackFcn(app, @filter_right_edgeValueChanged, true);
            app.filter_right_edge.Enable = 'off';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.setfilteredgesButton = uibutton(app.GridLayout9, 'push');
            app.setfilteredgesButton.ButtonPushedFcn = createCallbackFcn(app, @setfilteredgesButtonPushed, true);
            app.setfilteredgesButton.Enable = 'off';
            app.setfilteredgesButton.Text = 'set filter edges';
            app.Switch_hist_filter = uiswitch(app.GridLayout6, 'slider');
            app.Switch_hist_filter.ValueChangedFcn = createCallbackFcn(app, @Switch_hist_filterValueChanged, true);
            app.Switch_hist_filter.Enable = 'off';
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.ColorDropDown_2Label = uilabel(app.GridLayout10);
            app.ColorDropDown_2Label.Enable = 'off';
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.GridLayout10);
            app.ColorDropDown_2.Items = {'red', 'green', 'blue', 'yellow', 'black', 'white', 'custom'};
            app.ColorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorDropDown_2ValueChanged, true);
            app.ColorDropDown_2.Enable = 'off';
            app.ColorDropDown_2.Value = 'green';
            app.GridLayout11 = uigridlayout(app.GridLayout6);
            app.TransparencySliderLabel = uilabel(app.GridLayout11);
            app.TransparencySliderLabel.Enable = 'off';
            app.TransparencySliderLabel.Text = {'Transparency'; ''};
            app.TransparencySlider = uislider(app.GridLayout11);
            app.TransparencySlider.Limits = [0.1 1];
            app.TransparencySlider.MajorTicks = [0.2 0.4 0.6 0.8 1];
            app.TransparencySlider.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderValueChanging, true);
            app.TransparencySlider.MinorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.TransparencySlider.Enable = 'off';
            app.TransparencySlider.Value = 0.5;
            app.FiltertypeLabel = uilabel(app.GridLayout6);
            app.FiltertypeLabel.Enable = 'off';
            app.FiltertypeLabel.Text = 'Filter type';
            app.Switch_hist_filter_type = uiswitch(app.GridLayout6, 'slider');
            app.Switch_hist_filter_type.Items = {'positive', 'negative'};
            app.Switch_hist_filter_type.ValueChangedFcn = createCallbackFcn(app, @Switch_hist_filter_typeValueChanged, true);
            app.Switch_hist_filter_type.Enable = 'off';
            app.Switch_hist_filter_type.Tooltip = {'positive: values between the filter edges are highlighted.'; 'negative: all values that do NOT lie between the filter edges are highlighted.'};
            app.Switch_hist_filter_type.Value = 'positive';
            app.ImagefilteringTab = uitab(app.TabGroup);
            app.ImagefilteringTab.Title = 'Image filtering';
            app.GridLayout12 = uigridlayout(app.ImagefilteringTab);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.FiltergeometryLabel = uilabel(app.GridLayout13);
            app.FiltergeometryLabel.Enable = 'off';
            app.FiltergeometryLabel.Text = 'Filter geometry';
            app.GridLayout14 = uigridlayout(app.GridLayout12);
            app.Im_Geometry = uidropdown(app.GridLayout14);
            app.Im_Geometry.Items = {'Rectangle', 'Circle', 'Ellipse', 'Polygon', 'Freehand'};
            app.Im_Geometry.Enable = 'off';
            app.Im_Geometry.Value = 'Rectangle';
            app.GridLayout15 = uigridlayout(app.GridLayout12);
            app.Im_AddButton = uibutton(app.GridLayout15, 'push');
            app.Im_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Im_AddButtonPushed, true);
            app.Im_AddButton.Enable = 'off';
            app.Im_AddButton.Text = 'Add';
            app.GridLayout16 = uigridlayout(app.GridLayout12);
            app.Im_SubstractButton = uibutton(app.GridLayout16, 'push');
            app.Im_SubstractButton.ButtonPushedFcn = createCallbackFcn(app, @Im_SubstractButtonPushed, true);
            app.Im_SubstractButton.Enable = 'off';
            app.Im_SubstractButton.Text = 'Subtract';
            app.GridLayout17 = uigridlayout(app.GridLayout12);
            app.Im_ResetButton = uibutton(app.GridLayout17, 'push');
            app.Im_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Im_ResetButtonPushed, true);
            app.Im_ResetButton.Enable = 'off';
            app.Im_ResetButton.Text = 'Reset';
            app.GridLayout18 = uigridlayout(app.GridLayout12);
            app.Im_TransparencyLabel = uilabel(app.GridLayout18);
            app.Im_TransparencyLabel.Enable = 'off';
            app.Im_TransparencyLabel.Text = 'Transparency:';
            app.ImagefilterSwitchLabel = uilabel(app.GridLayout12);
            app.ImagefilterSwitchLabel.Enable = 'off';
            app.ImagefilterSwitchLabel.Text = 'Image filter';
            app.ImagefilterSwitch = uiswitch(app.GridLayout12, 'slider');
            app.ImagefilterSwitch.ValueChangedFcn = createCallbackFcn(app, @ImagefilterSwitchValueChanged, true);
            app.ImagefilterSwitch.Enable = 'off';
            app.Im_ColorLabel = uilabel(app.GridLayout12);
            app.Im_ColorLabel.Enable = 'off';
            app.Im_ColorLabel.Text = 'Color:';
            app.Im_TransparencySlider = uislider(app.GridLayout12);
            app.Im_TransparencySlider.Limits = [0.1 1];
            app.Im_TransparencySlider.MajorTicks = [0.2 0.4 0.6 0.8 1];
            app.Im_TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @Im_TransparencySliderValueChanged, true);
            app.Im_TransparencySlider.ValueChangingFcn = createCallbackFcn(app, @Im_TransparencySliderValueChanging, true);
            app.Im_TransparencySlider.MinorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.Im_TransparencySlider.Enable = 'off';
            app.Im_TransparencySlider.Value = 0.5;
            app.Im_ColorDropDown = uidropdown(app.GridLayout12);
            app.Im_ColorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'black', 'white', 'custom'};
            app.Im_ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @Im_ColorDropDownValueChanged, true);
            app.Im_ColorDropDown.Enable = 'off';
            app.Im_ColorDropDown.Value = 'green';
            app.tab_group_hist = uitabgroup(app.right_grid);
            app.show_tab = uitab(app.tab_group_hist);
            app.show_tab.Title = 'Histogram options';
            app.GridLayout2 = uigridlayout(app.show_tab);
            app.DisplayoptionsPanel = uipanel(app.GridLayout2);
            app.DisplayoptionsPanel.Title = 'Display options';
            app.GridLayout = uigridlayout(app.DisplayoptionsPanel);
            app.FittypeLabel = uilabel(app.GridLayout);
            app.FittypeLabel.Enable = 'off';
            app.FittypeLabel.Text = 'Fit type';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.bimodaldistributionButton = uiradiobutton(app.ButtonGroup);
            app.bimodaldistributionButton.Enable = 'off';
            app.bimodaldistributionButton.Text = 'bimodal distribution';
            app.bimodaldistributionButton.Value = true;
            app.GaussiandistributionButton = uiradiobutton(app.ButtonGroup);
            app.GaussiandistributionButton.Enable = 'off';
            app.GaussiandistributionButton.Text = 'Gaussian distribution';
            app.ShowsingelGaussiansLabel = uilabel(app.GridLayout);
            app.ShowsingelGaussiansLabel.Enable = 'off';
            app.ShowsingelGaussiansLabel.Text = 'Show singel Gaussians';
            app.ShowinitialguessLabel = uilabel(app.GridLayout);
            app.ShowinitialguessLabel.Enable = 'off';
            app.ShowinitialguessLabel.Text = 'Show initial guess';
            app.AddAnnotationLabel = uilabel(app.GridLayout);
            app.AddAnnotationLabel.Enable = 'off';
            app.AddAnnotationLabel.Text = 'Add Annotation';
            app.Switch_showInitial = uiswitch(app.GridLayout, 'slider');
            app.Switch_showInitial.Items = {'no', 'yes'};
            app.Switch_showInitial.ValueChangedFcn = createCallbackFcn(app, @Switch_showInitialValueChanged, true);
            app.Switch_showInitial.Enable = 'off';
            app.Switch_showInitial.Value = 'no';
            app.Switch_AddAnno = uiswitch(app.GridLayout, 'slider');
            app.Switch_AddAnno.Items = {'no', 'yes'};
            app.Switch_AddAnno.ValueChangedFcn = createCallbackFcn(app, @Switch_AddAnnoValueChanged, true);
            app.Switch_AddAnno.Enable = 'off';
            app.Switch_AddAnno.Value = 'no';
            app.Switch_showSingle = uiswitch(app.GridLayout, 'slider');
            app.Switch_showSingle.Items = {'no', 'yes'};
            app.Switch_showSingle.ValueChangedFcn = createCallbackFcn(app, @Switch_showSingleValueChanged, true);
            app.Switch_showSingle.Enable = 'off';
            app.Switch_showSingle.Value = 'yes';
            app.PlotrangeLabel = uilabel(app.GridLayout);
            app.PlotrangeLabel.Enable = 'off';
            app.PlotrangeLabel.Text = 'Plot range';
            app.NumberofbinsLabel = uilabel(app.GridLayout);
            app.NumberofbinsLabel.Enable = 'off';
            app.NumberofbinsLabel.Text = 'Number of bins';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.EditField_BinNum = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_BinNum.Limits = [1 Inf];
            app.EditField_BinNum.ValueDisplayFormat = '%d';
            app.EditField_BinNum.ValueChangedFcn = createCallbackFcn(app, @EditField_BinNumValueChanged, true);
            app.EditField_BinNum.Enable = 'off';
            app.EditField_BinNum.Value = 50;
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.EditField_rangeMin = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_rangeMin.ValueDisplayFormat = '%.2g';
            app.EditField_rangeMin.ValueChangedFcn = createCallbackFcn(app, @EditField_rangeMinValueChanged, true);
            app.EditField_rangeMin.Enable = 'off';
            app.EditField_rangeMax = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_rangeMax.ValueDisplayFormat = '%.2g';
            app.EditField_rangeMax.ValueChangedFcn = createCallbackFcn(app, @EditField_rangeMaxValueChanged, true);
            app.EditField_rangeMax.Enable = 'off';
            app.EditField_rangeMax.Value = 300000;
            app.toLabel = uilabel(app.GridLayout5);
            app.toLabel.Enable = 'off';
            app.toLabel.Text = 'to';
            app.ColorDropDown = uidropdown(app.GridLayout);
            app.ColorDropDown.Items = {'blue', 'red', 'yellow', 'green', 'custom'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Enable = 'off';
            app.ColorDropDown.Value = 'blue';
            app.ColorLabel = uilabel(app.GridLayout);
            app.ColorLabel.Enable = 'off';
            app.ColorLabel.Text = 'Color';
            app.DatachannelLabel = uilabel(app.GridLayout);
            app.DatachannelLabel.Enable = 'off';
            app.DatachannelLabel.Text = 'Data channel';
            app.HistChannelPopup = uidropdown(app.GridLayout);
            app.HistChannelPopup.Items = {};
            app.HistChannelPopup.ValueChangedFcn = createCallbackFcn(app, @HistChannelPopupValueChanged, true);
            app.HistChannelPopup.Enable = 'off';
            app.HistChannelPopup.Value = {};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.startparameterTable = uitable(app.GridLayout3);
            app.startparameterTable.ColumnName = {'Parameter'; 'Start value'};
            app.startparameterTable.RowName = {''};
            app.startparameterTable.ColumnEditable = [false true];
            app.startparameterTable.CellEditCallback = createCallbackFcn(app, @startparameterTableCellEdit, true);
            app.startparameterTable.Enable = 'off';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout22 = uigridlayout(app.Panel);
            app.findstartparameterDropDownLabel = uilabel(app.GridLayout22);
            app.findstartparameterDropDownLabel.Enable = 'off';
            app.findstartparameterDropDownLabel.Text = 'find start parameter';
            app.findstartparameterDropDown = uidropdown(app.GridLayout22);
            app.findstartparameterDropDown.Items = {'auto', 'user defined'};
            app.findstartparameterDropDown.ValueChangedFcn = createCallbackFcn(app, @findstartparameterDropDownValueChanged, true);
            app.findstartparameterDropDown.Enable = 'off';
            app.findstartparameterDropDown.Value = 'auto';
            app.middle_grid = uigridlayout(app.main_grid);
            app.flow_button = uibutton(app.middle_grid, 'state');
            app.flow_button.ValueChangedFcn = createCallbackFcn(app, @flow_buttonValueChanged, true);
            app.flow_button.Enable = 'off';
            app.flow_button.Icon = 'button_0.png';
            app.flow_button.Text = '';
            app.GridLayout19 = uigridlayout(app.main_grid);
            app.top_axes = uiaxes(app.GridLayout19);
            app.top_axes.Tag = 'top_axes';
            app.bottom_axes = uiaxes(app.GridLayout19);
            app.bottom_axes.Tag = 'bottom_axes';
            app.GridLayout20 = uigridlayout(app.GridLayout19);
            app.DisplayedchannelLabel = uilabel(app.GridLayout20);
            app.DisplayedchannelLabel.Text = 'Displayed channel:';
            app.top_graph_dropdown = uidropdown(app.GridLayout20);
            app.top_graph_dropdown.Items = {'No Channel'};
            app.top_graph_dropdown.ValueChangedFcn = createCallbackFcn(app, @top_graph_dropdownValueChanged, true);
            app.top_graph_dropdown.Value = 'No Channel';
            app.GridLayout21 = uigridlayout(app.GridLayout19);
            app.DisplayedchannelLabel_2 = uilabel(app.GridLayout21);
            app.DisplayedchannelLabel_2.Text = 'Displayed channel:';
            app.bottom_graph_dropdown = uidropdown(app.GridLayout21);
            app.bottom_graph_dropdown.Items = {'No channel'};
            app.bottom_graph_dropdown.ValueChangedFcn = createCallbackFcn(app, @bottom_graph_dropdownValueChanged, true);
            app.bottom_graph_dropdown.Value = 'No channel';
            app.Histogram_ContextMenu = uicontextmenu(app.ResultFilteringToolUIFigure);
            app.Histogram_ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @Histogram_ContextMenuOpening, true);
            app.ExportimageMenu = uimenu(app.Histogram_ContextMenu);
            app.ExportimageMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportimageMenuSelected, true);
            app.ExportimageMenu.Text = 'Export image';
            app.SavefiltereddataMenu = uimenu(app.Histogram_ContextMenu);
            app.SavefiltereddataMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefiltereddataMenuSelected, true);
            app.SavefiltereddataMenu.Text = 'Save filtered data';
            % Assign app.Histogram_ContextMenu
            app.histogram_axes.ContextMenu = app.Histogram_ContextMenu;
            app.top_image_ContextMenu = uicontextmenu(app.ResultFilteringToolUIFigure);
            app.ImageexportMenu = uimenu(app.top_image_ContextMenu);
            app.ImageexportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageexportMenuSelected, true);
            app.ImageexportMenu.Text = 'Image export';
            % Assign app.top_image_ContextMenu
            app.top_axes.ContextMenu = app.top_image_ContextMenu;
            app.bottom_image_ContextMenu = uicontextmenu(app.ResultFilteringToolUIFigure);
            app.ImageexportMenu_2 = uimenu(app.bottom_image_ContextMenu);
            app.ImageexportMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ImageexportMenu_2Selected, true);
            app.ImageexportMenu_2.Text = 'Image export';
            % Assign app.bottom_image_ContextMenu
            app.bottom_axes.ContextMenu = app.bottom_image_ContextMenu;
            % Show the figure after all components are created
            app.ResultFilteringToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hist_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultFilteringToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultFilteringToolUIFigure)
        end
    end
end