classdef SimpleFilteringTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fast_RS_filtering          matlab.ui.Figure
        OpentsvfileCtrlOMenu       matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        SavefiltereddataCtrlSMenu  matlab.ui.container.Menu
        Menu_2                     matlab.ui.container.Menu
        BatchfilteringCtrlBMenu    matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        filter_value               matlab.ui.control.NumericEditField
        GridLayout2                matlab.ui.container.GridLayout
        FilterDataButton           matlab.ui.control.Button
        filtered_r2_axes           matlab.ui.control.UIAxes
        filtered_data_axes         matlab.ui.control.UIAxes
        original_r2_axes           matlab.ui.control.UIAxes
        original_data_axes         matlab.ui.control.UIAxes
        original_data_ContextMenu  matlab.ui.container.ContextMenu
        ChangeBinNumberMenu        matlab.ui.container.Menu
        original_r2_ContextMenu    matlab.ui.container.ContextMenu
        ChangeBinNumberMenu_2      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        load_path = ""; % Path to the folder containing the tsv file to load.
        save_path = ""; % Path to the folder where the filtered tsv file will be saved.
        original_data = []; % Unfiltered loaded data.
        filtered_data = []; % Filtered data.
        original_r2 = [];   % Unfiltered R2 values.
        filtered_r2 = [];   % Filtered R2 values.
        Bin_number_data = 50; % Value for the number of bins for the data histograms
        Bin_number_r2 = 50; % Value for the number of bins for the r2 histograms
        data_filtered = false; % Check value if the loaded data has been filtered.
        data_hist_obj = []; % Object of the data histogram.
        r2_hist_obj = []; % Object of the r2 histogram.
        data_filter_hist_obj = []; % Object of the filtered data histogram.
        r2_filter_hist_obj = []; % Object of the filtered r2 histogram.
        r2_patch_obj = []; % Object of the patch drawn in the r2 histogram.
        TSV_Tabel = []; % Tabel of the tsv file.
        loaded_file_name = []; % name of the laoded tsv file.
        DataOrderOFMag = 1; % Order of magnitude of the max value of the original data.
        UnitString = "" % Unit string for the x-labels of the 'orgininal data' and 'filtered data' histogram plots.
    end
    
    methods (Access = public)
        
        function display_hist(app,ID)
            % check for valid ID
            if ~ismember(ID,["original_data";"original_r2";"filtered_data";"filtered_r2"])
                error("Invalid ID value for the display_hist function!\n\nValid IDs:\t%s\t%s\t%s\t%s.\nInstead, ""%s"" was received.","original_data","original_r2","filtered_data","filtered_r2",ID);
            end
            
            % get in the correct case ID
            switch ID
                case "original_data"
                    
                    cla(app.original_data_axes);
                    
                    origData = app.original_data;
                    nanFilter = isnan(origData);
                    origData(nanFilter) = [];
                    infFilter = isinf(origData);
                    origData(infFilter) = [];
                    hist_data_max = max(origData);
                    [~,max_power,max_value_unit,~] = get_order_of_magnitude(hist_data_max,'Pa');
                    app.DataOrderOFMag = max_power;
                    app.UnitString = max_value_unit;

                    plot_data = app.original_data./10^max_power;
                    app.data_hist_obj = histogram(app.original_data_axes,plot_data,app.Bin_number_data,"FaceColor","b","EdgeColor","k");
                    
                    xlabel(app.original_data_axes,sprintf("Young's modulus [%s]",max_value_unit));
                   
                case "original_r2"
                    cla(app.original_r2_axes);
                    plot_data = app.original_r2;
                    if (min(plot_data) >= 0) && (max(plot_data) <= 1)
                        bin_edges = linspace(0,1,app.Bin_number_r2);
                        xlim(app.original_r2_axes,[0,1]);
                    elseif (max(plot_data) > 1)
                        bin_edges = linspace(min(plot_data),max(plot_data),app.Bin_number_r2);
                        xlim(app.original_r2_axes,"auto");
                    else
                        bin_edges = linspace(floor(min(plot_data)),1,app.Bin_number_r2);
                        app.original_r2_axes.XLimMode = "auto";
                    end
                    app.r2_hist_obj = histogram(app.original_r2_axes,app.original_r2,bin_edges,"FaceColor","b","EdgeColor","k");
                case "filtered_data"
                    cla(app.filtered_data_axes)

                    plot_data = app.filtered_data./10^app.DataOrderOFMag;
                    app.data_filter_hist_obj = histogram(app.filtered_data_axes,plot_data,app.data_hist_obj.BinEdges,"FaceColor","b","EdgeColor","k");
                    xlabel(app.filtered_data_axes,sprintf("Young's modulus [%s]",app.UnitString));
                    app.filtered_data_axes.XLim = app.original_data_axes.XLim;
                    app.filtered_data_axes.YLim = app.original_data_axes.YLim;
                    
                case "filtered_r2"
                    cla(app.filtered_r2_axes);
                    app.filtered_r2_axes.XLimMode = app.original_r2_axes.XLimMode;
                    app.filtered_r2_axes.XLim = app.original_r2_axes.XLim;
                    app.filtered_r2_axes.YLim = app.original_r2_axes.YLim;
                    app.r2_filter_hist_obj = histogram(app.filtered_r2_axes,app.filtered_r2,app.r2_hist_obj.BinEdges,"FaceColor","b","EdgeColor","k");
                    
            end
        end
        
        function add_patches(app)
            if ~isempty(app.r2_patch_obj)
                delete(app.r2_patch_obj)
                app.r2_patch_obj = [];
            end
            xaxis_lim = app.original_r2_axes.XLim;
            yaxis_lim = app.original_r2_axes.YLim;
            filter_lim = app.filter_value.Value;
            app.r2_patch_obj = patch(app.original_r2_axes,[xaxis_lim(1) filter_lim filter_lim xaxis_lim(1)],[yaxis_lim(1) yaxis_lim(1) yaxis_lim(2) yaxis_lim(2)],[1 0 0],"FaceAlpha",.4,"EdgeColor","none");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.original_data_axes.Toolbar.Visible = 0;
            app.original_r2_axes.Toolbar.Visible = 0;
            app.filtered_data_axes.Toolbar.Visible = 0;
            app.filtered_r2_axes.Toolbar.Visible = 0;
            disableDefaultInteractivity(app.original_data_axes);
            disableDefaultInteractivity(app.original_r2_axes);
            disableDefaultInteractivity(app.filtered_data_axes);
            disableDefaultInteractivity(app.filtered_r2_axes);
        end

        % Callback function
        function OpentsvfileMenuSelected(app, event)
            default_filter = app.load_path+"*.tsv";
            [load_file,selected_path] = uigetfile(default_filter);
            if selected_path == 0
                return;
            end
            full_path = fullfile(selected_path,load_file);
            app.load_path = selected_path;
            app.loaded_file_name = load_file;
            app.TSV_Tabel = readtable(full_path,"FileType","text","ReadVariableNames",true,"TextType","string","Delimiter","\t","EmptyValue",NaN);
            app.data_filtered = false;
                        
            table_vars = app.TSV_Tabel.Properties.VariableNames;
            [indx,tf] = listdlg("ListString",table_vars,"PromptString","Select the data to be filtered:","SelectionMode","single","OKString","Submit");
            if ~tf
                return;
            end
            app.original_data = app.TSV_Tabel.(table_vars{indx});
            
            table_vars(indx) = [];
            [indx,tf] = listdlg("ListString",table_vars,"PromptString","Select the R² data:","SelectionMode","single","OKString","Submit");
            if ~tf
                return;
            end
            app.filtered_data_axes.Visible = 0;
            app.filtered_r2_axes.Visible = 0;
            
            if ~isempty(app.data_filter_hist_obj)
                delete(app.data_filter_hist_obj)
            end
            if ~isempty(app.r2_filter_hist_obj)
                delete(app.r2_filter_hist_obj)
            end 
            app.original_r2 = app.TSV_Tabel.(table_vars{indx});
            display_hist(app,"original_data");
            display_hist(app,"original_r2");
            app.FilterDataButton.Enable = 1;
            app.filter_value.Enable = 1;
            app.ChangeBinNumberMenu.Enable = 1;
            app.ChangeBinNumberMenu_2.Enable = 1;
        end

        % Button pushed function: FilterDataButton
        function FilterDataButtonPushed(app, event)
            
            r2_value = app.filter_value.Value;
            filter_mask = app.original_r2 > r2_value;
            app.filtered_data = app.original_data(filter_mask);
            app.filtered_r2 = app.original_r2(filter_mask);
            app.filtered_data_axes.Visible = 1;
            app.filtered_r2_axes.Visible = 1;
            display_hist(app,"filtered_data");
            display_hist(app,"filtered_r2");
            app.data_filtered = true;
            add_patches(app);
        end

        % Value changed function: filter_value
        function filter_valueValueChanged(app, event)
            value = app.filter_value.Value;
            if (app.data_filtered) || (~isempty(app.data_hist_obj))
                FilterDataButtonPushed(app,"filter_value_changed")
            end
        end

        % Callback function
        function SavefiltereddatatotsvMenuSelected(app, event)
            if app.filtered_data
                last_path = app.save_path;
                if strcmp(last_path,"")
                    last_path = app.load_path;
                end
                file_name = app.loaded_file_name;
                [~,file_name_string,file_extention] = fileparts(file_name);
                filter_V = app.filter_value.Value;
                default_path = sprintf("%s_filtered_%1.4g%s",file_name_string,filter_V,file_extention);
                full_default_path = fullfile(last_path,default_path);
                [file,path] = uiputfile(full_default_path);
                if path == 0
                    return
                end
                app.save_path = path;
                full_save_path = fullfile(path,file);
                filter_mask = app.original_r2 > filter_V;
                filtered_TSV_Table = app.TSV_Tabel(filter_mask,:);
                                
                writetable(filtered_TSV_Table,full_save_path,"FileType","text","WriteVariableNames",true,"Delimiter","\t");
            else
                uialert(app.fast_RS_filtering,"There is no filtered Data!","No filtered Data","Icon","info","Modal",true)
            end
        end

        % Menu selected function: ChangeBinNumberMenu
        function ChangeBinNumberMenuSelected(app, event)
            if ~isempty(app.data_hist_obj)
                answer = inputdlg("Enter new bin number:","Input",[1 35],sprintf("%d",app.Bin_number_data));
                if isempty(answer)
                    return;
                end
                answer = strrep(answer,",",".");
                answer_double = str2double(answer);
                if isnan(answer_double) || answer_double <= 0
                   uialert(app.fast_RS_filtering,"You entered an invalid bin number!","Invalid Number","Modal",true,"Icon","error");
                   return;
                end
                answer_double = floor(answer_double);
                if answer_double == app.Bin_number_data
                   return; 
                end
                app.Bin_number_data = answer_double;
                display_hist(app,"original_data")
                if app.data_filtered
                   display_hist(app,"filtered_data") 
                end
            end
        end

        % Menu selected function: ChangeBinNumberMenu_2
        function ChangeBinNumberMenu_2Selected(app, event)
            if ~isempty(app.r2_hist_obj)
                answer = inputdlg("Enter new bin number:","Input",[1 35],sprintf("%d",app.Bin_number_r2));
                if isempty(answer)
                    return;
                end
                answer = strrep(answer,",",".");
                answer_double = str2double(answer);
                if isnan(answer_double) || answer_double <= 0
                   uialert(app.fast_RS_filtering,"You entered an invalid bin number!","Invalid Number","Modal",true,"Icon","error");
                   return;
                end
                answer_double = floor(answer_double);
                if answer_double == app.Bin_number_r2
                   return; 
                end
                app.Bin_number_r2 = answer_double;
                display_hist(app,"original_r2")
                if app.data_filtered
                   display_hist(app,"filtered_r2")
                   add_patches(app)
                end
            end
        end

        % Close request function: fast_RS_filtering
        function fast_RS_filteringCloseRequest(app, event)
            delete(app)
            run("CANTER_Processing_Toolbox.mlapp")
        end

        % Menu selected function: OpentsvfileCtrlOMenu
        function OpentsvfileCtrlOMenuSelected2(app, event)
            default_filter = app.load_path+"*.tsv";
            [load_file,selected_path] = uigetfile(default_filter);
            if selected_path == 0
                return;
            end
            full_path = fullfile(selected_path,load_file);
            app.load_path = selected_path;
            app.loaded_file_name = load_file;
            app.TSV_Tabel = readtable(full_path,"FileType","text","ReadVariableNames",true,"TextType","string","Delimiter","\t","EmptyValue",NaN);
            app.data_filtered = false;
                        
            table_vars = app.TSV_Tabel.Properties.VariableNames;
            [indx,tf] = listdlg("ListString",table_vars,"PromptString","Select the data to be filtered:","SelectionMode","single","OKString","Submit");
            if ~tf
                return;
            end
            app.original_data = app.TSV_Tabel.(table_vars{indx});
            
            table_vars(indx) = [];
            [indx,tf] = listdlg("ListString",table_vars,"PromptString","Select the R² data:","SelectionMode","single","OKString","Submit");
            if ~tf
                return;
            end
            app.filtered_data_axes.Visible = 0;
            app.filtered_r2_axes.Visible = 0;
            
            if ~isempty(app.data_filter_hist_obj)
                delete(app.data_filter_hist_obj)
            end
            if ~isempty(app.r2_filter_hist_obj)
                delete(app.r2_filter_hist_obj)
            end 
            app.original_r2 = app.TSV_Tabel.(table_vars{indx});
            display_hist(app,"original_data");
            display_hist(app,"original_r2");
            app.FilterDataButton.Enable = 1;
            app.filter_value.Enable = 1;
            app.ChangeBinNumberMenu.Enable = 1;
            app.ChangeBinNumberMenu_2.Enable = 1;
        end

        % Menu selected function: SavefiltereddataCtrlSMenu
        function SavefiltereddataCtrlSMenuSelected(app, event)
            if app.filtered_data
                last_path = app.save_path;
                if strcmp(last_path,"")
                    last_path = app.load_path;
                end
                file_name = app.loaded_file_name;
                [~,file_name_string,file_extention] = fileparts(file_name);
                filter_V = app.filter_value.Value;
                default_path = sprintf("%s_filtered_%1.4g%s",file_name_string,filter_V,file_extention);
                full_default_path = fullfile(last_path,default_path);
                [file,path] = uiputfile(full_default_path);
                if path == 0
                    return
                end
                app.save_path = path;
                full_save_path = fullfile(path,file);
                filter_mask = app.original_r2 > filter_V;
                filtered_TSV_Table = app.TSV_Tabel(filter_mask,:);
                                
                writetable(filtered_TSV_Table,full_save_path,"FileType","text","WriteVariableNames",true,"Delimiter","\t");
            else
                uialert(app.fast_RS_filtering,"There is no filtered Data!","No filtered Data","Icon","info","Modal",true)
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            return; % -> Dummy menu button as spacer.
        end

        % Key press function: fast_RS_filtering
        function fast_RS_filteringKeyPress(app, event)
            key = event.Key;
            modifier = string(event.Modifier);
            if strcmp(key,"o") && strcmp(modifier,"control")
                OpentsvfileMenuSelected(app, event)
            elseif strcmp(key,"s") && strcmp(modifier,"control")
                SavefiltereddatatotsvMenuSelected(app, event)
            elseif strcmp(key,"b") && strcmp(modifier,"control")
                BatchfilteringCtrlBMenuSelected(app, event)
            end
        end

        % Menu selected function: BatchfilteringCtrlBMenu
        function BatchfilteringCtrlBMenuSelected(app, event)
            dirpath = uigetdir(app.load_path);
            if dirpath == 0
                return;
            end
            app.load_path = dirpath;
            default_filter = fullfile(app.load_path,filesep,"*.tsv");
            DirTable = struct2table(dir(default_filter));
            ListOfFiles = string(DirTable.name);
            [indx, tf] = listdlg('ListString',ListOfFiles,'PromptString',"Select all files the filtering should be applied to:",'SelectionMode','multiple','ListSize',[250,400],'InitialValue',[1:length(ListOfFiles)],'Name',"File Selection");
            if tf == 0
                return;
            end

            answer = inputdlg("Enter the filter cut-off value:","Filter Cut-Off",[1 30],"0.00");
            if isempty(answer)
                return;
            end
            CutOffValue = str2double(string(answer));
            if isnan(CutOffValue)
                uialert(app.fast_RS_filtering,"Invalid Cut-Off Value!","Invalid Value");
                return;
            end

            for i=1:length(indx)
                currentIndx = indx(i);
                currentFile = fullfile(dirpath,ListOfFiles(currentIndx));
                curr_TSV_Tabel = readtable(currentFile,"FileType","text","ReadVariableNames",true,"TextType","string","Delimiter","\t","EmptyValue",NaN);
                if i == 1
                    table_vars = string(curr_TSV_Tabel.Properties.VariableNames);
                    [filter_indx,filter_tf] = listdlg("ListString",table_vars,"PromptString","Select the rsquare filter data:","SelectionMode","single","OKString","Submit");
                    if ~filter_tf
                        return;
                    end
                    progBar = uiprogressdlg(app.fast_RS_filtering,"Cancelable","off","Icon","info","Title","Batch Filtering ...","Message",sprintf("Filtering: %s",ListOfFiles(currentIndx)),"Value",0);
                else
                    progBar.Value = i./length(indx);
                    progBar.Message = sprintf("Filtering: %s",ListOfFiles(currentIndx));
                end
                filterMask = curr_TSV_Tabel.(table_vars(filter_indx)) > CutOffValue;
                filtered_Table = curr_TSV_Tabel(filterMask,:);
    
                [~,file_name_string,file_extention] = fileparts(currentFile);
                new_file_name = sprintf("%s_filtered_%1.4g%s",file_name_string,CutOffValue,file_extention);
                full_save_path = fullfile(dirpath,new_file_name);
                writetable(filtered_Table,full_save_path,"FileType","text","WriteVariableNames",true,"Delimiter","\t","WriteMode","overwrite");              
            end
            progBar.Value = 1;
            close(progBar);

            uialert(app.fast_RS_filtering,"Batch filtering completed succressfully!","Filtering completed","Icon","success");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fast_RS_filtering = uifigure('Visible', 'off');
            app.fast_RS_filtering.Name = 'R² Filtering Tool';
            app.fast_RS_filtering.CloseRequestFcn = createCallbackFcn(app, @fast_RS_filteringCloseRequest, true);
            app.fast_RS_filtering.KeyPressFcn = createCallbackFcn(app, @fast_RS_filteringKeyPress, true);
            app.OpentsvfileCtrlOMenu = uimenu(app.fast_RS_filtering);
            app.OpentsvfileCtrlOMenu.MenuSelectedFcn = createCallbackFcn(app, @OpentsvfileCtrlOMenuSelected2, true);
            app.OpentsvfileCtrlOMenu.Text = 'Open tsv file (Ctrl + O)';
            app.OpentsvfileCtrlOMenu.Tag = 'Open_menu';
            app.Menu = uimenu(app.fast_RS_filtering);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '         ';
            app.SavefiltereddataCtrlSMenu = uimenu(app.fast_RS_filtering);
            app.SavefiltereddataCtrlSMenu.MenuSelectedFcn = createCallbackFcn(app, @SavefiltereddataCtrlSMenuSelected, true);
            app.SavefiltereddataCtrlSMenu.Text = 'Save filtered data (Ctrl + S)';
            app.Menu_2 = uimenu(app.fast_RS_filtering);
            app.Menu_2.Text = '            ';
            app.BatchfilteringCtrlBMenu = uimenu(app.fast_RS_filtering);
            app.BatchfilteringCtrlBMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchfilteringCtrlBMenuSelected, true);
            app.BatchfilteringCtrlBMenu.Text = 'Batch filtering (Ctrl + B)';
            app.GridLayout = uigridlayout(app.fast_RS_filtering);
            app.original_data_axes = uiaxes(app.GridLayout);
            title(app.original_data_axes, 'Original Data')
            xlabel(app.original_data_axes, 'Young''s modulus')
            ylabel(app.original_data_axes, 'Frequency')
            app.original_r2_axes = uiaxes(app.GridLayout);
            title(app.original_r2_axes, 'Original R²')
            xlabel(app.original_r2_axes, 'R^{2}')
            ylabel(app.original_r2_axes, 'Frequency')
            app.original_r2_axes.XLim = [0 1];
            app.filtered_data_axes = uiaxes(app.GridLayout);
            title(app.filtered_data_axes, 'Filtered Data')
            xlabel(app.filtered_data_axes, 'Young''s modulus')
            ylabel(app.filtered_data_axes, 'Frequency')
            app.filtered_data_axes.Visible = 'off';
            app.filtered_r2_axes = uiaxes(app.GridLayout);
            title(app.filtered_r2_axes, 'Filtered R²')
            xlabel(app.filtered_r2_axes, 'R_{2}')
            ylabel(app.filtered_r2_axes, 'Frequency')
            app.filtered_r2_axes.XLim = [0 1];
            app.filtered_r2_axes.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FilterDataButton = uibutton(app.GridLayout2, 'push');
            app.FilterDataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterDataButtonPushed, true);
            app.FilterDataButton.Enable = 'off';
            app.FilterDataButton.Text = 'Filter Data';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.filter_value = uieditfield(app.GridLayout3, 'numeric');
            app.filter_value.ValueChangedFcn = createCallbackFcn(app, @filter_valueValueChanged, true);
            app.filter_value.Enable = 'off';
            app.original_data_ContextMenu = uicontextmenu(app.fast_RS_filtering);
            app.ChangeBinNumberMenu = uimenu(app.original_data_ContextMenu);
            app.ChangeBinNumberMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeBinNumberMenuSelected, true);
            app.ChangeBinNumberMenu.Enable = 'off';
            app.ChangeBinNumberMenu.Text = 'Change Bin Number';
            app.ChangeBinNumberMenu.Tag = 'change_bin_number_contextmenu';
            % Assign app.original_data_ContextMenu
            app.original_data_axes.ContextMenu = app.original_data_ContextMenu;
            app.original_r2_ContextMenu = uicontextmenu(app.fast_RS_filtering);
            app.ChangeBinNumberMenu_2 = uimenu(app.original_r2_ContextMenu);
            app.ChangeBinNumberMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeBinNumberMenu_2Selected, true);
            app.ChangeBinNumberMenu_2.Enable = 'off';
            app.ChangeBinNumberMenu_2.Text = 'Change Bin Number';
            % Assign app.original_r2_ContextMenu
            app.original_r2_axes.ContextMenu = app.original_r2_ContextMenu;
            % Show the figure after all components are created
            app.fast_RS_filtering.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleFilteringTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fast_RS_filtering)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fast_RS_filtering)
        end
    end
end