classdef bihertz_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        exit                        matlab.ui.container.Menu
        bug_report_menu             matlab.ui.container.Menu
        report_a_bug_submenu        matlab.ui.container.Menu
        how_to_report_submenu       matlab.ui.container.Menu
        help_menu                   matlab.ui.container.Menu
        help_wiki                   matlab.ui.container.Menu
        SoundsMenu                  matlab.ui.container.Menu
        SoundOff                    matlab.ui.container.Menu
        SoundOn                     matlab.ui.container.Menu
        MainGUIGrid                 matlab.ui.container.GridLayout
        hertz_fit_panel             matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout11                matlab.ui.container.GridLayout
        CP_unit_label               matlab.ui.control.Label
        ContactPointField           matlab.ui.control.NumericEditField
        ContactPointLabel           matlab.ui.control.Label
        btn_gof                     matlab.ui.control.Button
        hertz_gof                   matlab.ui.control.Label
        text53                      matlab.ui.control.Label
        btn_histogram               matlab.ui.control.Button
        text49                      matlab.ui.control.Label
        hertz_EModul                matlab.ui.control.Label
        text47                      matlab.ui.control.Label
        GridLayout8                 matlab.ui.container.GridLayout
        ContactPointDropDown        matlab.ui.control.DropDown
        ContactPointDropDownLabel   matlab.ui.control.Label
        GridLayout10                matlab.ui.container.GridLayout
        text46                      matlab.ui.control.Label
        hertz_fit_depth             matlab.ui.control.EditField
        GridLayout9                 matlab.ui.container.GridLayout
        text73                      matlab.ui.control.Label
        hertz_fit_start             matlab.ui.control.EditField
        text44                      matlab.ui.control.Label
        text72                      matlab.ui.control.Label
        BiHertzFitPanel             matlab.ui.container.Panel
        GridLayout12                matlab.ui.container.GridLayout
        uipanel10                   matlab.ui.container.Panel
        GridLayout14                matlab.ui.container.GridLayout
        result_rsquare              matlab.ui.control.Label
        text30                      matlab.ui.control.Label
        text71                      matlab.ui.control.Label
        result_switch_point         matlab.ui.control.EditField
        text69                      matlab.ui.control.Label
        text20                      matlab.ui.control.Label
        result_dh                   matlab.ui.control.Label
        text18                      matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        result_Eh                   matlab.ui.control.Label
        text15                      matlab.ui.control.Label
        text14                      matlab.ui.control.Label
        result_Es                   matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        uipanel5                    matlab.ui.container.Panel
        GridLayout13                matlab.ui.container.GridLayout
        text10                      matlab.ui.control.Label
        fit_perc                    matlab.ui.control.EditField
        text9                       matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        fit_depth                   matlab.ui.control.EditField
        text7                       matlab.ui.control.Label
        GridLayout24                matlab.ui.container.GridLayout
        ForceCurveAnalysisLabel     matlab.ui.control.Label
        uipanel4                    matlab.ui.container.Panel
        GridLayout22                matlab.ui.container.GridLayout
        save_status_led             matlab.ui.control.EditField
        save_status_text            matlab.ui.control.Label
        edit_savepath               matlab.ui.control.EditField
        button_save_path            matlab.ui.control.Button
        GridLayout17                matlab.ui.container.GridLayout
        uipanel13                   matlab.ui.container.Panel
        GridLayout16                matlab.ui.container.GridLayout
        fit_model_popup             matlab.ui.control.DropDown
        GridLayout3                 matlab.ui.container.GridLayout
        button_discard_highlighted  matlab.ui.control.Button
        button_undo_highlighted     matlab.ui.control.Button
        button_keep_highlighted     matlab.ui.control.Button
        info_panel_panel            matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        info_table                  matlab.ui.control.Table
        uipanel9                    matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        button_keep_all             matlab.ui.control.Button
        button_undo                 matlab.ui.control.Button
        button_discard              matlab.ui.control.Button
        button_keep                 matlab.ui.control.Button
        listbox1                    matlab.ui.control.ListBox
        uipanel11                   matlab.ui.container.Panel
        GridLayout18                matlab.ui.container.GridLayout
        ContactPointFinderPanel     matlab.ui.container.Panel
        GridLayout20                matlab.ui.container.GridLayout
        text59                      matlab.ui.control.Label
        contact_percentage_hertz    matlab.ui.control.EditField
        btngroup_contact            matlab.ui.container.ButtonGroup
        cp_hertzfit                 matlab.ui.control.RadioButton
        intersection                matlab.ui.control.RadioButton
        uipanel17                   matlab.ui.container.Panel
        GridLayout21                matlab.ui.container.GridLayout
        text29                      matlab.ui.control.Label
        text28                      matlab.ui.control.Label
        text27                      matlab.ui.control.Label
        baseline_btngroup           matlab.ui.container.ButtonGroup
        offset_and_tilt             matlab.ui.control.RadioButton
        offset                      matlab.ui.control.RadioButton
        radiobutton4                matlab.ui.control.RadioButton
        uipanel12                   matlab.ui.container.Panel
        GridLayout19                matlab.ui.container.GridLayout
        text_spring_const           matlab.ui.control.Label
        text_sensitivity            matlab.ui.control.Label
        uipanel20                   matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        image_channels_popup        matlab.ui.control.DropDown
        uipanel2                    matlab.ui.container.Panel
        GridLayout23                matlab.ui.container.GridLayout
        button_load_data            matlab.ui.control.Button
        button_folder               matlab.ui.control.Button
        button_file                 matlab.ui.control.Button
        edit_filepath               matlab.ui.control.EditField
        map_axes                    matlab.ui.control.UIAxes
        map_axes_context            matlab.ui.container.ContextMenu
        interpolation_type          matlab.ui.container.Menu
        none                        matlab.ui.container.Menu
        linear                      matlab.ui.container.Menu
        bicubic                     matlab.ui.container.Menu
        save_image                  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        FileType = []; % Indicates whether a jpk-force-map file, a text file, or a folder was selected.
        PrevAngleOpt = []; % Indicates whether 'to edge' or 'to face' was selected.
        PrevAngle = []; % The previously given angle value.
        PrevRad = []; % The previously entered cylinder radius.
        PrevUnit = []; % The previously selected radius unit.
        PrevPoisson = []; % The previously enterd value for the Poisson's ratio.
        PrevGeom = []; % The previously selected indenter geometry.
        FitResultStruct = []; % Varargout of the various fit functions.
        CurrFMapName = ""; % File name of the currently loaded force map file.
        NotificationSounds = []; % Property storing the notification sounds loaded in the bihertz_gui_OpeningFcn.
    end
    
    methods (Access = private)
        function [hObject, handles] = checker_helpf(~, hObject, handles)
                button_tag = hObject.Tag;
                switch button_tag
                    case 'none'
                        handles.none.Checked = 'on';
                        handles.linear.Checked  = 'off';
                        handles.bicubic.Checked = 'off';
                    case 'linear'
                        handles.none.Checked = 'off';
                        handles.linear.Checked  = 'on';
                        handles.bicubic.Checked = 'off';
                    case 'bicubic'
                        handles.none.Checked = 'off';
                        handles.linear.Checked  = 'off';
                        handles.bicubic.Checked = 'on';
                end
            guidata(hObject,handles);
        end
        
        function handles = colorbar_helpf(~, ax_handle, handles)
                axes(ax_handle);
                cbar = colorbar(ax_handle);
                cbar.Ruler.Exponent = 0;
                image_handle = findobj(ax_handle,'Type','image');
                if isempty(image_handle)
                    warning('No image object was found in the given axes object');
                    return;
                end
                map_type = handles.loaded_file_type;
                channels = handles.image_channels_popup.String;
                channel_indx = handles.image_channels_popup.Value;
                channel = channels{channel_indx};
            
                if strcmp(map_type,'jpk-force-map') || strcmp(map_type,'ibw')
                    % the following is the colorbar correction for jpk-force-maps
                    switch channel
                        case 'height_measured'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            check = abs(c_max) < 1e-6 && abs(c_min) < 1e-6;
                            labels = cbar.Ticks;
                            if check
                                labels = strcat(string(labels*1e9), ' nm');
                                max_label = sprintf('max: %.f nm',c_max*1e9);
                                min_label = sprintf('min: %.f nm',c_min*1e9);
                            else
                                labels = strcat(string(labels*1e6), ' µm');
                                max_label = sprintf('max: %.2f µm',c_max*1e6);
                                min_label = sprintf('min: %.2f µm',c_min*1e6);
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'height'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            check = abs(c_max) < 1e-6 && abs(c_min) < 1e-6;
                            labels = cbar.Ticks;
                            if check
                                labels = strcat(string(labels*1e9), ' nm');
                                max_label = sprintf('max: %.f nm',c_max*1e9);
                                min_label = sprintf('min: %.f nm',c_min*1e9);
                            elseif abs(c_min) > .1
                                if isfield(handles.map_images.height,"data_unit")
                                    unit = " "+handles.map_images.height.data_unit;
                                else
                                    unit = " ";
                                end
                                labels = strcat(string(labels), unit);
                                max_label = sprintf('max: %.f %s',c_max,unit);
                                min_label = sprintf('min: %.f %s',c_min,unit);
                            else
                                labels = strcat(string(labels*1e6), ' µm');
                                max_label = sprintf('max: %.2f µm',c_max*1e6);
                                min_label = sprintf('min: %.2f µm',c_min*1e6);
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'measuredHeight'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            check = abs(c_max) < 1e-6 && abs(c_min) < 1e-6;
                            labels = cbar.Ticks;
                            if check
                                labels = strcat(string(labels*1e9), ' nm');
                                max_label = sprintf('max: %.f nm',c_max*1e9);
                                min_label = sprintf('min: %.f nm',c_min*1e9);
                            else
                                labels = strcat(string(labels*1e6), ' µm');
                                max_label = sprintf('max: %.2f µm',c_max*1e6);
                                min_label = sprintf('min: %.2f µm',c_min*1e6);
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'slope'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            if isfield(handles.map_images.slope,"data_unit") && strcmp(handles.map_images.slope.data_unit,"N/m")
                                labels = cbar.TickLabels;
                                for i = 1:length(labels)
                                    label_char = labels{i};
                                    label_num = str2double(label_char);
                                    labels(i) = {sprintf('%.2g nN/µm',label_num.*1e3)};
                                    max_label = sprintf('max: %.2g nN/µm',c_max.*1e3);
                                    min_label = sprintf('min: %.2g nN/µm',c_min.*1e3);
                                end
                            else
                                check = abs(c_max) < 1e6 && abs(c_min) < 1e6;
                                labels = cbar.TickLabels;
                                for i = 1:length(labels)
                                   label_char = labels{i};
                                   label_num = str2double(label_char);
                                   if isnan(label_num)
                                       split_cell = strsplit(label_char,'\\times10^{');
                                       new_string = sprintf('%se%s',split_cell{1},split_cell{2}(1:end-1));
                                       label_num = str2double(new_string);
                                   end
                                   if check
                                       label_num = label_num*1e-3;
                                       labels(i) = {sprintf('%3.0f mV/µm',label_num)};
                                       max_label = sprintf('max: %.2g mV/µm',c_max*1e-3);
                                       min_label = sprintf('min: %.2g mV/µm',c_min*1e-3);
                                   else
                                       label_num = label_num*1e-6;
                                       labels(i) = {sprintf('%.1f V/µm',label_num)};
                                       max_label = sprintf('max: %.2g V/µm',c_max*1e-6);
                                       min_label = sprintf('min: %.2g V/µm',c_min*1e-6);
                                   end
                                end
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'adhesion'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            check = abs(c_max) < 1 && abs(c_min) < 1;
                            labels = cbar.TickLabels;
                            for i = 1:length(labels)
                               lable_char = labels{i};
                               label_num = str2double(lable_char);
                               if check
                                   label_num = label_num*1e3;
                                   labels(i) = {sprintf('%3.0f mV',label_num)};
                                   max_label = sprintf('max: %.2g mV',c_max*1e3);
                                   min_label = sprintf('min: %.2g mV',c_min*1e3);
                               else
                                   labels(i) = {sprintf('%1.1f V',label_num)};
                                   max_label = sprintf('max: %.2g V',c_max);
                                   min_label = sprintf('min: %.2g V',c_min);
                               end
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'vDeflection'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            check = abs(c_max) < 1 && abs(c_min) < 1;
                            labels = cbar.TickLabels;
                            for i = 1:length(labels)
                               lable_char = labels{i};
                               label_num = str2double(lable_char);
                               if check
                                   label_num = label_num*1e3;
                                   labels(i) = {sprintf('%3.0f mV',label_num)};
                                   max_label = sprintf('max: %.2g mV',c_max*1e3);
                                   min_label = sprintf('min: %.2g mV',c_min*1e3);
                               else
                                   labels(i) = {sprintf('%1.1f V',label_num)};
                                   max_label = sprintf('max: %.2g V',c_max);
                                   min_label = sprintf('min: %.2g V',c_min);
                               end
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'hDeflection'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine if the maximum dimension is nm or µm and
                            % correct the tick lables of the colorbar
                            check = abs(c_max) < 1 && abs(c_min) < 1;
                            labels = cbar.TickLabels;
                            for i = 1:length(labels)
                               lable_char = labels{i};
                               label_num = str2double(lable_char);
                               if check
                                   label_num = label_num*1e3;
                                   labels(i) = {sprintf('%3.0f mV',label_num)};
                                   max_label = sprintf('max: %.2g mV',c_max*1e3);
                                   min_label = sprintf('min: %.2g mV',c_min*1e3);
                               else
                                   labels(i) = {sprintf('%1.1f V',label_num)};
                                   max_label = sprintf('max: %.2g V',c_max);
                                   min_label = sprintf('min: %.2g V',c_min);
                               end
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'Youngs Modulus'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine the order of magnetude and
                            % correct the tick lables of the colorbar
                            order_max = floor(log(abs(c_max))./log(10));
                            order_min = floor(log(abs(c_min))./log(10));
                            max_order = max([order_max order_min]);
                            labelValues = cbar.Ticks;
                            labels = cbar.TickLabels;
                            for i = 1:length(labelValues)
                               % lable_char = labelValues{i};
                               currLabel = labelValues(i);
                               % label_num = str2double(lable_char);
                               label_num = currLabel./10^max_order;
                               if max_order < 3
                                   labels(i) = {sprintf('%.1f Pa',label_num)};
                                   max_label = sprintf('max: %f Pa',c_max);
                                   min_label = sprintf('min: %f Pa',c_min);
                               elseif max_order >= 3 && max_order < 6
                                   % label_num = label_num*1e-3;
                                   labels(i) = {sprintf('%.1f kPa',label_num)};
                                   max_label = sprintf('max: %.2f kPa',c_max*1e-3);
                                   min_label = sprintf('min: %.2f kPa',c_min*1e-3);
                               else
                                   % label_num = label_num*1e-6;
                                   labels(i) = {sprintf('%.1f MPa',label_num)};
                                   max_label = sprintf('max: %.2f MPa',c_max*1e-6);
                                   min_label = sprintf('min: %.2f MPa',c_min*1e-6);
                               end
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        case 'Contactpoint'
                            % find min and max of CData
                            c_min = min(min(image_handle.CData));
                            c_max = max(max(image_handle.CData));
                            % determine the order of magnetude and
                            % correct the tick lables of the colorbar
                            order_max = floor(log(abs(c_max))./log(10));
                            order_min = floor(log(abs(c_min))./log(10));
                            max_order = max([order_max order_min]);
                            labels = cbar.TickLabels;
                            for i = 1:length(labels)
                               lable_char = labels{i};
                               label_num = str2double(lable_char);
                               if max_order <= -9
                                   label_num = label_num*1e9;
                                   labels(i) = {sprintf('%3.0f nm',label_num)};
                                   max_label = sprintf('max: %.2g nm',c_max*1e9);
                                   min_label = sprintf('min: %.2g nm',c_min*1e9);
                               else
                                   label_num = label_num*1e6;
                                   labels(i) = {sprintf('%.2f µm',label_num)};
                                   max_label = sprintf('max: %.2g µm',c_max*1e6);
                                   min_label = sprintf('min: %.2g µm',c_min*1e6);
                               end
                            end
                            cbar.TickLabels = labels;
                            % provide min and max information of shown data
                            title(ax_handle,{'Full data range:';max_label;min_label},'FontSize',9);
                        otherwise
                            warning('No colorbar processing is available for the choosen map channel');
                    end
                else
                    warning('No matching filetype for the color bar processing is loaded!')
                    return;
                end
        end
        
        function edit2_CreateFcn(app, hObject, ~, ~)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function handles = get_indenter_parameter(app, handles)
            % help function to get the indetner geometry parameter and the poisson ration from the options struct
            handles.tip_angle = handles.options.tip_angle;
            handles.poisson = handles.options.poisson;
            handles.tip_shape = handles.options.tip_shape;
            handles.cylinder_radius = handles.options.cylinder_radius;
        end
        
        function handles = info_panel_helpf(app, handles)
                %     Function to read file information and display them in the info panel
                loaded_file = handles.loaded_file_type;
            
                % Check if a jpk-force-map was loaded
                if strcmp(loaded_file,'jpk-force-map')
                    handles.info_table.Data = handles.map_info_array;
                % Check if ibw files were loaded
                elseif strcmp(loaded_file,'ibw')
                    handles.info_table.Data = handles.mfpmapdata{1};
                % Check if txt files were loaded
                elseif strcmp(loaded_file,'txt')
                    handles.info_table.Data = handles.text_file_info;
                elseif strcmp(loaded_file,'mach-txt')
                    seg_num = handles.current_curve;
                    seg = sprintf('segment%d',seg_num);
                    handles.info_table.Data = handles.mach_info_struct.(seg);
                end
        end
        
        function main_plot_CloseRequest(app, ~, ~, ~)
               warndlg({'You are not allowed to close the main plot window this way!';...
                   'The main plot window will be closed together with the processing window!'})
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.info_table.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.info_table.ColumnFormat = {'char' 'char'};
        end
        
        function keep_result_helpf(app,event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to button_keep (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.fit_model_popup.Enable = 'off';

            % Data has been modified -> not saved
            handles.save_status = 0;
            handles.save_status_led.BackgroundColor = [1 0 0];
            
            % save fit results
            curve_index = handles.current_curve;
            discarded = handles.progress.num_discarded;
            
            switch handles.options.model
                case 'bihertz'
                    if handles.options.bihertz_variant == 1
                        handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                           uint64(curve_index),...
                                           handles.fit_results.initial_E_s,...
                                           handles.fit_results.initial_E_h,...
                                           handles.fit_results.initial_d_h,...
                                           handles.fit_results.fit_E_s,...
                                           handles.fit_results.fit_E_h,...
                                           handles.fit_results.fit_d_h,...
                                           handles.fit_results.rsquare_fit};
                    elseif handles.options.bihertz_variant == 2
                        handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                           uint64(curve_index),...
                                           handles.fit_results.initial_E_s,...
                                           handles.fit_results.initial_E_h,...
                                           handles.fit_results.initial_d_h,...
                                           handles.fit_results.initial_s_p,...
                                           handles.fit_results.fit_E_s,...
                                           handles.fit_results.fit_E_h,...
                                           handles.fit_results.fit_d_h,...
                                           handles.fit_results.fit_s_p,...
                                           handles.fit_results.rsquare_fit};
                    end
            
                case 'hertz'
                    handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                       uint64(curve_index),...
                                       handles.fit_results.EModul,...
                                       handles.fit_results.gof_rsquare};
            end
            
            
            % change listbox element
            it = handles.listbox1.String;
            it{curve_index,1} = sprintf('curve %3u  ->  processed',curve_index);
            handles.listbox1.String = it;
            scroll(app.listbox1,it{curve_index,1});
            
            
            % add 1 to the current_curve value
            handles.current_curve = curve_index +1;
            new_curve_index = curve_index + 1;
            guidata(hObject,handles);
            
            if handles.ibw == true
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            elseif strcmp(handles.loadtype,'file') && handles.ibw == false && ~strcmp(handles.loaded_file_type,'mach-txt')
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            end
            %Check if the last force curve is reached
            if new_curve_index == handles.num_files+1
            
                % disable buttons during processing
                handles.button_keep.Enable = 'off';
                handles.button_discard.Enable = 'off';
                handles.button_keep_all.Enable = 'off';
            
                % update progress values
                handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                handles.progress.num_processed = handles.progress.num_processed +1;
            
                % write progess info
                [hObject,handles] = update_progress_info(hObject,handles);
                guidata(hObject,handles);
            
                % save dialog
                answer = questdlg({'Curve processing completed!',...
                    'Do you want to save the results?'},...
                    'Processing completed!','Yes','No','Yes');
            
                if strcmp(answer,'Yes')
                    handles = save_current_results(app,handles);
                end
            
            else
            
                % highlight next list item
                handles.listbox1.Value = new_curve_index;
                scroll(app.listbox1,sprintf(sprintf('curve %3u  ->  unprocessed',new_curve_index)));
                guidata(hObject,handles);
            
                %Process new curve
                [hObject,handles] = process_options(hObject,handles);
            
                % draw new curve
                switch handles.options.model
                    case 'bihertz'
                        [handles] = plot_bihertz(handles);
                        guidata(hObject,handles);
                    case 'hertz'
                        [hObject,handles] = plot_hertz(hObject,handles);
                        guidata(hObject,handles);
                end
            
                % fit data to processed curve and display fitresult
                [hObject,handles] = curve_fit_functions(hObject,handles);
                guidata(hObject,handles);
        
                % update gui fit results
                [hObject,handles] = update_fit_results(hObject,handles);
                guidata(hObject,handles);
            
                % update progress values
                handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                handles.progress.num_processed = handles.progress.num_processed +1;
            
                % if a MACH-1 text file is laoded update the info panel
                if strcmp(handles.loaded_file_type,'mach-txt')
                    handles = info_panel_helpf(app, handles);
                end
            
                % write process info
                [hObject,handles] = update_progress_info(hObject,handles);
                guidata(hObject,handles);
            
                % activate undo & histogram button & youngs modulus image when first time pushed
                if curve_index == 1
                    handles.button_undo.Enable = 'on';
                    handles.btn_histogram.Enable = 'on';
                    handles.btn_gof.Enable = 'on';
                end
                if (curve_index == 1 || handles.progress.num_processed == 1)
                    if handles.ibw == true
                        handles.channel_names = [handles.channel_names(:,1);{'Youngs Modulus'};{'Contactpoint'}];
                    else
                        handles.channel_names = [handles.channel_names(:,1);{'Youngs Modulus'}];
                    end
                    handles.image_channels_popup.String = handles.channel_names;
                end
            
            
            end
            guidata(hObject,handles);
        end
        
        function contact_type_changed_helpf(app,event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to the selected object in btngroup_contact
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Curve is redrawn with a different Contactpoint finding model. This is
            %checked in the contact point function during the process_options
            
            sel_obj = handles.btngroup_contact.SelectedObject;
            
            
            if strcmp(sel_obj.String,'via Hertz fit')
                set(handles.contact_percentage_hertz,'Enable','on');
            else
                set(handles.contact_percentage_hertz,'Enable','off');
            end
            
            if handles.load_status ~=0
                %Process curve
                [hObject,handles] = process_options(hObject,handles);
            
                % draw new curve
                switch handles.options.model
                    case 'bihertz'
                        [handles] = plot_bihertz(handles);
                        guidata(hObject,handles);
                    case 'hertz'
                        [hObject,handles] = plot_hertz(hObject,handles);
                        guidata(hObject,handles);
                end
            
                % fit data to processed curve and display fitresult
                [hObject,handles] = curve_fit_functions(hObject,handles);
                guidata(hObject,handles);
            
                % update gui fit results
                [hObject,handles] = update_fit_results(hObject,handles);
            end
            
            guidata(hObject,handles);
        end
    end
    
    methods (Access = public)
        
        function handles = save_current_results(app,handles)

            if isfield(handles,'T_result') && ~isempty(handles.T_result)
                [file,path,~] = uiputfile({'*.tsv;*.xlsx','Save files (*.tsv,*.xlsx)';...
                        '*.*','All Files (*.*)'},'Save results',handles.last_save_path);
            
                if path ~= 0
                    % save path for later revokes of uiputfile
                    handles.last_save_path = path;
            
                
                    savepath = fullfile(path,file);
                    %filter for rows with missing values
                    not_missing = ~ismissing(handles.T_result.File_name);
                    table_clean = handles.T_result(not_missing,:);

                    % Identify parts of the selected savepath
                    [p,f,e] = fileparts(savepath);

                    % save tsv file
                    if strcmp(e,".xlsx")
                        tsvpath = fullfile(p,f+".tsv");
                    elseif strcmp(e,".tsv")
                        tsvpath = savepath;
                    else
                        tsvpath = fullfile(p,f,e+".tsv");
                    end
                    writetable(table_clean,tsvpath,"FileType","text","WriteVariableNames",true,"WriteMode","overwrite","Delimiter","\t","QuoteStrings","none");
                    app.edit_savepath.Value = tsvpath;

                    % save excel file
                    if strcmp(e,".tsv")
                        excelpath = fullfile(p,f+".xlsx");
                    elseif strcmp(e,".xlsx")
                        excelpath = savepath;
                    else
                        excelpath = fullfile(p,f,e+".xlsx");
                    end
                    writetable(table_clean,excelpath,"FileType","spreadsheet","WriteMode","replacefile","WriteVariableNames",true);

                    % save meta data
                    metapath = fullfile(p,f+".meta_data");
                    metafilestring = getMetaData(app,handles);
                    if ~isMATLABReleaseOlderThan("R2022a")
                        writelines(metafilestring,metapath,"WriteMode","overwrite");
                    else
                        Fid = fopen(metapath,'w');
                        if Fid == -1
                            return;
                        end
                        for i = 1:length(metafilestring)
                            fprintf(Fid,"%s\n",metafilestring(i));
                        end
                        fclose(Fid);
                    end

                    % Data has been saved
                    handles.save_status = 1;
                    handles.save_status_led.BackgroundColor = [0 1 0];
                end
            end
        end
        
        function FullMetaStringArray = getMetaData(app,handles)
            
            FullMetaStringArray = string();

            % 1. Get file or folder name
            if strcmp(handles.loadtype,"file")
                MapFileNameString = sprintf("Map_File_Name:\t%s",app.CurrFMapName);
            else
                PathString = string(app.edit_filepath.Value);
                PathParts = split(PathString,filesep);
                FolderName = PathParts(end);
                MapFileNameString = sprintf("Map_Folder_Name:\t%s",FolderName);
            end
            FullMetaStringArray = [FullMetaStringArray;" ";MapFileNameString];
            
            % 2. Get map information
            InfoPanelTable = app.info_table.Data;
            InfoPanelTableString = string(InfoPanelTable);
            InfoPanelTableStringNew = string();
            for i = 1:length(InfoPanelTableString)
                InfoPanelTableStringNew(i,1) = sprintf("%s:\t\t%s",InfoPanelTableString(i,1),InfoPanelTableString(i,2));
            end
            FullMetaStringArray = [FullMetaStringArray;" ";InfoPanelTableStringNew];
            
            % 3. Get used calibration values
            if strcmp(handles.options.correction,'yes')
                UsedCalibrationString = "Used calibration values:";
                SensString = sprintf("InvOLS:\t%.2f nm/V",handles.options.sensitivity);
                ConstString = sprintf("Spring Const.:\t%.4f N/m",handles.options.spring_const);
                UsedCalibrationString = [UsedCalibrationString;SensString;ConstString];
                FullMetaStringArray = [FullMetaStringArray;" ";UsedCalibrationString];
            end

            % 4. Get Indenter & Sample Parameters
            ParametersString = "Indenter and Sample Parameters:";
            GeometryString = sprintf("Indenter Geometry:\t%s",handles.options.tip_shape);
            ParametersString = [ParametersString;GeometryString];
            switch handles.options.tip_shape
                case "four_sided_pyramid"
                    switch handles.options.AngleButton
                        case "angle_face"
                            AngleTypeString = sprintf("Type of angle:\thalf opening angle to face");
                            AngleString = sprintf("Angle value:\t\t%.2f° (\x2248 %.2f° to edge)",atand(tand(handles.options.tip_angle)/sqrt(2)),handles.options.tip_angle);
                        case "angle_edge"
                            AngleTypeString = sprintf("Type of angle:\thalf opening angle to edge");
                            AngleString = sprintf("Angle value:\t\t%.2f°",handles.options.tip_angle);
                    end
                    ParametersString = [ParametersString;AngleTypeString;AngleString];
                case "three_sided_pyramid"
                    AngleTypeString = sprintf("Type of angle:\thalf opening angle to face");
                    AngleString = sprintf("Angle value:\t\t%.2f°",handles.options.tip_angle);
                    ParametersString = [ParametersString;AngleTypeString;AngleString];
                case "cone"
                    AngleTypeString = sprintf("Type of angle:\thalf opening angle of cone");
                    AngleString = sprintf("Angle value:\t\t%.2f°",handles.options.tip_angle);
                    ParametersString = [ParametersString;AngleTypeString;AngleString];
                case "flat_cylinder"
                    RadiusString = sprintf("Cylinder radius:\t%.2e m",handles.options.cylinder_radius);
                    ParametersString = [ParametersString;RadiusString];
                otherwise
                    warning("off","backtrace")
                    warning("off","verbose")
                    warning("The selected geometry is not implemented for the meta data output file!\nImplementation in the function 'getMetaData' is needed to save all indenter parameters!")
                    ParametersString = [ParametersString;"For more information, implementation in 'getMetaData' of this geometry is needed!"];
            end
            PoissonString = sprintf("Poisson's ratio:\t%.3f",handles.options.poisson);
            ParametersString = [ParametersString;PoissonString];
            FullMetaStringArray = [FullMetaStringArray;" ";ParametersString];

            % 5. Get processing info
            ProcessingString = "Used processing parameters:";
            BaselineString = sprintf("Baseline correction mode:\t%s",app.baseline_btngroup.SelectedObject.Text);
            switch app.btngroup_contact.SelectedObject.Text
                case "via Hertz fit"
                    ContactPointString = sprintf("Contact point determination:\t%s using last %g%% of curve",app.btngroup_contact.SelectedObject.Text,str2double(app.contact_percentage_hertz.Value));
                otherwise
                    ContactPointString = sprintf("Contact point determination:\t%s",app.btngroup_contact.SelectedObject.Text);
            end
            ProcessingString = [ProcessingString;BaselineString;ContactPointString];
            FullMetaStringArray = [FullMetaStringArray;" ";ProcessingString];

            % Get Fit Parameters
            StartString = sprintf("Fit start:\t%s %s",app.hertz_fit_start.Value,app.text73.Text);
            EndString = sprintf("Fit depth:\t%s %s",app.hertz_fit_depth.Value,app.text46.Text);
            if strcmp(handles.options.tip_shape,"flat_cylinder")
                ContactPointString = sprintf("Contact point:\t%s","Fitted");
            else
                ContactPointString = sprintf("Contact point:\t%s",app.ContactPointDropDown.Value);
            end

            FullMetaStringArray = [FullMetaStringArray;" ";"Fit options:";StartString;EndString;ContactPointString];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function bihertz_gui_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Turn off axis and toolbar of map_axes
            axis(app.map_axes,"off")
            app.map_axes.Toolbar.Visible = "off";

            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);


            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to bihertz_gui (see VARARGIN)
            warning off
            % Choose default command line output for bihertz_gui
            handles.output = hObject;
                        
            % Update handles structure
            options = struct('list_object',2,'model','hertz','bihertz_processing','','bihertz_baseline','','bihertz_results','','bihertz_save','');
            options.bihertz_processing = 'automatic';
            options.bihertz_baseline = 'offset_and_tilt';
            options.bihertz_results = 'no';
            options.bihertz_save = 'dont_save';
            handles.options = options;            handles.curves = struct();
            handles.figures = struct('main_fig',[]);
            handles.load_status = 0;
            handles.loaded_file_type = 'none';
            handles.save_status = 0;
            handles.interpolation_type = 'bicubic';
            handles.ibw = false;
            handles.options.bihertz_variant = 1;
            handles.last_load_path = [];
            handles.last_save_path = [];
            
            guidata(hObject, handles);
            
            
            % set gui appearance depending on chosen model
%             handles.fit_model_popup.Enable = 'on';            ---->  Currently disabled!!!
            switch handles.options.model
                case 'bihertz'
                    handles.fit_model_popup.Value = 2;
                case 'hertz'
                    handles.fit_model_popup.Value = 1;
                    app.BiHertzFitPanel.Visible = "off";
%                     handles.uipanel5.Visible = 'off';
%                     handles.uipanel10.Visible = 'off';
                    handles.hertz_fit_panel.Visible = 'on';
                    axes(handles.map_axes);
                    axis off
            
            guidata(hObject,handles);
            end
            
%             handles = grid_creation_function(handles);

            % Load notification sounds
            try
                sounds = load("NotificationSounds.mat");
                app.NotificationSounds = sounds.NotificationSounds;
            catch ME % if you can
                app.SoundsMenu.Enable = "off";
            end
            
            warning on
            guidata(hObject,handles);
        end

        % Selection changed function: baseline_btngroup
        function baseline_btngroup_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to the selected object in baseline_btngroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SelectedMode = handles.baseline_btngroup.SelectedObject.String;
            if strcmp(SelectedMode, 'none')
                handles.options.bihertz_baseline = 'none';
            elseif strcmp(SelectedMode, 'Offset')
                handles.options.bihertz_baseline = 'offset';
            else
                handles.options.bihertz_baseline = 'offset_and_tilt';
            end
            
            if isfield(handles, 'curves')
            
                %Update the graph with the new fit options
                [hObject,handles] = process_options(hObject,handles);
                %Plot the data as Bihertz or as Hertz
                switch handles.options.model
                    case 'bihertz'
                        [handles] = plot_bihertz(handles);
                        guidata(hObject,handles);
                    case 'hertz'
                        [hObject,handles] = plot_hertz(hObject,handles);
                        guidata(hObject,handles);
                end
            
                if strcmp(SelectedMode, 'none')
                    curve_str = sprintf('curve%u',handles.current_curve);
                    handles.proc_curves.(curve_str) = handles.curves.(curve_str);
                end
            
                % fit data to processed curve and display fitresult
                [hObject,handles] = curve_fit_functions(hObject,handles);
                guidata(hObject,handles);
            
                % update gui fit results
                [hObject,handles] = update_fit_results(hObject,handles);
            
            else
                %Nothing
            end
            guidata(hObject,handles)
        end

        % Menu selected function: bicubic
        function bicubic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bicubic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [hObject,handles] = checker_helpf(app, hObject,handles);
            handles.interpolation_type = 'bicubic';
            channel_num = handles.image_channels_popup.Value;
            channel_string = handles.channel_names{channel_num};
            channel_interpolation = handles.interpolation_type;
            if handles.ibw == false
                if ~strcmp(channel_interpolation,'none')
                    interpolation_string = sprintf('_%s_interpolation',channel_interpolation);
                else
                    interpolation_string = '';
                end
                if strcmp(channel_string,'height_measured')
                    channel_image = handles.map_images.height_measured.(sprintf('absolute_%s_data%s',...
                    "height",interpolation_string));
                elseif strcmp(channel_string, 'Youngs Modulus')
                    channel_image = handles.EModul_matrix_cubic_interpolation;
                else
                    channel_image = handles.map_images.(channel_string).(sprintf('%s_data%s',...
                        channel_string,interpolation_string));
                end
                axes(handles.map_axes);
                imshow(flip(channel_image,1),[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel]);
                handles.map_axes.YDir = 'normal';
            else
                if strcmp(channel_string,'height')
                    axes(handles.map_axes);
                    imshow(handles.MFP_height_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'slope')
                    axes(handles.map_axes);
                    imshow(handles.MFP_mslope_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'Contactpoint')
                    axes(handles.map_axes);
                    imshow(handles.MFP_cpoint_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                else strcmp(channel_string, 'Youngs Modulus')
                    axes(handles.map_axes);
                    imshow(handles.MFP_Ymodulus_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                end
            end
            handles = update_curve_marker(handles);
            hline = findall(gca,'Type','image');
            set(hline(1),'uicontextmenu',handles.map_axes_context);
            set_afm_gold;
            handles = colorbar_helpf(app, handles.map_axes,handles);
            app.map_axes.Toolbar.Visible = "off";
            guidata(hObject,handles);
        end

        % Button pushed function: btn_gof
        function btn_gof_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btn_gof (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                close(handles.GoFFig)
                handles = rmfield(handles, 'GoFFig');
            catch
            end
            if isempty(findobj('Name', 'Histogram: Youngs Modulus'))
                try
                    handles = rmfield(handles, 'EModulFig');
                catch
                end
            end
            rsquare = handles.T_result.rsquare_fit;
            if any(rsquare)
                handles.GoFFig = figure('Name','Histogram: Goodness of Fit','Units', 'normalized', 'NumberTitle','off', 'Color', 'white');
                rsquare(rsquare <= 0)=[];
                rsquare(isnan(rsquare)) = [];
                [h] = histogram_fits(rsquare, 'none', 50);
                h.Histogram_handle.FaceColor = [1 0.72 0.73];
                h.Histogram_handle.EdgeColor = [0.77 0.32 0.34];
                xlim([0 1])
                xticks([0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1])
                xticklabels({0, [], 0.1, [], 0.2, [], 0.3, [], 0.4, [], 0.5, [], 0.6, [], 0.7, [], 0.8, [], 0.9, [], 1})
                title('Goodness of Fit')
                xlabel('Goodness of Fit[rsquare]')
                ylabel('Frequency')
            
                %Present both histograms in a good way if both are opened
                if isfield(handles, 'EModulFig') == 1
                    handles.EModulFig.Position = [0.1 0.3 0.4 0.5];
                    handles.GoFFig.Position = [0.5 0.3 0.4 0.5];
                    figure(handles.EModulFig);
                    figure(handles.GoFFig);
                else
                    handles.GoFFig.Position = [0.1 0.3 0.4 0.5];
                    figure(handles.GoFFig);
                end
            else
                warning('No results are available at the moment!')
            end
            guidata(hObject,handles);
        end

        % Button pushed function: btn_histogram
        function btn_histogram_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to btn_histogram (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                close(handles.EModulFig)
                handles = rmfield(handles, 'EModulFig');
            catch
            end
            if isempty(findobj('Name', 'Histogram: Goodness of Fit'))
                try
                    handles = rmfield(handles, 'GoFFig');
                catch
                end
            end
            EModul = handles.T_result.EModul;
            if any(EModul)
                handles.EModulFig = figure('Name','Histogram: Youngs Modulus','Units', 'normalized', 'NumberTitle','off', 'Color', 'white');
                EModul(EModul == 0)=[];
                EModul(isnan(EModul)) = [];
                EModul = EModul/1000;
                [~,~] = histogram_fits(EModul, 'gauss', 100);
            %     h.Histogram_handle.FaceColor = [1 0.72 0.73];     -> The face color is now set directly in the histogram_fits function when the histogram is created.
            %     h.Histogram_handle.EdgeColor = [0.77 0.32 0.34];  -> The Edge color is now set directly in the histogram_fits function when the histogram is created.
                title('Youngs Modulus');
                xlabel('Youngs Modulus [kPa]');
                ylabel('Frequency');
            
                %Present both histograms in a good way if both are opened
                if isfield(handles, 'GoFFig') == 1
                    handles.EModulFig.Position = [0.1 0.3 0.4 0.5];
                    handles.GoFFig.Position = [0.5 0.3 0.4 0.5];
                    figure(handles.EModulFig);
                    figure(handles.GoFFig);
                else
                    handles.EModulFig.Position = [0.1 0.3 0.4 0.5];
                    figure(handles.EModulFig);
                end
            else
                warning('No results are available at the moment!')
            end
            
            guidata(hObject,handles);
        end

        % Selection changed function: btngroup_contact
        function btngroup_contact_SelectionChangedFcn(app, event)
            % Execute helper function
            contact_type_changed_helpf(app,event)
        end

        % Button pushed function: button_discard
        function button_discard_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_discard (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.fit_model_popup.Enable = 'off';

            % Data has been modified -> not saved
            handles.save_status = 0;
            handles.save_status_led.BackgroundColor = [1 0 0];
            
            % Don't write fit results
            curve_index = handles.current_curve;
            discarded = handles.progress.num_discarded;
            handles.T_result(curve_index-discarded,:) = [];
            
            % change listbox element
            it = handles.listbox1.String;
            it{curve_index,1} = sprintf('curve %3u  ->  discarded',curve_index);
            handles.listbox1.String = it;
            
            
            % add 1 to the current_curve value
            handles.current_curve = curve_index +1;
            new_curve_index = curve_index + 1;
            guidata(hObject,handles);
            
            if handles.ibw == true
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            elseif strcmp(handles.loadtype,'file') && handles.ibw == false && ~strcmp(handles.loaded_file_type,'mach-txt')
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            end
            
            if new_curve_index == handles.num_files+1
            
                % disable buttons during processing
                handles.button_keep.Enable = 'off';
                handles.button_discard.Enable = 'off';
                handles.button_keep_all.Enable = 'off';
            
                % update progress values
                handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                handles.progress.num_discarded = handles.progress.num_discarded +1;
            
                % write process info
                [hObject,handles] = update_progress_info(hObject,handles);
                guidata(hObject,handles);
            
                % save dialog
%                 answer = questdlg({'Curve processing completed!',...
%                     'Do you want to save the results?'},...
%                     'Processing completed!','Yes','No','Yes');
                answer = uiconfirm(app.figure1,...
                                   ["Curve processing completed!";"Do you want to save the results?"],...
                                   "Processing Completed",...
                                   "Options",["Yes","No"],...
                                   "DefaultOption",1,...
                                   "CancelOption",2);
            
                if strcmp(answer,'Yes')
                    handles = save_current_results(app,handles);
                end
            
            else
            
                % highlight next list item
                handles.listbox1.Value = new_curve_index;
                scroll(app.listbox1,sprintf(sprintf('curve %3u  ->  unprocessed',new_curve_index)));
                guidata(hObject,handles);
            
                %Process new curve
                [hObject,handles] = process_options(hObject,handles);
            
                % draw new curve
                switch handles.options.model
                    case 'bihertz'
                        [handles] = plot_bihertz(handles);
                        guidata(hObject,handles);
                    case 'hertz'
                        [hObject,handles] = plot_hertz(hObject,handles);
                        guidata(hObject,handles);
                end
            
                % fit data to processed curve and display fitresult
                [hObject,handles] = curve_fit_functions(hObject,handles);
                guidata(hObject,handles);
            
                % update gui fit results
                [hObject,handles] = update_fit_results(hObject,handles);
                guidata(hObject,handles);
            
                % update progress values
                handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                handles.progress.num_discarded = handles.progress.num_discarded +1;
                guidata(hObject,handles);
            
                % write process info
                [hObject,handles] = update_progress_info(hObject,handles);
                guidata(hObject,handles);
            
                % if a MACH-1 text file is laoded update the info panel
                if strcmp(handles.loaded_file_type,'mach-txt')
                    handles = info_panel_helpf(app, handles);
                end
            
                % activate undo button when first time pushed
                if curve_index == 1
                    handles.button_undo.Enable = 'on';
                    handles.btn_histogram.Enable = 'on';
                    handles.btn_gof.Enable = 'on';
                end
            
            end
            guidata(hObject,handles);
        end

        % Button pushed function: button_discard_highlighted
        function button_discard_highlighted_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_discard_highlighted (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            selection_num = handles.listbox1.Value;
            selection_diff = selection_num - handles.current_curve;
            
            % Data has been modified -> not saved
            handles.save_status = 0;
            handles.save_status_led.BackgroundColor = [1 0 0];
            
            
            for i=1:selection_diff
                % Don't write fit results
                curve_index = handles.current_curve;
                discarded = handles.progress.num_discarded;
                handles.T_result(curve_index-discarded,:) = [];
            
                % change listbox element
                it = handles.listbox1.String;
                it{curve_index,1} = sprintf('curve %3u  ->  discarded',curve_index);
                handles.listbox1.String = it;
            
                if handles.ibw == true
                    % update current curve marker on map axes
                    handles = update_curve_marker(handles);
                elseif strcmp(handles.loadtype,'file') && handles.ibw == false && ~strcmp(handles.loaded_file_type,'mach-txt')
                    % update current curve marker on map axes
                    handles = update_curve_marker(handles);
                end
            
                % add 1 to the current_curve value
                handles.current_curve = curve_index +1;
                new_curve_index = curve_index + 1;
                guidata(hObject,handles);
            
                if new_curve_index == handles.num_files+1
            
                    % disable buttons during processing
                    handles.button_keep.Enable = 'off';
                    handles.button_discard.Enable = 'off';
                    handles.button_keep_all.Enable = 'off';
            
                    % update progress values
                    handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                    handles.progress.num_discarded = handles.progress.num_discarded +1;
            
                    % write process info
                    [hObject,handles] = update_progress_info(hObject,handles);
                    guidata(hObject,handles);
            
                    % save dialog
                    answer = questdlg({'Curve processing completed!',...
                        'Do you want to save the results?'},...
                        'Processing completed!','Yes','No','Yes');
            
                    if strcmp(answer,'Yes')
                        handles = save_current_results(app,handles);
                    end
            
                else
            
                    % highlight next list item
                    handles.listbox1.Value = new_curve_index;
                    scroll(app.listbox1,sprintf(sprintf('curve %3u  ->  unprocessed',new_curve_index)));
                    guidata(hObject,handles);
            
                    % update progress values
                    handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                    handles.progress.num_discarded = handles.progress.num_discarded +1;
                    guidata(hObject,handles);
            
                    % write process info
                    [hObject,handles] = update_progress_info(hObject,handles);
                    guidata(hObject,handles);
            
                    % activate undo button when first time pushed
                    if curve_index == 1
                        handles.button_undo.Enable = 'on';
                        handles.btn_histogram.Enable = 'on';
                        handles.btn_gof.Enable = 'on';
                    end
            
                end


            end
            
            %Process new curve
            [hObject,handles] = process_options(hObject,handles);
            
            % draw new curve
            switch handles.options.model
                case 'bihertz'
                    [handles] = plot_bihertz(handles);
                    guidata(hObject,handles);
                case 'hertz'
                    [hObject,handles] = plot_hertz(hObject,handles);
                    guidata(hObject,handles);
            end
            
            if handles.ibw == true
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            elseif strcmp(handles.loadtype,'file') && handles.ibw == false && ~strcmp(handles.loaded_file_type,'mach-txt')
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            end
            
            % fit data to processed curve and display fitresult
            [hObject,handles] = curve_fit_functions(hObject,handles);
            guidata(hObject,handles);
            
            % update gui fit results
            [hObject,handles] = update_fit_results(hObject,handles);
            guidata(hObject,handles);
            
            % if a MACH-1 text file is laoded update the info panel
            if strcmp(handles.loaded_file_type,'mach-txt')
                handles = info_panel_helpf(app, handles);
            end
            
            
            % switch all buttons to off after processing
            handles.button_keep_highlighted.Enable = 'off';
            handles.button_undo_highlighted.Enable = 'off';
            handles.button_discard_highlighted.Enable = 'off';
            guidata(hObject,handles)
        end

        % Button pushed function: button_file
        function button_file_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_file (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [file,path,ext] = uigetfile({'*.jpk-force-map;*.jpk-qi-data','JPK-Data-Maps';...
                '*.txt','Mach-1 text-file'},'Select a File',handles.last_load_path);
            
            if ~isequal(file,0)
                % save last load path for next invoke of uigetfile
                if ext == 1
                    % [~,~,FExt] = fileparts(file);
                    handles.last_load_path = fullfile(path,"*.jpk-force-map;*.jpk-qi-data");
                    app.FileType = 'jpk-force-map';
                elseif ext == 2
                    handles.last_load_path = fullfile(path,"*.txt");
                    app.FileType = 'txt';
                end
                fullpathtofile = fullfile(path,file);
                handles.edit_filepath.HorizontalAlignment = 'right';
                handles.edit_filepath.Enable = 'on';
                handles.edit_filepath.String = fullpathtofile;
                handles.edit_filepath.Tooltip = fullpathtofile;
                handles.filefilter = ext;
                handles.loadtype = 'file';
                guidata(hObject,handles)
            end
        end

        % Button pushed function: button_folder
        function button_folder_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [path] = uigetdir(handles.last_load_path,'Select folder with curve-files');
            
            
            if ~isequal(path,0)
                % remember last path for the next invoke of uigetdir or uigetfile
                parts = strsplit(path,filesep);
                path_short = fullfile(parts{1:end-1});
                handles.last_load_path = path_short;
                app.FileType = 'folder';
                handles.edit_filepath.String = path;
                handles.edit_filepath.Enable = 'on';
                handles.edit_filepath.HorizontalAlignment = 'right';
                handles.edit_filepath.Tooltip = path;
                handles.loadtype = 'folder';
                guidata(hObject,handles)
            end
        end

        % Button pushed function: button_keep
        function button_keep_Callback(app, event)
            
            % Execute the keep_result_helpf
            keep_result_helpf(app,event)
                        
        end

        % Button pushed function: button_keep_all
        function button_keep_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to button_keep_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Data has been modified -> not saved
            handles.save_status = 0;
            handles.save_status_led.BackgroundColor = [1 0 0];
            
            % disable fit model popup
            handles.fit_model_popup.Enable = 'off';
            
            % disable buttons during processing
            handles.button_keep.Enable = 'off';
            handles.button_discard.Enable = 'off';
            handles.button_keep_all.Enable = 'off';
            handles.button_undo.Enable = 'off';
            
            % disable histogram buttons
            handles.btn_histogram.Enable = 'off';
            handles.btn_gof.Enable = 'off';
            
            % Enable Youngs Modulus/Contactpoint image
            if (handles.current_curve == 1 || handles.progress.num_processed == 0)
                channels = handles.image_channels_popup.String;
                if handles.ibw == true
                    handles.channel_names = [channels;{'Youngs Modulus'};{'Contactpoint'}];
                else
                    handles.channel_names = [channels;{'Youngs Modulus'}];
                end
                handles.image_channels_popup.String = handles.channel_names;
            end
            
            curve_index = handles.current_curve;
            
%             handles.save_status = 0; %Always keep it on 0 if there is still unsaved data
            
            % loop iterations
            loop_it = (handles.num_files - curve_index);
            
            % Do the keep and apply function with or without graphical presentation
%             answer_display = questdlg('Do you want every graph/fit to be displayed?',...
%                 'Graphical Presentation','Yes','No','Yes');
            answer_display = uiconfirm(app.figure1,"Do you want every graph/fit to be displayed?","Graphical Presentation","Icon","question","Options",["Yes","No"],"DefaultOption",1,"CancelOption",2);

            % initialise waitbar with cancel button
%             wb = waitbar(0,sprintf('curve %3u of %3u',curve_index,handles.num_files),...
%                 'CreateCancelBtn','setappdata(gcbf,''canceling'',1)','WindowStyle','modal');
%             setappdata(wb,'canceling',0);
            wb = uiprogressdlg(app.figure1,"Cancelable","on","Icon","none",...
                "Message",sprintf("Curve %3u of %3u",curve_index,handles.num_files),...
                "Title","Processing Progress",...
                "Value",curve_index/handles.num_files);
            
            % Devider for the waitbar to speed up the code performance of
            % keep_all_callback
            
            try
                for a = 1:loop_it
                
                    % check for clicked cancel button
    %                 if getappdata(wb,'canceling')
    %                     break
    %                 end
                    if wb.CancelRequested
                        break
                    end            
                
                    % save fit results
                    curve_index = handles.current_curve;
                    discarded = handles.progress.num_discarded;
                    switch handles.options.model
                    case 'bihertz'
                        if handles.options.bihertz_variant == 1
                            handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                               uint64(curve_index),...
                                               handles.fit_results.initial_E_s,...
                                               handles.fit_results.initial_E_h,...
                                               handles.fit_results.initial_d_h,...
                                               handles.fit_results.fit_E_s,...
                                               handles.fit_results.fit_E_h,...
                                               handles.fit_results.fit_d_h,...
                                               handles.fit_results.rsquare_fit};
                        elseif handles.options.bihertz_variant == 2
                            handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                               uint64(curve_index),...
                                               handles.fit_results.initial_E_s,...
                                               handles.fit_results.initial_E_h,...
                                               handles.fit_results.initial_d_h,...
                                               handles.fit_results.initial_s_p,...
                                               handles.fit_results.fit_E_s,...
                                               handles.fit_results.fit_E_h,...
                                               handles.fit_results.fit_d_h,...
                                               handles.fit_results.fit_s_p,...
                                               handles.fit_results.rsquare_fit};
                        end
                
                    case 'hertz'
                        handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                           uint64(curve_index),...
                                           handles.fit_results.EModul,...
                                           handles.fit_results.gof_rsquare};
                    end
                
                    % change listbox element
                    it = handles.listbox1.String;
                    it{curve_index,1} = sprintf('curve %3u  ->  processed',curve_index);
                    handles.listbox1.String = it;
                
                
                    % add 1 to the current_curve value
                    handles.current_curve = curve_index +1;
                    new_curve_index = curve_index + 1;
                    guidata(hObject,handles);
                
                    if strcmp(handles.loaded_file_type,'ibw') || strcmp(handles.loaded_file_type,'jpk-force-map')
                        % update current curve marker on map axes
                        handles = update_curve_marker(handles);
                    end
                
                    % highlight next list item
                    handles.listbox1.Value = new_curve_index;
                    scroll(app.listbox1,sprintf(sprintf('curve %3u  ->  unprocessed',new_curve_index)));
                    guidata(hObject,handles);
                
                    % update waitbar and message
    %                 waitbar(a/loop_it,wb,sprintf('curve %3u of %3u',new_curve_index,handles.num_files));
                    wb.Message = sprintf("Curve %3u of %3u",new_curve_index,handles.num_files);
                    wb.Value = new_curve_index/handles.num_files;
                
                
                    % Process new curve
                    [hObject,handles] = process_options(hObject,handles);
                
                    % Only show the curve when the user want to see it
                    if isempty(answer_display) || strcmp(answer_display, 'Yes')
                        % draw new curve
                        switch handles.options.model
                            case 'bihertz'
                                [handles] = plot_bihertz(handles);
                                guidata(hObject,handles);
                            case 'hertz'
                                [hObject,handles] = plot_hertz(hObject,handles);
                                guidata(hObject,handles);
                        end
                    end
                
                    % fit data to processed curve and display fitresult
                    [hObject,handles] = curve_fit_functions(hObject,handles, answer_display);
                    guidata(hObject,handles);
                
                
                    % update gui fit results
                    [hObject,handles] = update_fit_results(hObject,handles);
                    guidata(hObject,handles);
                
                    % update progress values
                    handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                    handles.progress.num_processed = handles.progress.num_processed +1;
                
                    % write process info
                    [hObject,handles] = update_progress_info(hObject,handles);
                    guidata(hObject,handles);
                
                    % update the changed GUI elements
                    % drawnow limitrate nocallbacks
                    % drawnow
                
                end
            catch ME % if you can
                
                % reable buttons after processing
                handles.button_keep.Enable = 'on';
                handles.button_discard.Enable = 'on';
                handles.button_keep_all.Enable = 'on';
                handles.button_undo.Enable = 'on';
                handles.btn_histogram.Enable = 'on';
                handles.btn_gof.Enable = 'on';

                throw(ME);
            end

            % reset user answer for displaying curves
            handles.answer_display = [];
            
            % reable buttons after processing
            handles.button_keep.Enable = 'on';
            handles.button_discard.Enable = 'on';
            handles.button_keep_all.Enable = 'on';
            handles.button_undo.Enable = 'on';
            handles.btn_histogram.Enable = 'on';
            handles.btn_gof.Enable = 'on';
            
%             if ~getappdata(wb,'canceling')
            if ~wb.CancelRequested
            
                % disable buttons after processing of last curve
                handles.button_keep.Enable = 'off';
                handles.button_discard.Enable = 'off';
                handles.button_keep_all.Enable = 'off';
            
                % save results of last curve
                curve_index = handles.current_curve;
                discarded = handles.progress.num_discarded;
                switch handles.options.model
                case 'bihertz'
                    if handles.options.bihertz_variant == 1
                        handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                           uint64(curve_index),...
                                           handles.fit_results.initial_E_s,...
                                           handles.fit_results.initial_E_h,...
                                           handles.fit_results.initial_d_h,...
                                           handles.fit_results.fit_E_s,...
                                           handles.fit_results.fit_E_h,...
                                           handles.fit_results.fit_d_h,...
                                           handles.fit_results.rsquare_fit};
                    elseif handles.options.bihertz_variant == 2
                        handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                           uint64(curve_index),...
                                           handles.fit_results.initial_E_s,...
                                           handles.fit_results.initial_E_h,...
                                           handles.fit_results.initial_d_h,...
                                           handles.fit_results.initial_s_p,...
                                           handles.fit_results.fit_E_s,...
                                           handles.fit_results.fit_E_h,...
                                           handles.fit_results.fit_d_h,...
                                           handles.fit_results.fit_s_p,...
                                           handles.fit_results.rsquare_fit};
                    end
            
                case 'hertz'
                    handles.T_result(curve_index-discarded,:) = {handles.file_names(curve_index),...
                                       uint64(curve_index),...
                                       handles.fit_results.EModul,...
                                       handles.fit_results.gof_rsquare};
                end
            
                % change last listbox element
                it = handles.listbox1.String;
                it{curve_index,1} = sprintf('curve %3u  ->  processed',curve_index);
                handles.listbox1.String = it;
            
                % add one to current curve
                handles.current_curve = curve_index + 1;
                guidata(hObject,handles);
            
                % update progress values
                handles.progress.num_unprocessed = handles.progress.num_unprocessed -1;
                handles.progress.num_processed = handles.progress.num_processed +1;
            
                % if a MACH-1 text file is laoded update the info panel
                if strcmp(handles.loaded_file_type,'mach-txt') && handles.current_curve <= handles.num_files
                    handles = info_panel_helpf(app, handles);
                end
            
                % write process info
                [hObject,handles] = update_progress_info(hObject,handles);
                guidata(hObject,handles);
            
                % set current curve back to index of last curve
                handles.current_curve = handles.num_files;
                
                % If sounds are enabled play the 'Finish' sound
                if strcmp(app.SoundsMenu.findobj("Checked","on").Text,"On")
                    sound(app.NotificationSounds.FinishSound.y,app.NotificationSounds.FinishSound.Fs);
                end

                % save dialog
                answer = uiconfirm(app.figure1,["Curve processing completed!";"Do you want to save the results?"],"Processing completed!","Options",["Yes","No"],"DefaultOption","Yes","CancelOption","No");            
                if strcmp(answer,"Yes")
                    handles = save_current_results(app,handles);
                end
            end
            delete(wb)
            guidata(hObject,handles)
        end

        % Button pushed function: button_keep_highlighted
        function button_keep_highlighted_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to button_keep_highlighted (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            curve_index_old = handles.current_curve;
            selection_num = handles.listbox1.Value;
            selection_diff = selection_num - handles.current_curve;
            for i=1:selection_diff
%                 button_keep_Callback(handles.button_keep,[],handles)
                keep_result_helpf(app,event)
                % drawnow limitrate nocallbacks;
                handles = guidata(handles.button_keep);
            end
            % switch all buttons to off after processing
            handles.button_keep_highlighted.Enable = 'off';
            handles.button_undo_highlighted.Enable = 'off';
            handles.button_discard_highlighted.Enable = 'off';
            
            % when last curve is reached
            if selection_num == handles.num_files
                handles.button_keep.Enable = 'off';
                handles.button_discard.Enable = 'off';
                handles.button_keep_all.Enable = 'off';
                handles.button_undo.Enable = 'on';
                handles.fit_model_popup.Enable = 'off';
            end
            guidata(hObject,handles)
        end

        % Button pushed function: button_load_data
        function button_load_data_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to button_load_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            answer = 'NaN';
            if handles.save_status == 0 && handles.load_status == 1
                answer = questdlg({'There are unsaved results!',...
                    'If you load new data all unsaved results will be lost!',...
                    'Do you really want to load new data?'},...
                    'Loaded data warning','Yes','No','No');
            end
            
            if strcmp(answer,'No')
                return;
            elseif strcmp(answer,'Yes')  || strcmp(answer, 'NaN')
            
                % initialise fit results struct or reset previous fit results
                handles.fit_results = struct();
                path = get(handles.edit_filepath,'String');
                if strcmp(path,'    filepath')
                    errordlg('No file or folder selected','No selection found');
                else
                    % Call app for calibration values
%                     handles.options = Calibration(handles.options);
                    cal_app = Calibration_App(handles.options);
                    waitfor(cal_app,"app_CloseRequested",true);
                    if isempty(cal_app.app_output)
                        delete(cal_app);
                        return
                    end
                    handles.options = cal_app.app_output;
                    delete(cal_app);
                    guidata(hObject,handles);
                    
                    % Call app for indenter geometry
%                     handles.options = canti_sample_gui(handles.options);
                    canti_sample_options = struct('FileType',app.FileType,...
                                                  'PreviousGeometry',app.PrevGeom,...
                                                  'PreviousAngleOption',app.PrevAngleOpt,...
                                                  'PreviousAngle',app.PrevAngle,...
                                                  'PreviousUnit',app.PrevUnit,...
                                                  'PreviousRadius',app.PrevRad,...
                                                  'PreviousPoisson',app.PrevPoisson);
                                        
                    canti_app = canti_sample_gui_App(handles.options,canti_sample_options);
                    waitfor(canti_app,"app_CloseRequested",true);
                    if isempty(canti_app.app_output)
                        delete(canti_app);
                        return;
                    end
                    handles.options = canti_app.app_output;
                    outp = canti_app.app_output;
                    app.PrevGeom = outp.PopupValue;
                    app.PrevAngleOpt = outp.AngleButton;
                    if isfield(outp,"AngleRaw")
                        app.PrevAngle = outp.AngleRaw;
                    end
                    app.PrevUnit = outp.RadiusUnit;
                    app.PrevRad = outp.RadiusRaw;
                    app.PrevPoisson = outp.PoissRaw;
                    delete(canti_app);
                    guidata(hObject,handles);
                    
                    % get indenter options and poisson ration in dependency of the choosen
                    % indenter shape
                    handles = get_indenter_parameter(app, handles);
            
                    % reset invalid_data_type controle parameter
                    handles.invalid_data_type = false;


                    % provide processing infos
                    str = sprintf('InvOLS: %.2f nm/V',handles.options.sensitivity);
                    set(handles.text_sensitivity,'String',str);
                    str = sprintf('Spring constant: %.4f N/m',handles.options.spring_const);
                    set(handles.text_spring_const,'String',str);
            
                    % set the prefered load path also as prefered save path if save
                    % path is empty
                    if isempty(handles.last_save_path)
                        load_path = handles.last_load_path;
                        [set_path,~,~] = fileparts(load_path);
                        handles.last_save_path = set_path;
                    end
            
                    switch handles.loadtype
                        case 'file'
                            if handles.filefilter == 1
                               handles.loaded_file_type = 'jpk-force-map';
                               % if a jpk-force-map is loaded reset the fit start and
                               % fit depth values
                               if handles.load_status == 0
                                   handles.hertz_fit_depth.String = '1';
                                   handles.hertz_fit_start.String = '0';
                               end
                               [~,~,Fext] = fileparts(handles.edit_filepath.String);
                               switch Fext
                                   case ".jpk-force-map"
                                        [x_data,y_data, ~, ~, Forcecurve_label,~,~,name_of_file,map_images,~,handles.map_info_array] = ReadJPKMaps_App(app,handles.edit_filepath.String);
                                   case ".jpk-qi-data"
                                       ProgBar = uiprogressdlg(app.figure1,"Message","Loading QI Map Data ...","Title","Loading Procedure","Indeterminate","on");
                                       [x_data,y_data, ~, ~, Forcecurve_label,~,~,name_of_file,map_images,~,handles.map_info_array] = ReadQIMaps(handles.edit_filepath.String);
                                       close(ProgBar);
                               end
                               % create filename array
                               Forcecurve_label = Forcecurve_label';
                               curves_in_map = strcat(name_of_file,'.',Forcecurve_label);
                               handles.file_names = curves_in_map;
                               Forcecurve_label = Forcecurve_label';
                               % save map image in handles and display in axes
                               handles.map_images = map_images;
                               guidata(hObject,handles);
                               axes(handles.map_axes);
                               % get image channels
                               handles.channel_names = fieldnames(handles.map_images);
                               if strcmp(handles.channel_names{1},'thumbnail')
                                   handles.channel_names(1) = [];
                               end
                               handles.image_channels_popup.String = handles.channel_names;
            
                               % create processing grid for visual curve feedback
                               map_tags = fieldnames(handles.map_images);
                               if strcmp(map_tags{1},'thumbnail')
                                   map_tags(1) = [];
                               end
                               handles.map_info = struct('x_pixel',0,'y_pixel',0,'processing_grid',[0,0]);
                               handles.map_info.x_pixel = handles.map_images.(map_tags{1}).XPixel;
                               handles.map_info.y_pixel = handles.map_images.(map_tags{1}).YPixel;
                               handles.map_info.processing_grid = zeros(handles.map_info.x_pixel*handles.map_info.y_pixel,2);
                               grid_index = 0;
                               for i=1:(handles.map_info.y_pixel)
                                   for j = 1:(handles.map_info.x_pixel)
                                       grid_index = grid_index + 1;
                                       handles.map_info.processing_grid(grid_index,1) = j;
                                       handles.map_info.processing_grid(grid_index,2) = i;
                                   end
                                   % flip every second line of the processing_grid for .jpk-force-map file
                                   % because the jpk NanoWizard records a force map on
                                   % a snakelia line.
                                   if ~mod(i,2) && strcmp(Fext,".jpk-force-map")
                                       run_ind = i*handles.map_info.x_pixel;
                                       last_vec = handles.map_info.processing_grid((run_ind)-handles.map_info.x_pixel+1:run_ind,1);
                                       flip_vec = flipud(last_vec);
                                       handles.map_info.processing_grid((run_ind)-handles.map_info.x_pixel+1:run_ind,1) = flip_vec;
                                   end
                               end
            
                               % write image channels in popup
                               handles.image_channels_popup.Enable = 'on';
                               reset(app.map_axes);
                               % Turn off axis and toolbar of map_axes
                               axis(app.map_axes,"off")
                               app.map_axes.Toolbar.Visible = "off";
                               for i=1:length(handles.channel_names)
                                   if strcmp(handles.channel_names{i},'height_measured')
                                       handles.image_channels_popup.Value = i;
                                       channel_image = handles.map_images.height_measured.absolute_height_data_bicubic_interpolation;
                                       imshow(flip(channel_image,1),[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel],"Parent",app.map_axes);
                                       handles.map_axes.YDir = 'normal';
                                       app.map_axes.XLimMode = "manual";
                                       app.map_axes.YLimMode = "manual";
                                       app.map_axes.XLim = [0 handles.map_info.x_pixel+1];
                                       app.map_axes.YLim = [0 handles.map_info.y_pixel+1];
                                       hline = findall(gca,'Type','image');
                                       set(hline(1),'uicontextmenu',handles.map_axes_context);
                                       set_afm_gold;
                                       handles = colorbar_helpf(app, handles.map_axes,handles);
                                       app.map_axes.Toolbar.Visible = "off";
                                       break
                                   elseif i==length(handles.channel_names) && ~strcmp(handles.channel_names{i},'height_measured')
                                       handles.image_channels_popup.Value = 1;
                                       channel_image = handles.map_images.(handles.channel_names{1}).(sprintf('%s_data_bicubic_interpolation',...
                                            handles.channel_names{1}));
                                       imshow(flip(channel_image,1),[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel],"Parent",app.map_axes);
                                       handles.map_axes.YDir = 'normal';
                                       hline = findall(gca,'Type','image');
                                       set(hline(1),'uicontextmenu',handles.map_axes_context);
                                       set_afm_gold;
                                       handles = colorbar_helpf(app, handles.map_axes,handles);
                                       app.map_axes.Toolbar.Visible = "off";
                                   end
                               end
                               app.map_axes.Toolbar.Visible = "off";
            
                               % display first processing grid point and text
                               axes(handles.map_axes);
                               hold(handles.map_axes,'on');
                               handles.figures.proc_point = plot(app.map_axes,1,'.w','MarkerSize',15);
                               handles.figures.proc_text = text(app.map_axes,1.5,1.5,'1','Color','w','FontWeight','bold');
                               hold(handles.map_axes,'off');
                               guidata(hObject,handles);
            
                               num_files = length(Forcecurve_label);
                                % preallocate curve struct
                                for i=1:num_files
                                    c_string = sprintf('curve%u',i);
                                    curves.(c_string) = struct('x_values',[],'y_values',[]);
                                end
            
                               % create waitbar for load process with cancel button
                               
                               wb_num = 0;

%                                 wb = waitbar(0,sprintf('Loading progress: %.g%%',wb_num*100),'Name',...
%                                     'Loading ...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
%                                 setappdata(wb,'canceling',0);
                                    
                                prog_dlg = uiprogressdlg(app.figure1,"Title","Loading Data","Message",sprintf("Loading progress %.2f%%",wb_num*100),"Value",0);
                                
                                first_item = app.listbox1.Items(1);
                                app.listbox1.Value = first_item;

                                % preallocate listbox cell
                                app.listbox1.Items = "";
                                it(1:num_files,1) = "";
                                it(1) = {"Curves are loading ..."};
%                                 handles.listbox1.String = it;
%                                 guidata(hObject,handles);
                                app.listbox1.Items = it;
                                drawnow;
                                            
                                % load x and y values from file in struct elements
                                for i=1:num_files
                                    
                                    % load x and y values of each force curve
                                    c_string = sprintf("curve%u",i);
                                    curves.(c_string).x_values = x_data.(Forcecurve_label{i}); %Save data as meter[m]
                                    curves.(c_string).y_values = y_data.(Forcecurve_label{i}); %Save data as Volt[V]
                                    % add listbox element
%                                     it = string(app.listbox1.Items);
                                    it(i) = sprintf("curve %3u  ->  unprocessed",i);
%                                     handles.listbox1.String = it;
%                                     app.listbox1.Items = it;
%                                     drawnow;
                                    % update waitbar
                                    wb_num = i/num_files;
                                    prog_dlg.Message = sprintf("Loading progress %.2f%%",wb_num*100);
                                    prog_dlg.Value = wb_num;
                                end

                                app.listbox1.Items = it;
                                drawnow;
            
            
                            elseif handles.filefilter == 2
                                [mach_data,mach_info] = ReadMachTextFile_App(app,handles.edit_filepath.String);
            
                                % interrupt callback when an empty MACH-1 file is
                                % loaded
                                if (isnumeric(mach_data) && mach_data == -1) || (isnumeric(mach_info) && mach_info == -1)
                                   return;
                                end
            
                                handles.mach_info_struct = mach_info;
                                % if loading of file was successful, set the loaded
                                % file type to mach-txt and set fit-depth to  500 µm
                                handles.loaded_file_type = 'mach-txt';
                                % set fit-depth to 500 µm just in case the value is 1 µm
                                % which is the AFM default value for the fit-depth
                                if strcmp(handles.hertz_fit_depth.String,'1')
                                    handles.hertz_fit_depth.String = '500';
                                end
            
                                % set channel_names empty
                                handles.channel_names = 'no loaded channels';
            
                                % create segment_labels and determine number of
                                % segments
                                segment_labels = fieldnames(mach_data);
                                handles.file_names = segment_labels;
                                seg_num = length(segment_labels);
            
                                % preallocate listbox cell
                                handles.listbox1.String = {};
                                it(1:seg_num,1) = {''};
                                app.listbox1.Items = it;
                                guidata(hObject,handles);
            
                                % create watibar
                                wb_num = 0;
%                                 wb = waitbar(0,sprintf('Loading progress: %.g%%',wb_num*100),'Name',...
%                                     'Loading ...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
%                                 setappdata(wb,'canceling',0);
                                prog_dlg = uiprogressdlg(app.figure1,"Title","Loading Data","Message",sprintf("Loading progress %.2f%%",wb_num*100),"Value",0);
            
                                % create main data struct with x and y values and write
                                % strings in listbox
                                for i = 1:seg_num
                                    
                                    % load x and y values of each force curve
                                    c_string = sprintf('curve%u',i);
                                    current_label = segment_labels{i};
                                    % load x values in dependency of unit
                                    % (The multiplication by (-1) is due to the different sign convention of the positive distance direction of the MACH-1 sytem)
                                    switch mach_data.(current_label).Position_z.Unit
                                        case 'm'
                                            curves.(c_string).x_values = mach_data.(current_label).Position_z.Values.*(-1);   % get x values in meter
                                        case 'cm'
                                            curves.(c_string).x_values = mach_data.(current_label).Position_z.Values.*(-1).*1e-2;   % get x values in meter
                                        case 'mm'
                                            curves.(c_string).x_values = mach_data.(current_label).Position_z.Values.*(-1).*1e-3;   % get x values in meter
                                        case 'µm'
                                            curves.(c_string).x_values = mach_data.(current_label).Position_z.Values.*(-1).*1e-6;   % get x values in meter
                                        otherwise
                                            curves.(c_string).x_values = mach_data.(current_label).Position_z.Values.*(-1);   % get x values in any unit
                                            warning on
                                            warning('Unknown unit for Position_z\nValues may be laoded in a different unit than meter!%s\n',' ');
                                    end
                                    % Determine multiplier for Fz data
                                    curr_fz = mach_data.(current_label).Fz.Values;
                                    curr_min = min(curr_fz);
                                    curr_max = max(curr_fz);
                                    curr_median = median(curr_fz);
                                    if (abs(curr_min-curr_median)) < (abs(curr_max-curr_median))
                                        multiplier = 1;
                                    else
                                        multiplier = -1;
                                    end
                                    % load y values in dependency of unit
                                    % (The multiplication by (-1) is due to the different sign convention of the positive force direction of the MACH-1 sytem)
                                    switch mach_info.(current_label){6,2}
                                        case 'Single-axis'
                                            switch mach_data.(current_label).Fz.Unit
                                                case 'gf'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier.*0.00980665; % convert gf to N
                                                case 'N'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier;   % get y values in newton
                                                case 'mN'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.multiplier.*1e-3;   % get y values in newton
                                                case 'µN'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier.*1e-6;   % get y values in newton
                                                case 'nN'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier.*1e-9;   % get y values in newton
                                                otherwise
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier;   % get y values in any unit
                                                    warning on
                                                    warning('Unknown unit for Fz\nValues may be laoded in a different unit than newton!%s\n',' ');
                                            end
                                        case 'Multiple-axis'
                                            switch mach_data.(current_label).Fz.Unit
                                                case 'N'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier;   % get y values in newton
                                                case 'mN'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier.*1e-3;   % get y values in newton
                                                case 'µN'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier.*1e-6;   % get y values in newton
                                                case 'nN'
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier.*1e-9;   % get y values in newton
                                                otherwise
                                                    curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier;   % get y values in any unit
                                                    warning on
                                                    warning('Unknown unit for Fz\nValues may be laoded in a different unit than newton!%s\n',' ');
                                            end
                                        otherwise
                                            curves.(c_string).y_values = mach_data.(current_label).Fz.Values.*multiplier;   % get y values in any unit
                                            warning on
                                            warning('Unknown Load Cell Type:\nValues may be laoded in a different unit than newton!%s\n',' ');
                                    end
            
                                    % add listbox element
                                    currItem = sprintf('curve %3u  ->  unprocessed',i);
                                    app.listbox1.Items(i) = {currItem};
                                    % update waitbar
                                    wb_num = i/seg_num;
                                    prog_dlg.Message = sprintf("Loading progress %.2f%%",wb_num*100);
                                    prog_dlg.Value = wb_num;
                                end
            
                            end
                            [~,f,e] = fileparts(handles.edit_filepath.String);
                            app.CurrFMapName = string(f)+string(e);
            
                        case 'folder'
                            folderpath = get(handles.edit_filepath,'String');       % get folder location
                            listing = [dir(fullfile(folderpath,'*.ibw'));dir(fullfile(folderpath,'*.txt'))];    % information of files in folder
                            [~,~,filetype] = fileparts(listing(1).name);
            
                            % Check if the folder contains .ibw files from the MFP-3D
                            if strcmp(filetype, '.ibw')
                                handles.loaded_file_type = 'ibw';
                                handles.ibw = true;
                                % if a ibw-file is loaded reset the fit start and
                                % fit depth values
                                handles.hertz_fit_depth.String = '1';
                                handles.hertz_fit_start.String = '0';
                                [x_data,y_data,~,~, Forcecurve_label, name_of_file, mfpmapdata] = ReadMFPMaps(folderpath);
                                Forcecurve_label = Forcecurve_label';
                                curves_in_map = strcat(name_of_file,'.',Forcecurve_label);
                                handles.file_names = curves_in_map;
                                num_files = length(Forcecurve_label);
                                handles.mfpmapdata = mfpmapdata;
                                % preinitialise curve struct
                                for i=1:num_files
                                    c_string = sprintf('curve%u',i);
                                    curves.(c_string) = struct('x_values',[],'y_values',[]);
                                end
            
                                % create waitbar for load process with cancel button
                                wb_num = 0;
%                                 wb = waitbar(0,sprintf('Loading progress: %.g%%',wb_num*100),'Name',...
%                                     'Loading ...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
%                                 setappdata(wb,'canceling',0);
                                prog_dlg = uiprogressdlg(app.figure1,"Title","Loading Data","Message",sprintf("Loading progress %.2f%%",wb_num*100),"Value",0);
                                % preallocate listbox cell
                                it(1:num_files,1) = {''};
                                handles.listbox1.String = it;
                                guidata(hObject,handles);
            
                                % Get the size of the forcemap
                                map_parameters = mfpmapdata{2};
                                scanpt = map_parameters.FMapScanPoints;
                                scanl = map_parameters.FMapScanLines;
                                handles.MFP_height_matrix = zeros(scanl,scanpt);
                                handles.MFP_mslope_matrix = zeros(scanl,scanpt);
                                handles.MFP_fmap_num_line = scanl;    %Number of lines in the forcemap
                                handles.MFP_fmap_num_points = scanpt; %Number of points in the forcemap
            
                                % load x and y values from file in struct elements
                                for i=1:num_files
                                    
                                    % load x and y values of each force curve
                                    c_string = sprintf('curve%u',i);
                                    curves.(c_string).x_values = x_data.(Forcecurve_label{i}); %x_data are stored as meter[m]
                                    curves.(c_string).y_values = y_data.(Forcecurve_label{i}); %y_data are stored as Volt[V]
                                    if i == 1
                                        pt_count = 1;
                                        line_count = scanl;
                                    end
                                    %Get the height value of each curve
                                    handles.MFP_height_matrix(line_count,pt_count) = min(x_data.(Forcecurve_label{i}));
            
                                    %Get the measured slope value of each curve
                                    y_data_singlecurve = y_data.(Forcecurve_label{i});
                                    x_data_singlecurve = x_data.(Forcecurve_label{i});
                                    y_data_fit = y_data_singlecurve((length(y_data_singlecurve)-round(0.02*length(y_data_singlecurve))):end);
                                    x_data_fit = x_data_singlecurve((length(x_data_singlecurve)-round(0.02*length(x_data_singlecurve))):end);
                                    [p] = polyfit(x_data_fit, y_data_fit, 1);
                                    handles.MFP_mslope_matrix(line_count,pt_count) = p(1);
            
                                    if pt_count == scanpt
                                        pt_count = 0;
                                        line_count = line_count - 1;
                                    end
                                    pt_count = pt_count +1;
                                    % add listbox element
                                    it = handles.listbox1.String;
                                    it{i,1} = sprintf('curve %3u  ->  unprocessed',i);
                                    handles.listbox1.String = it;
                                    % update waitbar
                                    wb_num = i/num_files;
                                    prog_dlg.Message = sprintf("Loading progress %.2f%%",wb_num*100);
                                    prog_dlg.Value = wb_num;
                                end
                                %Subtract the minimum value of the height matrix to get
                                %the real height
                                lowest_point = min(min(handles.MFP_height_matrix));
                                handles.MFP_height_matrix = handles.MFP_height_matrix-lowest_point;
            
                                %Replace all the left zeros by the minimal/maximal value to get
                                %still a good image
                                mslope_matrix = handles.MFP_mslope_matrix;
                                mslope_matrix(mslope_matrix==0) = [];
                                handles.MFP_mslope_matrix(handles.MFP_mslope_matrix==0) = (min(min(mslope_matrix))); %Calling min twice is a trick to get the minimum value of a whole matrix
            
                                height_matrix = handles.MFP_height_matrix;
                                height_matrix(height_matrix==0) = [];
                                handles.MFP_height_matrix(handles.MFP_height_matrix ==0) = (min(min(height_matrix))); %Calling min twice is a trick to get the minimum value of a whole matrix
            
                                %Create interpolation images of height and slope matrix
                                [handles.MFP_mslope_matrix_linear_interpolation, handles.MFP_mslope_matrix_cubic_interpolation] = ImageInterpolationMFP(map_parameters.FMapScanPoints,map_parameters.FMapScanLines,handles.MFP_mslope_matrix);
                                [handles.MFP_height_matrix_linear_interpolation, handles.MFP_height_matrix_cubic_interpolation] = ImageInterpolationMFP(map_parameters.FMapScanPoints,map_parameters.FMapScanLines,handles.MFP_height_matrix);
            
                                % Get the color gradient for each matrix
                                handles.colorgrad_height = flipud(linspace(min(min(handles.MFP_height_matrix)), max(max(handles.MFP_height_matrix)), 100))';
                                handles.colorgrad_slope = flipud(linspace(min(min(handles.MFP_mslope_matrix)), max(max(handles.MFP_mslope_matrix)), 100))';
            
                                %Enable the Image Channel popup menu
                                handles.channel_names = {'height', 'slope'}';
                                handles.image_channels_popup.String = handles.channel_names;
                                handles.image_channels_popup.Enable = 'on';
            
                                % plot an channel image and the given color gradient
                                handles.map_axes.Visible = 'on';
                                handles.image_channels_popup.Value = 1;
                                axes(handles.map_axes);
                                %Bicubic interpolation is set as default
                                imshow(handles.MFP_height_matrix_cubic_interpolation, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', [],"Parent",app.map_axes);
                                set_afm_gold();
                                handles = colorbar_helpf(app, handles.map_axes,handles);
                                hline = findall(gca,'Type','image');
                                set(hline(1),'uicontextmenu',handles.map_axes_context);
                                app.map_axes.Toolbar.Visible = "off";
            
                                % create processing grid for visual curve feedback
                                handles.map_info = struct('x_pixel',0,'y_pixel',0,'processing_grid',[0,0]);
                                handles.map_info.x_pixel = map_parameters.FMapScanPoints;
                                handles.map_info.y_pixel = map_parameters.FMapScanLines;
                                handles.map_info.processing_grid = zeros(handles.map_info.x_pixel*handles.map_info.y_pixel,2);
                                grid_index = 0;
                                for i=1:(handles.map_info.y_pixel)
                                    for j = 1:(handles.map_info.x_pixel)
                                        grid_index = grid_index + 1;
                                        handles.map_info.processing_grid(grid_index,1) = j;
                                        handles.map_info.processing_grid(grid_index,2) = i;
                                    end
                                end
                                handles.map_info.processing_grid(:,2) = flip(handles.map_info.processing_grid(:,2));
            
                                % display first processing grid point and text
                                axes(handles.map_axes);
                                hold(handles.map_axes,'on');
                                handles.figures.proc_point = plot(app.map_axes,1,map_parameters.FMapScanLines,'.w','MarkerSize',15);
                                handles.figures.proc_text = text(app.map_axes,1.5,map_parameters.FMapScanLines-0.5,'1','Color','w','FontWeight','bold');
                                hold(handles.map_axes,'off');
                                guidata(hObject,handles);
            
                           elseif strcmp(filetype, '.txt')
            
                                handles.loaded_file_type = 'txt';
                                T_files_in_folder = struct2table(listing);
                                files_in_folder = table2array(T_files_in_folder(:,1));  % cell array with all file names
                                handles.file_names = files_in_folder;                   % save the filenames
                                num_files = length(files_in_folder);                    % number of files in folder
            
                                % preallocate curve struct
                                for i=1:num_files
                                    c_string = sprintf('curve%u',i);
                                    curves.(c_string) = struct('x_values',[],'y_values',[]);
                                end
            
                                % create waitbar for load process with cancel button
                                wb_num = 0;
%                                 wb = waitbar(0,sprintf('Loading progress: %.g%%',wb_num*100),'Name',...
%                                     'Loading ...','CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
%                                 setappdata(wb,'canceling',0);
                                prog_dlg = uiprogressdlg(app.figure1,"Title","Loading Data","Message",sprintf("Loading progress %.2f%%",wb_num*100),"Value",0);
                                % preallocate listbox cell
                                it(1:num_files,1) = {''};
                                handles.listbox1.String = it;
                                guidata(hObject,handles);
            
                                % load x and y values from file in struct elements
                                for i=1:num_files
                                    
                                    % load x and y values of next file in folder
                                    filepath = fullfile(path,files_in_folder{i});
                                    % get first line with number
                                    testid = fopen(filepath);
                                    line = fgetl(testid);
            
                                    if i == 1
                                        % preallocate info_cell_array
                                        info_cell_array = {};
                                    end
            
                                    count = 1;
                                    while ischar(line)
                                        line = fgetl(testid);
                                        line_sep = split(line,' ');
                                        if ~strcmp(line_sep{1},'#') && ~isempty(line_sep{1})
                                            break
                                        end
                                        if i == 1
                                            info_cell_array(end+1,1) = {line};
                                        end
                                        count = count + 1;
                                    end
                                    count = count + 1;
                                    fclose(testid);
                                    handles.text_file_info = read_JPK_text_header(info_cell_array);
                                    coordinates = import_force_curve_txt_file_fast(filepath,count);
                                    c_string = sprintf('curve%u',i);
                                    curves.(c_string).x_values = coordinates.x_values;
                                    curves.(c_string).y_values = coordinates.y_values;
                                    % add listbox element
                                    it = handles.listbox1.String;
                                    it{i,1} = sprintf('curve %3u  ->  unprocessed',i);
                                    handles.listbox1.String = it;
                                    % update waitbar
                                    wb_num = i/num_files;
                                    prog_dlg.Message = sprintf("Loading progress %.2f%%",wb_num*100);
                                    prog_dlg.Value = wb_num;
                                end
            
                                % plot an channel image dummy
                                axes(handles.map_axes);
                                imshow('no_image_dummy.jpg');
                            else
                                warndlg('No readable datatype is contained in the choosen folder!');
                                handles.invalid_data_type = true;
                            end
                    end
            
                    if ~handles.invalid_data_type
            
                        handles.listbox1.Value = 1;         % highlight listbox item number one
                        handles.curves = curves;            % write curves struct to handles
                        handles.current_curve = 1;          % set the current curve to first
                        handles.load_status = 1;            % set load status tag to 1
                        handles.save_status = 0;            % set save status tag to 0
                        handles.save_status_led.BackgroundColor = [1 0 0];
                        delete(prog_dlg);                          % delete loading waitbar
                        if strcmp(handles.loaded_file_type,'mach-txt')
                            num_files = seg_num;
                        end
                        if length(fieldnames(curves)) == num_files
                            handles.num_files = num_files;  % provide max curve number in handles
                        else
                        num_files = length(fieldnames(curves))-1;                % number of fully loaded curves
                        handles.num_files = length(fieldnames(curves))-1;        % provide max curve number in handles
                        end
            
                        % provide file information in the info panel
                        handles = info_panel_helpf(app, handles);
            
                        % write progress values
                        % needed variables
                        handles.progress = struct('num_unprocessed',num_files,...
                                                  'num_processed',0,...
                                                  'num_discarded',0);
                        % write process
                        [hObject,handles] = update_progress_info(hObject,handles);
            
                        % create struct for processed data
                        handles.proc_curves = curves;
            
                        %% Curve processing function V
                        % function for the processing of current curve depending on user
                        % options.
                        try
                        [hObject,handles] = process_options(hObject,handles);
                        catch % ME
                            % if you can
                        end
                        %%
            
                        % create main plot window for displaying processed curves
                        if ~isempty(handles.figures.main_fig)
                            delete(handles.figures.main_fig);
                            handles.figures.main_fig = [];
                        end
            
                        handles.figures.main_fig = figure('NumberTitle','off','Name','Main plot window');
                        figure(handles.figures.main_fig);
                        handles.figures.main_fig.CloseRequestFcn = {@app.main_plot_CloseRequest,handles};
                        handles.figures.main_ax = axes;
                        handles.figures.main_ax.FontSize = 16;
                        hold(handles.figures.main_ax,'on');
                        handles.figures.main_plot = plot(nan,nan);
                        handles.figures.patch_handle = patch(nan,nan,nan);
                        hold(handles.figures.main_ax,'off');
                        xlabel('Vertical tip position [µm]');
                        ylabel({'Force [nN]';''});
                        guidata(hObject,handles);
            
                        % fit data to processed curve and display fitresult
                        [hObject,handles] = curve_fit_functions(hObject,handles);
                        guidata(hObject,handles);
            
                        %Plot the data with chosen model
                        switch handles.options.model
                            case 'bihertz'
                                [handles] = plot_bihertz(handles);
                                guidata(hObject,handles);
                            case 'hertz'
                                [hObject,handles] = plot_hertz(hObject,handles);
                                guidata(hObject,handles);
                        end
            
                        % preallocate result table
                        switch handles.options.model
                            case 'bihertz'
                                if handles.options.bihertz_variant == 1
                                    varTypes =  {'string','uint64','double','double','double',...
                                                 'double','double','double','double'};
                                    varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                                'initial_d_h_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','rsquare_fit'};
                                    handles.T_result = table('size',[handles.num_files 9],'VariableTypes',varTypes,'VariableNames',varNames);
                                elseif handles.options.bihertz_variant == 2
                                    varTypes =  {'string','uint64','double','double','double','double',...
                                                 'double','double','double','double','double'};
                                    varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                                'initial_d_h_m','initial_s_p_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','fit_s_p_m','rsquare_fit'};
                                    handles.T_result = table('size',[handles.num_files 11],'VariableTypes',varTypes,'VariableNames',varNames);
                                end
            
                            case 'hertz'
                                varTypes =  {'string','uint64','double','double'};
                                varNames = {'File_name','Index','EModul','rsquare_fit'};
                                handles.T_result = table('size',[num_files 4],'VariableTypes',varTypes,'VariableNames',varNames);
                        end
            
            
                        % update gui fit results
                        handles.ContactPointField = app.ContactPointField;
                        handles.CP_unit_label = app.CP_unit_label;
                        [hObject,handles] = update_fit_results(hObject,handles);
            
                        % activate the gui buttons
                        handles.button_keep.Enable = 'on';
                        handles.button_discard.Enable = 'on';
                        handles.button_keep_all.Enable = 'on';
%                         handles.fit_model_popup.Enable = 'on';     ---->  Currently disabled!!!


                    end
                end
            end
            
            %If button is pressed again without reopening app, update interpolation
            handles.interpolation_type = 'bicubic';
            handles.none.Checked = 'off';
            handles.linear.Checked  = 'off';
            handles.bicubic.Checked = 'on';
            

            % If sounds are enabled play the 'opening finished' sound
            if strcmp(app.SoundsMenu.findobj("Checked","on").Text,"On")
                sound(app.NotificationSounds.OpenSound.y,app.NotificationSounds.OpenSound.Fs);
            end

            guidata(hObject,handles);
        end

        % Button pushed function: button_save_path
        function button_save_path_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); 
            
            % hObject    handle to button_save_path (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.load_status == 0 || ~isfield(handles,"T_result") || isempty(handles.T_result)
                return;
            end

            handles = save_current_results(app,handles);

            guidata(hObject,handles);
        end

        % Button pushed function: button_undo
        function button_undo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_undo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Data has been modified -> not saved
            handles.save_status = 0;
            handles.save_status_led.BackgroundColor = [1 0 0];
            
            % if undo button is pushed after reaching last curve reactivate buttons and
            % adjust current curve
            if handles.current_curve == handles.num_files
               handles.current_curve = handles.current_curve+1;
            
               handles.button_keep.Enable = 'on';
                handles.button_discard.Enable = 'on';
                handles.button_keep_all.Enable = 'on';
                handles.fit_model_popup.Enable = 'off';
            end
            
            % Subtract 1 from the current curve value
            curve_index = handles.current_curve-1;
            
            % change listbox element and save previous string
            it = handles.listbox1.String;
            prev_string = it{curve_index,1};
            it{curve_index,1} = sprintf('curve %3u  ->  unprocessed',curve_index);
            handles.listbox1.String = it;
            prev_string = split(prev_string,'  ->  ');
            prev_string = prev_string{2};
            
            % do the string correction again, if the selection was on the last element
            % which is an unprocessed one with adjusted curve_index
            if strcmp(prev_string,'unprocessed')
                curve_index = curve_index - 1;
                it = handles.listbox1.String;
                prev_string = it{curve_index,1};
                it{curve_index,1} = sprintf('curve %3u  ->  unprocessed',curve_index);
                handles.listbox1.String = it;
                prev_string = split(prev_string,'  ->  ');
                prev_string = prev_string{2};
            end
            
            % undo previous fit results
            if curve_index == 1
                switch handles.options.model
                    case 'bihertz'
                        if handles.options.bihertz_variant == 1
                            varTypes = {'string','uint64','double','double','double',...
                                        'double','double','double','double'};
                            varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                        'initial_d_h_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','rsquare_fit'};
                            handles.T_result = table('Size',[handles.num_files 9],'VariableTypes',varTypes,'VariableNames',varNames);
                        elseif handles.options.bihertz_variant == 2
                            varTypes =  {'string','uint64','double','double','double','double',...
                                             'double','double','double','double','double'};
                            varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                        'initial_d_h_m','initial_s_p_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','fit_s_p_m','rsquare_fit'};
                            handles.T_result = table('size',[handles.num_files 11],'VariableTypes',varTypes,'VariableNames',varNames);
                        end
                    case 'hertz'
                        varTypes =  {'string','uint64','double','double'};
                        varNames = {'File_name','Index','EModul','rsquare_fit'};
                        handles.T_result = table('size',[handles.num_files 4],'VariableTypes',varTypes,'VariableNames',varNames);
                end
            else
                discarded = handles.progress.num_discarded;
                if curve_index ~= handles.num_files
            
                    if strcmp(prev_string,'processed')
                        T_indx = curve_index-discarded;
                    else
                        T_indx = size(handles.T_result,1)+1;
                    end
            
                    switch handles.options.model
                        case 'bihertz'
                            if handles.options.bihertz_variant == 1
                                handles.T_result(T_indx,:) = {missing,...
                                                           uint64(0),...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0};
                            elseif handles.options.bihertz_variant == 2
                                handles.T_result(T_indx,:) = {missing,...
                                                       uint64(0),...
                                                       0,...
                                                       0,...
                                                       0,...
                                                       0,...
                                                       0,...
                                                       0,...
                                                       0,...
                                                       0,...
                                                       0};
                            end
                        case 'hertz'
                            handles.T_result(T_indx,:) = {missing,uint64(0), 0, 0};
                    end
                end
            end


            % Save the new current_curve value
            handles.current_curve = curve_index;
            new_curve_index = curve_index;
            guidata(hObject,handles);
            
            if handles.ibw == true
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            elseif strcmp(handles.loadtype,'file') && handles.ibw == false && ~strcmp(handles.loaded_file_type,'mach-txt')
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            end
            
            % highlight next list item
            handles.listbox1.Value = new_curve_index;
            scroll(app.listbox1,sprintf(sprintf('curve %3u  ->  unprocessed',new_curve_index)));
            guidata(hObject,handles);
            
            % Process new curve
            [hObject,handles] = process_options(hObject,handles);
            
            % Draw new curve
            switch handles.options.model
                case 'bihertz'
                    [handles] = plot_bihertz(handles);
                    guidata(hObject,handles);
                case 'hertz'
                    [hObject,handles] = plot_hertz(hObject,handles);
                    guidata(hObject,handles);
            end
            % fit data to processed curve and display fitresult
            [hObject,handles] = curve_fit_functions(hObject,handles);
            guidata(hObject,handles);
            
            % update gui fit results
            [hObject,handles] = update_fit_results(hObject,handles);
            guidata(hObject,handles);
            
            % update progress values
            switch prev_string
                case 'processed'
                    handles.progress.num_unprocessed = handles.progress.num_unprocessed +1;
                    handles.progress.num_processed = handles.progress.num_processed -1;
                case 'discarded'
                    handles.progress.num_unprocessed = handles.progress.num_unprocessed +1;
                    handles.progress.num_discarded = handles.progress.num_discarded -1;
            end
            guidata(hObject,handles);
            
            % write process info
            [hObject,handles] = update_progress_info(hObject,handles);
            guidata(hObject,handles);
            
            % if a MACH-1 text file is laoded update the info panel
            if strcmp(handles.loaded_file_type,'mach-txt')
                handles = info_panel_helpf(app, handles);
            end
            
            % when first curve reached disable undo button, Youngs Modulus and Contactpoint image
            if new_curve_index == 1
                handles.button_undo.Enable = 'off';
%                 handles.fit_model_popup.Enable = 'on';       ---->  Currently disabled!!!
                handles.btn_histogram.Enable = 'off';
                handles.btn_gof.Enable = 'off';
                channel_find = cellfun(@(x)strcmp(x,'Youngs Modulus'),handles.channel_names);
                if any(channel_find)
                    handles.channel_names = handles.channel_names(~channel_find);
                    handles.image_channels_popup.Value = length(handles.channel_names);
                    handles.image_channels_popup.String = handles.channel_names;
                end
                if handles.ibw == true
                    channel_find_contact = cellfun(@(x)strcmp(x,'Contactpoint'),handles.channel_names);
                    if any(channel_find)
                        handles.channel_names = handles.channel_names(~channel_find_contact);
                        handles.image_channels_popup.Value = length(handles.channel_names);
                        handles.image_channels_popup.String = handles.channel_names;
                    end
                end
            end
            
            
            guidata(hObject,handles);
        end

        % Button pushed function: button_undo_highlighted
        function button_undo_highlighted_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_undo_highlighted (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            selection_num = handles.listbox1.Value;
            selection_diff = handles.current_curve - selection_num;
            
            % Data has been modified -> not saved
            handles.save_status = 0;
            handles.save_status_led.BackgroundColor = [1 0 0];
            
            % if pushed after reaching the last curve keep and discard buttons are
            % enabled again
            new_curve_index = handles.current_curve;
            if new_curve_index == handles.num_files
                handles.button_keep.Enable = 'on';
                handles.button_discard.Enable = 'on';
                handles.button_keep_all.Enable = 'on';
                handles.fit_model_popup.Enable = 'off';
            end
            guidata(hObject,handles);
            
            if new_curve_index == handles.num_files
               handles.current_curve = handles.current_curve+1;
               selection_diff = selection_diff+1;
            end
            
            
            for i=1:selection_diff
            
                % Subtract 1 from the current curve value
                curve_index = handles.current_curve-1;
            
                % change listbox element and save previous string
                it = handles.listbox1.String;
                prev_string = it{curve_index,1};
                it{curve_index,1} = sprintf('curve %3u  ->  unprocessed',curve_index);
                handles.listbox1.String = it;
                prev_string = split(prev_string,'  ->  ');
                prev_string = prev_string{2};
            
                % undo previous fit results
                if curve_index == 1
                    switch handles.options.model
                        case 'bihertz'
                            if handles.options.bihertz_variant == 1
                                varTypes = {'string','uint64','double','double','double',...
                                            'double','double','double','double'};
                                varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                            'initial_d_h_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','rsquare_fit'};
                                handles.T_result = table('Size',[handles.num_files 9],'VariableTypes',varTypes,'VariableNames',varNames);
                            elseif handles.options.bihertz_variant == 2
                                varTypes =  {'string','uint64','double','double','double','double',...
                                                 'double','double','double','double','double'};
                                varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                            'initial_d_h_m','initial_s_p_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','fit_s_p_m','rsquare_fit'};
                                handles.T_result = table('size',[handles.num_files 11],'VariableTypes',varTypes,'VariableNames',varNames);
                            end
                        case 'hertz'
                            varTypes =  {'string','uint64','double','double'};
                            varNames = {'File_name','Index','EModul','rsquare_fit'};
                            handles.T_result = table('size',[handles.num_files 4],'VariableTypes',varTypes,'VariableNames',varNames);
                    end
                else
                    discarded = handles.progress.num_discarded;
                    if curve_index ~= handles.num_files
            
                        if strcmp(prev_string,'processed')
                            T_indx = curve_index-discarded;
                        else
                            T_indx = size(handles.T_result,1)+1;
                        end
            
                        switch handles.options.model
                            case 'bihertz'
                                if handles.options.bihertz_variant == 1
                                    handles.T_result(T_indx,:) = {missing,...
                                                               uint64(0),...
                                                               0,...
                                                               0,...
                                                               0,...
                                                               0,...
                                                               0,...
                                                               0,...
                                                               0};
                                elseif handles.options.bihertz_variant == 2
                                    handles.T_result(T_indx,:) = {missing,...
                                                           uint64(0),...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0,...
                                                           0};
                                end
                            case 'hertz'
                                handles.T_result(T_indx,:) = {missing,uint64(0), 0, 0};
                        end
                    end
                end
            
                % Save the new current_curve value
                handles.current_curve = curve_index;
                new_curve_index = curve_index;
                guidata(hObject,handles);
            
                % update progress values
                switch prev_string
                    case 'processed'
                        handles.progress.num_unprocessed = handles.progress.num_unprocessed +1;
                        handles.progress.num_processed = handles.progress.num_processed -1;
                    case 'discarded'
                        handles.progress.num_unprocessed = handles.progress.num_unprocessed +1;
                        handles.progress.num_discarded = handles.progress.num_discarded -1;
                end
                guidata(hObject,handles);
            
                % write process info
                [hObject,handles] = update_progress_info(hObject,handles);
                guidata(hObject,handles);
            
                % if a MACH-1 text file is laoded update the info panel
                if strcmp(handles.loaded_file_type,'mach-txt')
                    handles = info_panel_helpf(app, handles);
                end
            
            end
            
            if handles.ibw == true
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            elseif strcmp(handles.loadtype,'file') && handles.ibw == false && ~strcmp(handles.loaded_file_type,'mach-txt')
                % update current curve marker on map axes
                handles = update_curve_marker(handles);
            end
            
            % highlight next list item
            handles.listbox1.Value = new_curve_index;
            scroll(app.listbox1,sprintf(sprintf('curve %3u  ->  unprocessed',new_curve_index)));
            guidata(hObject,handles);
            
            
            % Process new curve
            [hObject,handles] = process_options(hObject,handles);
            
            % Draw new curve
            switch handles.options.model
                case 'bihertz'
                    [handles] = plot_bihertz(handles);
                    guidata(hObject,handles);
                case 'hertz'
                    [hObject,handles] = plot_hertz(hObject,handles);
                    guidata(hObject,handles);
            end
            % fit data to processed curve and display fitresult
            [hObject,handles] = curve_fit_functions(hObject,handles);
            guidata(hObject,handles);
            
            % update gui fit results
            [hObject,handles] = update_fit_results(hObject,handles);
            guidata(hObject,handles);
            
            % when first curve reached disable undo button and Youngs Modulus image
            if new_curve_index == 1
                handles.button_undo.Enable = 'off';
%                 handles.fit_model_popup.Enable = 'on';      ---->  Currently disabled!!!
                handles.btn_histogram.Enable = 'off';
                handles.btn_gof.Enable = 'off';
                channel_find = cellfun(@(x)strcmp(x,'Youngs Modulus'),handles.channel_names);
                if any(channel_find)
                    handles.channel_names = handles.channel_names(~channel_find);
                    handles.image_channels_popup.Value = length(handles.channel_names);
                    handles.image_channels_popup.String = handles.channel_names;
                end
                if handles.ibw == true
                    channel_find_contact = cellfun(@(x)strcmp(x,'Contactpoint'),handles.channel_names);
                    if any(channel_find_contact)
                        handles.channel_names = handles.channel_names(~channel_find_contact);
                        handles.image_channels_popup.Value = length(handles.channel_names);
                        handles.image_channels_popup.String = handles.channel_names;
                    end
                end
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: contact_percentage_hertz
        function contact_percentage_hertz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to contact_percentage_hertz (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of contact_percentage_hertz as text
            %        str2double(get(hObject,'String')) returns contents of contact_percentage_hertz as a double
            
            perc_str = get(hObject,'String');
            perc_str = strrep(perc_str,',','.');
            perc = str2double(perc_str);
            
            if isnan(perc)
               set(hObject,'String','20');
            else
                set(hObject,'String',sprintf('%g',perc));
            end
            
            contact_type_changed_helpf(app, event);
%             btngroup_contact_SelectionChangedFcn(handles.btngroup_contact,[], handles);
        end

        % Value changed function: edit_filepath
        function edit_filepath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_filepath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_filepath as text
            %        str2double(get(hObject,'String')) returns contents of edit_filepath as a double
            
            tooltip_string = hObject.Tooltip;
            hObject.String = tooltip_string;
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = 'Yes';
            if handles.save_status == 0 && handles.load_status == 1
                answer = questdlg({'There are unsaved results.','Do you really want to exit the window?'},...
                    'Exit request','Yes','No','No');
            end
            
            if strcmp(answer,'Yes')
                    % Delete created folder and .zip of the ReadJPKMaps function
                if isfield(handles, 'loadtype') == 1
                    if strcmp(handles.loadtype,'file')
                        [filepath, name, ~] = fileparts(handles.edit_filepath.String);
                        zipname = strcat(filepath, '\', name, '.zip');
                        unzipfolder = strcat(filepath, '\', 'Forcemap');
                        if exist(zipname, 'file') == 2
                            delete (zipname)
                        end
                        if exist(unzipfolder, 'dir') == 7
                            rmdir(unzipfolder, 's')
                        end
                    end
                end
            
                try
                    delete(handles.figures.main_fig);
                catch
                    % nix
                end
                warning on
                delete(handles.figure1);
                delete(allchild(groot));
                run('CANTER_Processing_Toolbox.mlapp');
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            exit_Callback(app,eventdata);
        end

        % Value changed function: fit_depth
        function fit_depth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fit_depth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of fit_depth as text
            %        str2double(get(hObject,'String')) returns contents of fit_depth as a double
            

            [hObject,handles] = update_patches(hObject,handles);
            
            % fit data to processed curve and display fitresult
            [hObject,handles] = curve_fit_functions(hObject,handles);
            guidata(hObject,handles);
            
            % update gui fit results
            [hObject,handles] = update_fit_results(hObject,handles);
            
            
            guidata(hObject,handles)
        end

        % Value changed function: fit_model_popup
        function fit_model_popup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to fit_model_popup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns fit_model_popup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from fit_model_popup

            % switch panels
            switch hObject.Value
                case 1
%                     handles.uipanel5.Visible = 'off';
%                     handles.uipanel10.Visible = 'off';
                    app.BiHertzFitPanel.Visible = "off";
                    handles.hertz_fit_panel.Visible = 'on';
                    handles.map_axes.Visible = 'on';
                    handles.options.model = 'hertz';
                    % recreate T_results if exists
                    if isfield(handles,'T_result')
                        varTypes =  {'string','uint64','double','double'};
                        varNames = {'File_name','Index','EModul','rsquare_fit'};
                        handles.T_result = table('size',[handles.num_files 4],'VariableTypes',varTypes,'VariableNames',varNames);
                    end
            
                case 2
%                     handles.uipanel5.Visible = 'on';
%                     handles.uipanel10.Visible = 'on';
                    app.BiHertzFitPanel.Visible = "on";
                    handles.hertz_fit_panel.Visible = 'off';
                    handles.map_axes.Visible = 'off';
                    handles.options.model = 'bihertz';
                    handles.options.bihertz_variant = 1;
%                     handles = grid_creation_function(handles);
                    % recreate T_results if exists
                    if isfield(handles,'T_result')
                        varTypes =  {'string','uint64','double','double','double',...
                                             'double','double','double','double'};
                        varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                    'initial_d_h_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','rsquare_fit'};
                        handles.T_result = table('size',[handles.num_files 9],'VariableTypes',varTypes,'VariableNames',varNames);
                    end
            
                case 3
%                     handles.uipanel5.Visible = 'on';
%                     handles.uipanel10.Visible = 'on';
                    app.BiHertzFitPanel.Visible = "on";
                    handles.hertz_fit_panel.Visible = 'off';
                    handles.map_axes.Visible = 'off';
                    handles.options.model = 'bihertz';
                    handles.options.bihertz_variant = 2;
%                     handles = grid_creation_function(handles);
                    % recreate T_results if exists
                    if isfield(handles,'T_result')
                        varTypes =  {'string','uint64','double','double','double','double',...
                                             'double','double','double','double','double'};
                        varNames = {'File_name','Index','initial_E_s_Pa','initial_E_h_Pa',...
                                    'initial_d_h_m','initial_s_p_m','fit_E_s_Pa','fit_E_h_Pa','fit_d_h_m','fit_s_p_m','rsquare_fit'};
                        handles.T_result = table('size',[handles.num_files 11],'VariableTypes',varTypes,'VariableNames',varNames);
                    end
            
            end
            
            try delete(handles.figures.patch_handle); catch;  end
            try delete(handles.figures.baseline); catch; end
            try delete(handles.figures.baselineedges); catch; end
            try delete(handles.figures.baselineedges_2); catch; end
            try delete(handles.figures.fittedcurve); catch; end
            try delete(handles.figures.contactpoint_line); catch; end
            
            try  % draw new curve
                switch handles.options.model
                    case 'bihertz'
                        [handles] = plot_bihertz(handles);
                        guidata(hObject,handles);
                    case 'hertz'
                        [hObject,handles] = plot_hertz(hObject,handles);
                        guidata(hObject,handles);
                end
            
                % fit data to processed curve and display fitresult
                [hObject,handles] = curve_fit_functions(hObject,handles);
                guidata(hObject,handles);
            
                % update gui fit results
                [hObject,handles] = update_fit_results(hObject,handles);
            catch
               % if no curve is loaded don't do anything
            end
            guidata(hObject,handles);
        end

        % Value changed function: fit_perc
        function fit_perc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % hObject    handle to fit_perc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of fit_perc as text
            %        str2double(get(hObject,'String')) returns contents of fit_perc as a double
            
            
            [hObject,handles] = update_patches(hObject,handles);
            
            % fit data to processed curve and display fitresult
            [hObject,handles] = curve_fit_functions(hObject,handles);
            guidata(hObject,handles);
            
            % update gui fit results
            [hObject,handles] = update_fit_results(hObject,handles);
            
            
            guidata(hObject,handles);
        end

        % Menu selected function: help_wiki
        function help_wiki_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to help_wiki (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % if clicked, open the help wiki on our github site
            web('https://github.com/CANTERhm/CANTER_Processing_Tool/wiki/2a.-Force-indentation-processing','-browser');
        end

        % Value changed function: hertz_fit_depth
        function hertz_fit_depth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hertz_fit_depth (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fit_d_string = hObject.String;
            fit_d_string = strrep(fit_d_string,',','.');
            hObject.String = fit_d_string;
            
            fit_d_num_check = str2double(fit_d_string);
            
            if ~isnan(fit_d_num_check)
                try
                    [hObject,handles] = curve_fit_functions(hObject,handles);
                    [hObject,handles] = update_patches_hertzfit(hObject,handles);
                    [hObject,handles] = update_fit_results(hObject,handles);
                    guidata(hObject,handles);
                catch ME
                    % if you can
                end
            else
                hObject.String = '1.0';
            end
        end

        % Value changed function: hertz_fit_start
        function hertz_fit_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hertz_fit_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of hertz_fit_start as text
            %        str2double(get(hObject,'String')) returns contents of hertz_fit_start as a double
            fit_s_string = hObject.String;
            fit_s_string = strrep(fit_s_string,',','.');
            hObject.String = fit_s_string;
            
            fit_s_num_check = str2double(fit_s_string);
            
            if ~isnan(fit_s_num_check)
                try
                    [hObject,handles] = curve_fit_functions(hObject,handles);
                    [hObject,handles] = update_patches_hertzfit(hObject,handles);
                    [hObject,handles] = update_fit_results(hObject,handles);
                    guidata(hObject,handles);
                catch %ME
                    % if you can
                end
            else
                hObject.String = '0.0';
            end
        end

        % Menu selected function: how_to_report_submenu
        function how_to_report_submenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to how_to_report_submenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            web('https://github.com/CANTERhm/CANTER_Processing_Tool/wiki/How-to-report-an-issue','-browser');
        end

        % Value changed function: image_channels_popup
        function image_channels_popup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to image_channels_popup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns image_channels_popup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from image_channels_popup
            [hObject,handles] = checker_helpf(app, hObject,handles);
            if (handles.ibw == true)
                channel_interpolation = handles.interpolation_type;
                channel_string = hObject.String(hObject.Value);
                if strcmp(channel_string,'height')
                    axes(handles.map_axes);
                    if strcmp (channel_interpolation, 'linear')
                        imshow(handles.MFP_height_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                    elseif strcmp (channel_interpolation, 'bicubic')
                        imshow(handles.MFP_height_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                    else
                        imshow(handles.MFP_height_matrix, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', []);
                    end
                    set_afm_gold();
                    handles = colorbar_helpf(app, handles.map_axes,handles);
                    hline = findall(gca,'Type','image');
                    set(hline(1),'uicontextmenu',handles.map_axes_context);
                    app.map_axes.Toolbar.Visible = "off";
            
                elseif strcmp(channel_string, 'slope')
                    axes(handles.map_axes);
                    if strcmp (channel_interpolation, 'linear')
                        imshow(handles.MFP_mslope_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                    elseif strcmp(channel_interpolation, 'bicubic')
                        imshow(handles.MFP_mslope_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                    else
                        imshow(handles.MFP_mslope_matrix, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', []);
                    end
                    set_afm_gold();
                    handles = colorbar_helpf(app, handles.map_axes,handles);
                    hline = findall(gca,'Type','image');
                    set(hline(1),'uicontextmenu',handles.map_axes_context);
                    app.map_axes.Toolbar.Visible = "off";
            
                elseif strcmp(channel_string, 'Youngs Modulus')
            
                    current_curve = handles.current_curve;
                    curve_index = 1;    %only processed curves are saved in the handles.T_result table
                    handles.MFP_Ymodulus_matrix = zeros(handles.MFP_fmap_num_line,handles.MFP_fmap_num_points);
            
                    for i=1:current_curve
                        if i == 1
                            pt_count = 1;
                            line_count = handles.MFP_fmap_num_line;
                        end
                        if handles.T_result{curve_index,2} == i
                            handles.MFP_Ymodulus_matrix(line_count,pt_count)= handles.T_result{curve_index,3};
                            curve_index = curve_index+1;
                        else
                            handles.MFP_Ymodulus_matrix(line_count,pt_count) = pi; % A way to fill in empty spaces
                        end
                        if pt_count == handles.MFP_fmap_num_points
                            pt_count = 0;
                            line_count = line_count - 1;
                        end
                        pt_count = pt_count +1;
                    end
                    Ymodulus_matrix = handles.MFP_Ymodulus_matrix;
                    Ymodulus_matrix(Ymodulus_matrix == 0 | Ymodulus_matrix == pi) = [];
                    handles.MFP_Ymodulus_matrix(handles.MFP_Ymodulus_matrix == 0 | handles.MFP_Ymodulus_matrix == pi) = min(min(Ymodulus_matrix));
                    handles.colorgrad_Ymodulus = flipud(linspace(min(min(handles.MFP_Ymodulus_matrix)), max(max(handles.MFP_Ymodulus_matrix)), 100))';
            
                    % Get interpolation maps
                    [handles.MFP_Ymodulus_matrix_linear_interpolation, handles.MFP_Ymodulus_matrix_cubic_interpolation] = ImageInterpolationMFP(handles.MFP_fmap_num_points,handles.MFP_fmap_num_line,handles.MFP_Ymodulus_matrix);
            
                    %Display the Youngs Modulus matrix
                    axes(handles.map_axes);
                    if strcmp (channel_interpolation, 'linear')
                        imshow(handles.MFP_Ymodulus_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                    elseif strcmp(channel_interpolation, 'bicubic')
                        imshow(handles.MFP_Ymodulus_matrix_cubic_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                    else
                        imshow(handles.MFP_Ymodulus_matrix, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', []);
                    end
                    set_afm_gold();
                    handles = colorbar_helpf(app, handles.map_axes,handles);
                    hline = findall(gca,'Type','image');
                    set(hline(1),'uicontextmenu',handles.map_axes_context);
                    app.map_axes.Toolbar.Visible = "off";
            
                elseif strcmp(channel_string, 'Contactpoint')
            
                    for i=1:handles.current_curve
                        string = strcat('curve', num2str(i));
                        cpoint(i) = handles.proc_curves.(string).cpoint;
                    end
            
                    cpoint_matrix = zeros(handles.MFP_fmap_num_line, handles.MFP_fmap_num_points);
                    for i=1:handles.MFP_fmap_num_line
                        for j=1:handles.MFP_fmap_num_points
                            if (i-1)*handles.MFP_fmap_num_points+j >= size(cpoint, 2)
                                cpoint_matrix(i,j) = 0;
                            else
                                cpoint_matrix(i,j) = cpoint((i-1)*handles.MFP_fmap_num_points+j);
                            end
                        end
                    end
            
                    cpoint_matrix(cpoint_matrix == 0) = min(cpoint);
                    cpoint_matrix = flip(cpoint_matrix);
                    handles.MFP_cpoint_matrix = cpoint_matrix;
            
                    % Get interpolation maps
                    [handles.MFP_cpoint_matrix_linear_interpolation, handles.MFP_cpoint_matrix_cubic_interpolation] = ImageInterpolationMFP(handles.MFP_fmap_num_points,handles.MFP_fmap_num_line,handles.MFP_cpoint_matrix);
            
                    axes(handles.map_axes);
                    if strcmp (channel_interpolation, 'linear')
                        imshow(handles.MFP_cpoint_matrix_linear_interpolation, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', []);
                    elseif strcmp(channel_interpolation, 'bicubic')
                        imshow(handles.MFP_cpoint_matrix_cubic_interpolation, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', []);
                    else
                        imshow(cpoint_matrix, 'InitialMagnification', 'fit', 'XData', [1 handles.MFP_fmap_num_points], 'YData', [1 handles.MFP_fmap_num_line], 'DisplayRange', []);
                    end
                    set_afm_gold();
                    handles = colorbar_helpf(app, handles.map_axes,handles);
                    hline = findall(gca,'Type','image');
                    set(hline(1),'uicontextmenu',handles.map_axes_context);
                    app.map_axes.Toolbar.Visible = "off";
            
                end
            else
                channel_num = hObject.Value;
                channel_string = handles.channel_names{channel_num};
                channel_interpolation = handles.interpolation_type;
                if ~strcmp(channel_interpolation,'none')
                    interpolation_string = sprintf('_%s_interpolation',channel_interpolation);
                else
                    interpolation_string = '';
                end
                if strcmp(channel_string,'height_measured')
                    channel_image = handles.map_images.height_measured.(sprintf('absolute_%s_data%s',...
                    "height",interpolation_string));
                elseif isfield(handles.map_images,channel_string)
                    channel_image = handles.map_images.(channel_string).(sprintf('%s_data%s',...
                    channel_string,interpolation_string));
                end
                if isfield(handles.map_images,channel_string)
                    axes(handles.map_axes);
                    imshow(flip(channel_image,1),[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel]);
                    handles.map_axes.YDir = 'normal';
                    handles = update_curve_marker(handles);
                    hline = findall(gca,'Type','image');
                    set(hline(1),'uicontextmenu',handles.map_axes_context);
                    set_afm_gold;
                    handles = colorbar_helpf(app, handles.map_axes,handles);
                    app.map_axes.Toolbar.Visible = "off";
                end
                if strcmp(channel_string, 'Youngs Modulus')
                    EModul_matrix = zeros(handles.map_info.y_pixel, handles.map_info.x_pixel);
                    for i=1:handles.map_info.y_pixel
                        for j=1:handles.map_info.x_pixel
                            if (i-1)*handles.map_info.x_pixel+j > length(handles.T_result.EModul)
                                EModul_matrix(i,j) = 0;
                            else
                                EModul_matrix(i,j) = handles.T_result.EModul((i-1)*handles.map_info.x_pixel+j);
                            end
                        end
                    end
            
%                     EModul_matrix(EModul_matrix == 0) = min(handles.T_result.EModul);
                    [~,~,FExt] = fileparts(app.edit_filepath.Value);
                    if strcmp(FExt,".jpk-force-map")
                        for i = 2:2:handles.map_info.y_pixel
                            EModul_matrix(i,:) = flip(EModul_matrix(i,:),2);
                        end
                    end
                    % EModul_matrix = flip(EModul_matrix);
                  
                    handles.EModul_matrix = fillmissing(EModul_matrix,"constant",0);
            
                    % Get interpolation maps
                    [handles.EModul_matrix_linear_interpolation, handles.EModul_matrix_cubic_interpolation] = ImageInterpolationMFP(handles.map_info.x_pixel,handles.map_info.y_pixel,handles.EModul_matrix);
            
                    axes(handles.map_axes);
                    if strcmp (channel_interpolation, 'linear')
                        imshow(handles.EModul_matrix_linear_interpolation, 'InitialMagnification', 'fit', 'XData', [1 handles.map_info.x_pixel], 'YData', [1 handles.map_info.y_pixel], 'DisplayRange', [],"Parent",app.map_axes);
                    elseif strcmp(channel_interpolation, 'bicubic')
                        % imshow(handles.EModul_matrix_cubic_interpolation, 'InitialMagnification', 'fit', 'XData', [1 handles.map_info.x_pixel], 'YData', [1 handles.map_info.y_pixel], 'DisplayRange', [],"Parent",app.map_axes);
                        imshow(handles.EModul_matrix_cubic_interpolation,[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel]);
                    else
                        imshow(handles.EModul_matrix, 'InitialMagnification', 'fit', 'XData', [1 handles.map_info.x_pixel], 'YData', [1 handles.map_info.y_pixel], 'DisplayRange', [], "Parent",app.map_axes);
                    end
                    handles.map_axes.YDir = 'normal';
                    set_afm_gold();
                    handles = colorbar_helpf(app, handles.map_axes,handles);
                    hline = findall(gca,'Type','image');
                    set(hline(1),'uicontextmenu',handles.map_axes_context);
                    app.map_axes.Toolbar.Visible = "off";
                end
            
            end
            
            % update current curve marker on map axes
            handles = update_curve_marker(handles);
            
            guidata(hObject,handles);
        end

        % Menu selected function: linear
        function linear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to linear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [hObject,handles] = checker_helpf(app, hObject,handles);
            handles.interpolation_type = 'linear';
            channel_num = handles.image_channels_popup.Value;
            channel_string = handles.channel_names{channel_num};
            channel_interpolation = handles.interpolation_type;
            if handles.ibw == false
                if ~strcmp(channel_interpolation,'none')
                    interpolation_string = sprintf('_%s_interpolation',channel_interpolation);
                else
                    interpolation_string = '';
                end
                if strcmp(channel_string,'height_measured')
                    channel_image = handles.map_images.height_measured.(sprintf('absolute_%s_data%s',...
                    "height",interpolation_string));
                elseif strcmp(channel_string, 'Youngs Modulus')
                    channel_image = handles.EModul_matrix_linear_interpolation;
                else
                    channel_image = handles.map_images.(channel_string).(sprintf('%s_data%s',...
                        channel_string,interpolation_string));
                end
                axes(handles.map_axes);
                imshow(flip(channel_image,1),[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel]);
                handles.map_axes.YDir = 'normal';
            
            else
                if strcmp(channel_string,'height')
                    axes(handles.map_axes);
                    imshow(handles.MFP_height_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'slope')
                    axes(handles.map_axes);
                    imshow(handles.MFP_mslope_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'Contactpoint')
                    axes(handles.map_axes);
                    imshow(handles.MFP_cpoint_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                else strcmp(channel_string, 'Youngs Modulus')
                    axes(handles.map_axes);
                    imshow(handles.MFP_Ymodulus_matrix_linear_interpolation,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                end
            end
            handles = update_curve_marker(handles);
            hline = findall(gca,'Type','image');
            set(hline(1),'uicontextmenu',handles.map_axes_context);
            set_afm_gold;
            handles = colorbar_helpf(app, handles.map_axes,handles);
            guidata(hObject,handles);
            app.map_axes.Toolbar.Visible = "off";
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox1
            
            % differ between single and double click on listbox item
            figure_hand = gcf;
            if strcmp(figure_hand.SelectionType,'open')
                try
                    delete(handles.figures.single_curve_fig);
                catch
                    % ME if you can
                end
                handles.figures.single_curve_fig = figure;
                handles.figures.single_curve_ax = axes;
            
                % get number of double clicked_curve
                curve_index = hObject.Value;
            
                % make a copy of handles
                handles_copy = handles;
                % set the curve index as current curve in handles copy
                handles_copy.current_curve = curve_index;
                % process the choosen curve with the handles copy
                [hObject,handles_copy] = process_options(hObject,handles_copy);
                guidata(hObject,handles);
            
                % make the single_curve_fig the main_fig in handles copy aswell as the
                % axes and overwrite fit_handle
                handles_copy.figures.main_fig = handles.figures.single_curve_fig;
                handles_copy.figures.main_ax = handles.figures.single_curve_ax;
                handles_copy.figures.fit_plot = [];
            
                % plot unprocessed curve as dummy in main_plot
                figure(handles_copy.figures.main_fig);
                c_string = sprintf('curve%u',curve_index);
                handles_copy.figures.main_plot = ...
                    plot(handles_copy.proc_curves.(c_string).x_values,handles_copy.proc_curves.(c_string).y_values);
            
                % draw choosen curve
                switch handles.options.model
                    case 'bihertz'
                        [handles_copy] = plot_bihertz(handles_copy);
                        guidata(hObject,handles);
                    case 'hertz'
                        [hObject,handles_copy] = plot_hertz(hObject,handles_copy);
                        guidata(hObject,handles);
                end
            
                % fit model to choosen curve and display fitresult in single_curve_fig
                warning off
                [hObject,handles_copy] = curve_fit_functions(hObject,handles_copy);
                warning on
                guidata(hObject,handles);
            
                % add annotation to single_curve_fig
                figure(handles.figures.single_curve_fig);
                switch handles.options.model
                    case 'bihertz'
                        plot_str = {c_string,...
                            sprintf('Young''s Modulus soft: %.2f kPa',handles_copy.fit_results.fit_E_s/1e3),...
                            sprintf('Young''s Modulus hard: %.2f kPa',handles_copy.fit_results.fit_E_h/1e3),...
                            sprintf('Soft layer thickness: %.2f µm',handles_copy.fit_results.fit_d_h*1e6),...
                            sprintf('R^2: %1.5f',handles_copy.fit_results.rsquare_fit)};
                        hold(handles.figures.single_curve_ax,'on')
                        h1 = line(nan,nan,'Color','none');
                        h2 = line(nan,nan,'Color','none');
                        h3 = line(nan,nan,'Color','none');
                        h4 = line(nan,nan,'Color','none');
                        h5 = line(nan,nan,'Color','none');
                        warning off
                        [~,leg_icons] = legend([h1 h2 h3 h4 h5],plot_str,'Location','northeast');
                        warning on
                        icon_hnd = findobj(leg_icons,'type','text');
                        icon_hnd(1).Position(1) = .08;
                        icon_hnd(2).Position(1) = .08;
                        icon_hnd(3).Position(1) = .08;
                        icon_hnd(4).Position(1) = .08;
                        icon_hnd(5).Position(1) = .08;
                    case 'hertz'
                        plot_str = {c_string,...
                            sprintf('Young''s Modulus: %.2f kPa',handles_copy.fit_results.EModul/1e3),...
                            sprintf('R^2: %1.5f',handles_copy.fit_results.gof_rsquare)};
                        h1 = line(nan,nan,'Color','none');
                        h2 = line(nan,nan,'Color','none');
                        h3 = line(nan,nan,'Color','none');
                        warning off
                        [~,leg_icons] = legend([h1 h2 h3],plot_str,'Location','northeast');
                        warning on
                        icon_hnd = findobj(leg_icons,'type','text');
                        icon_hnd(1).Position(1) = .08;
                        icon_hnd(2).Position(1) = .08;
                        icon_hnd(3).Position(1) = .08;
                end


            else
                % the following code will be executed after single-click
                selection_num = hObject.Value;
            
                if selection_num == handles.current_curve
                    % deaktivate all list buttons
                    handles.button_keep_highlighted.Enable = 'off';
                    handles.button_undo_highlighted.Enable = 'off';
                    handles.button_discard_highlighted.Enable = 'off';
                elseif selection_num < handles.current_curve
                    % aktivate undo list button and deaktivate the other list buttons
                    handles.button_keep_highlighted.Enable = 'off';
                    handles.button_undo_highlighted.Enable = 'on';
                    handles.button_discard_highlighted.Enable = 'off';
                elseif selection_num > handles.current_curve
                    % deaktivate undo list button and aktivate the other list buttons
                    handles.button_keep_highlighted.Enable = 'on';
                    handles.button_undo_highlighted.Enable = 'off';
                    handles.button_discard_highlighted.Enable = 'on';
                end
            end
        end

        % Menu selected function: none
        function none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [hObject,handles] = checker_helpf(app, hObject,handles);
            handles.interpolation_type = 'none';
            channel_num = handles.image_channels_popup.Value;
            channel_string = handles.channel_names{channel_num};
            channel_interpolation = handles.interpolation_type;
            if handles.ibw == false
                if ~strcmp(channel_interpolation,'none')
                    interpolation_string = sprintf('_%s_interpolation',channel_interpolation);
                else
                    interpolation_string = '';
                end
            
                if strcmp(channel_string,'height_measured')
                    channel_image = handles.map_images.height_measured.(sprintf('absolute_%s_data%s',...
                    "height",interpolation_string));
                elseif strcmp(channel_string, 'Youngs Modulus')
                    channel_image = handles.EModul_matrix;    
                else
                    channel_image = handles.map_images.(channel_string).(sprintf('%s_data%s',...
                        channel_string,interpolation_string));
                end
            
                axes(handles.map_axes);
                imshow(flip(channel_image,1),[],'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel]);
                handles.map_axes.YDir = 'normal';
            else
                if strcmp(channel_string,'height')
                    axes(handles.map_axes);
                    imshow(handles.MFP_height_matrix,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'slope')
                    axes(handles.map_axes);
                    imshow(handles.MFP_mslope_matrix,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'Contactpoint')
                    axes(handles.map_axes);
                    imshow(handles.MFP_cpoint_matrix,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
            
                elseif strcmp(channel_string, 'Youngs Modulus')
                    axes(handles.map_axes);
                    imshow(handles.MFP_Ymodulus_matrix,'InitialMagnification','fit','XData',[1 handles.map_info.x_pixel],'YData',[1 handles.map_info.y_pixel], 'DisplayRange', []);
                end
            end
            
            handles = update_curve_marker(handles);
            hline = findall(gca,'Type','image');
            set(hline(1),'uicontextmenu',handles.map_axes_context);
            set_afm_gold;
            handles = colorbar_helpf(app, handles.map_axes,handles);
            guidata(hObject,handles);
        end

        % Menu selected function: report_a_bug_submenu
        function report_a_bug_submenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to report_a_bug_submenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            web('https://github.com/CANTERhm/CANTER_Processing_Tool/issues','-browser');
        end

        % Menu selected function: save_image
        function save_image_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_image (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            path = handles.edit_filepath.String;
            [filepath,filename,~] = fileparts(path);
            
            if ~exist(filepath, 'dir')
              mkdir(filepath);
            end
            
            channels = handles.image_channels_popup.String;
            channel_indx = handles.image_channels_popup.Value;
            channel = channels{channel_indx};
            
            baseFileName =  sprintf('%s_%s_%s.jpg',filename,channel, handles.interpolation_type);
            fullFileName = fullfile(filepath, baseFileName);
            image = getframe(handles.map_axes);
            if exist(fullFileName, 'file')
                answer = questdlg({'File already exists!',...
                        'Do you want to overwrite the existing file?'},...
                        'Warning!','Yes','No','Yes');
                    if strcmp(answer,'Yes')
                        imwrite(image.cdata, fullFileName); % img respresents input image.
                    else
                    end
            else
                imwrite(image.cdata, fullFileName); % img respresents input image.
            end
        end

        % Menu selected function: SoundOff, SoundOn
        function AlertSoundsCallback(app, event)
            SelectedOption = event.Source.Text;
            switch SelectedOption
                case "On"
                    app.SoundOn.Checked = "on";
                    app.SoundOff.Checked = "off";
                case "Off"
                    app.SoundOn.Checked = "off";
                    app.SoundOff.Checked = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Force Curve Analysis';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.exit = uimenu(app.figure1);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Text = 'Exit';
            app.exit.Tag = 'exit';
            app.bug_report_menu = uimenu(app.figure1);
            app.bug_report_menu.Text = 'Bug report';
            app.bug_report_menu.Tag = 'bug_report_menu';
            app.report_a_bug_submenu = uimenu(app.bug_report_menu);
            app.report_a_bug_submenu.MenuSelectedFcn = createCallbackFcn(app, @report_a_bug_submenu_Callback, true);
            app.report_a_bug_submenu.Text = 'Report a bug';
            app.report_a_bug_submenu.Tag = 'report_a_bug_submenu';
            app.how_to_report_submenu = uimenu(app.bug_report_menu);
            app.how_to_report_submenu.MenuSelectedFcn = createCallbackFcn(app, @how_to_report_submenu_Callback, true);
            app.how_to_report_submenu.Text = 'How to report?';
            app.how_to_report_submenu.Tag = 'how_to_report_submenu';
            app.help_menu = uimenu(app.figure1);
            app.help_menu.Text = 'Help';
            app.help_menu.Tag = 'help_menu';
            app.help_wiki = uimenu(app.help_menu);
            app.help_wiki.MenuSelectedFcn = createCallbackFcn(app, @help_wiki_Callback, true);
            app.help_wiki.Text = 'Go to Wiki';
            app.help_wiki.Tag = 'help_wiki';
            app.SoundsMenu = uimenu(app.figure1);
            app.SoundsMenu.Text = 'Sounds';
            app.SoundOff = uimenu(app.SoundsMenu);
            app.SoundOff.MenuSelectedFcn = createCallbackFcn(app, @AlertSoundsCallback, true);
            app.SoundOff.Checked = 'on';
            app.SoundOff.Text = 'Off';
            app.SoundOn = uimenu(app.SoundsMenu);
            app.SoundOn.MenuSelectedFcn = createCallbackFcn(app, @AlertSoundsCallback, true);
            app.SoundOn.Text = 'On';
            app.MainGUIGrid = uigridlayout(app.figure1);
            app.map_axes = uiaxes(app.MainGUIGrid);
            app.map_axes.Tag = 'map_axes';
            app.map_axes.Visible = 'off';
            app.uipanel2 = uipanel(app.MainGUIGrid);
            app.uipanel2.Title = 'Load File';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout23 = uigridlayout(app.uipanel2);
            app.edit_filepath = uieditfield(app.GridLayout23, 'text');
            app.edit_filepath.ValueChangedFcn = createCallbackFcn(app, @edit_filepath_Callback, true);
            app.edit_filepath.Tag = 'edit_filepath';
            app.edit_filepath.Editable = 'off';
            app.edit_filepath.Value = '    filepath';
            app.button_file = uibutton(app.GridLayout23, 'push');
            app.button_file.ButtonPushedFcn = createCallbackFcn(app, @button_file_Callback, true);
            app.button_file.Tag = 'button_file';
            app.button_file.Text = 'Select File';
            app.button_folder = uibutton(app.GridLayout23, 'push');
            app.button_folder.ButtonPushedFcn = createCallbackFcn(app, @button_folder_Callback, true);
            app.button_folder.Tag = 'button_folder';
            app.button_folder.Text = 'Select Folder';
            app.button_load_data = uibutton(app.GridLayout23, 'push');
            app.button_load_data.ButtonPushedFcn = createCallbackFcn(app, @button_load_data_Callback, true);
            app.button_load_data.Tag = 'button_load_data';
            app.button_load_data.Text = 'Load Data';
            app.uipanel20 = uipanel(app.MainGUIGrid);
            app.uipanel20.Title = 'Image Channel';
            app.uipanel20.Tag = 'uipanel20';
            app.GridLayout6 = uigridlayout(app.uipanel20);
            app.image_channels_popup = uidropdown(app.GridLayout6);
            app.image_channels_popup.Items = {'no loaded channels'};
            app.image_channels_popup.ValueChangedFcn = createCallbackFcn(app, @image_channels_popup_Callback, true);
            app.image_channels_popup.Tag = 'image_channels_popup';
            app.image_channels_popup.Enable = 'off';
            app.image_channels_popup.Value = 'no loaded channels';
            app.uipanel11 = uipanel(app.MainGUIGrid);
            app.uipanel11.Title = 'Processing infos';
            app.uipanel11.Tag = 'uipanel11';
            app.GridLayout18 = uigridlayout(app.uipanel11);
            app.uipanel12 = uipanel(app.GridLayout18);
            app.uipanel12.Title = 'Calibration values';
            app.uipanel12.Tag = 'uipanel12';
            app.GridLayout19 = uigridlayout(app.uipanel12);
            app.text_sensitivity = uilabel(app.GridLayout19);
            app.text_sensitivity.Tag = 'text_sensitivity';
            app.text_sensitivity.Text = 'InvOLS:';
            app.text_spring_const = uilabel(app.GridLayout19);
            app.text_spring_const.Tag = 'text_spring_const';
            app.text_spring_const.Text = 'Spring const.:';
            app.baseline_btngroup = uibuttongroup(app.GridLayout18);
            app.baseline_btngroup.SelectionChangedFcn = createCallbackFcn(app, @baseline_btngroup_SelectionChangedFcn, true);
            app.baseline_btngroup.Title = 'Baseline correction mode';
            app.baseline_btngroup.Tag = 'baseline_btngroup';
            app.radiobutton4 = uiradiobutton(app.baseline_btngroup);
            app.radiobutton4.Tag = 'radiobutton4';
            app.radiobutton4.Text = 'none';
            app.offset = uiradiobutton(app.baseline_btngroup);
            app.offset.Tag = 'offset';
            app.offset.Text = 'Offset';
            app.offset_and_tilt = uiradiobutton(app.baseline_btngroup);
            app.offset_and_tilt.Tag = 'offset_and_tilt';
            app.offset_and_tilt.Text = 'Offset + Tilt';
            app.offset_and_tilt.Value = true;
            app.uipanel17 = uipanel(app.GridLayout18);
            app.uipanel17.Title = 'Progress';
            app.uipanel17.Tag = 'uipanel17';
            app.GridLayout21 = uigridlayout(app.uipanel17);
            app.text27 = uilabel(app.GridLayout21);
            app.text27.Tag = 'text27';
            app.text27.Text = 'unprocessed:';
            app.text28 = uilabel(app.GridLayout21);
            app.text28.Tag = 'text28';
            app.text28.Text = 'processed:';
            app.text29 = uilabel(app.GridLayout21);
            app.text29.Tag = 'text29';
            app.text29.Text = 'discarted';
            app.ContactPointFinderPanel = uipanel(app.GridLayout18);
            app.ContactPointFinderPanel.Title = 'Contact Point Finder';
            app.GridLayout20 = uigridlayout(app.ContactPointFinderPanel);
            app.btngroup_contact = uibuttongroup(app.GridLayout20);
            app.btngroup_contact.SelectionChangedFcn = createCallbackFcn(app, @btngroup_contact_SelectionChangedFcn, true);
            app.btngroup_contact.Tag = 'btngroup_contact';
            app.intersection = uiradiobutton(app.btngroup_contact);
            app.intersection.Tag = 'intersection';
            app.intersection.Text = 'via intersection';
            app.intersection.Value = true;
            app.cp_hertzfit = uiradiobutton(app.btngroup_contact);
            app.cp_hertzfit.Tag = 'cp_hertzfit';
            app.cp_hertzfit.Text = 'via Hertz fit';
            app.contact_percentage_hertz = uieditfield(app.GridLayout20, 'text');
            app.contact_percentage_hertz.ValueChangedFcn = createCallbackFcn(app, @contact_percentage_hertz_Callback, true);
            app.contact_percentage_hertz.Tag = 'contact_percentage_hertz';
            app.contact_percentage_hertz.Enable = 'off';
            app.contact_percentage_hertz.Value = '20';
            app.text59 = uilabel(app.GridLayout20);
            app.text59.Tag = 'text59';
            app.text59.Text = '%';
            app.listbox1 = uilistbox(app.MainGUIGrid);
            app.listbox1.Items = {'No curves loaded'};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = 'No curves loaded';
            app.uipanel9 = uipanel(app.MainGUIGrid);
            app.uipanel9.Title = 'Controls';
            app.uipanel9.Tag = 'uipanel9';
            app.GridLayout4 = uigridlayout(app.uipanel9);
            app.button_keep = uibutton(app.GridLayout4, 'push');
            app.button_keep.ButtonPushedFcn = createCallbackFcn(app, @button_keep_Callback, true);
            app.button_keep.Tag = 'button_keep';
            app.button_keep.Enable = 'off';
            app.button_keep.Text = 'Keep';
            app.button_discard = uibutton(app.GridLayout4, 'push');
            app.button_discard.ButtonPushedFcn = createCallbackFcn(app, @button_discard_Callback, true);
            app.button_discard.Tag = 'button_discard';
            app.button_discard.Enable = 'off';
            app.button_discard.Text = 'Discard';
            app.button_undo = uibutton(app.GridLayout4, 'push');
            app.button_undo.ButtonPushedFcn = createCallbackFcn(app, @button_undo_Callback, true);
            app.button_undo.Tag = 'button_undo';
            app.button_undo.Enable = 'off';
            app.button_undo.Text = 'Undo';
            app.button_keep_all = uibutton(app.GridLayout4, 'push');
            app.button_keep_all.ButtonPushedFcn = createCallbackFcn(app, @button_keep_all_Callback, true);
            app.button_keep_all.Tag = 'button_keep_all';
            app.button_keep_all.Enable = 'off';
            app.button_keep_all.Text = {'Keep & Apply'; 'to all'};
            app.info_panel_panel = uipanel(app.MainGUIGrid);
            app.info_panel_panel.Title = 'Info Panel';
            app.info_panel_panel.Tag = 'info_panel_panel';
            app.GridLayout5 = uigridlayout(app.info_panel_panel);
            app.info_table = uitable(app.GridLayout5);
            app.info_table.ColumnName = {'Info'; 'Value'};
            app.info_table.RowName = '';
            app.info_table.ColumnEditable = [false false];
            app.info_table.Tag = 'info_table';
            app.GridLayout3 = uigridlayout(app.MainGUIGrid);
            app.button_keep_highlighted = uibutton(app.GridLayout3, 'push');
            app.button_keep_highlighted.ButtonPushedFcn = createCallbackFcn(app, @button_keep_highlighted_Callback, true);
            app.button_keep_highlighted.Tag = 'button_keep_highlighted';
            app.button_keep_highlighted.Enable = 'off';
            app.button_keep_highlighted.Text = {'keep till'; 'highlighted'};
            app.button_undo_highlighted = uibutton(app.GridLayout3, 'push');
            app.button_undo_highlighted.ButtonPushedFcn = createCallbackFcn(app, @button_undo_highlighted_Callback, true);
            app.button_undo_highlighted.Tag = 'button_undo_highlighted';
            app.button_undo_highlighted.Enable = 'off';
            app.button_undo_highlighted.Text = {'undo till'; 'highlighted'};
            app.button_discard_highlighted = uibutton(app.GridLayout3, 'push');
            app.button_discard_highlighted.ButtonPushedFcn = createCallbackFcn(app, @button_discard_highlighted_Callback, true);
            app.button_discard_highlighted.Tag = 'button_discard_highlighted';
            app.button_discard_highlighted.Enable = 'off';
            app.button_discard_highlighted.Text = {'discard till'; 'highlighted'};
            app.GridLayout17 = uigridlayout(app.MainGUIGrid);
            app.uipanel13 = uipanel(app.GridLayout17);
            app.uipanel13.Title = 'Fit-Model';
            app.uipanel13.Tag = 'uipanel13';
            app.GridLayout16 = uigridlayout(app.uipanel13);
            app.fit_model_popup = uidropdown(app.GridLayout16);
            app.fit_model_popup.Items = {'Hertz-Fit', 'Bihertz_sum_heaviside', 'Bihertz_switch_heaviside', ''};
            app.fit_model_popup.ValueChangedFcn = createCallbackFcn(app, @fit_model_popup_Callback, true);
            app.fit_model_popup.Tag = 'fit_model_popup';
            app.fit_model_popup.Enable = 'off';
            app.fit_model_popup.Value = 'Hertz-Fit';
            app.uipanel4 = uipanel(app.MainGUIGrid);
            app.uipanel4.Title = 'Save Results';
            app.uipanel4.Tag = 'uipanel4';
            app.GridLayout22 = uigridlayout(app.uipanel4);
            app.button_save_path = uibutton(app.GridLayout22, 'push');
            app.button_save_path.ButtonPushedFcn = createCallbackFcn(app, @button_save_path_Callback, true);
            app.button_save_path.Tag = 'button_save_path';
            app.button_save_path.Text = 'Save Results';
            app.edit_savepath = uieditfield(app.GridLayout22, 'text');
            app.edit_savepath.Tag = 'edit_savepath';
            app.edit_savepath.Editable = 'off';
            app.edit_savepath.Value = '     savepath';
            app.save_status_text = uilabel(app.GridLayout22);
            app.save_status_text.Tag = 'save_status_text';
            app.save_status_text.Text = 'Save status: ';
            app.save_status_led = uieditfield(app.GridLayout22, 'text');
            app.save_status_led.Tag = 'save_status_led';
            app.save_status_led.Enable = 'off';
            app.GridLayout24 = uigridlayout(app.MainGUIGrid);
            app.ForceCurveAnalysisLabel = uilabel(app.GridLayout24);
            app.ForceCurveAnalysisLabel.Text = 'Force Curve Analysis';
            app.BiHertzFitPanel = uipanel(app.MainGUIGrid);
            app.BiHertzFitPanel.Title = 'BiHertz-Fit';
            app.GridLayout12 = uigridlayout(app.BiHertzFitPanel);
            app.uipanel5 = uipanel(app.GridLayout12);
            app.uipanel5.Title = 'Fit Parameter';
            app.uipanel5.Tag = 'uipanel5';
            app.GridLayout13 = uigridlayout(app.uipanel5);
            app.text7 = uilabel(app.GridLayout13);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Fit depth of soft part';
            app.fit_depth = uieditfield(app.GridLayout13, 'text');
            app.fit_depth.ValueChangedFcn = createCallbackFcn(app, @fit_depth_Callback, true);
            app.fit_depth.Tag = 'fit_depth';
            app.fit_depth.Value = '1';
            app.text8 = uilabel(app.GridLayout13);
            app.text8.Tag = 'text8';
            app.text8.Text = 'µm';
            app.text9 = uilabel(app.GridLayout13);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Fit percentage of hard part';
            app.fit_perc = uieditfield(app.GridLayout13, 'text');
            app.fit_perc.ValueChangedFcn = createCallbackFcn(app, @fit_perc_Callback, true);
            app.fit_perc.Tag = 'fit_perc';
            app.fit_perc.Value = '5';
            app.text10 = uilabel(app.GridLayout13);
            app.text10.Tag = 'text10';
            app.text10.Text = '%';
            app.uipanel10 = uipanel(app.GridLayout12);
            app.uipanel10.Title = 'Fit Results';
            app.uipanel10.Tag = 'uipanel10';
            app.GridLayout14 = uigridlayout(app.uipanel10);
            app.text12 = uilabel(app.GridLayout14);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Young''s Modulus soft';
            app.result_Es = uilabel(app.GridLayout14);
            app.result_Es.Tag = 'result_Es';
            app.result_Es.Enable = 'off';
            app.result_Es.Text = 'none';
            app.text14 = uilabel(app.GridLayout14);
            app.text14.Tag = 'text14';
            app.text14.Text = 'kPa';
            app.text15 = uilabel(app.GridLayout14);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Young''s Modulus hard';
            app.result_Eh = uilabel(app.GridLayout14);
            app.result_Eh.Tag = 'result_Eh';
            app.result_Eh.Enable = 'off';
            app.result_Eh.Text = 'none';
            app.text17 = uilabel(app.GridLayout14);
            app.text17.Tag = 'text17';
            app.text17.Text = 'kPa';
            app.text18 = uilabel(app.GridLayout14);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Soft layer thickness';
            app.result_dh = uilabel(app.GridLayout14);
            app.result_dh.Tag = 'result_dh';
            app.result_dh.Enable = 'off';
            app.result_dh.Text = 'none';
            app.text20 = uilabel(app.GridLayout14);
            app.text20.Tag = 'text20';
            app.text20.Text = 'µm';
            app.text69 = uilabel(app.GridLayout14);
            app.text69.Tag = 'text69';
            app.text69.Text = 'Switch point';
            app.result_switch_point = uieditfield(app.GridLayout14, 'text');
            app.result_switch_point.Tag = 'result_switch_point';
            app.result_switch_point.Enable = 'off';
            app.result_switch_point.Value = 'none';
            app.text71 = uilabel(app.GridLayout14);
            app.text71.Tag = 'text71';
            app.text71.Text = 'µm';
            app.text30 = uilabel(app.GridLayout14);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Goodness of Fit';
            app.result_rsquare = uilabel(app.GridLayout14);
            app.result_rsquare.Tag = 'result_rsquare';
            app.result_rsquare.Enable = 'off';
            app.result_rsquare.Text = 'none';
            app.hertz_fit_panel = uipanel(app.MainGUIGrid);
            app.hertz_fit_panel.Title = 'Hertz-Fit';
            app.hertz_fit_panel.Tag = 'hertz_fit_panel';
            app.GridLayout7 = uigridlayout(app.hertz_fit_panel);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.text72 = uilabel(app.GridLayout8);
            app.text72.Tag = 'text72';
            app.text72.Text = 'Fit start';
            app.text44 = uilabel(app.GridLayout8);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Fit depth';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.hertz_fit_start = uieditfield(app.GridLayout9, 'text');
            app.hertz_fit_start.ValueChangedFcn = createCallbackFcn(app, @hertz_fit_start_Callback, true);
            app.hertz_fit_start.Tag = 'hertz_fit_start';
            app.hertz_fit_start.Value = '0';
            app.text73 = uilabel(app.GridLayout9);
            app.text73.Tag = 'text73';
            app.text73.Text = 'µm';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.hertz_fit_depth = uieditfield(app.GridLayout10, 'text');
            app.hertz_fit_depth.ValueChangedFcn = createCallbackFcn(app, @hertz_fit_depth_Callback, true);
            app.hertz_fit_depth.Tag = 'hertz_fit_depth';
            app.hertz_fit_depth.Value = '1';
            app.text46 = uilabel(app.GridLayout10);
            app.text46.Tag = 'text46';
            app.text46.Text = 'µm';
            app.ContactPointDropDownLabel = uilabel(app.GridLayout8);
            app.ContactPointDropDownLabel.Enable = 'off';
            app.ContactPointDropDownLabel.Text = 'Contact Point:';
            app.ContactPointDropDown = uidropdown(app.GridLayout8);
            app.ContactPointDropDown.Items = {'Fixed', 'Free'};
            app.ContactPointDropDown.Enable = 'off';
            app.ContactPointDropDown.Value = 'Fixed';
            app.GridLayout11 = uigridlayout(app.GridLayout7);
            app.text47 = uilabel(app.GridLayout11);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Young''s Modulus';
            app.hertz_EModul = uilabel(app.GridLayout11);
            app.hertz_EModul.Tag = 'hertz_EModul';
            app.hertz_EModul.Text = 'none';
            app.text49 = uilabel(app.GridLayout11);
            app.text49.Tag = 'text49';
            app.text49.Text = 'kPa';
            app.btn_histogram = uibutton(app.GridLayout11, 'push');
            app.btn_histogram.ButtonPushedFcn = createCallbackFcn(app, @btn_histogram_Callback, true);
            app.btn_histogram.Tag = 'btn_histogram';
            app.btn_histogram.Enable = 'off';
            app.btn_histogram.Text = 'Histogram';
            app.text53 = uilabel(app.GridLayout11);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Goodness of Fit';
            app.hertz_gof = uilabel(app.GridLayout11);
            app.hertz_gof.Tag = 'hertz_gof';
            app.hertz_gof.Text = 'none';
            app.btn_gof = uibutton(app.GridLayout11, 'push');
            app.btn_gof.ButtonPushedFcn = createCallbackFcn(app, @btn_gof_Callback, true);
            app.btn_gof.Tag = 'btn_gof';
            app.btn_gof.Enable = 'off';
            app.btn_gof.Text = 'Histogram';
            app.ContactPointLabel = uilabel(app.GridLayout11);
            app.ContactPointLabel.Text = 'Contact Point';
            app.ContactPointField = uieditfield(app.GridLayout11, 'numeric');
            app.ContactPointField.ValueDisplayFormat = '%.2f';
            app.ContactPointField.Editable = 'off';
            app.CP_unit_label = uilabel(app.GridLayout11);
            app.CP_unit_label.Tag = 'CP_unit_label';
            app.CP_unit_label.Text = 'nm';
            app.map_axes_context = uicontextmenu(app.figure1);
            app.map_axes_context.Tag = 'map_axes_context';
            app.interpolation_type = uimenu(app.map_axes_context);
            app.interpolation_type.Text = 'Interpolation';
            app.interpolation_type.Tag = 'interpolation_type';
            app.none = uimenu(app.interpolation_type);
            app.none.MenuSelectedFcn = createCallbackFcn(app, @none_Callback, true);
            app.none.Text = 'none';
            app.none.Tag = 'none';
            app.linear = uimenu(app.interpolation_type);
            app.linear.MenuSelectedFcn = createCallbackFcn(app, @linear_Callback, true);
            app.linear.Text = 'linear';
            app.linear.Tag = 'linear';
            app.bicubic = uimenu(app.interpolation_type);
            app.bicubic.MenuSelectedFcn = createCallbackFcn(app, @bicubic_Callback, true);
            app.bicubic.Checked = 'on';
            app.bicubic.Text = 'bicubic';
            app.bicubic.Tag = 'bicubic';
            app.save_image = uimenu(app.map_axes_context);
            app.save_image.MenuSelectedFcn = createCallbackFcn(app, @save_image_Callback, true);
            app.save_image.Text = 'Save Image';
            app.save_image.Tag = 'save_image';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bihertz_gui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)bihertz_gui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end